classdef AnalyzeJumpHistogram_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackAnalyzeJumpHistogramUIFigure  matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        LoadJDHanalysisMenu  matlab.ui.container.Menu
        SaveJDHanalysisMenu  matlab.ui.container.Menu
        DONEButton           matlab.ui.control.Button
        SaveFigure           matlab.ui.control.Button
        CopyFitPar           matlab.ui.control.Button
        CopyFit              matlab.ui.control.Button
        CopyHist             matlab.ui.control.Button
        ChooseDraw           matlab.ui.control.DropDown
        edit1                matlab.ui.control.Label
        plot_axes            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        PathName % Description
        FileNames % Description
        AnalysisS % Description
        tlist % Description
        rlist % Description
        StableParams % Description
        fit1Cmp % Description
        fit2Cmp % Description
        ROIName % Description
        JDH % Description
        Db % Description
        Sigma % Description
        useParallel % Description
        fitDiffOnly % Description
        deltaZ % Description
        bootStrapN % Description
        JDH_boot % Description
        fitSelect % Description
    end

    methods (Access = private)

        function JDH_PhB = correctBleachJDH(~, JDH, BleachRate, tlist)
            % This function corrects a Jump distance histogram for photobleaching
            % described by a single exponential.
            % Input:
            % The histogram JDH has the following structure
            %
            %           |  JDH(r1, t1)      JDH(r2, t1)    ...      JDH(rmax, t1)   |
            %           |  JDH(r1, t2)      JDH(r2, t2)    ...      JDH(rmax, t2)   |
            %  JDH    = |      ...              ...        ...           ...        |
            %           |  JDH(r1, tmax)    JDH(r2, tmax)   ...     JDH(rmax, tmax) |
            %
            %--------------------------------------------------------------------------
            % tlist is a column vector with the times [t1, ...,tmax]
            % Bleach rate is a scalar (units s^-1)


            Rate1 = BleachRate(1);
            Rate2 = BleachRate(2);
            f1 = BleachRate(3);

            CorrMatrix = repmat(tlist, [1 length(JDH(1,:))]);
            CorrMatrix = f1*exp(-CorrMatrix*Rate1) + (1-f1)*exp(-CorrMatrix*Rate2);
            JDH_PhB = JDH./CorrMatrix;
        end


        function [Esp_Coef, Esp_Sigma, Esp_Fit] = ExpDecay_2Cmp_fit(app,data,k0s)
            % Calculate best two compoonent exponential fit with a the lsqnonlin non
            %linear least square fitting routine.


            % Read input data

            tlist1 =data(:,1);
            Y = data(:,2);


            k01 = k0s(1);
            k02 = k0s(2);
            f01 = 0.5;
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k01, k02, f01, A0];


            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0, 0 , 0];


            % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf, 1,Inf];

            % Define anonymous function for the fitting
            fitfun = @(COEF) (ExpDecay_2Cmp_fun(app,COEF, tlist1)) - (Y);

            % Select Options for the fitting
            options = optimset('FunValCheck','off', 'MaxIter', 1000, 'MaxFunEvals', 1000);

            % run fitting routibe
            [Esp_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);


            ci = nlparci(Esp_Coef,residuals,'jacobian',jacobian);
            Esp_Sigma = (ci(:,2) - ci(:,1))/2;
            Esp_Sigma = Esp_Sigma';


            % Compute output
            Esp_Fit(:,1) = data(:,1);
            Esp_Fit(:,2) = ExpDecay_2Cmp_fun(app,Esp_Coef, data(:,1));

            %Compute the individual components --DB
            Esp_Coef_1 = [Esp_Coef(1) Esp_Coef(4)*Esp_Coef(3)];
            Esp_Fit(:,3) = ExpDecay_fun(app,Esp_Coef_1,data(:,1));

            Esp_Coef_2 = [Esp_Coef(2) Esp_Coef(4)*(1 - Esp_Coef(3))];
            Esp_Fit(:,4) = ExpDecay_fun(app,Esp_Coef_2,data(:,1));
        end

        function esp = ExpDecay_2Cmp_fun(~,coeff,x)
            k1 = coeff(1);
            k2 = coeff(2);
            f = coeff(3);
            a = coeff(4);


            esp = a*(f*(exp(-k1*x)) + (1-f)*exp(-k2*x))*(1 - (k1 < 0|k2 < 0|f < 0|f > 1| a<0));
        end


        %         function [FitPar, FitParSigma, FitSSR, Fit] = RD_JD_Fit_dz_BoundDiff(app,Data, Par, Ini)
        %             % ReactionDiffusion_JD_Fit
        %             % ------------------------------------------------------------------------
        %             % Fit a Reaction diffusion model to the Three-dimensional Jump Distance
        %             % Histogram. The bound component is allowed to diffuse with a fixed
        %             % diffusion coefficient Db
        %             %
        %             % Data is the data to be fit displayed in a matrix with:
        %             %
        %             %
        %             %        |  Data(r1, t1)      Data(r2, t1)    ...      Data(rmax, t1)   |
        %             %        |  Data(r1, t2)      Data(r2, t2)    ...      Data(rmax, t2)   |
        %             %  Data= |      ...              ...        ...           ...           |
        %             %        |  Data(r1, tmax)    Data(r2, tmax)   ...     Data(rmax, tmax) |
        %             %
        %             % Par is a cell array containing the time-points and displacements for
        %             % which the Data array is calculated:
        %             % Par{1} = tlist
        %             % Par{2} = rlist
        %             % Par{3} = Db
        %             % Par{4} = sigma
        %             %
        %             % Ini is the initial values for the parameters, in the order:
        %             % D0, n, kon, koff, dZ
        %
        %
        %
        %
        %             % define lower boundaries for the fitted parameters
        %             FitParLB = [0, 0, 0, 0,  0.4];
        %             % define upper boundaries for the fitted parameters
        %             FitParUB = [Inf, Inf, Inf, Inf, 10];
        %
        %             % prepare function for fit
        %             fitfun = @(COEF) (RD_JD_Fun_dz_BoundDiff(app,COEF, Par) - Data);
        %
        %             % Select Options for the fitting
        %             options = optimset('FunValCheck','off');
        %
        %
        %
        %             % run fitting routine
        %             [FitPar, ~, residuals,~,~,~,jacobian] ...
        %                 = lsqnonlin(fitfun,Ini,FitParLB,FitParUB,options);
        %
        %             ci = nlparci(FitPar,residuals,'jacobian',jacobian);
        %             Fit = RD_JD_Fun_dz_BoundDiff(app,FitPar, Par);
        %
        %             FitParSigma = (ci(:,2) - ci(:,1))/2;
        %             FitParSigma = FitParSigma';
        %             FitSSR = sum(residuals(:).^2);
        %         end

        %         function OUT = RD_JD_Fun_dz_BoundDiff(app,COEF, PAR)
        %             % Compute the reaction diffusion formula for the Jump distance histogram.
        %             % The formula account for a finite size of the observation slice, according
        %             % to the "absorbing boundary" approximation. The bound popularion is
        %             % allowed to diffuse with a fixed diffusion coefficient Db
        %             %
        %             % INPUT
        %             % -------------------------------------------------------------------------
        %             % COEF is a vector with the following structure:
        %             % COEF(1) = D
        %             % COEF(2) = n
        %             % COEF(3) = kon
        %             % COEF(4) = koff
        %             % COEF(5) = DeltaZ
        %             %
        %             % PAR is a cell array containing the list of the spatial and temporal
        %             % coordinates for which the Jump histogram has to be calculated.
        %             % PAR{1} = tlist;
        %             % PAR{2} = rlist;
        %             % PAR{3} = Db;
        %             % PAR{4} = sigma;
        %             %
        %             % OUTPUT
        %             % -------------------------------------------------------------------------
        %             % OUT is a matrix with the (time and space dependent) jump distance
        %             % histogram. The structure of OUT is:
        %             %
        %             %        |  OUT(r1, t1)      OUT(r2, t1)    ...      OUT(rmax, t1)   |
        %             %        |  OUT(r1, t2)      OUT(r2, t2)    ...      OUT(rmax, t2)   |
        %             %  OUT = |      ...              ...        ...           ...        |
        %             %        | OUT(r1, tmax)    OUT(r2, tmax)   ...     OUT(rmax, tmax)  |
        %             %
        %             %--------------------------------------------------------------------------
        %
        %
        %
        %
        %             % Read and prepare input
        %             % ------------------------
        %             tlist1 = PAR{1};
        %             rlist1 = PAR{2};
        %             Db1 = PAR{3};
        %             sigma1 = PAR{4};
        %
        %             D = COEF(1);
        %             n = COEF(2);
        %             kon =  COEF(3);
        %             koff = COEF(4);
        %             deltaZ = COEF(5);
        %
        %
        %
        %             % Calculate Jump distance histogram
        %             % -------------------------------------
        %             % The formula for the jump distance histogram is composed by three parts:
        %             % the first part, JDa, represent the molecules that stay bound for the
        %             % whole time t, JDb, the second term JDb is the molecules that stay unbound for the
        %             % whole time t,the third, JDc that accounts for molecules that associate and
        %             % dissociate over the inteval t (which need to be integrated numerically)
        %             % Each of the components is represented as a matrix with the following
        %             % structure:
        %             %                |  JDi(r1 , t1)    ....    JDi(rmax, t1)  |
        %             %  JDi(r,t) =    |      ...         ....         ...       |
        %             %                |  JDi(r1, tmax)   ....    JDi(rmax, tmax)|
        %             %
        %
        %
        %             % Calculation of JDa
        %             % JDa is the term accounting for the particles that are bound for the whole
        %             % time t
        %
        %             [rM, tM] = meshgrid(rlist1,tlist1);
        %
        %             Loc = rM./(2*(sigma1^2+Db1*tM)).*exp(-rM.^2./(4*(sigma1^2+ Db1*tM)));      % Spatial dependency
        %             Exp = kon/(kon+koff)*exp(-koff*tM);                                    % Temporal dependency
        %             JDa = Loc.*Exp;
        %
        %             % Calculation of JDb
        %             % JDb is the diffusion solution for the particles that are free for the
        %             % whole time t. It has to be scaled for the probability of staying within
        %             % the observation slice (DeltaZ correction).
        %
        %
        %
        %             % Prepare coefficients for the calculation of the DeltaZ correction
        %             PARdz(:,1) = tlist1;
        %             PARdz(1,2) = D;
        %             PARdz(2,2) = 1;
        %
        %             % Call zScale function
        %             zScale = RD_JD_zScaleAB(app,deltaZ, PARdz, 0);
        %             zScale = zScale*ones(1,length(rlist1));
        %
        %
        %
        %             Diff = zScale.*rM./(2*(D*tM + sigma1^2)).*...
        %                 exp(-rM.^2./(4*(D*tM + sigma1^2))); % diffusive part
        %
        %             Exp = koff/(kon+koff)*exp(-kon*tM); % population decay over time
        %             JDb = Diff.*Exp;
        %
        %             % Calculation of JDc
        %             % JDc can't be calculated matrix-wide, since numerical
        %             % integration is required.
        %
        %             % Set coefficients for numerical integration.
        %             COEFint = [D,kon,koff, sigma1, deltaZ, Db1];
        %             % initialize Jdc
        %             JDc = zeros(length(tlist1), length(rlist1));
        %
        %             for i = 1: length(tlist1)
        %
        %                 IntFun = @(s) RD_JD_Int_dz_BoundDiff(app,s,tlist1(i), rlist1, COEFint);
        %                 LowLimit = 0.1*tlist1(1);
        %                 HighLimit = 0.99*tlist1(i);
        %                 JDc(i,:) = kon*koff / (kon + koff) * quadv(IntFun, LowLimit, HighLimit);
        %
        % %                 JDc(i,:) = kon*koff / (kon + koff) * integral(IntFun, LowLimit, HighLimit,'ArrayValued',true);
        %
        %
        %             end
        %
        %             %Sum the three components together
        %
        %
        %
        %             OUT = n*(JDa + JDb + JDc)*(rlist1(2)-rlist1(1));
        %         end

        function [COEF, fit, COEFsigma] = JDHfixedT_3cmp_fit(app, Hist,jumpTime, D0s)
            % JDHfixedT_3cmp_fit
            % -------------------------------------------------------------------------
            % This function calls the fitting routine for the fit of a jump histogram
            % (fixed time). The fitting is performed with the lsqnonlin function from
            % from the optimization toolbox. The function is a three-component diffusion
            % model
            % -------------------------------------------------------------------------
            % The output COEF has the resulting parameters of the fit in the order
            % [A, D1, D2, f1, SSR];
            % The output fit is a two column vector: fit(:,1) =rlist
            %                                        fit(:,2) = actual fit


            % Read Input
            rlist1(:,1) = Hist(:,1);
            rlist1(1,2)=jumpTime;

            Y = Hist(:,2);

            % define initial values for the parameters
            COEF0 = [3*max(Y), D0s(1), D0s(2) D0s(3), 0.33, 0.33];


            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0.05, 0.0001, 0.0001, 0, 0];

            % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, 10, 0.1, 0.1, 1, 1];

            % Define anonymous function for the fitting
            fitfun = @(COEF) (JDHfixedT_3cmp_fun(app,COEF, rlist1) - Y);


            % Define options for the fitting function
            options = optimset('FunValCheck','off','Display','off');

            % run fitting routibe
            [COEF, ~, residuals,~,~,~,jacobian]= lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            ci = nlparci(COEF,residuals,'jacobian',jacobian);
            COEFsigma = (ci(:,2) - ci(:,1))/2;
            COEFsigma = COEFsigma';

            % check if the fit provides acceptable estimates
            if min(COEF > 0) == 1 && max(COEF)< Inf
                fit(:,1)=rlist1(:,1);
                fit(:,2)=JDHfixedT_3cmp_fun(app,COEF,rlist1);

            else % Otherwise put the fit to 0
                fit= zeros(length(rlist1(:,1)),2);
                disp('THREE COMPONENT FIT of JDH1 FAILED!')
            end

            SSR = sum((fit(:,2)-Hist(:,2)).^2);

            disp('')
            disp('--------------------------------------------')
            disp('Three component Fit of FJH')
            disp(['fitted D1 = ', num2str(COEF(2)), ' mum^2/s'])
            disp(['fitted D2 = ', num2str(COEF(3)), ' mum^2/s'])
            disp(['fitted D3 = ', num2str(COEF(4)), ' mum^2/s'])
            disp(['fitted f1 = ', num2str(COEF(5))])
            disp(['fitted f2 = ', num2str(COEF(6))])
            disp(['SSR = ', num2str(SSR)]);
            disp('--------------------------------------------')
            disp('')

            COEF(7) = SSR;
        end

        function gaussian = JDHfixedT_3cmp_fun(~,COEF, rlist)
            % Calculate the jump distance histogram associated (for a fixed time)
            % associated to three species of diffusing molecules with diffusion
            % coefficient D for A jumps.

            % Read Input
            A=COEF(1);
            D1 = COEF(2);
            D2 = COEF(3);
            D3 = COEF(4);
            f1 = COEF(5);
            f2 = COEF(6);

            r = rlist(:,1);
            t_bin = rlist(1,2);
            dr = r(2) - r(1);

            gaussian=dr.*r.*A.*(f1/(2*D1*t_bin).*exp(-r.^2/(4*D1*t_bin)) + ...
                (f2/(2*D2*t_bin).*exp(-r.^2/(4*D2*t_bin))) + ...
                (1 - f1 - f2)/(2*D3*t_bin).*exp(-r.^2/(4*D3*t_bin)));
        end

        %         function [FitPar, FitParSigma, FitSSR, Fit] = RD_JD_Fit_dz_2CMP(app,Data, Par, Ini)
        %             % ReactionDiffusion_JD_Fit
        %             % ------------------------------------------------------------------------
        %             % Fit a Reaction diffusion model to the Three-dimensional Jump Distance
        %             % Histogram for two independent diffusing components exchanging with a
        %             % bound population.
        %             %
        %             % Data is the data to be fit displayed in a matrix with:
        %             %
        %             %
        %             %        |  Data(r1, t1)      Data(r2, t1)    ...      Data(rmax, t1)   |
        %             %        |  Data(r1, t2)      Data(r2, t2)    ...      Data(rmax, t2)   |
        %             %  Data= |      ...              ...        ...           ...           |
        %             %        |  Data(r1, tmax)    Data(r2, tmax)   ...     Data(rmax, tmax) |
        %             %
        %             % Par is a cell array containing the time-points and displacements for
        %             % which the Data array is calculated:
        %             % Par{1} = tlist
        %             % Par{2} = rlist
        %             % Par{3} = Db, diffusion coefficient of bound molecules
        %             % Par{4} = sigma, localization accuracy
        %             % Par{5} = [D1,D2], diffusion coefficients for the two components
        %             %
        %             % Ini is the initial values for the parameters, in the order:
        %             % f1, n, kon, koff, dZ
        %
        %
        %
        %
        %
        %             % define lower boundaries for the fitted parameters
        %             FitParLB = [0,  0, 0, 0,  0.4];
        %             % define upper boundaries for the fitted parameters
        %             FitParUB = [ 1, Inf, Inf, Inf, 10];
        %
        %             % prepare function for fit
        %             fitfun = @(COEF) (RD_JD_Fun_dz_2CMP(app,COEF, Par) - Data);
        %
        %             % Select Options for the fitting
        %             options = optimset('FunValCheck','off');
        %
        %
        %
        %             % run fitting routine
        %             [FitPar, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,Ini,FitParLB,FitParUB,options);
        %
        %             ci = nlparci(FitPar,residuals,'jacobian',jacobian);
        %
        %             FitParSigma = (ci(:,2) - ci(:,1))/2;
        %             FitParSigma = FitParSigma';
        %             FitSSR = sum(residuals(:).^2);
        %
        %
        %             Fit = RD_JD_Fun_dz_2CMP(app,FitPar, Par);
        %
        %
        %
        %             % Display results of the fit.
        %             disp('_________________________________________')
        %             disp('FIT of the FULL JUMP DISTANCE HISTOGRAMS:')
        %             disp(['D1 = ', num2str(Par{5}(1),2), '\mum^2/s'])
        %             disp(['D2 = ' num2str(Par{5}(2),2), '\mum^2/s'])
        %             disp(['kon = ', num2str(FitPar(3),2), 's^{-1}'])
        %             disp(['koff = ', num2str(FitPar(4),2), 's^{-1}'])
        %             disp(['deltaZ = ', num2str(FitPar(5),2), '\mum'])
        %             disp('_________________________________________')
        %
        %         end

        %         function OUT = RD_JD_Fun_dz_2CMP(app,COEF, PAR)
        %             % compute the reaction diffusion formula for the Jump distance histogram.
        %             % The formula account for a finite size of the observation slice, according
        %             % to the "Boundary at acquisition time" approximation. It computes the
        %             % reaction diffusion formula with two independent diffusion components
        %             % exchanging with a bound (immobile) component.
        %
        %             % INPUT
        %             % -------------------------------------------------------------------------
        %             % COEF is a vector with the following structure:
        %
        %             % COEF(1) = f1
        %             % COEF(2) = n
        %             % COEF(3) = kon
        %             % COEF(4) = koff
        %             % COEF(6) = deltaZ
        %             %
        %             % PAR is a cell array containing the list of the spatial and temporal
        %             % coordinates for which the Jump histogram has to be calculated.
        %             % PAR{1} = tlist;
        %             % PAR{2} = rlist;
        %             %
        %             % OUTPUT
        %             % -------------------------------------------------------------------------
        %             % OUT is a matrix with the (time and space dependent) jump distance
        %             % histogram. The structure of OUT is:
        %             %
        %             %        |  OUT(r1, t1)      OUT(r2, t1)    ...      OUT(rmax, t1)   |
        %             %        |  OUT(r1, t2)      OUT(r2, t2)    ...      OUT(rmax, t2)   |
        %             %  OUT = |      ...              ...        ...           ...        |
        %             %        | OUT(r1, tmax)    OUT(r2, tmax)   ...     OUT(rmax, tmax)  |
        %             %
        %             %--------------------------------------------------------------------------
        %
        %
        %             % Read and prepare input
        %             % ------------------------
        %
        %             D1 = PAR{5}(1);
        %             D2 = PAR{5}(2);
        %
        %             f1 = COEF(1);
        %             n = COEF(2);
        %             kon =  COEF(3);
        %             koff = COEF(4);
        %             deltaZ = COEF(5);
        %
        %
        %             COEF_D1 = [D1, f1*n, kon, koff, deltaZ];
        %             COEF_D2 = [D2, (1-f1)*n, kon, koff, deltaZ];
        %
        %             OUT1 =  RD_JD_Fun_dz_BoundDiff(app,COEF_D1, PAR);
        %             OUT2 =  RD_JD_Fun_dz_BoundDiff(app,COEF_D2, PAR);
        %
        %             OUT = OUT1 + OUT2;
        %
        %         end

        function esp = ExpDecay_fun(~,coeff, x)
            k = coeff(1);
            a= coeff(2);


            esp = a*(exp(-k*x));


        end

        %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                app.PathName = varargin{1};
                app.FileNames = varargin{2};
                app.AnalysisS = varargin{3};
                app.useParallel = varargin{4};

                if length(varargin) > 4
                    pos_fig = varargin{5};
                    ChangeAppPosition(app.QuantiTrackAnalyzeJumpHistogramUIFigure,pos_fig);

                    drawnow;
                end

                JHistBinSize = str2double(app.AnalysisS{1});
                JHistMaxFrameN = str2double(app.AnalysisS{2});
                maxJump = str2double(app.AnalysisS{3});
                % frameTime = str2double(app.AnalysisS{4});
                app.Db = str2double(app.AnalysisS{4});
                app.deltaZ = str2double(app.AnalysisS{5});
                app.bootStrapN = str2double(app.AnalysisS{6});
                app.fitSelect = app.AnalysisS{7};


                binEdges = 0:JHistBinSize:maxJump;

                %disable the buttons for copying the fits since we are showing
                %the heat map
                app.CopyFit.Enable = 'off';
                app.CopyFitPar.Enable = 'off';


                % If only one file has been selected change the fileNames variable
                % to a cell array

                if ~iscell(app.FileNames)
                    app.FileNames = {app.FileNames};
                end

                nFiles = length(app.FileNames);

                %if we have only 1 file it will likely be a trackTable
                if nFiles == 1
                    IN = load(fullfile(app.PathName,app.FileNames{1}));
                    if isfield(IN,'trackTable')
                        [Tracks, NParticles, ROIChoice] = compileTracksFromTables(...
                            IN.trackTable,0,...
                            app.QuantiTrackAnalyzeJumpHistogramUIFigure,...
                            'Tracking');
                        nMovies = length(Tracks);
                        frameTime = IN.trackTable.interval(1);
                        app.Sigma = mean(IN.trackTable.Loc_precision,'omitmissing');

                    else
                        [Tracks, NParticles, ROIChoice,~,~,~,~,~,~,~,~,locPrec] = compileTracksFromFiles(...
                            app.FileNames,app.PathName,0,...
                            app.QuantiTrackAnalyzeJumpHistogramUIFigure,...
                            'Tracking');
                        app.Sigma = mean(locPrec);
                        frameTime = Tracks{1}(2,3) - Tracks{1}(1,3);
                        nMovies = 1;
                    end
                else %if more than one file is selected it should be tracking files
                    [Tracks, NParticles, ROIChoice,~,~,~,~,~,~,~,~,locPrec] = compileTracksFromFiles(...
                        app.FileNames,app.PathName,0,...
                        app.QuantiTrackAnalyzeJumpHistogramUIFigure,...
                        'Tracking');
                    app.Sigma = mean(locPrec);
                    frameTime = Tracks{1}(2,3) - Tracks{1}(1,3);
                    nMovies = nFiles;
                end

                [app.tlist, app.rlist, JDH_tmp(:,:,1),jd1{1}] = calculateJDH(Tracks{1},JHistMaxFrameN , binEdges,frameTime);

                if app.useParallel == 1
                    d = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Compiling Jump Histogram','Indeterminate','on');
                    parfor i = 2:nMovies
                        % d.Message = ['Analyzing File ', num2str(i), ' of ', num2str(nMovies)];
                        [~, ~, JDH_tmp(:,:,i),jd1{i}] = calculateJDH(Tracks{i},JHistMaxFrameN , binEdges,frameTime);
                        % d.Value = i/nMovies;
                    end
                else
                    d = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Compiling Jump Histogram');
                    for i = 2:nMovies
                        d.Message = ['Analyzing File ', num2str(i), ' of ', num2str(nMovies)];
                        [~, ~, JDH_tmp(:,:,i),jd1{i}] = calculateJDH(Tracks{i},JHistMaxFrameN , binEdges,frameTime);
                        d.Value = i/nMovies;
                    end
                end


                close(d);


                if app.bootStrapN > 0
                    d = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Bootstrapping data','Message','Generating Bootstrapped samples');
                    for i = 1:app.bootStrapN
                        %Update the progress bar
                        d.Value = (i-1)/app.bootStrapN;

                        %Sample tracks with replacement
                        bootStrap_ind = randi(length(Tracks),length(Tracks),1);
                        % Tracks_boot = Tracks(bootStrap_ind);
                        JDH_boot_tmp = JDH_tmp(:,:,bootStrap_ind);

                        app.JDH_boot{i} = sum(JDH_boot_tmp, 3);


                    end
                    close(d);
                end
                % Accumulate Jump Histogram
                JDH_tmp = sum(JDH_tmp, 3);
                % Calculate the total number of particles;

                % find the data containing the lowest number of frames;

                nMax = length(NParticles{1}(:,1));
                TimePoints = NParticles{1}(:,1);
                for i = 1:nMovies

                    if length(NParticles{i}(:,1)) < nMax

                        TimePoints = NParticles{i}(:,1);
                        nMax = length(TimePoints);
                    end
                end

                % Accumulate the histogram
                Hist_Matrix = zeros(nMax,nMovies);

                for i = 1:nMovies
                    Hist_Matrix(1:nMax,i) = NParticles{i}(1:nMax,2);
                end

                CumNParticles(:,1) = TimePoints * frameTime;
                CumNParticles(:,2) = sum(Hist_Matrix, 2);

                % Fit a double exponential to the photobleaching curve
                disp(' ')
                disp('____________________________________')
                disp('Estimating bleaching characteristics')
                disp('____________________________________')

                [BleachRates, ~, CumNParticles(:,3:6)] =...
                    ExpDecay_2Cmp_fit(app,CumNParticles, [1 0.1]);
                disp(['Bleach Rate 1: ', num2str(BleachRates(1), 3), ' s^-1'])
                disp(['Bleach Rate 2: ', num2str(BleachRates(2), 3), ' s^-1'])
                disp(['Fraction 1: ', num2str(BleachRates(3), 3)])
                disp('____________________________________')
                disp(' ')
                CumNParticles = CumNParticles(:,1:4);
                % Plot the photobleaching curve

                plot(app.plot_axes, CumNParticles(:,1), CumNParticles(:,2)/max(CumNParticles(:,4)), '+', 'MarkerEdgeColor', [0.5 0.5 0.5]');
                hold(app.plot_axes, 'on');


                plot(app.plot_axes, CumNParticles(:,3), CumNParticles(:,4)/max(CumNParticles(:,4)),'r');

                box(app.plot_axes, 'on');

                title(app.plot_axes, {'Photobleaching kinetics:', ['k_{b1} = ', num2str(BleachRates(1), 3),...
                    ' s^{-1}, k_{b2} = ', num2str(BleachRates(2), 3), ' s^{-1}, f_1 = ',  ...
                    num2str(BleachRates(3), 3)]})

                xlabel(app.plot_axes, 'Time [s]');
                ylabel(app.plot_axes, 'Normalized Counts');

                hold(app.plot_axes, 'off');


                % Ask if you want to correct the jump histogram for photobleaching
                answer = questdlg('Do you want to correct the histogram for photobleaching?');

                switch answer
                    case 'Yes'
                        JDH_tmp = correctBleachJDH(app, JDH_tmp, BleachRates, app.tlist);
                        if app.bootStrapN > 0
                            for i = 1:app.bootStrapN
                                app.JDH_boot{i} = correctBleachJDH(app, app.JDH_boot{i}, BleachRates, app.tlist);
                            end
                        end
                end

                % % Ask if you want to normalize the jump histogram to 1.
                % answer = questdlg('Do you want to normalize the jump histogram to 1?');
                %
                % switch answer
                %     case 'Yes'
                JDH_tmp = JDH_tmp/sum(JDH_tmp(1,:));
                if app.bootStrapN > 0
                    for i = 1:app.bootStrapN
                        app.JDH_boot{i} = app.JDH_boot{i}/sum(app.JDH_boot{i}(1,:));
                    end
                end


                app.JDH = JDH_tmp;
                app.ROIName = ROIChoice{1};
                figname = app.QuantiTrackAnalyzeJumpHistogramUIFigure.Name;
                figname = [figname ' ' ROIChoice{1}];
                app.QuantiTrackAnalyzeJumpHistogramUIFigure.Name = figname;

                %Fit the distribution to a simple diffusion model
                %show indeterminate progresss bar
                d1 = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Fitting to Model','Indeterminate','on');
                if strcmp(app.fitSelect,'Brownian Motion')
                    app.ChooseDraw.Value = 'Pure Diffusion Fit';


                    % d1 = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Fitting to Model','Indeterminate','on');
                    d1.Message = 'Fitting to 1-component diffusion model';

                    Par0{1} = app.tlist;
                    Par0{2} = app.rlist;
                    Par0{3} = app.Sigma;


                    Ini0 = [0.5,...  %Diffusion coefficient
                        1, ...      %Scaling factor
                        app.deltaZ];       %deltaZ


                    [FitPar, FitErr, FitSSR, Fit] =  RD_JD_Fit_dz_Diff_only(app.JDH, Par0, Ini0,app.useParallel,0);
                    FitPar_boot = zeros(app.bootStrapN,length(FitPar));
                    if app.bootStrapN > 0
                        for i = 1:app.bootStrapN
                            
                            FitPar_boot(i,:) =  RD_JD_Fit_dz_Diff_only(app.JDH_boot{i}, Par0, Ini0,app.useParallel,0);
                        end
                    end

                    % Prepare output
                    app.fitDiffOnly.Fit = Fit;
                    app.fitDiffOnly.FitPar = FitPar;
                    app.fitDiffOnly.FitErr = FitErr;
                    if app.bootStrapN > 0
                        app.fitDiffOnly.FitErr = std(FitPar_boot);
                    end


                    app.fitDiffOnly.SSR = FitSSR;

                    Fit = app.fitDiffOnly.Fit;
                    FitPar = app.fitDiffOnly.FitPar;
                    FitErr = app.fitDiffOnly.FitErr;
                    FitSSR = app.fitDiffOnly.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{free} = ', num2str(FitPar(1),3)],...
                        ['dZ = ', num2str(FitPar(3),3), '\mum']});

                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');
                    app.plot_axes.YDir = 'normal';


                    FitString{1} = 'Estimated Parameters of the Pure Diffusion fit:';
                    FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                    FitString{3} = ['dZ = ', num2str(FitPar(3),3), ' +/- ', num2str(FitErr(3),3), 'um'];
                    FitString{4} = ['SSR = ' num2str(FitSSR(1))];
                    if app.bootStrapN > 0
                        FitString{5} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end

                    app.edit1.Text = FitString;


                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');
                elseif strcmp(app.fitSelect,'Diffusion + Binding')
                    app.ChooseDraw.Value = 'Diffusion + Binding Fit';


                    %Fit to the 1-component Diffusion + Binding model
                    Par1{1} = app.tlist;
                    Par1{2} = app.rlist;
                    Par1{3} = app.Db;
                    Par1{4} = app.Sigma;
                    Par1{5} = app.deltaZ;


                    d1.Message = 'Fitting to 1-component diffusion + binding model';
                    Ini1 = [2,...    %Diffusion coefficient for free
                        1, ...      %Scaling factor
                        0.5, ...    %On rate
                        1];%, ...      %Off rate


                    % % Fit
                    [FitPar, FitErr, FitSSR, Fit] = RD_JD_Fit_dz_BoundDiff( app.JDH, Par1, Ini1,app.useParallel,0);

                    FitPar_boot = zeros(app.bootStrapN,length(FitPar));
                    if app.bootStrapN > 0
                        for i = 1:app.bootStrapN
                            
                            FitPar_boot(i,:) =  RD_JD_Fit_dz_BoundDiff(app.JDH_boot{i}, Par1, Ini1,app.useParallel,0);
                        end
                    end


                    % Prepare output
                    app.fit1Cmp.Fit = Fit;
                    app.fit1Cmp.FitPar = FitPar;
                    app.fit1Cmp.FitErr = FitErr;
                    if app.bootStrapN > 0
                        app.fit1Cmp.FitErr = std(FitPar_boot);
                    end
                    app.fit1Cmp.SSR = FitSSR;

                    Fit = app.fit1Cmp.Fit;
                    FitPar = app.fit1Cmp.FitPar;
                    FitErr = app.fit1Cmp.FitErr;
                    FitSSR = app.fit1Cmp.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{free} = ', num2str(FitPar(1),3), '\mum^{2}/s, D_{bound} = ', num2str(app.Db), '\mum^{2}/s, '],...
                        ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                        ['dZ = ', num2str(app.deltaZ,3),' \mum']})
                    % ['dZ = ', num2str(FitPar(5),3), '\mum']})

                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');
                    app.plot_axes.YDir = 'normal';
                    k_on = FitPar(3);
                    k_off = FitPar(4);
                    k_on_err = FitErr(3);
                    k_off_err = FitErr(4);

                    C_eq = k_on./(k_on + k_off);

                    C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                    C_eq_den = k_on + k_off;

                    C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));


                    FitString{1} = 'Estimated Parameters of the one component diffusion fit:';
                    FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                    FitString{3} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                    FitString{4} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                    FitString{5} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                    % FitString{6} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                    FitString{6} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                    FitString{7} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                    FitString{8} = ['SSR = ' num2str(FitSSR(1))];
                    if app.bootStrapN > 0
                        FitString{9} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end
                    app.edit1.Text = FitString;


                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');

                elseif strcmp(app.fitSelect,'2-cmp Diffusion + Binding')
                    app.ChooseDraw.Value = '2-cmp Diffusion + Binding Fit';


                    %Fit to the 2-component model

                    Par2{1} = app.tlist;
                    Par2{2} = app.rlist;
                    Par2{3} = app.Db;
                    Par2{4} = app.Sigma;
                    Par2{5} = app.deltaZ;

                    %show indeterminate progresss bar
                    % d2 = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Fitting to Model','Indeterminate','on');
                    d1.Message = 'Fitting to 2-component diffusion + binding model';
                    drawnow;

                    % IniD = [str2double(IniGuess{1}), ...
                    % str2double(IniGuess{2}),str2double(IniGuess{2})/10];
                    IniD = [5,...
                        0.5,...
                        0.05];

                    [Fit_2CMP_Ds, ~, Fit_2CMP_Ds_Err] = JDHfixedT_3cmp_fit(app,[app.rlist,app.JDH(1,:)'], ...
                        app.tlist(1), IniD);

                    Par2{6} = Fit_2CMP_Ds(2:3);


                    Ini2 = [0.5,...
                        1,...
                        0.5,...
                        1];%,...


                    [FitPar, FitErr, FitSSR, Fit] = RD_JD_Fit_dz_2CMP(app.JDH, Par2, Ini2,app.useParallel,0);
                    FitPar_boot = zeros(app.bootStrapN,length(FitPar));
                    if app.bootStrapN > 0
                        for i = 1:app.bootStrapN
                            
                            FitPar_boot(i,:) =  RD_JD_Fit_dz_2CMP(app.JDH_boot{i}, Par2, Ini2,app.useParallel,0);
                        end
                    end


                    % Prepare output
                    app.fit2Cmp.Fit = Fit;
                    app.fit2Cmp.FitPar = [Fit_2CMP_Ds(2:3), FitPar];
                    app.fit2Cmp.FitErr = [Fit_2CMP_Ds_Err(2:3), FitErr];
                    if app.bootStrapN > 0
                        app.fit1Cmp.FitErr = [Fit_2CMP_Ds_Err(2:3), std(FitPar_boot)];
                    end
                    app.fit2Cmp.SSR = FitSSR;
                    Fit = app.fit2Cmp.Fit;
                    Fit_2CMP_Ds = app.fit2Cmp.FitPar(1:2);
                    FitPar = app.fit2Cmp.FitPar(3:end);
                    FitErr_2CMP_Ds = app.fit2Cmp.FitErr(1:2);
                    FitErr = app.fit2Cmp.FitErr(3:end);
                    FitSSR = app.fit2Cmp.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{1} = ', num2str(Fit_2CMP_Ds(1),3),'\mum^{2}/s, ', ...
                        'D_{2} = ', num2str(Fit_2CMP_Ds(2),3),'\mum^{2}/s, ', ...
                        'D_bound = ' num2str(app.Db),' \mum^{2}/s',...
                        'f_{1} = ', num2str(FitPar(1),3), ', '],...
                        ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                        ['dZ = ', num2str(app.deltaZ,3), '\mum']})
                    % ['dZ = ', num2str(FitPar(5),3), '\mum']})


                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');
                    app.plot_axes.YDir = 'normal';

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');

                    k_on = FitPar(3);
                    k_off = FitPar(4);
                    k_on_err = FitErr(3);
                    k_off_err = FitErr(4);

                    C_eq = k_on./(k_on + k_off);

                    C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                    C_eq_den = k_on + k_off;

                    C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));

                    FitString{1} = 'Estimated Parameters of the two component diffusion fit:';
                    FitString{2} = ['D1 = ' num2str(Fit_2CMP_Ds(1),3),' +/- ', num2str(FitErr_2CMP_Ds(1),3), ' um2/s'];
                    FitString{3} = ['D2 = ' num2str(Fit_2CMP_Ds(2),3),' +/- ', num2str(FitErr_2CMP_Ds(2),3), ' um2/s'];
                    FitString{4} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                    FitString{5} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                    FitString{6} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                    % FitString{7} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                    FitString{7} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                    FitString{8} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                    FitString{9} = ['SSR = ' num2str(FitSSR(1))];
                    if app.bootStrapN > 0
                        FitString{10} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end
                    app.edit1.Text = FitString;

                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');
                end
                close(d1);
            end

        end

        % Value changed function: ChooseDraw
        function ChooseDrawValueChanged(app, event)
            Selection = app.ChooseDraw.Value;

            if strcmp(Selection, 'Heat Map')
                imagesc(app.plot_axes, app.rlist, app.tlist, app.JDH);
                set(app.plot_axes, 'FontSize', 10);
                view(app.plot_axes, 0,90)
                xlabel(app.plot_axes, 'Jump distance [\mum]');
                ylabel(app.plot_axes, 'Time [s]');
                title(app.plot_axes, 'Time-dependent histogram of displacements');
                colorbar(app.plot_axes, 'location','EastOutside','XTick',0.5,'XTickLabel','Counts');

                axis(app.plot_axes, 'ij');
                %disable the buttons for copying the fits since we are showing
                %the heat map
                app.CopyFit.Enable = 'off';
                app.CopyFitPar.Enable = 'off';

                FitString = 'Fit Results';
                app.edit1.Text = FitString;
            elseif strcmp(Selection,'Pure Diffusion Fit')
                if ~isempty(app.fitDiffOnly)

                    Fit = app.fitDiffOnly.Fit;
                    FitPar = app.fitDiffOnly.FitPar;
                    FitErr = app.fitDiffOnly.FitErr;
                    FitSSR = app.fitDiffOnly.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{free} = ', num2str(FitPar(1),3)],...
                        ['dZ = ', num2str(FitPar(3),3), '\mum']});

                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');
                    app.plot_axes.YDir = 'normal';


                    FitString{1} = 'Estimated Parameters of the Pure Diffusion fit:';
                    FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                    FitString{3} = ['dZ = ', num2str(FitPar(3),3), ' +/- ', num2str(FitErr(3),3), 'um'];
                    FitString{4} = ['SSR = ' num2str(FitSSR(1));];
                    if app.bootStrapN > 0
                        FitString{5} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end
                    app.edit1.Text = FitString;


                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');
                else
                    newFitAns = questdlg('Fit doesnt exist, would you like to run it?','Perform Fitting','Yes','No','No');
                    if strcmp(newFitAns,'Yes')
                        d1 = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Fitting to Model','Indeterminate','on');
                        d1.Message = 'Fitting to 1-component diffusion model';

                        Par0{1} = app.tlist;
                        Par0{2} = app.rlist;
                        Par0{3} = app.Sigma;


                        Ini0 = [0.5,...  %Diffusion coefficient
                            1, ...      %Scaling factor
                            app.deltaZ];       %deltaZ


                        [FitPar, FitErr, FitSSR, Fit] =  RD_JD_Fit_dz_Diff_only(app.JDH, Par0, Ini0,app.useParallel,0);
                        FitPar_boot = zeros(app.bootStrapN,length(FitPar));
                        if app.bootStrapN > 0
                            for i = 1:app.bootStrapN
                                
                                FitPar_boot(i,:) =  RD_JD_Fit_dz_Diff_only(app.JDH_boot{i}, Par0, Ini0,app.useParallel,0);
                            end
                        end

                        % Prepare output
                        app.fitDiffOnly.Fit = Fit;
                        app.fitDiffOnly.FitPar = FitPar;
                        app.fitDiffOnly.FitErr = FitErr;
                        if app.bootStrapN > 0
                            app.fitDiffOnly.FitErr = std(FitPar_boot);
                        end


                        app.fitDiffOnly.SSR = FitSSR;

                        Fit = app.fitDiffOnly.Fit;
                        FitPar = app.fitDiffOnly.FitPar;
                        FitErr = app.fitDiffOnly.FitErr;
                        FitSSR = app.fitDiffOnly.SSR;

                        for t = 1:1:length(app.tlist)
                            tplot = app.tlist(t)*ones(length(app.rlist), 1);
                            plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                            hold(app.plot_axes, 'on');
                            plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                        end
                        hold(app.plot_axes, 'off');

                        title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                            ['D_{free} = ', num2str(FitPar(1),3)],...
                            ['dZ = ', num2str(FitPar(3),3), '\mum']});

                        set(app.plot_axes, 'FontSize', 12);
                        view(app.plot_axes, 108,30)
                        grid(app.plot_axes, 'on');

                        xlabel(app.plot_axes, 'Time [s]');
                        ylabel(app.plot_axes, 'Jump Distance [\mum]');
                        zlabel(app.plot_axes, 'Counts');
                        app.plot_axes.YDir = 'normal';


                        FitString{1} = 'Estimated Parameters of the Pure Diffusion fit:';
                        FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                        FitString{3} = ['dZ = ', num2str(FitPar(3),3), ' +/- ', num2str(FitErr(3),3), 'um'];
                        FitString{4} = ['SSR = ' num2str(FitSSR(1))];
                        if app.bootStrapN > 0
                            FitString{5} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                        end

                        app.edit1.Text = FitString;


                        %enable the buttons for copying the fits
                        app.CopyFit.Enable = 'on';
                        app.CopyFitPar.Enable = 'on';
                        colorbar(app.plot_axes,'off');
                        close(d1)
                    end
                end

            elseif strcmp(Selection, 'Diffusion + Binding Fit')
                if ~isempty(app.fit1Cmp)

                    Fit = app.fit1Cmp.Fit;
                    FitPar = app.fit1Cmp.FitPar;
                    FitErr = app.fit1Cmp.FitErr;
                    FitSSR = app.fit1Cmp.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{free} = ', num2str(FitPar(1),3), '\mum^{2}/s, D_{bound} = ', num2str(app.Db), '\mum^{2}/s, '],...
                        ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                        ['dZ = ', num2str(app.deltaZ,3),' \mum']})
                    % ['dZ = ', num2str(FitPar(5),3), '\mum']})

                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');
                    app.plot_axes.YDir = 'normal';
                    k_on = FitPar(3);
                    k_off = FitPar(4);
                    k_on_err = FitErr(3);
                    k_off_err = FitErr(4);

                    C_eq = k_on./(k_on + k_off);

                    C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                    C_eq_den = k_on + k_off;

                    C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));


                    FitString{1} = 'Estimated Parameters of the one component diffusion fit:';
                    FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                    FitString{3} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                    FitString{4} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                    FitString{5} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                    % FitString{6} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                    FitString{6} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                    FitString{7} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                    FitString{8} = ['SSR = ' num2str(FitSSR(1))];
                    if app.bootStrapN > 0
                        FitString{9} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end
                    app.edit1.Text = FitString;


                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');

                    colorbar(app.plot_axes,'off');

                else
                    newFitAns = questdlg('Fit doesnt exist, would you like to run it?','Perform Fitting','Yes','No','No');
                    if strcmp(newFitAns,'Yes')
                        %Fit to the 1-component Diffusion + Binding model
                        Par1{1} = app.tlist;
                        Par1{2} = app.rlist;
                        Par1{3} = app.Db;
                        Par1{4} = app.Sigma;
                        Par1{5} = app.deltaZ;

                        d1 = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Fitting to Model','Indeterminate','on');
                        d1.Message = 'Fitting to 1-component diffusion + binding model';
                        Ini1 = [2,...    %Diffusion coefficient for free
                            1, ...      %Scaling factor
                            0.5, ...    %On rate
                            1];%, ...      %Off rate


                        % % Fit
                        [FitPar, FitErr, FitSSR, Fit] = RD_JD_Fit_dz_BoundDiff( app.JDH, Par1, Ini1,app.useParallel,0);

                        FitPar_boot = zeros(app.bootStrapN,length(FitPar));
                        if app.bootStrapN > 0
                            for i = 1:app.bootStrapN
                                

                                FitPar_boot(i,:) =  RD_JD_Fit_dz_BoundDiff(app.JDH_boot{i}, Par1, Ini1,app.useParallel,0);
                            end
                        end


                        % Prepare output
                        app.fit1Cmp.Fit = Fit;
                        app.fit1Cmp.FitPar = FitPar;
                        app.fit1Cmp.FitErr = FitErr;
                        if app.bootStrapN > 0
                            app.fit1Cmp.FitErr = std(FitPar_boot);
                        end
                        app.fit1Cmp.SSR = FitSSR;

                        Fit = app.fit1Cmp.Fit;
                        FitPar = app.fit1Cmp.FitPar;
                        FitErr = app.fit1Cmp.FitErr;
                        FitSSR = app.fit1Cmp.SSR;

                        for t = 1:1:length(app.tlist)
                            tplot = app.tlist(t)*ones(length(app.rlist), 1);
                            plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                            hold(app.plot_axes, 'on');
                            plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                        end
                        hold(app.plot_axes, 'off');

                        title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                            ['D_{free} = ', num2str(FitPar(1),3), '\mum^{2}/s, D_{bound} = ', num2str(app.Db), '\mum^{2}/s, '],...
                            ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                            ['dZ = ', num2str(app.deltaZ,3),' \mum']})
                        % ['dZ = ', num2str(FitPar(5),3), '\mum']})

                        set(app.plot_axes, 'FontSize', 12);
                        view(app.plot_axes, 108,30)
                        grid(app.plot_axes, 'on');

                        xlabel(app.plot_axes, 'Time [s]');
                        ylabel(app.plot_axes, 'Jump Distance [\mum]');
                        zlabel(app.plot_axes, 'Counts');
                        app.plot_axes.YDir = 'normal';
                        k_on = FitPar(3);
                        k_off = FitPar(4);
                        k_on_err = FitErr(3);
                        k_off_err = FitErr(4);

                        C_eq = k_on./(k_on + k_off);

                        C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                        C_eq_den = k_on + k_off;

                        C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));


                        FitString{1} = 'Estimated Parameters of the one component diffusion fit:';
                        FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                        FitString{3} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                        FitString{4} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                        FitString{5} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                        % FitString{6} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                        FitString{6} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                        FitString{7} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                        FitString{8} = ['SSR = ' num2str(FitSSR(1))];
                        if app.bootStrapN > 0
                            FitString{9} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                        end
                        app.edit1.Text = FitString;


                        %enable the buttons for copying the fits
                        app.CopyFit.Enable = 'on';
                        app.CopyFitPar.Enable = 'on';
                        colorbar(app.plot_axes,'off');
                        close(d1)
                    end
                end

            elseif strcmp(Selection, '2-cmp Diffusion + Binding Fit')

                % Plot Fit

                if ~isempty(app.fit2Cmp)
                    Fit = app.fit2Cmp.Fit;
                    Fit_2CMP_Ds = app.fit2Cmp.FitPar(1:2);
                    FitPar = app.fit2Cmp.FitPar(3:end);
                    FitErr_2CMP_Ds = app.fit2Cmp.FitErr(1:2);
                    FitErr = app.fit2Cmp.FitErr(3:end);
                    FitSSR = app.fit2Cmp.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{1} = ', num2str(Fit_2CMP_Ds(1),3),'\mum^{2}/s, ', ...
                        'D_{2} = ', num2str(Fit_2CMP_Ds(2),3),'\mum^{2}/s, ', ...
                        'D_bound = ' num2str(app.Db),' \mum^{2}/s',...
                        'f_{1} = ', num2str(FitPar(1),3), ', '],...
                        ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                        ['dZ = ', num2str(app.deltaZ,3), '\mum']})
                    % ['dZ = ', num2str(FitPar(5),3), '\mum']})


                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');
                    app.plot_axes.YDir = 'normal';

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');

                    k_on = FitPar(3);
                    k_off = FitPar(4);
                    k_on_err = FitErr(3);
                    k_off_err = FitErr(4);

                    C_eq = k_on./(k_on + k_off);

                    C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                    C_eq_den = k_on + k_off;

                    C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));

                    FitString{1} = 'Estimated Parameters of the two component diffusion fit:';
                    FitString{2} = ['D1 = ' num2str(Fit_2CMP_Ds(1),3),' +/- ', num2str(FitErr_2CMP_Ds(1),3), ' um2/s'];
                    FitString{3} = ['D2 = ' num2str(Fit_2CMP_Ds(2),3),' +/- ', num2str(FitErr_2CMP_Ds(2),3), ' um2/s'];
                    FitString{4} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                    FitString{5} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                    FitString{6} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                    % FitString{7} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                    FitString{7} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                    FitString{8} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                    FitString{9} = ['SSR = ' num2str(FitSSR(1))];
                    if app.bootStrapN > 0
                        FitString{10} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end
                    app.edit1.Text = FitString;

                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');
                else
                    newFitAns = questdlg('Fit doesnt exist, would you like to run it?','Perform Fitting','Yes','No','No');
                    if strcmp(newFitAns,'Yes')
                        %Fit to the 2-component model

                        Par2{1} = app.tlist;
                        Par2{2} = app.rlist;
                        Par2{3} = app.Db;
                        Par2{4} = app.Sigma;
                        Par2{5} = app.deltaZ;

                        %show indeterminate progresss bar
                        d1 = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramUIFigure,'Title','Fitting to Model','Indeterminate','on');
                        d1.Message = 'Fitting to 2-component diffusion + binding model';
                        drawnow;

                        % IniD = [str2double(IniGuess{1}), ...
                        % str2double(IniGuess{2}),str2double(IniGuess{2})/10];
                        IniD = [5,...
                            0.5,...
                            0.05];

                        [Fit_2CMP_Ds, ~, Fit_2CMP_Ds_Err] = JDHfixedT_3cmp_fit(app,[app.rlist,app.JDH(1,:)'], ...
                            app.tlist(1), IniD);

                        Par2{6} = Fit_2CMP_Ds(2:3);


                        Ini2 = [0.5,...
                            1,...
                            0.5,...
                            1];%,...


                        [FitPar, FitErr, FitSSR, Fit] = RD_JD_Fit_dz_2CMP(app.JDH, Par2, Ini2,app.useParallel,0);
                        FitPar_boot = zeros(app.bootStrapN,length(FitPar));
                        if app.bootStrapN > 0
                            for i = 1:app.bootStrapN
                                
                                FitPar_boot(i,:) =  RD_JD_Fit_dz_2CMP(app.JDH_boot{i}, Par2, Ini2,app.useParallel,0);
                            end
                        end


                        % Prepare output
                        app.fit2Cmp.Fit = Fit;
                        app.fit2Cmp.FitPar = [Fit_2CMP_Ds(2:3), FitPar];
                        app.fit2Cmp.FitErr = [Fit_2CMP_Ds_Err(2:3), FitErr];
                        if app.bootStrapN > 0
                            app.fit1Cmp.FitErr = [Fit_2CMP_Ds_Err(2:3), std(FitPar_boot)];
                        end
                        app.fit2Cmp.SSR = FitSSR;
                        Fit = app.fit2Cmp.Fit;
                        Fit_2CMP_Ds = app.fit2Cmp.FitPar(1:2);
                        FitPar = app.fit2Cmp.FitPar(3:end);
                        FitErr_2CMP_Ds = app.fit2Cmp.FitErr(1:2);
                        FitErr = app.fit2Cmp.FitErr(3:end);
                        FitSSR = app.fit2Cmp.SSR;

                        for t = 1:1:length(app.tlist)
                            tplot = app.tlist(t)*ones(length(app.rlist), 1);
                            plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                            hold(app.plot_axes, 'on');
                            plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                        end
                        hold(app.plot_axes, 'off');

                        title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                            ['D_{1} = ', num2str(Fit_2CMP_Ds(1),3),'\mum^{2}/s, ', ...
                            'D_{2} = ', num2str(Fit_2CMP_Ds(2),3),'\mum^{2}/s, ', ...
                            'D_bound = ' num2str(app.Db),' \mum^{2}/s',...
                            'f_{1} = ', num2str(FitPar(1),3), ', '],...
                            ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                            ['dZ = ', num2str(app.deltaZ,3), '\mum']})
                        % ['dZ = ', num2str(FitPar(5),3), '\mum']})


                        set(app.plot_axes, 'FontSize', 12);
                        view(app.plot_axes, 108,30)
                        grid(app.plot_axes, 'on');
                        app.plot_axes.YDir = 'normal';

                        xlabel(app.plot_axes, 'Time [s]');
                        ylabel(app.plot_axes, 'Jump Distance [\mum]');
                        zlabel(app.plot_axes, 'Counts');

                        k_on = FitPar(3);
                        k_off = FitPar(4);
                        k_on_err = FitErr(3);
                        k_off_err = FitErr(4);

                        C_eq = k_on./(k_on + k_off);

                        C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                        C_eq_den = k_on + k_off;

                        C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));

                        FitString{1} = 'Estimated Parameters of the two component diffusion fit:';
                        FitString{2} = ['D1 = ' num2str(Fit_2CMP_Ds(1),3),' +/- ', num2str(FitErr_2CMP_Ds(1),3), ' um2/s'];
                        FitString{3} = ['D2 = ' num2str(Fit_2CMP_Ds(2),3),' +/- ', num2str(FitErr_2CMP_Ds(2),3), ' um2/s'];
                        FitString{4} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                        FitString{5} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                        FitString{6} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                        % FitString{7} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                        FitString{7} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                        FitString{8} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                        FitString{9} = ['SSR = ' num2str(FitSSR(1))];
                        if app.bootStrapN > 0
                            FitString{10} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                        end
                        app.edit1.Text = FitString;

                        %enable the buttons for copying the fits
                        app.CopyFit.Enable = 'on';
                        app.CopyFitPar.Enable = 'on';
                        colorbar(app.plot_axes,'off');
                        close(d1);
                    end
                end


            end
        end

        % Button pushed function: CopyFit
        function CopyFitButtonPushed(app, event)

            Selection = app.ChooseDraw.Value;
            if strcmp(Selection, 'Pure Diffusion Fit')

                OUT = app.fitDiffOnly.Fit;
                % num2clip(OUT);
                for i = 1:length(app.rlist)
                    colNames{:,i} = num2str(app.rlist(i));
                end
                for i = 1:length(app.tlist)
                    rowNames{i,:} = num2str(app.tlist(i));
                end
                JDHTable = array2table(OUT,'VariableNames',...
                    colNames,'RowNames',rowNames);
                assignin('base','JDH_DiffOnlyFit_out',JDHTable);

                msgbox('The array with the pure diffusion model has been copied to the workspace as the variable JDH_DiffOnlyFit_out');
            elseif strcmp(Selection, 'Diffusion + Binding Fit')
                % OUT= zeros(size(app.JDH) + 1);
                % OUT(2:end,1) = app.tlist;
                % OUT(1, 2:end) = app.rlist;
                % OUT(2:end, 2:end) = app.fit1Cmp.Fit;
                OUT = app.fit1Cmp.Fit;
                % num2clip(OUT);
                for i = 1:length(app.rlist)
                    colNames{:,i} = num2str(app.rlist(i));
                end
                for i = 1:length(app.tlist)
                    rowNames{i,:} = num2str(app.tlist(i));
                end
                JDHTable = array2table(OUT,'VariableNames',...
                    colNames,'RowNames',rowNames);
                assignin('base','JDH_1CmpFit_out',JDHTable);

                msgbox('The array with the 1CMP Full model has been copied to the workspace as the variable JDH_1CmpFit_out');

            elseif strcmp(Selection, '2-cmp Diffusion + Binding Fit')
                % OUT= zeros(size(app.JDH) + 1);
                % OUT(2:end,1) = app.tlist;
                % OUT(1, 2:end) = app.rlist;
                % OUT(2:end, 2:end) = app.fit2Cmp.Fit;
                OUT = app.fit1Cmp.Fit;
                % num2clip(OUT);
                for i = 1:length(app.rlist)
                    colNames{:,i} = num2str(app.rlist(i));
                end
                for i = 1:length(app.tlist)
                    rowNames{i,:} = num2str(app.tlist(i));
                end
                JDHTable = array2table(OUT,'VariableNames',...
                    colNames,'RowNames',rowNames);
                assignin('base','JDH_2CmpFit_out',JDHTable);
                msgbox('The array with the 2CMP Full model has been copied to the workspace as the variable JDH_1CmpFit_out');


            end
        end

        % Button pushed function: CopyFitPar
        function CopyFitParButtonPushed(app, event)

            Selection = app.ChooseDraw.Value;
            if strcmp(Selection, 'Pure Diffusion Fit')
                OUT = [app.fitDiffOnly.FitPar, C_eq, app.fitDiffOnly.SSR; ...
                    app.fitDiffOnly.FitErr, fitDiffOnly, 0];

                % num2clip(OUT);
                ParTable = array2table(OUT,'VariableNames',...
                    {'Free Diffusion Coefficient (mean and error)','Normalization Factor (mean and error)', ...
                    'deltaZ (mean and error)', 'SSR'});
                assignin('base','DiffOnlyFitParams_out',ParTable);

                msgbox('The array with the 1CMP Full model fit parameters has been copied to the workspace as variable OneCompFitParams_out');
            elseif strcmp(Selection, 'Diffusion + Binding Fit')


                k_on = app.fit1Cmp.FitPar(3);
                k_off = app.fit1Cmp.FitPar(4);
                k_on_err = app.fit1Cmp.FitErr(3);
                k_off_err = app.fit1Cmp.FitErr(4);

                C_eq = k_on./(k_on + k_off);

                C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                C_eq_den = k_on + k_off;

                C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));

                OUT = [app.fit1Cmp.FitPar, app.deltaZ, app.Db, C_eq, app.fit1Cmp.SSR; ...
                    app.fit1Cmp.FitErr, 0, 0, C_eq_err, 0];

                % num2clip(OUT);
                ParTable = array2table(OUT,'VariableNames',...
                    {'Free Diffusion Coefficient (mean and error)','Normalization Factor (mean and error)', ...
                    'k_on (mean and error)','k_off (mean and error)',...
                    'deltaZ (mean and error)','Bound Diffusion Coefficient (mean and error)','Bound Fraction (mean and error)', 'SSR'});
                assignin('base','OneCompFitParams_out',ParTable);

                msgbox('The array with the 1CMP Full model fit parameters has been copied to the workspace as variable OneCompFitParams_out');

            elseif strcmp(Selection, '2-cmp Diffusion + Binding Fit')
                k_on = app.fit2Cmp.FitPar(5);
                k_off = app.fit2Cmp.FitPar(6);
                k_on_err = app.fit2Cmp.FitErr(5);
                k_off_err = app.fit2Cmp.FitErr(6);

                C_eq = k_on./(k_on + k_off);

                C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                C_eq_den = k_on + k_off;

                C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));

                OUT = [app.fit2Cmp.FitPar,app.Db, app.deltaZ, C_eq, app.fit2Cmp.SSR; ...
                    app.fit2Cmp.FitErr, 0, 0, C_eq_err, 0];

                num2clip(OUT);

                ParTable = array2table(OUT,'VariableNames',...
                    {'Diffusion Coefficient 1 (mean and error)','Diffusion Coefficient 2 (mean and error)', ...
                    'Fraction with Diff. Coef. 1', 'Normalization factor', ...
                    'k_on (mean and error)','k_off (mean and error)',...
                    'Bound Diffusion Coefficient (mean and error)', ...
                    'deltaZ (mean and error)','Bound Fraction (mean and error)','SSR'});
                assignin('base','TwoCompFitParams_out',ParTable);

                msgbox('The array with the 2CMP Full model fit parameters has been copied to the workspace as variable TwoCompFitParams_out');


            end
        end

        % Button pushed function: CopyHist
        function CopyHistButtonPushed(app, event)

            OUT = zeros(length(app.tlist)+1,length(app.rlist)+1);

            OUT(2:end,1) = app.tlist;
            OUT(1, 2:end) = app.rlist;
            OUT(2:end, 2:end) = app.JDH;
            OUT = app.JDH;
            for i = 1:length(app.rlist)
                colNames{:,i} = num2str(app.rlist(i));
            end
            for i = 1:length(app.tlist)
                rowNames{i,:} = num2str(app.tlist(i));
            end
            JDHTable = array2table(OUT,'VariableNames',...
                colNames,'RowNames',rowNames);
            assignin('base','JDH_out',JDHTable);

            % num2clip(OUT);
            msgbox('The array with the histogram of displacements has been copied to the workspace as variable JDH_out');
        end

        % Button pushed function: SaveFigure
        function SaveFigureButtonPushed(app, event)
            %This button saves the figure currently shown.


            DefaultName = 'JDH_histogram';
            SearchStr = '(.*)\.\w*';


            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .4*scrsz(3) .5*scrsz(4)]) ;
            colormap(h1,'jet')
            Selection = app.ChooseDraw.Value;

            if strcmp(Selection,'Heat Map')

                imagesc(app.rlist, app.tlist,app.JDH);
                set(gca, 'FontSize', 10);

                xlabel('Jump distance [\mum]');
                ylabel('Time [s]');
                title('Time-dependent histogram of displacements');
                colorbar('location','EastOutside','XTick',0.5,'XTickLabel','Counts');

                axis ij

                % Save the figures

                DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIName '_JDH']);
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, fullfile(PathNameOut,FileNameOut));
                end
            elseif strcmp(Selection, 'Pure Diffusion Fit') % One component diffusion fit

                Fit = app.fitDiffOnly.Fit;
                FitPar = app.fitDiffOnly.FitPar;


                for t = 1:1:length(app.tlist)
                    tplot = app.tlist(t)*ones(length(app.rlist), 1);
                    plot3(tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                    hold(gca, 'on');
                    plot3(tplot, app.rlist, Fit(t,:), 'r');
                end
                hold(gca, 'off');

                title(gca, {'Jump Histogram Distribution - Full Model Fit',...
                    ['D = ', num2str(FitPar(1),3), '\mum^2/s'],...
                    ['dZ =', num2str(FitPar(3),3), '\mum']})

                set(gca, 'FontSize', 12);
                view(gca, 108,30)
                grid(gca, 'on');

                xlabel(gca, 'Time [s]');
                ylabel(gca, 'Jump Distance [\mum]');
                zlabel(gca, 'Counts');

                DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIName '_FJH_DiffOnly']);
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end
            elseif strcmp(Selection, 'Diffusion + Binding Fit') % One component diffusion fit

                Fit = app.fit1Cmp.Fit;
                FitPar = app.fit1Cmp.FitPar;


                for t = 1:1:length(app.tlist)
                    tplot = app.tlist(t)*ones(length(app.rlist), 1);
                    plot3(tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                    hold(gca, 'on');
                    plot3(tplot, app.rlist, Fit(t,:), 'r');
                end
                hold(gca, 'off');

                title(gca, {'Jump Histogram Distribution - Full Model Fit',...
                    ['D = ', num2str(FitPar(1),3), '\mum^2/s'],...
                    ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                    ['dZ = ', num2str(app.deltaZ,3),' \mum']})
                    % ['dZ =', num2str(FitPar(5),3), '\mum']})

                set(gca, 'FontSize', 12);
                view(gca, 108,30)
                grid(gca, 'on');

                xlabel(gca, 'Time [s]');
                ylabel(gca, 'Jump Distance [\mum]');
                zlabel(gca, 'Counts');

                DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIName '_FJH_1CMP']);
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end


            elseif strcmp(Selection, '2-cmp Diffusion + Binding Fit') % Two component diffusion fit

                % Plot Fit

                if ~isempty(app.fit2Cmp)
                    Fit = app.fit2Cmp.Fit;
                    Fit_2CMP_Ds = app.fit2Cmp.FitPar(1:2);
                    FitPar = app.fit2Cmp.FitPar(3:end);


                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(gca, 'on');
                        plot3(tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(gca, 'off');

                    title(gca, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_1 = ', num2str(Fit_2CMP_Ds(1)),'\mum^2/s, ', ...
                        'D_2 = ', num2str(Fit_2CMP_Ds(2)),'\mum^2/s, '], ...
                        ['f_1 = ', num2str(FitPar(1),3), '\mum^2/s, '],...
                        ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                        ['dZ = ', num2str(app.deltaZ,3),' \mum']})
                        % ['dZ =', num2str(FitPar(5),3), '\mum']})


                    set(gca, 'FontSize', 12);
                    view(gca, 108,30)
                    grid(gca, 'on');

                    xlabel(gca, 'Time [s]');
                    ylabel(gca, 'Jump Distance [\mum]');
                    zlabel(gca, 'Counts');

                    DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIName '_FJH_2CMP']);
                    FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                    [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                    if FileNameOut ~= 0
                        saveas(h1, [PathNameOut,FileNameOut]);
                    end


                end
            end

        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            close(app.QuantiTrackAnalyzeJumpHistogramUIFigure);
        end

        % Menu selected function: SaveJDHanalysisMenu
        function SaveJDHanalysisMenuSelected(app, event)
            if ~isempty(app.PathName)
                defpath = app.PathName;
            else
                defpath = pwd;
            end


            saveFilename = 'JDH_fitting.mat';

            [saveName,savePath] = uiputfile('*.mat','Save Analysis File',fullfile(defpath,...
                saveFilename));
            figure(app.QuantiTrackAnalyzeJumpHistogramUIFigure);
            drawnow
            if saveName ~= 0
                Results.AnalysisS = app.AnalysisS;
                Results.tlist = app.tlist;
                Results.rlist = app.rlist;
                Results.PathName = app.PathName;
                Results.FileNames = app.FileNames;

                Results.StableParams = app.StableParams;
                Results.fit1Cmp = app.fit1Cmp;
                Results.fit2Cmp = app.fit2Cmp;
                Results.ROIName = app.ROIName;
                Results.JDH = app.JDH;
                Results.Db = app.Db;
                Results.Sigma = app.Sigma;
                Results.fitDiffOnly = app.fitDiffOnly;
                Results.bootStrapN = app.bootStrapN;
                Results.useParallel = app.useParallel;
                Results.deltaZ = app.deltaZ;
                Results.JDH_boot = app.JDH_boot;
                Results.fitSelect = app.fitSelect;

                save(fullfile(savePath,saveName),'Results','-v7.3');
            end
        end

        % Menu selected function: LoadJDHanalysisMenu
        function LoadJDHanalysisMenuSelected(app, event)
            if ~isempty(app.PathName)
                defpath = app.PathName;
            else
                defpath = pwd;
            end
            [loadName,loadPath] = uigetfile('*.mat','Load Previously Analyzed Data',defpath);
            figure(app.QuantiTrackAnalyzeJumpHistogramUIFigure);
            drawnow
            if loadName ~= 0
                IN = load(fullfile(loadPath,loadName));
                app.AnalysisS = IN.Results.AnalysisS;
                app.PathName = IN.Results.PathName;
                app.FileNames = IN.Results.FileNames;
                app.tlist = IN.Results.tlist;
                app.rlist = IN.Results.rlist;
                app.StableParams = IN.Results.StableParams;
                app.fit1Cmp = IN.Results.fit1Cmp;
                app.fit2Cmp = IN.Results.fit2Cmp;
                app.fitDiffOnly = IN.Results.fitDiffOnly;
                app.ROIName = IN.Results.ROIName;
                app.JDH = IN.Results.JDH;
                app.Db = IN.Results.Db;
                app.Sigma = IN.Results.Sigma;
                app.bootStrapN = IN.Results.bootStrapN;
                app.useParallel = IN.Results.useParallel;
                app.deltaZ = IN.Results.deltaZ;
                app.JDH_boot = IN.Results.JDH_boot;
                app.fitSelect = IN.Results.fitSelect;


                %Plot the exitsing fit
                if ~isempty(app.fit1Cmp)

                    app.ChooseDraw.Value = 'Diffusion + Binding Fit';

                    Fit = app.fit1Cmp.Fit;
                    FitPar = app.fit1Cmp.FitPar;
                    FitErr = app.fit1Cmp.FitErr;
                    FitSSR = app.fit1Cmp.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{free} = ', num2str(FitPar(1),3), '\mum^{2}/s, D_{bound} = ', num2str(app.Db), '\mum^{2}/s, '],...
                        ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                        ['dZ = ', num2str(app.deltaZ,3),' \mum']})
                    % ['dZ = ', num2str(FitPar(5),3), '\mum']})

                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');
                    app.plot_axes.YDir = 'normal';
                    k_on = FitPar(3);
                    k_off = FitPar(4);
                    k_on_err = FitErr(3);
                    k_off_err = FitErr(4);

                    C_eq = k_on./(k_on + k_off);

                    C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                    C_eq_den = k_on + k_off;

                    C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));


                    FitString{1} = 'Estimated Parameters of the one component diffusion fit:';
                    FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                    FitString{3} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                    FitString{4} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                    FitString{5} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                    % FitString{6} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                    FitString{6} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                    FitString{7} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                    FitString{8} = ['SSR = ' num2str(FitSSR(1));];
                    if app.bootStrapN > 0
                        FitString{9} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end

                    app.edit1.Text = FitString;


                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');
                elseif ~isempty(app.fit2Cmp)

                    app.ChooseDraw.Value = '2-cmp Diffusion + Binding Fit';

                    Fit = app.fit2Cmp.Fit;
                    Fit_2CMP_Ds = app.fit2Cmp.FitPar(1:2);
                    FitPar = app.fit2Cmp.FitPar(3:end);
                    FitErr_2CMP_Ds = app.fit2Cmp.FitErr(1:2);
                    FitErr = app.fit2Cmp.FitErr(3:end);
                    FitSSR = app.fit2Cmp.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{1} = ', num2str(Fit_2CMP_Ds(1),3),'\mum^{2}/s, ', ...
                        'D_{2} = ', num2str(Fit_2CMP_Ds(2),3),'\mum^{2}/s, ', ...
                        'D_bound = ' num2str(app.Db),' \mum^{2}/s',...
                        'f_{1} = ', num2str(FitPar(1),3), ', '],...
                        ['k_{on} = ', num2str(FitPar(3),3), 's^{-1} k_{off} = ', num2str(FitPar(4),3), 's^{-1}'],...
                        ['dZ = ', num2str(app.deltaZ,3), '\mum']})
                    % ['dZ = ', num2str(FitPar(5),3), '\mum']})


                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');
                    app.plot_axes.YDir = 'normal';

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');

                    k_on = FitPar(3);
                    k_off = FitPar(4);
                    k_on_err = FitErr(3);
                    k_off_err = FitErr(4);

                    C_eq = k_on./(k_on + k_off);

                    C_eq_den_err = sqrt(k_on_err.^2 + k_off_err.^2);
                    C_eq_den = k_on + k_off;

                    C_eq_err = C_eq.*(sqrt((k_on_err./k_on).^2 + (C_eq_den_err./C_eq_den).^2));

                    FitString{1} = 'Estimated Parameters of the two component diffusion fit:';
                    FitString{2} = ['D1 = ' num2str(Fit_2CMP_Ds(1),3),' +/- ', num2str(FitErr_2CMP_Ds(1),3), ' um2/s'];
                    FitString{3} = ['D2 = ' num2str(Fit_2CMP_Ds(2),3),' +/- ', num2str(FitErr_2CMP_Ds(2),3), ' um2/s'];
                    FitString{4} = ['D_bound = ' num2str(app.Db), ' um2/s'];
                    FitString{5} = ['k_on = ' num2str(FitPar(3),3),' +/- ', num2str(FitErr(3),3), ' 1/s'];
                    FitString{6} = ['k_off = ' num2str(FitPar(4),3),' +/- ', num2str(FitErr(4),3), ' 1/s'];
                    % FitString{7} = ['dZ = ', num2str(FitPar(5),3), ' +/- ', num2str(FitErr(5),3), 'um'];
                    FitString{7} = ['dZ = ', num2str(app.deltaZ,3), 'um'];
                    FitString{8} = ['F_bound = ', num2str(C_eq,3), ' +/- ', num2str(C_eq_err,3)];
                    FitString{9} = ['SSR = ' num2str(FitSSR(1))];
                    if app.bootStrapN > 0
                        FitString{10} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end
                    app.edit1.Text = FitString;

                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');
                elseif ~isempty(app.fitDiffOnly)
                    app.ChooseDraw.Value = 'Pure Diffusion Fit';
                    Fit = app.fitDiffOnly.Fit;
                    FitPar = app.fitDiffOnly.FitPar;
                    FitErr = app.fitDiffOnly.FitErr;
                    FitSSR = app.fitDiffOnly.SSR;

                    for t = 1:1:length(app.tlist)
                        tplot = app.tlist(t)*ones(length(app.rlist), 1);
                        plot3(app.plot_axes, tplot, app.rlist, app.JDH(t,:), 'ok', 'MarkerSize', 4);
                        hold(app.plot_axes, 'on');
                        plot3(app.plot_axes, tplot, app.rlist, Fit(t,:), 'r');
                    end
                    hold(app.plot_axes, 'off');

                    title(app.plot_axes, {'Jump Histogram Distribution - Full Model Fit',...
                        ['D_{free} = ', num2str(FitPar(1),3)],...
                        ['dZ = ', num2str(FitPar(3),3), '\mum']});

                    set(app.plot_axes, 'FontSize', 12);
                    view(app.plot_axes, 108,30)
                    grid(app.plot_axes, 'on');

                    xlabel(app.plot_axes, 'Time [s]');
                    ylabel(app.plot_axes, 'Jump Distance [\mum]');
                    zlabel(app.plot_axes, 'Counts');
                    app.plot_axes.YDir = 'normal';


                    FitString{1} = 'Estimated Parameters of the Pure Diffusion fit:';
                    FitString{2} = ['D_free = ' num2str(FitPar(1),3),' +/- ', num2str(FitErr(1),3), ' um2/s'];
                    FitString{3} = ['dZ = ', num2str(FitPar(3),3), ' +/- ', num2str(FitErr(3),3), 'um'];
                    FitString{4} = ['SSR = ' num2str(FitSSR(1))];
                    if app.bootStrapN > 0
                        FitString{5} = ['Bootstrap samples = ' num2str(app.bootStrapN)];
                    end

                    app.edit1.Text = FitString;


                    %enable the buttons for copying the fits
                    app.CopyFit.Enable = 'on';
                    app.CopyFitPar.Enable = 'on';
                    colorbar(app.plot_axes,'off');
                else

                    app.ChooseDraw.Value = 'Heat Map';

                    imagesc(app.plot_axes, app.rlist, app.tlist, app.JDH);
                    set(app.plot_axes, 'FontSize', 10);
                    view(app.plot_axes, 0,90)
                    xlabel(app.plot_axes, 'Jump distance [\mum]');
                    ylabel(app.plot_axes, 'Time [s]');
                    title(app.plot_axes, 'Time-dependent histogram of displacements');
                    colorbar(app.plot_axes, 'location','EastOutside','XTick',0.5,'XTickLabel','Counts');

                    axis(app.plot_axes, 'ij');
                    %disable the buttons for copying the fits since we are showing
                    %the heat map
                    app.CopyFit.Enable = 'off';
                    app.CopyFitPar.Enable = 'off';

                    FitString = 'Fit Results';
                    app.edit1.Text = FitString;
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackAnalyzeJumpHistogramUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackAnalyzeJumpHistogramUIFigure.Name = 'QuantiTrack - Analyze Jump Histogram';
            app.FileMenu = uimenu(app.QuantiTrackAnalyzeJumpHistogramUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadJDHanalysisMenu = uimenu(app.FileMenu);
            app.LoadJDHanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadJDHanalysisMenuSelected, true);
            app.LoadJDHanalysisMenu.Accelerator = 'o';
            app.LoadJDHanalysisMenu.Text = 'Load JDH analysis';
            app.SaveJDHanalysisMenu = uimenu(app.FileMenu);
            app.SaveJDHanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveJDHanalysisMenuSelected, true);
            app.SaveJDHanalysisMenu.Accelerator = 's';
            app.SaveJDHanalysisMenu.Text = 'Save JDH analysis';
            app.plot_axes = uiaxes(app.QuantiTrackAnalyzeJumpHistogramUIFigure);
            app.edit1 = uilabel(app.QuantiTrackAnalyzeJumpHistogramUIFigure);
            app.edit1.Text = 'Fit Results';
            app.ChooseDraw = uidropdown(app.QuantiTrackAnalyzeJumpHistogramUIFigure);
            app.ChooseDraw.Items = {'Heat Map', 'Pure Diffusion Fit', 'Diffusion + Binding Fit', '2-cmp Diffusion + Binding Fit'};
            app.ChooseDraw.ValueChangedFcn = createCallbackFcn(app, @ChooseDrawValueChanged, true);
            app.ChooseDraw.Value = 'Heat Map';
            app.CopyHist = uibutton(app.QuantiTrackAnalyzeJumpHistogramUIFigure, 'push');
            app.CopyHist.ButtonPushedFcn = createCallbackFcn(app, @CopyHistButtonPushed, true);
            app.CopyHist.Text = 'Copy 2D Histogram of Displacements';
            app.CopyFit = uibutton(app.QuantiTrackAnalyzeJumpHistogramUIFigure, 'push');
            app.CopyFit.ButtonPushedFcn = createCallbackFcn(app, @CopyFitButtonPushed, true);
            app.CopyFit.Text = 'Copy Current Fit Curve';
            app.CopyFitPar = uibutton(app.QuantiTrackAnalyzeJumpHistogramUIFigure, 'push');
            app.CopyFitPar.ButtonPushedFcn = createCallbackFcn(app, @CopyFitParButtonPushed, true);
            app.CopyFitPar.Text = 'Copy Current Fit Parameters';
            app.SaveFigure = uibutton(app.QuantiTrackAnalyzeJumpHistogramUIFigure, 'push');
            app.SaveFigure.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigure.Text = 'Save Figure';
            app.DONEButton = uibutton(app.QuantiTrackAnalyzeJumpHistogramUIFigure, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Text = 'DONE';
            % Show the figure after all components are created
            app.QuantiTrackAnalyzeJumpHistogramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyzeJumpHistogram_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackAnalyzeJumpHistogramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackAnalyzeJumpHistogramUIFigure)
        end
    end
end