classdef AnalyzeResTimes_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackAnalyzeResidenceTimesUIFigure  matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        SaveMenu              matlab.ui.container.Menu
        LoadMenu              matlab.ui.container.Menu
        ToolsMenu             matlab.ui.container.Menu
        PieChart              matlab.ui.container.Menu
        SaveFigure            matlab.ui.control.Button
        CopyFitPar            matlab.ui.control.Button
        CopyAnPar             matlab.ui.control.Button
        CopyHist              matlab.ui.control.Button
        BICvaluesPanel        matlab.ui.container.Panel
        BIC_PwrExpText        matlab.ui.control.Label
        BIC_PwrText           matlab.ui.control.Label
        BIC_BiExpText         matlab.ui.control.Label
        BIC_ExpText           matlab.ui.control.Label
        totalROIsText_2       matlab.ui.control.Label
        totalParticlesText_2  matlab.ui.control.Label
        boundParticlesText_2  matlab.ui.control.Label
        totalTracksText_2     matlab.ui.control.Label
        ParametersUsedPanel   matlab.ui.container.Panel
        NminText              matlab.ui.control.Label
        End2EndMaxText        matlab.ui.control.Label
        frame2frameMaxText    matlab.ui.control.Label
        DatasetMetricsPanel   matlab.ui.container.Panel
        totalROIsText         matlab.ui.control.Label
        totalParticlesText    matlab.ui.control.Label
        boundParticlesText    matlab.ui.control.Label
        totalTracksText       matlab.ui.control.Label
        FitstodisplayPanel    matlab.ui.container.Panel
        PowerExpCheck         matlab.ui.control.CheckBox
        PowerCheck            matlab.ui.control.CheckBox
        BiExpCheck            matlab.ui.control.CheckBox
        ExpCheck              matlab.ui.control.CheckBox
        Surv_Axis             matlab.ui.control.UIAxes
        PB_Axis               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PathName_PB % Path for files used in photobleaching correction
        FileNames_PB % files used in photobleaching correction
        PathName_TF % Path for files to be analyzed
        FileNames_TF % files analyzed
        Parameters % Analysis parameters
        FrameTime % time interval between movie frames
        minTracks % minimum number of tracks in the survival curve to use for fitting
        PBMethod % Method to use for photobleaching correction (1= histone survival fitting; 2 = number of molecules detected over time)
        maxTime % currently not used
        totalTracks % Total number of tracks from the Photobleaching files
        CumHist % Cumulative histogram for Photobleaching files
        nROIs % Number of ROIs in the tracking data
        BoundMolecules % Number of bound molecules in the data
        TotalMolecules % Total number of molecules in the data
        ResTimes_all % Raw residence time data with no photobleaching correction
        lastInd2Fit % index of the histogram with the last occurence of minTracks
        final_timepoint % last time-point to use in fitting
        init_timepoint %first time-point to use in fitting
        BIC
        Hist 
        FitPar
        
        PB % Photobleaching data
        PBPar % Description
        ROIChoice % Description
        condChoice % Description
    end
    
    methods (Access = private)
        
        function [Esp_Coef, Esp_Sigma, Esp_Fit] = ExpDecay_2Cmp_fit(app,data,k0s)
            % Calculate best two compoonent exponential fit with a the lsqnonlin non
            %linear least square fitting routine.


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);


            k01 = k0s(1);
            k02 = k0s(2);
            f01 = 0.5;
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k01, k02, f01, A0];


            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0, 0 , 0];
            

            % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf, 1,Inf];

            % Define anonymous function for the fitting
            fitfun = @(COEF) (ExpDecay_2Cmp_fun(app,COEF, tlist)) - (Y);
            
            % Select Options for the fitting
            options = optimset('FunValCheck','off', 'MaxIter', 1000, 'MaxFunEvals', 1000);

            % run fitting routibe
            [Esp_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            

            ci = nlparci(Esp_Coef,residuals,'jacobian',jacobian);
            Esp_Sigma = (ci(:,2) - ci(:,1))/2;
            Esp_Sigma = Esp_Sigma';


            % Compute output
            Esp_Fit(:,1) = data(:,1);
            Esp_Fit(:,2) = ExpDecay_2Cmp_fun(app,Esp_Coef, data(:,1));

            %Compute the individual components --DB
            Esp_Coef_1 = [Esp_Coef(1) Esp_Coef(4)*Esp_Coef(3)];
            Esp_Fit(:,3) = ExpDecay_fun(app,Esp_Coef_1,data(:,1));

            Esp_Coef_2 = [Esp_Coef(2) Esp_Coef(4)*(1 - Esp_Coef(3))];
            Esp_Fit(:,4) = ExpDecay_fun(app,Esp_Coef_2,data(:,1));

            
        end
        
        function esp = ExpDecay_2Cmp_fun(~,coeff,x)
            k1 = coeff(1);
            k2 = coeff(2);
            f = coeff(3);
            a = coeff(4);


            esp = a*(f*(exp(-k1*x)) + (1-f)*exp(-k2*x))*(1 - (k1 < 0|k2 < 0|f < 0|f > 1| a<0));
        end
        
        function [Esp_Coef, Esp_Sigma, Esp_Fit] = ExpDecay_2Cmp_log_fit(app,data, k0s)
            % Calculate best two compoonent exponential fit after log transforming it with a the lsqnonlin non
            %linear least square fitting routine.


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);
            tlist = tlist(Y > 0);
            Y = Y(Y >0);

            k01 = k0s(1);
            k02 = k0s(2);
            f01 = 0.5;
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k01, k02, f01, A0];


            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0, 0 , 0];
            %

            % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf, 1,Inf];

            % Define anonymous function for the fitting
            fitfun = @(COEF) (ExpDecay_2Cmp_log_fun(app,COEF, tlist)) - log10(Y);
            
            % Select Options for the fitting
            options = optimset('FunValCheck','off', 'MaxIter', 1000, 'MaxFunEvals', 1000);

            % run fitting routibe
            [Esp_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            % [Esp_Coef, resNorm, residuals,exitflag,output,lambda,jacobian] = lsqnonlin(fitfun,COEF0,options);

            ci = nlparci(Esp_Coef,residuals,'jacobian',jacobian);
            Esp_Sigma = (ci(:,2) - ci(:,1))/2;
            Esp_Sigma = Esp_Sigma';


            % Compute output
            Esp_Fit(:,1) = data(:,1);
            Esp_Fit(:,2) = ExpDecay_2Cmp_fun(app,Esp_Coef, data(:,1));

            %Compute the individual components --DB
            Esp_Coef_1 = [Esp_Coef(1) Esp_Coef(4)*Esp_Coef(3)];
            Esp_Fit(:,3) = ExpDecay_fun(app,Esp_Coef_1,data(:,1));

            Esp_Coef_2 = [Esp_Coef(2) Esp_Coef(4)*(1 - Esp_Coef(3))];
            Esp_Fit(:,4) = ExpDecay_fun(app,Esp_Coef_2,data(:,1));
        end
        
        function esp = ExpDecay_2Cmp_log_fun(~,coeff, x)
            k1 = coeff(1);
            k2 = coeff(2);
            f = coeff(3);
            a = coeff(4);


            esp = a*(f*(exp(-k1*x)) + (1-f)*exp(-k2*x))*(1 - (k1 < 0|k2 < 0|f < 0|f > 1| a<0));
            esp = log10(esp);
        end
        
        function [Esp_Coef, Esp_Sigma, Esp_Fit] = ExpDecay_fit(app,data, k0)
            % Calculate best exponential fit with a the lsqnonlin non linear least
            % square fitting routine


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k0, A0];

            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0];
            % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf];

            % Define anonymous function for the fitting
            fitfun = @(COEF) (ExpDecay_fun(app,COEF, tlist) -Y);

            % Select Options for the fitting
            options = optimset('FunValCheck','off','Display','off');

            % run fitting routibe
            [Esp_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            ci = nlparci(Esp_Coef,residuals,'jacobian',jacobian);
            Esp_Sigma = (ci(:,2) - ci(:,1))/2;
            Esp_Sigma = Esp_Sigma';

            % Compute output
            Esp_Fit(:,1) = data(:,1);
            Esp_Fit(:,2)= ExpDecay_fun(app,Esp_Coef,Esp_Fit(:,1));

        end
        
        function esp = ExpDecay_fun(~,coeff, x)
            k = coeff(1);
            a= coeff(2);

            esp = a*(exp(-k*x));

        end
        
        function [Esp_Coef, Esp_Sigma, Esp_Fit] = ExpDecay_log_fit(app,data, k0)
            % Calculate best exponential fit with a the lsqnonlin non linear least
            % square fitting routine


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);
            A0 = max(Y);
            tlist = tlist(Y>0);
            Y = Y(Y >0);

            % define initial values for the parameters
            COEF0 = [k0, A0];

            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0];
            % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf];

            % Define anonymous function for the fitting
            fitfun = @(COEF) (ExpDecay_log_fun(app,COEF, tlist) -log10(Y));

            % Select Options for the fitting
            options = optimset('FunValCheck','off','Display','off');

            % run fitting routibe
            [Esp_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            ci = nlparci(Esp_Coef,residuals,'jacobian',jacobian);
            Esp_Sigma = (ci(:,2) - ci(:,1))/2;
            Esp_Sigma = Esp_Sigma';

            % Compute output
            Esp_Fit(:,1) = data(:,1);
            Esp_Fit(:,2)= ExpDecay_fun(app,Esp_Coef,Esp_Fit(:,1));
        end
        
        function esp = ExpDecay_log_fun(~,coeff, x)
            k = coeff(1);
            a= coeff(2);


            esp = a*(exp(-k*x));
            esp = log10(esp);

        end
        
        function [Pwr_Coef, Pwr_Sigma, Pwr_Fit] = PwrLaw_Exp_Decay_log_nlinfit(app,data, k0s)
            % Calculate best two compoonent power law + exponential fit with a the lsqnonlin non
            %linear least square fitting routine after log transform.


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);
            tlist = tlist(Y>0);
            Y = Y(Y>0);

            k01 = k0s(1);
            k02 = k0s(2);
            f_p0 = 0.5;
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k01, k02, f_p0, A0];


            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0, 0 , 0];
            
            % % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf, 1,Inf];

            % Define anonymous function for the fitting
            
            fitfun = @(COEF)(PwrLaw_Exp_Decay_log_fun(app,COEF, tlist)-log10(Y));
            % Select Options for the fitting
            options = optimset('FunValCheck','off', 'MaxIter', 1000, 'MaxFunEvals', 1000, 'TolFun',10e-30);
            
            % run fitting routine
            [Pwr_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            

            ci = nlparci(Pwr_Coef,residuals,'jacobian',jacobian);
            Pwr_Sigma = (ci(:,2) - ci(:,1))/2;
            Pwr_Sigma = Pwr_Sigma';


            % Compute output
            Pwr_Fit(:,1) = data(:,1);
            Pwr_Fit(:,2) = PwrLaw_Exp_Decay_fun(app,Pwr_Coef, data(:,1));
        end
        
        function pwr = PwrLaw_Exp_Decay_log_fun(~,coeff, x)
            alpha = coeff(1);
            k = coeff(2);
            f_p = coeff(3);
            A = coeff(4);


            pwr = A.*((f_p.*x.^(-alpha)) + (1-f_p).*exp(-k.*x));

            pwr = log10(pwr);

            
        end
        
        function [Pwr_Coef, Pwr_Sigma, Pwr_Fit] = PwrLaw_Exp_Decay_nlinfit(app,data, k0s)
            % Calculate best two compoonent power law + exponential fit with a the lsqnonlin non
            %linear least square fitting routine.


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);


            k01 = k0s(1);
            k02 = k0s(2);
            f_p0 = 0.5;
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k01, k02, f_p0, A0];


            % define lower boundaries for the fitted parameters
            COEF_LB = [0, 0, 0 , 0];
            
            % % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf, 1,Inf];

            % Define anonymous function for the fitting
            
            fitfun = @(COEF)(PwrLaw_Exp_Decay_fun(app,COEF, tlist)-Y);
            % Select Options for the fitting
            options = optimset('FunValCheck','off', 'MaxIter', 1000, 'MaxFunEvals', 1000, 'TolFun',10e-30);
            
            % run fitting routibe
            [Pwr_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            

            ci = nlparci(Pwr_Coef,residuals,'jacobian',jacobian);
            Pwr_Sigma = (ci(:,2) - ci(:,1))/2;
            Pwr_Sigma = Pwr_Sigma';


            % Compute output
            Pwr_Fit(:,1) = data(:,1);
            Pwr_Fit(:,2) = PwrLaw_Exp_Decay_fun(app,Pwr_Coef, data(:,1));
        end
        
        function pwr = PwrLaw_Exp_Decay_fun(~,coeff, x)
            alpha = coeff(1);
            k = coeff(2);
            f_p = coeff(3);
            A = coeff(4);


            pwr = ((f_p.*x.^(-alpha)) + (1-f_p).*exp(-k.*x));
            pwr = pwr./pwr(1);
            pwr = A.*pwr;
        end
        
        function [Pwr_Coef, Pwr_Sigma, Pwr_Fit] = PwrLawDecay_log_nlinfit(app,data, k0s)
            % Calculate best power law fit with the lsqnonlin non
            %linear least square fitting routine.


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);
            tlist = tlist(Y > 0);
            Y = Y(Y > 0);

            k01 = k0s(1);
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k01, A0];


            % define lower boundaries for the fitted parameters
            
            COEF_LB = [0,0];
            %
            % % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf];

            % Define anonymous function for the fitting
            
            fitfun = @(COEF)(PwrLawDecay_log_fun(app,COEF, tlist)-log10(Y));
            % Select Options for the fitting
            options = optimset('FunValCheck','off', 'MaxIter', 1000, 'MaxFunEvals', 1000, 'TolFun',10e-30);
            % run fitting routine
            [Pwr_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            
            
            ci = nlparci(Pwr_Coef,residuals,'jacobian',jacobian);
            Pwr_Sigma = (ci(:,2) - ci(:,1))/2;
            Pwr_Sigma = Pwr_Sigma';


            % Compute output
            Pwr_Fit(:,1) = data(:,1);
            Pwr_Fit(:,2) = PwrLawDecay_fun(app,Pwr_Coef, data(:,1));
        end
        
        function pwr = PwrLawDecay_fun(~,coeff, x)
            alpha = coeff(1);
            a = coeff(2);


            pwr = a.*x.^(-alpha);
        end
        
        function [Pwr_Coef, Pwr_Sigma, Pwr_Fit] = PwrLawDecay_nlinfit(app,data, k0s)
            % Calculate best Power Law fit with a the lsqnonlin non
            %linear least square fitting routine.


            % Read input data

            tlist =data(:,1);
            Y = data(:,2);


            k01 = k0s(1);
            A0 = max(Y);


            % define initial values for the parameters
            COEF0 = [k01, A0];


            % define lower boundaries for the fitted parameters
            
            COEF_LB = [0, 0];
            %
            % % define upper boundaries for the fitted parameters
            COEF_UB = [Inf, Inf];

            % Define anonymous function for the fitting
            
            fitfun = @(COEF)(PwrLawDecay_fun(app,COEF, tlist)-Y);
            % Select Options for the fitting
            options = optimset('FunValCheck','off', 'MaxIter', 1000, 'MaxFunEvals', 1000, 'TolFun',10e-30);
           
            [Pwr_Coef, ~, residuals,~,~,~,jacobian] = lsqnonlin(fitfun,COEF0,COEF_LB,COEF_UB,options);
            % run fitting routibe
           

            ci = nlparci(Pwr_Coef,residuals,'jacobian',jacobian);
            Pwr_Sigma = (ci(:,2) - ci(:,1))/2;
            Pwr_Sigma = Pwr_Sigma';


            % Compute output
            Pwr_Fit(:,1) = data(:,1);
            Pwr_Fit(:,2) = PwrLawDecay_fun(app,Pwr_Coef, data(:,1));
        end
        
        function pwr = PwrLawDecay_log_fun(~,coeff, x)
            alpha = coeff(1);
            a = coeff(2);


            pwr = a.*x.^(-alpha);
            pwr = log10(pwr);
        end
        
        function BIC = ModelSelection_log_fun(app)
            %Model order: 1 - Exponential; 2 - Bi-Exponential; 3 - Power law; 4 - Power law + Exponential;
            numModels = 4;
            k_params = [2 4 2 4];
            init = app.init_timepoint;
    
            final = app.final_timepoint;
            % Data range and number of points
            range = init:final;
            n = length(range);

            CDF = app.Hist(:,2);
            fits = app.Hist(:,5:8)';
            BIC = zeros(numModels,1);

            for i = 1:numModels
                model = fits(i, range);
                data = CDF(range)';

                % residuals = log(model) - log(data);  % use natural log
                residuals = model - data;
                sigma2 = var(residuals);  % estimate variance of residuals

                % Log-likelihood for normal errors
                logL = -n/2 * log(2 * pi * sigma2) - (1 / (2 * sigma2)) * sum(residuals .^ 2);

                % BIC calculation
                k = k_params(i);
                BIC(i) = k * log(n) - 2 * logL;
            end

        end
        
        
        function [fractions,resTimes] = PieChartsFromResTimeAnalysis_HistPB(app,varargin)
%             FitSel = find(app.BIC == min(app.BIC));
            Ans = inputdlg(['Please select the fit to use for the Pie chart: ',...
                '1 - Exponential; 2 - Bi-Exponential; 3 - Power Law; ',...
                '4 - Power Law + Exponential'],'Choose Fit');
            if isempty(Ans)
                fractions = [];
                resTimes = [];

                return;
            end

            FitSel = str2double(Ans);
            if sum(FitSel == (1:4)) == 0
                errordlg('Fit selection must be an integer in the range [1,4]');
                return;
            end

            %set up the figure
            pie_fig = figure;
            pie_ax = axes(pie_fig);

            switch FitSel
                case 1 %MonoExponential
                    fractions(:,1) = [1.0-app.FitPar(1,2); app.FitPar(1,2)];

                    fractions(:,2) = [app.FitPar(2,2); app.FitPar(2,2)];

                    resTimes(:,1) = 1/app.FitPar(1,1);
                    resTimes(:,2) = (resTimes(1,1)/app.FitPar(1,1))*app.FitPar(2,1);


                    pie(pie_ax, fractions(:,1),{'',''});

                    set(pie_fig,'Units','centimeters');
                    set(pie_fig,'paperUnits','centimeters');

                    pos = get(pie_fig,'Position');

                    pos(3) = 17.8/2;
                    pos(4) = 23.0/4;

                    set(pie_fig,'Position',pos);
                    set(pie_fig,'paperPosition',pos);
                    pos_ax2 = [0.025, 0.015, 0.62,0.9];
                    set(pie_ax,'Position',pos_ax2);


                    text(pie_ax, -1.2,1.15,[num2str(round(fractions(1,1)*100,1)), '\pm', num2str(round(fractions(1,2)*100,1)),'%'],'FontSize',10);


                    text(pie_ax, 1.1,-0.25,[num2str(round(fractions(2,1)*100,1)), '\pm', num2str(round(fractions(2,2)*100,1)),'%'],'FontSize',10);
                    text(pie_ax, 0.9,-0.5,['\tau = ',num2str(round(resTimes(1,1),3)), '\pm', num2str(round(resTimes(1,2),3)),' s'],'FontSize',10);

                case 2 %Biexponential
                    fractions(:,1) = [1.0-app.FitPar(1,6); app.FitPar(1,6)*app.FitPar(1,5); app.FitPar(1,6)*(1.0-app.FitPar(1,5))];

                    fractions(:,2) = [app.FitPar(2,6); ...
                        fractions(2,1)*sqrt((app.FitPar(2,5)/app.FitPar(1,5)).^2 +...
                        (app.FitPar(2,6)/app.FitPar(1,6)).^2); ...
                        fractions(2,1)*sqrt((app.FitPar(2,5)/app.FitPar(1,5)).^2 +...
                        (app.FitPar(2,6)/app.FitPar(1,6)).^2)];

                    resTimes(:,1) = [1/app.FitPar(1,3); 1/app.FitPar(1,4)];
                    resTimes(:,2) = [(resTimes(1,1)/app.FitPar(1,3))*app.FitPar(2,3); ...
                        (resTimes(2,1)/app.FitPar(1,4))*app.FitPar(2,4)];


                    pie(pie_ax, fractions(:,1),{'','',''});

                    set(pie_fig,'Units','centimeters');
                    set(pie_fig,'paperUnits','centimeters');

                    pos = get(pie_fig,'Position');

                    pos(3) = 17.8/2;
                    pos(4) = 23.0/4;

                    set(pie_fig,'Position',pos);
                    set(pie_fig,'paperPosition',pos);
                    pos_ax2 = [0.025, 0.015, 0.62,0.9];
                    set(pie_ax, 'Position',pos_ax2);

                    %May need to ajust the x/y positions of each text


                    text(pie_ax, 1.1,-0.25,[num2str(round(fractions(2,1)*100,1)), '\pm', num2str(round(fractions(2,2)*100,1)),'%'],'FontSize',10)
                    text(pie_ax, 0.9,-0.5,['\tau = ',num2str(round(resTimes(1,1),3)), '\pm', num2str(round(resTimes(1,2),3))],'FontSize',10)

                    text(pie_ax, 0.5,1.2,[num2str(round(fractions(3,1)*100,1)), '\pm', num2str(round(fractions(3,2)*100,1)),'%'],'FontSize',10)
                    text(pie_ax, 0.5, 1.0,['\tau = ',num2str(round(resTimes(2,1),3)), '\pm', num2str(round(resTimes(2,2),3)),' s'],'FontSize',10)

                    text(pie_ax, -1.2,1.15,[num2str(round(fractions(1,1)*100,1)), '\pm', num2str(round(fractions(1,2)*100,1)),'%'],'FontSize',10)

                case 3 %Power Law
                    fractions(:,1) = [(1 - app.FitPar(1,8)); app.FitPar(1,8)];

                    fractions(:,2) = [app.FitPar(2,8); app.FitPar(2,8)];

                    resTimes(:,1) = app.FitPar(1,7);
                    resTimes(:,2) = app.FitPar(2,7);


                    pie(pie_ax, fractions(:,1),{'',''});

                    set(pie_fig,'Units','centimeters');
                    set(pie_fig,'paperUnits','centimeters');

                    pos = get(pie_fig,'Position');

                    pos(3) = 17.8/2;
                    pos(4) = 23.0/4;

                    set(pie_fig,'Position',pos);
                    set(pie_fig,'paperPosition',pos);
                    pos_ax2 = [0.025, 0.015, 0.62,0.9];
                    set(pie_ax,'Position',pos_ax2);

                    %For simulated Data:
                    text(pie_ax, -1.2,1.15,[num2str(round(fractions(1,1)*100,1)), '\pm', num2str(round(fractions(1,2)*100,1)),'%'],'FontSize',10);


                    text(pie_ax, 1.1,-0.25,[num2str(round(fractions(2,1)*100,1)), '\pm', num2str(round(fractions(2,2)*100,1)),'%'],'FontSize',10);
                    text(pie_ax, 0.9,-0.5,['\alpha = ',num2str(round(resTimes(1,1),3)), '\pm', num2str(round(resTimes(1,2),3))],'FontSize',10);
                case 4 %Power Law + Exponential
                    fractions(:,1) = [(1-app.FitPar(1,12));app.FitPar(1,12)*app.FitPar(1,11); app.FitPar(1,12)*(1.0-app.FitPar(1,11))];

                    fractions(:,2) = [app.FitPar(2,12); ...
                        fractions(2,1)*sqrt((app.FitPar(2,11)/app.FitPar(1,11)).^2 +...
                        (app.FitPar(2,12)/app.FitPar(1,12)).^2); ...
                        fractions(2,1)*sqrt((app.FitPar(2,11)/app.FitPar(1,11)).^2 +...
                        (app.FitPar(2,12)/app.FitPar(1,12)).^2)];

                    resTimes(:,1) = [app.FitPar(1,9); 1/app.FitPar(1,10)];
                    resTimes(:,2) = [app.FitPar(2,9); ...
                        (resTimes(2,1)/app.FitPar(1,10))*app.FitPar(2,10)];


                    pie(pie_ax, fractions(:,1),{'','',''});

                    set(pie_fig,'Units','centimeters');
                    set(pie_fig, 'paperUnits','centimeters');

                    pos = get(pie_fig,'Position');

                    pos(3) = 17.8/2;
                    pos(4) = 23.0/4;

                    set(pie_fig,'Position',pos);
                    set(pie_fig,'paperPosition',pos);
                    pos_ax2 = [0.025, 0.015, 0.62,0.9];
                    set(pie_ax,'Position',pos_ax2);

                    %May need to ajust the x/y positions of each text
                    %For real Data:

                    text(pie_ax, 1.1,-0.25,[num2str(round(fractions(2,1)*100,1)), '\pm', num2str(round(fractions(2,2)*100,1)),'%'],'FontSize',10);
                    text(pie_ax, 0.9,-0.5,['\alpha = ',num2str(round(resTimes(2,1),3)), '\pm', num2str(round(resTimes(2,2),3))],'FontSize',10);

                    text(pie_ax, 0.5,1.2,[num2str(round(fractions(3,1)*100,1)), '\pm', num2str(round(fractions(3,2)*100,1)),'%'],'FontSize',10);
                    text(pie_ax, 0.5, 1.0,['\tau = ',num2str(round(resTimes(1,1),3)), '\pm', num2str(round(resTimes(1,2),3)),' s'],'FontSize',10);

                    text(pie_ax, -1.2,1.15,[num2str(round(fractions(1,1)*100,1)), '\pm', num2str(round(fractions(1,2)*100,1)),'%'],'FontSize',10);

            
            end
            
            cmap(1,:) = [0.9,0.9,0.9];
            if ~isempty(varargin)
                minRes = varargin{1}(1);
                maxRes = varargin{1}(2);

                cmap_base = colormap('jet');


                for i = 1:size(resTimes,1)
                    ratio1 = (resTimes(i,1) - minRes)/(maxRes - minRes);
                    ratio1 = max(ratio1,0);
                    ratio1 = min(ratio1,1);
                    indx = round(ratio1*63) + 1;
                    cmap(i+1,:) = cmap_base(indx,:);
                end

            else
                cmap(2,:) = [0,1,0];
                if size(resTimes,1) == 2
                    cmap(3,:) = [1,1,0];
                end
            end
            colormap(pie_ax,cmap);
        end
        
        function [filTracks, allFilTracks] = calculateImmobileTracks(~,Tracks, ThresholdL, minFrame, ThresholdH)

            % calculateImmobileTracks
            % --------------------------------------------------
            % Find the segments of the tracks that correspond to
            % jumps shorter than Threshold.
            % ------
            % INPUT:
            % ------
            % tracks: Tracks as produced by the track.m routine. In brief
            %         Tracks(:,1) =  x_coordinate (in microns)
            %         Tracks(:,2) =  y_coordinate (in microns)
            %         Tracks(:,3) =  frame identifier
            %         Tracks(:,4) =  particle identifier
            % ------
            % OUTPUT:
            % ------
            % filTracks: Immobile track segments:
            %       filTracks(:,1) = x-Coordinate (in microns)
            %       filTracks(:,2) = y-Coordinate (in microns)
            %       filTracks(:,3) = frame identifier
            %       filTracks(:,4) = segment identifier
            %       filTracks(:,5) = particle identifier
            % Different segments with the same particle index belong
            % to the same original track


            allFilTracks = [];

            previous = 0;  % previous = 0 when the previous jump didn't meet
            % the filter criterium (jump shorter than 3 * locAcc),
            % 1 if it did.
            particleIx = 0;

            %% Identify particles that Jump Less than ThresholdL from frame to frame;
            if size(Tracks,2) == 5 || size(Tracks,2) == 7
                partInd = 4;
            elseif size(Tracks,2) == 6 || size(Tracks,2) == 8
                partInd = 5;
            end

            for i = 1:max(Tracks(:,partInd))
                
                trackTemp = Tracks(Tracks(:,partInd) == i,:);
                %      if size(Tracks,2) == 5 || size(Tracks,2) == 7
                jd_temp = sqrt((trackTemp(2:end,1)- trackTemp(1:end-1,1)).^2 + ...
                    (trackTemp(2:end,2)- trackTemp(1:end-1,2)).^2);
                %      elseif size(Tracks,2) == 6 || size(Tracks,2) == 8
                %          jd_temp = sqrt((trackTemp(2:end,1)- trackTemp(1:end-1,1)).^2 + ...
                %                     (trackTemp(2:end,2)- trackTemp(1:end-1,2)).^2 + ...
                %                     (trackTemp(2:end,3)- trackTemp(1:end-1,3)).^2);
                %      end

                for j = 1: length(jd_temp)

                    if jd_temp(j) < ThresholdL && ~previous
                        particleIx = particleIx +1;
                        previous = 1;

                        catTrack = trackTemp ([j j+1],1:(partInd-1));
                        catTrack(:,partInd) = particleIx;
                        catTrack(:,partInd+1:partInd+3) = trackTemp ([j j+1],partInd+1:partInd+3);
                        catTrack(:,partInd+4) = trackTemp([j j+1], partInd);
                        allFilTracks = [allFilTracks ; catTrack];


                    elseif jd_temp(j) < ThresholdL  && previous
                        catTrack = trackTemp (j+1,1:(partInd-1));
                        catTrack(:,partInd) = particleIx;
                        catTrack(:,partInd+1:partInd+3) = trackTemp (j+1,partInd+1:partInd+3);
                        catTrack(:,partInd+4) = trackTemp (j+1,partInd);
                        allFilTracks = [allFilTracks ; catTrack];

                    else
                        previous = 0;
                    end
                end
                previous = 0;
            end

            %% Discard Tracks shorter than minFrame

            filTracks = discardTracks(allFilTracks,minFrame);

            if  ~isempty(filTracks) && ThresholdH > 0
                %% Identify Tracks that jump less than ThresholdH between minFrame frames


                for i = 1:max(filTracks(:,partInd))

                    idx = find(filTracks(:,partInd)== i);
                    trackTemp = filTracks(idx,:);
                    %         if size(Tracks,2) == 5 || size(Tracks,2) == 7
                    Test = sqrt((trackTemp(minFrame:end,1)- trackTemp(1:end-minFrame+1,1)).^2 + ...
                        (trackTemp(minFrame:end,2)- trackTemp(1:end-minFrame+1,2)).^2);
                  

                    if max(Test) > ThresholdH
                        filTracks(idx,:) = [];
                    end
                end


                %% Reassign Particle index of filTracks
                TestAccum = [];
                Test = filTracks(2:end,partInd) - filTracks(1:end-1,partInd) > 0;
                for i = 1:length(Test)
                    TestAccum(i) = sum(int16(Test(1:i)));
                end
                TestAccum = [1, TestAccum + 1];

                filTracks(:,partInd) = TestAccum;

            end


        end
        
        function TrackLengthHist = calculateTrackLength(~,tracks, frameTime, minBound)
            % This function compute the length of each track and calculate a cumulative
            % histogram of the track lengths. The histogram is corrected for
            % photobleaching, assuming that an exponential bleaching decay.
            %
            % Input
            % tracks contains the information about the tracks
            % in particular track(:, 4) represents the track identifier
            % BleachRate is a scalar indicating the photobleaching rate [units of s^-1]
            % FrameTime is a scalar indicating the time between two points in the track

            if size(tracks,2) == 8
                partInd = 4;
            elseif size(tracks,2) == 9
                partInd = 5;
            end

            nTracks = max(tracks(:,partInd));
            TrackLength = zeros(nTracks,1);

            for i = 1:nTracks
                idx = find(tracks(:,partInd) == i);  % find the track identified by i
                TrackLength(i) = length(idx);
            end


            % Compute Number of tracks longer than N-frames
            LongestTrack = max(TrackLength);
            ShortestTrack = minBound;
            TrackLengthHist = zeros(LongestTrack-ShortestTrack + 2,2);
            TrackLengthHist(:,1) = ((ShortestTrack-1): LongestTrack)* frameTime;

            for  i = 1:nTracks
                TrackLengthHist(1:TrackLength(i)- ShortestTrack + 1,2) = ...
                    TrackLengthHist(1:TrackLength(i) - ShortestTrack + 1,2)+1;
            end
        end
        
        function TrackLengthHist = compileBoundTracks(app,Tracks)
            
            for i = 1:length(Tracks)
                if ~isempty(Tracks{i})
                    if size(Tracks{i},2) <= 8
                        [ImmTracks, ~] = calculateImmobileTracks(app,Tracks{i}, app.Parameters.ThreshL,...
                            app.Parameters.minBoundFrames, app.Parameters.ThreshH);


                        if isempty(ImmTracks)
                            TrackLengthHist{i} = [0 0];
                        else

                            % Identify bound molecules for each of the movies;
                            %
                            % Calculate survival histogram;
                            TrackLengthHist{i} = calculateTrackLength(app,ImmTracks, ...
                                app.FrameTime,app.Parameters.minBoundFrames);

                        end
                    else
                        ImmTracks = Tracks{i}(Tracks{i}(:,2) >= app.Parameters.minBoundFrames,:);
                        ImmTracks = ImmTracks(ImmTracks(:,3) <= app.Parameters.ThreshL,:);
                        if ~isempty(ImmTracks)
                            TrackLength = ImmTracks(:,2);
                            LongestTrack = max(TrackLength);
                            ShortestTrack = app.Parameters.minBoundFrames;

                            TrackLengthHist{i} = zeros(LongestTrack-ShortestTrack + 1,2);
                            TrackLengthHist{i}(:,1) = (ShortestTrack-1: LongestTrack)* app.FrameTime;
                            %         TrackLengthHist(:,2) = hist(TrackLength,(ShortestTrack:LongestTrack));
                            for  j = 1:length(TrackLength)
                                TrackLengthHist{i}(1:TrackLength(j)- ShortestTrack + 1,2) = ...
                                    TrackLengthHist{i}(1:TrackLength(j) - ShortestTrack + 1,2)+1;
                            end
                        end
                    end
                end
            end


        end
        
        function updateSurvPlot(app)
            ExpPlot = app.ExpCheck.Value;
            BiExpPlot = app.BiExpCheck.Value;
            PwrPlot = app.PowerCheck.Value;
            PwrExpPlot = app.PowerExpCheck.Value;
            

            hist = app.Hist;

            hold(app.Surv_Axis,'off');
            final = app.final_timepoint;
            init =  app.init_timepoint;
            p1 = scatter(app.Surv_Axis,hist(init:final,1),hist(init:final,2),...
                'o','MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
            xlabel(app.Surv_Axis,'Time (s)')
            ylabel(app.Surv_Axis,'Survival Probability')
            hold(app.Surv_Axis,'on');


            if final < length(hist(:,2))
                scatter(app.Surv_Axis,hist(final+1:end,1),hist(final+1:end,2),...
                    'o','MarkerEdgeColor',[0.65, 0.65, 0.65],'MarkerFaceColor',[0.65, 0.65, 0.65]);
            end
            p2 = plot(app.Surv_Axis,hist(:,1),hist(:,3),'k--');
            p3 = plot(app.Surv_Axis,hist(:,1),hist(:,4),'k--');

            legend_Str = {'Survival', 'Upper Bound', 'Lower Bound'};
            legend_subset = [p1 p2 p3];
            histplot = hist(:,2:4);
            ylims = app.Surv_Axis.YLim;
            if ExpPlot
                p4 = plot(app.Surv_Axis,hist(:,1),hist(:,5),'b');
                legend_Str = [legend_Str, 'Exponential'];
                legend_subset = [legend_subset, p4];


            end
            if BiExpPlot
                p5 = plot(app.Surv_Axis,hist(:,1),hist(:,6),'g');
                legend_Str = [legend_Str, 'Bi-Exponential'];
                legend_subset = [legend_subset, p5];

                
            end
            if PwrPlot
                p6 = plot(app.Surv_Axis,hist(:,1),hist(:,7),'k');
                legend_Str = [legend_Str, 'Power-Law'];
                legend_subset = [legend_subset, p6];

                
            end
            if PwrExpPlot
                p7 = plot(app.Surv_Axis,hist(:,1),hist(:,8),'r');
                legend_Str = [legend_Str, 'Power-Law + Exp'];
                legend_subset = [legend_subset, p7];

                
            end
            
            app.Surv_Axis.YLim = ylims;
            % plotYvals = histplot(:);
            % plotYvals = plotYvals(plotYvals > 0);
            % app.Surv_Axis.Ylim = [min(plotYvals), max(plotYvals)];

            set(app.Surv_Axis,'Xscale','log');
            set(app.Surv_Axis,'Yscale','log');
            set(app.Surv_Axis,'XGrid','on');
            set(app.Surv_Axis,'YGrid','on');
            

            legend(legend_subset,legend_Str,'Location','SouthWest');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Import Parameters.
            if ~isempty(varargin)
                app.PathName_PB = varargin{2};
                app.FileNames_PB = varargin{3};
                app.PathName_TF = varargin{4};
                app.FileNames_TF = varargin{5};
                AnalysisS = varargin{6};
                if length(varargin) > 6
                    pos_fig = varargin{7};
                    ChangeAppPosition(app.QuantiTrackAnalyzeResidenceTimesUIFigure,pos_fig);
                    drawnow;
                end

                app.Parameters = [];
                app.Parameters.ThreshL = str2double(AnalysisS{1});
                app.Parameters.ThreshH = str2double(AnalysisS{2});
                app.Parameters.minBoundFrames = str2double(AnalysisS{3});
                %     handles.Parameters.bin = str2num(AnalysisS{4});
                app.FrameTime = str2double(AnalysisS{4});
                app.minTracks = str2double(AnalysisS{5});
                app.PBMethod = AnalysisS{6};

                app.maxTime = 0;

                %Ask if user wants to use the additional correction based on number of
                %particles
                st = app.Parameters.minBoundFrames;
                period = app.FrameTime;
                if app.PBMethod == 1
                    PB_npart = questdlg('Do you want to perform the additional correction based on the number of particles?','Number of Particles PB correction');
                    if strcmp(PB_npart,'Cancel')
                        return;
                    elseif strcmp(PB_npart,'Yes')
                        defPar(1) = 1e-4;
                        defPar(2) = 0.01;
                        defaults = {num2str(defPar(1)), num2str(defPar(2))};
                        prompt = {'Minimum Decay Rate',...
                            'Minimum Fraction'};
                        dlgtitle = 'Thresholds for Selecting a Photbleaching Rate';
                        PB_NPartParam = inputdlg(prompt,dlgtitle, 1, defaults);
                    end


                    % If only one file has been selected change the fileNames variable
                    % to a cell array

                    if ~iscell(app.FileNames_PB)
                        app.FileNames_PB = {app.FileNames_PB};
                    end
                    nFiles_PB = length(app.FileNames_PB);

                    %if we have only 1 file it will likely be a trackTable
                    if nFiles_PB == 1
                        IN = load(fullfile(app.PathName_PB,app.FileNames_PB{1}));
                        if isfield(IN,'trackTable')
                            [Tracks, NParticles] = compileTracksFromTables(...
                                IN.trackTable,app.maxTime,...
                                app.QuantiTrackAnalyzeResidenceTimesUIFigure,...
                                'Photobleaching'); 
                            nMovies_PB = length(Tracks);
                        else
                            [Tracks, NParticles] = compileTracksFromFiles(...
                                app.FileNames_PB,app.PathName_PB,app.maxTime,...
                                app.QuantiTrackAnalyzeResidenceTimesUIFigure,...
                                'Photobleaching');
                            nMovies_PB = 1;
                        end
                    else %if more than one file is selected it should be tracking files
                        [Tracks, NParticles] = compileTracksFromFiles(...
                            app.FileNames_PB,app.PathName_PB,app.maxTime,...
                            app.QuantiTrackAnalyzeResidenceTimesUIFigure,...
                            'Photobleaching');
                        nMovies_PB = nFiles_PB;
                    end

                    TrackLengthHist = compileBoundTracks(app,Tracks);


                    % Calculate the total number of particles;

                    % find the data containing the lowest number of frames;
                    TimePoints = [];

                    nMax = length(NParticles{1}(:,1));
                    TimePoints = NParticles{1}(:,1);
                    for i = 1:nMovies_PB
                        if size(NParticles{i},1) < nMax

                            TimePoints = NParticles{i}(:,1);
                            nMax = length(TimePoints);
                        end
                    end

                    if isempty(TimePoints)
                        errordlg('Too few bound particles to produce an histogram');
                        return
                    end

                    % Accumulate the histogram
                    Hist_Matrix = zeros(nMax,nMovies_PB);

                    for i = 1:nMovies_PB
                        Hist_Matrix(1:nMax,i) = NParticles{i}(1:nMax,2);
                    end

                    CumNParticles(:,1) = (TimePoints-1) * app.FrameTime;
                    CumNParticles(:,2) = sum(Hist_Matrix, 2);

                    %Calculate the avg intensity


                    % Accumulate the different survival histograms;

                    % find the histogram containing the longest track
                    nMax = 1;
                    TimePoints = [];
                    total_tracks = 0;
                    if length(TrackLengthHist) < nMovies_PB
                        for i = length(TrackLengthHist)+1:nMovies_PB
                            TrackLengthHist{i} = [];
                        end
                    end
                    for i = 1:nMovies_PB
                        trInd = unique(Tracks{i}(:,4));
                        total_tracks = total_tracks + length(trInd);

                        if size(TrackLengthHist{i},1) > nMax

                            TimePoints = TrackLengthHist{i}(:,1);
                            nMax = length(TimePoints);
                        end
                    end
                    app.totalTracks = total_tracks;
                    if isempty(TimePoints)
                        errordlg('Too few bound particles to produce an histogram');
                        return
                    end


                    % Accumulate the histogram
                    Hist_Matrix = zeros(nMax,nMovies_PB);

                    for i = 1:nMovies_PB
                        if ~isempty(TrackLengthHist{i})
                            lengthHist = length(TrackLengthHist{i}(:,1));
                            Hist_Matrix(1:lengthHist,i) = TrackLengthHist{i}(:,2);
                        end
                    end

                    CumTrackLengthHist(:,1) = TimePoints;
                    CumTrackLengthHist(:,2) = sum(Hist_Matrix, 2);

                    % Copy Histogram to handles
                    app.CumHist = CumTrackLengthHist;


                    % First calculate the residence time histogram
                    ResTimeHist(:,1)= CumTrackLengthHist(:,1);
                    ResTimeHist(:,2) = ...
                        [(CumTrackLengthHist(1:end-1,2) - CumTrackLengthHist(2:end,2));CumTrackLengthHist(end,2)];


                    %Generate a list of Residence Times
                    zz1=[];

                    for i=1:length(ResTimeHist(:,2))
                        zz1=[zz1;repmat(ResTimeHist(i,1),ResTimeHist(i,2),1)];
                    end
                    [CDF,t,CDFlo,CDFup]=ecdf(zz1,'function','survivor','alpha',0.01,'bounds','on');
                    CDF = CDF(2:end);
                    t = t(2:end);
                    CDFup = CDFup(2:end);
                    CDFlo = CDFlo(2:end);
                    CDFup_diff = (CDFup(1) - CDF(1))./CDF(1);
                    CDFlo_diff = (CDF(1) - CDFlo(1))./CDF(1);
                    if strcmp(PB_npart,'Yes')


                        k0s = [0.1,0.01];
                        [PB_Coef1, PB_Sigma1, curve1] = ExpDecay_2Cmp_nlinfitRobust(CumNParticles, k0s);


                        %This is based on the background photobleaching of each experiment
                        [beta, ind] = min(PB_Coef1(1:2));
                        if ind == 1
                            frac = PB_Coef1(3);
                        else
                            frac = 1 - PB_Coef1(3);
                        end
                        if beta < str2double(PB_NPartParam{1}) || frac < str2double(PB_NPartParam{2})
                            beta = max(PB_Coef1(1:2));
                        end

                        P1 = ExpDecay_fun(app,[beta,1],t);
                        
                        CDF=CDF./P1;
                        CDFup=CDFup./P1;
                        CDFlo=CDFlo./P1;
                        CDFup_diff = (CDFup(1) - CDF(1))./CDF(1);
                        CDFlo_diff = (CDF(1) - CDFlo(1))./CDF(1);
                    end


                    % Parameters from the Histone data after background correction

                    init=find(t>=(st-1)*period,1,'first');   %Initial value corresponds to the max(ShortesTrack,Nmin)
                    CDFlo=(1-CDFlo_diff).*(CDFlo/CDFlo(init));
                    CDFup=(1+CDFup_diff).*(CDFup/CDFup(init));
                    CDF=CDF/CDF(init);
                    init1=init;

                    k0s = [0.1, 0.01, 0.001];
                    [PB_Coef2, Esp_Sigma, ~] = ExpDecay_3Cmp_nlinfit([t(init1:end),CDF(init1:end)], k0s);
                    curve2 = ExpDecay_3Cmp_fun(PB_Coef2,t);
                    % [h1f1,p1f1]= vartest2(curve2(t(init1:end)),CDF(init1:end)) ;
                    [h1f1,p1f1]= vartest2(curve2(init1:end),CDF(init1:end));
                    curve2 = ExpDecay_3Cmp_fun(PB_Coef2,t);
                    % final=find(CDF<0.01,1);
                    axes(app.PB_Axis);

                    scatter(app.PB_Axis,t(st:end),CDF(st:end),'o','MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
                    hold(app.PB_Axis,'on')
                    plot(app.PB_Axis,t(st:end),CDFup(st:end),'k--')
                    plot(app.PB_Axis,t(st:end),CDFlo(st:end),'k--')
                    
                    % plot(t,curve2(t),'g')
                    plot(app.PB_Axis,t(st:end),curve2(st:end),'r')
                    set(app.PB_Axis,'xscale','log')
                    set(app.PB_Axis,'yscale','log')
                    title(app.PB_Axis,'Photobleaching Kinetics')
                    xlabel(app.PB_Axis,'Time (s)')
                    ylabel(app.PB_Axis,'Survival Probability')
                    set(app.PB_Axis,'XGrid','on');
                    set(app.PB_Axis,'YGrid','on');
                    hold(app.PB_Axis,'off')
                    drawnow;
                    app.PB.Curve = [t,CDF,CDFlo,CDFup,curve2];
                    app.PBPar.Exp3_Par_all = PB_Coef2;
                    app.PBPar.KeyRate = min(PB_Coef2(1:3));

                    
                    %Clear some varibles so we don't accidently leave some of the data
                    %there
                    clear Tracks Particles NParticles TrackLengthHist CumTrackLengthHist ResTimeHist
                    
                end
                % If only one file has been selected change the fileNames variable
                % to a cell array

                if ~iscell(app.FileNames_TF)
                    app.FileNames_TF = {app.FileNames_TF};
                end
                nFiles_TF = length(app.FileNames_TF);

                % Accumulate the different survival histograms;
                %if we have only 1 file it will likely be a trackTable
                if nFiles_TF == 1
                    IN = load(fullfile(app.PathName_TF,app.FileNames_TF{1}));
                    if isfield(IN,'trackTable')
                        [Tracks, NParticles,app.ROIChoice,app.nROIs,~,~,~,~,~,~,~,app.condChoice] = compileTracksFromTables(...
                            IN.trackTable,app.maxTime,...
                            app.QuantiTrackAnalyzeResidenceTimesUIFigure,...
                            'Tracking'); 
                        nMovies_TF = length(Tracks);
                    else
                        [Tracks, NParticles,app.ROIChoice,app.nROIs] = compileTracksFromFiles(...
                            app.FileNames_TF,app.PathName_TF,app.maxTime,...
                            app.QuantiTrackAnalyzeResidenceTimesUIFigure,...
                            'Tracking');
                        nMovies_TF = 1;
                    end
                else %if more than one file is selected it should be tracking files
                    [Tracks, NParticles,app.ROIChoice,app.nROIs] = compileTracksFromFiles(...
                        app.FileNames_TF,app.PathName_TF,app.maxTime,...
                        app.QuantiTrackAnalyzeResidenceTimesUIFigure,...
                        'Tracking');
                    nMovies_TF = nFiles_TF;
                end
                figname = app.QuantiTrackAnalyzeResidenceTimesUIFigure.Name;
                if iscell(app.ROIChoice{1})
                    
                    if ~isempty(app.condChoice)
                        figname = [figname, ' - ', app.condChoice];
                    end
                    figname = [figname, ' - ', app.ROIChoice{1}{1}];
                else
                    if ~isempty(app.condChoice)
                        figname = [figname, ' - ', app.condChoice];
                    end
                    figname = [figname, ' - ', app.ROIChoice{1}];
                end
                app.QuantiTrackAnalyzeResidenceTimesUIFigure.Name = figname;
                d = uiprogressdlg(app.QuantiTrackAnalyzeResidenceTimesUIFigure,'Title','Residence Time Analysis', ...
                    'Message','Finding Bound Segments', 'Indeterminate','on');
                TrackLengthHist = compileBoundTracks(app,Tracks);
                % find the histogram containing the longest track
                nMax = 1;
                TimePoints = [];
                total_tracks = 0;
                if length(TrackLengthHist) < length(Tracks)
                    for i = length(TrackLengthHist)+1:length(Tracks)
                        TrackLengthHist{i} = [];
                    end
                end
                close(d);
                d = uiprogressdlg(app.QuantiTrackAnalyzeResidenceTimesUIFigure,'Title','Residence Time Analysis', ...
                    'Message','Fitting Data','Value',0);

                for i = 1:length(Tracks)
                    trInd = unique(Tracks{i}(:,4));
                    total_tracks = total_tracks + length(trInd);
                    if ~isempty(TrackLengthHist{i})
                        if size(TrackLengthHist{i},1) > nMax

                            TimePoints = TrackLengthHist{i}(:,1);
                            nMax = length(TimePoints);
                        end
                    end
                end
                app.totalTracks = total_tracks;
                if isempty(TimePoints)
                    errordlg('Too few bound particles to produce an histogram');
                    return
                end


                % Accumulate the histogram
                Hist_Matrix = zeros(nMax,length(Tracks));

                for i = 1:length(Tracks)
                    if ~isempty(TrackLengthHist{i})
                        lengthHist = length(TrackLengthHist{i}(:,1));
                        Hist_Matrix(1:lengthHist,i) = TrackLengthHist{i}(:,2);
                    end
                end

                CumTrackLengthHist(:,1) = TimePoints;
                CumTrackLengthHist(:,2) = sum(Hist_Matrix, 2);

                % Copy Histogram to handles
                app.CumHist = CumTrackLengthHist;


                % First calculate the residence time histogram
                ResTimeHist(:,1)= CumTrackLengthHist(:,1);
                ResTimeHist(:,2) = ...
                    [(CumTrackLengthHist(1:end-1,2) - CumTrackLengthHist(2:end,2));CumTrackLengthHist(end,2)];

                TotalBoundMolecules = sum(ResTimeHist(:,2).*ResTimeHist(:,1)/app.FrameTime);
                app.BoundMolecules = TotalBoundMolecules;
                % and the total number of spots
                % TotalMolecules = sum(CumNParticles(:,2));
                Total_Molecules = 0;
                for i = 1:length(Tracks)
                    Total_Molecules = Total_Molecules + sum(NParticles{i}(:,2));
                end
                app.TotalMolecules = Total_Molecules;
                
                NPart_mat = zeros(nMax,length(Tracks));
                for i = 1:length(Tracks)
                    nMax_cur = min(nMax,size(NParticles{i},1));
                    NPart_mat(1:nMax_cur,i) = NParticles{i}(1:nMax_cur,2);
                end

                % ResTimeHist=ResTimeHist;
                CumNParticles2(:,1) = TimePoints;
                CumNParticles2(:,2) = sum(NPart_mat, 2);

%                 app.totalTracks=sum(ResTimeHist(:,2));
                zz1=[];


                for i=1:length(ResTimeHist(:,2))
                    zz1=[zz1;repmat(ResTimeHist(i,1),ResTimeHist(i,2),1)];
                end
                app.ResTimes_all = zz1;

                actCDF = CumTrackLengthHist(:,2)/CumTrackLengthHist(1,2).*size(zz1,1);
                last_Ind2Fit = find(actCDF < app.minTracks,1,'first');
                if app.minTracks > 0 && actCDF(last_Ind2Fit) < app.minTracks
                    last_Ind2Fit = last_Ind2Fit - 1;
                end
                app.lastInd2Fit = last_Ind2Fit;

                [CDF,t,CDFlo,CDFup]=ecdf(zz1,'function','survivor','alpha',0.01,'bounds','on');
                CDF = CDF(2:end);
                t = t(2:end);
                CDFup = CDFup(2:end);
                CDFlo = CDFlo(2:end);
                
                d.Value = 0.01;
                if app.PBMethod == 2 || (app.PBMethod == 1 && strcmp(PB_npart,'Yes'))
                    k0s = [0.1,0.01];
                    [PB_Coef1, PB_Sigma1, curve1] = ExpDecay_2Cmp_fit(app,CumNParticles2, k0s);

                    axes(app.PB_Axis);
                    scatter(app.PB_Axis,CumNParticles2(:,1),CumNParticles2(:,2),'o','MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
                    hold(app.PB_Axis,'on')
                    plot(app.PB_Axis,CumNParticles2(:,1),curve1(:,2),'r')
                    set(app.PB_Axis,'xscale','log');
                    set(app.PB_Axis,'yscale','log');
                    set(app.PB_Axis,'XGrid','on');
                    set(app.PB_Axis,'YGrid','on');
                    title(app.PB_Axis,'Photobleaching Kinetics')
                    xlabel(app.PB_Axis,'Time (s)')
                    ylabel(app.PB_Axis,'Survival Probability')
                    hold(app.PB_Axis,'off')

                    if app.PBMethod == 2
                        app.PB.Coef = [PB_Coef1;PB_Sigma1];
                        app.PB.Curve = [CumNParticles2, curve1];
                    
                        
                    end


                    P1 = ExpDecay_2Cmp_fun(app,PB_Coef1,t);
                    CDF=CDF./P1;
                    CDFup=CDFup./P1;
                    CDFlo=CDFlo./P1;

                    CDFup_diff = (CDFup(1) - CDF(1))./CDF(1);
                    CDFlo_diff = (CDF(1) - CDFlo(1))./CDF(1);
                elseif app.PBMethod == 0
                    CDFup_diff = (CDFup(1) - CDF(1))./CDF(1);
                    CDFlo_diff = (CDF(1) - CDFlo(1))./CDF(1);
                    app.PB.Curve = [CumNParticles2(:,1), nan(size(CumNParticles2))];
                end

                if app.PBMethod == 1
                    %%Photobleaching Correction
                    %%% Edit H2B rate here
                    H2B_decayRate = app.PBPar.KeyRate;
                    P2=exp(-H2B_decayRate*t); %H2B Photobleaching parameter

                    CDF=CDF./P2;
                    CDFup=CDFup./P2;
                    CDFlo=CDFlo./P2;
                    CDFup_diff = (CDFup(1) - CDF(1))./CDF(1);
                    CDFlo_diff = (CDF(1) - CDFlo(1))./CDF(1);
                end


                d.Value = 0.15;
                %Previous fit
                init=find(t>=(st-1)*period,1,'first');   %Initial value corresponds to the min(ShortesTrack,Nmin)
                if app.minTracks == 0
                    t_last = max(t);
                else
                    t_last = CumTrackLengthHist(last_Ind2Fit,1);
                end
                final = find(t <= t_last,1,'last');
                app.final_timepoint = final;
                app.init_timepoint = init;
                %
                CDFlo=(1-CDFlo_diff).*(CDFlo/CDFlo(init)).*app.BoundMolecules/app.TotalMolecules;
                CDFup=(1+CDFup_diff).*(CDFup/CDFup(init)).*app.BoundMolecules/app.TotalMolecules;
                CDF=(CDF/CDF(init)).*app.BoundMolecules/app.TotalMolecules;
                
                k0s1 = 0.1;
                t_start = t(init);
                t_offset = t - t(init);
                [TF_Coef1, TF_Sigma1, ~] = ExpDecay_fit(app,[t_offset(init:final),CDF(init:final)], k0s1);
                %Remake the fit with all time-points
                curve0 = ExpDecay_fun(app,TF_Coef1,t_offset);

                [h1f1,p1f1]= vartest2(curve0(init:final),CDF(init:final)) ;

                
                d.Value = 0.3;
                % %%%%Fit 2 Biexponential
                
                k0s2 = [0.1, 0.01];
                [TF_Coef2_lin, TF_Sigma2_lin, TF_Fit2_lin] = ExpDecay_2Cmp_fit(app,[t_offset(init:final),CDF(init:final)], k0s2);
                res_lin = CDF(init:final) - TF_Fit2_lin(:,2);
                res_lin_sq = res_lin.^2;
                chi2_lin = sum(res_lin_sq);

                [TF_Coef2_log, TF_Sigma2_log, TF_Fit2_log] = ExpDecay_2Cmp_log_fit(app,[t_offset(init:final),CDF(init:final)], k0s2);
                res_log = CDF(init:final) - TF_Fit2_log(:,2);
                res_log_sq = res_log.^2;
                chi2_log = sum(res_log_sq);
                if chi2_log < chi2_lin
                    TF_Coef2 = TF_Coef2_log;
                    TF_Sigma2 = TF_Sigma2_log;
                else
                    TF_Coef2 = TF_Coef2_lin;
                    TF_Sigma2 = TF_Sigma2_lin;
                end
                %Remake the fit with all time-points
                curve1 = ExpDecay_2Cmp_fun(app,TF_Coef2,t_offset);

                [h1f1,p1f1]= vartest2(curve1(init:final),CDF(init:final)) ;

                
                %Steady State Calculation of Proportions
               
                SlowProp1=TF_Coef2(3)/TF_Coef2(1);
                FastProp1=(1-TF_Coef2(3))/TF_Coef2(2);
                BetaProp=SlowProp1/(1+SlowProp1+FastProp1)
                GammaProp=FastProp1/(1+SlowProp1+FastProp1)
                DiffProp=1/(1+SlowProp1+FastProp1)


                d.Value = 0.45;
                %%%%Fit 3 Power-Law
                
                k0 = 1;
                [Pwr_Coef_lin, Pwr_Sigma_lin, Pwr_Fit_lin] = PwrLawDecay_nlinfit(app,[t(init:final),CDF(init:final)], k0);
                res_lin = CDF(init:final) - Pwr_Fit_lin(:,2);
                res_lin_sq = res_lin.^2;
                chi2_lin = sum(res_lin_sq);

                [Pwr_Coef_log, Pwr_Sigma_log, Pwr_Fit_log] = PwrLawDecay_log_nlinfit(app,[t(init:final),CDF(init:final)], k0);
                res_log = CDF(init:final) - Pwr_Fit_log(:,2);
                res_log_sq = res_log.^2;
                chi2_log = sum(res_log_sq);

                if chi2_log < chi2_lin
                    Pwr_Coef = Pwr_Coef_log;
                    Pwr_Sigma = Pwr_Sigma_log;
                else
                    Pwr_Coef = Pwr_Coef_lin;
                    Pwr_Sigma = Pwr_Sigma_lin;
                end

                %     Remake the curve with all t
                curve2 = PwrLawDecay_fun(app,Pwr_Coef,t);

                [h1f2,p1f2]= vartest2(curve2(init:final),CDF(init:final)) ;


                d.Value = 0.6;
                %%%Fit 4 Power-Law+Exponential
                
                k0s = [1, 0.1];
                [Pwr_Exp_Coef_lin, Pwr_Exp_Sigma_lin, Pwr_Exp_Fit_lin] = PwrLaw_Exp_Decay_nlinfit(app,[t(init:final),CDF(init:final)], k0s);
                res_lin = CDF(init:final) - Pwr_Exp_Fit_lin(:,2);
                res_lin_sq = res_lin.^2;
                chi2_lin = sum(res_lin_sq);

                [Pwr_Exp_Coef_log, Pwr_Exp_Sigma_log, Pwr_Exp_Fit_log] = PwrLaw_Exp_Decay_log_nlinfit(app,[t(init:final),CDF(init:final)], k0s);
                res_log = CDF(init:final) - Pwr_Exp_Fit_log(:,2);
                res_log_sq = res_log.^2;
                chi2_log = sum(res_log_sq);

                if chi2_log < chi2_lin
                    Pwr_Exp_Coef = Pwr_Exp_Coef_log;
                    Pwr_Exp_Sigma = Pwr_Exp_Sigma_log;
                else
                    Pwr_Exp_Coef = Pwr_Exp_Coef_lin;
                    Pwr_Exp_Sigma = Pwr_Exp_Sigma_lin;
                end

                %Remake the curve with all t
                curve3 = PwrLaw_Exp_Decay_fun(app,Pwr_Exp_Coef,t);

                [h1f3,p1f3]= vartest2(curve3(init:final),CDF(init:final));

                d.Value = 0.75;
                % %%%Fit 5 Kinetic Model
                % lb=zeros(7,1);
                % ub=Inf(7,1);
                % B0 = 0.1*rand(7,1);
                % 
                % %     figure;
                % 
                % fitfun = @(B,t)AnalyticEq(app,B,t);
                % 
                % [Par3,Rsdnrm,Rsd,ExFlg,OptmInfo,Lmda,Jmat]=lsqcurvefit(fitfun,B0,t(init:final),1-CDF(init:final),lb,ub);
                % Analytic_Coef = Par3';
                % ci = nlparci(Par3,Rsd,'jacobian',Jmat);
                % Analytic_Sigma = (ci(:,2) - ci(:,1))/2;
                % Analytic_Sigma = Analytic_Sigma';
                % curve4 = 1 - AnalyticEq(app,Par3,t);
                % 
                % 
                
                % 
                % d.Value = 0.9;
                % %%Residence Time
                % Slow=Par3(2)^2/(Par3(2)+Par3(5))+Par3(5)^2/(Par3(2)+Par3(5))
                % Fast=Par3(4)^2/(Par3(4)+Par3(6))+Par3(6)^2/(Par3(4)+Par3(6))
                % % Ratio between D--> S and D--> F
                % 
                % ratio=Par3(1)/(Par3(3)+Par3(1))
                % 
                % % Steady State
                % D0=1;
                % C(1,1)=Par3(5)+Par3(2);
                % C(1,2)=-Par3(6);
                % C(2,1)=-Par3(5);
                % C(2,2)=(Par3(4)+Par3(6));
                % e=linsolve(C,[Par3(1)*D0;Par3(3)*D0]);
                % slowprop= e(1)/(e(1)+e(2)+D0)
                % fastprop= e(2)/(e(1)+e(2)+D0)
                % Unbound= D0/(e(1)+e(2)+D0)


                % [h1f4,p1f4]= vartest2(AnalyticEq(app,Par3,t(init:final)),CDF(init:final)) ;

                app.Hist = [t,CDF,CDFlo,CDFup,curve0,curve1,curve2,curve3];
                updateSurvPlot(app);

                p1f1
                p1f2
                p1f3
                % p1f4

                app.BIC = ModelSelection_log_fun(app);
                
                % app.FitPar(1,:) = [TF_Coef1,TF_Coef2, Pwr_Coef, Pwr_Exp_Coef, Analytic_Coef];
                app.FitPar(1,:) = [TF_Coef1,TF_Coef2, Pwr_Coef, Pwr_Exp_Coef];
                % app.FitPar(2,:) = [TF_Sigma1,TF_Sigma2, Pwr_Sigma, Pwr_Exp_Sigma, Analytic_Sigma];
                app.FitPar(2,:) = [TF_Sigma1,TF_Sigma2, Pwr_Sigma, Pwr_Exp_Sigma];
                app.totalTracksText.Text = ['Total Tracks: ',num2str(app.totalTracks)];
                app.boundParticlesText.Text = ['Bound Particles: ',num2str(app.BoundMolecules)];
                app.totalParticlesText.Text = ['Total Particles: ',num2str(app.TotalMolecules)];
                app.totalROIsText.Text = ['Total ROIs: ', num2str(app.nROIs)];
                app.frame2frameMaxText.Text = ['Max. Frame to Frame Jump: ', num2str(app.Parameters.ThreshL), ' um'];
                app.End2EndMaxText.Text = ['Max. End to End Jump: ', num2str(app.Parameters.ThreshH), ' um'];
                app.NminText.Text = ['Min. Bound Frames (Nmin): ', num2str(app.Parameters.minBoundFrames)];

                app.BIC_ExpText.Text = num2str(app.BIC(1),4);
                app.BIC_BiExpText.Text = num2str(app.BIC(2),4);
                app.BIC_PwrText.Text = num2str(app.BIC(3),4);
                app.BIC_PwrExpText.Text = num2str(app.BIC(4),4);
                
                close(d);
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if ~isempty(app.PathName_TF)
                defpath = app.PathName_TF;
            else
                defpath = pwd;
            end
            if app.PBMethod == 0

                saveFilename = 'Surv_NoCorr.mat';
            elseif app.PBMethod == 1
                saveFilename = 'Surv_HistoneCorr.mat';
            elseif app.PBMethod == 2
                saveFilename = 'Surv_NParticlesCorr.mat';
            end
            [saveName,savePath] = uiputfile('*.mat','Save Analysis File',fullfile(defpath,...
                saveFilename));
            figure(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            drawnow
            if saveName ~= 0
                Results.Parameters = app.Parameters;
                Results.minTracks = app.minTracks;
                Results.PathName_PB = app.PathName_PB;
                Results.PathName_TF = app.PathName_TF;
                Results.FileNames_PB = app.FileNames_PB;
                Results.FileNames_TF = app.FileNames_TF;
                Results.FrameTime = app.FrameTime;
                Results.maxTime = app.maxTime;
                Results.nROIs = app.nROIs;
                Results.totalTracks = app.totalTracks;
                Results.CumHist = app.CumHist;
                Results.PB = app.PB;
                Results.PBPar = app.PBPar;
                Results.BoundMolecules = app.BoundMolecules;
                Results.TotalMolecules = app.TotalMolecules;
                Results.BIC = app.BIC;
                Results.Hist = app.Hist;
                Results.FitPar = app.FitPar;
                Results.PBMethod = app.PBMethod;
                Results.init_timepoint = app.init_timepoint;
                Results.final_timepoint = app.final_timepoint;

                save(fullfile(savePath,saveName),'Results','-v7.3');
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            if ~isempty(app.PathName_TF)
                defpath = app.PathName_TF;
            else
                defpath = pwd;
            end
            [loadName,loadPath] = uigetfile('*.mat','Load Previously Analyzed Data',defpath);
            figure(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            drawnow
            if loadName ~= 0
                IN = load(fullfile(loadPath,loadName));
                app.Parameters = IN.Results.Parameters;
                app.PathName_PB = IN.Results.PathName_PB;
                app.PathName_TF = IN.Results.PathName_TF;
                app.FileNames_PB = IN.Results.FileNames_PB;
                app.FileNames_TF = IN.Results.FileNames_TF;
                app.FrameTime = IN.Results.FrameTime;
                app.maxTime = IN.Results.maxTime;
                app.nROIs = IN.Results.nROIs;
                app.totalTracks = IN.Results.totalTracks;
                app.CumHist = IN.Results.CumHist;
                app.PBMethod = IN.Results.PBMethod;
                app.PB = IN.Results.PB;
                app.PBPar = IN.Results.PBPar;
                app.BoundMolecules = IN.Results.BoundMolecules;
                app.TotalMolecules = IN.Results.TotalMolecules;
                app.BIC = IN.Results.BIC;
                app.Hist = IN.Results.Hist;
                app.FitPar = IN.Results.FitPar;
                app.init_timepoint = IN.Results.init_timepoint;
                app.final_timepoint = IN.Results.final_timepoint;

                %Update the display strings
                app.totalTracksText.Text = ['Total Tracks: ',num2str(app.totalTracks)];
                app.boundParticlesText.Text = ['Bound Particles: ',num2str(app.BoundMolecules)];
                app.totalParticlesText.Text = ['Total Particles: ',num2str(app.TotalMolecules)];
                app.totalROIsText.Text = ['Total ROIs: ', num2str(app.nROIs)];
                app.frame2frameMaxText.Text = ['Max. Frame to Frame Jump: ', num2str(app.Parameters.ThreshL), ' um'];
                app.End2EndMaxText.Text = ['Max. End to End Jump: ', num2str(app.Parameters.ThreshH), ' um'];
                app.NminText.Text = ['Min. Bound Frames (Nmin): ', num2str(app.Parameters.minBoundFrames)];

                app.BIC_ExpText.Text = num2str(app.BIC(1),4);
                app.BIC_BiExpText.Text = num2str(app.BIC(2),4);
                app.BIC_PwrText.Text = num2str(app.BIC(3),4);
                app.BIC_PwrExpText.Text = num2str(app.BIC(4),4);
                

                %Plot the photobleaching
                
                hold(app.PB_Axis,'off');
                t = app.PB.Curve(:,1);
                CDF = app.PB.Curve(:,2);
                if app.PBMethod == 0
                    curve2 = app.PB.Curve(:,2);
                elseif app.PBMethod == 1
                    CDFup = app.PB.Curve(:,3);
                    CDFlo = app.PB.Curve(:,4);
                    curve2 = app.PB.Curve(:,5);
                elseif app.PBMethod == 2
                    curve2 = app.PB.Curve(:,4);
                end

                scatter(app.PB_Axis,t,CDF,'o','MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
                hold(app.PB_Axis,'on');
                if app.PBMethod == 1
                    plot(app.PB_Axis,t,CDFup,'k--')
                    plot(app.PB_Axis,t,CDFlo,'k--')
                end
                
                % plot(t,curve2(t),'g')
                plot(app.PB_Axis,t,curve2,'r')
                set(app.PB_Axis,'xscale','log')
                set(app.PB_Axis,'yscale','log')
                title(app.PB_Axis,'Photobleaching Kinetics')
                xlabel(app.PB_Axis,'Time (s)')
                ylabel(app.PB_Axis,'Survival Probability')
                hold(app.PB_Axis,'off');
                drawnow;

                %Reset the fit selection to show all
                app.ExpCheck.Value = 1;
                app.BiExpCheck.Value = 1;
                app.PowerCheck.Value = 1;
                app.PowerExpCheck.Value = 1;
                % app.AnalyticCheck.Value = 1;

                %Plot the survival and fits
                updateSurvPlot(app);
                % t = app.Hist(:,1);
                % CDF = app.Hist(:,2);
                % CDFup = app.Hist(:,3);
                % CDFlo = app.Hist(:,4);
                % curve0 = app.Hist(:,5);
                % curve1 = app.Hist(:,6);
                % curve2 = app.Hist(:,7);
                % curve3 = app.Hist(:,8);
                % curve4 = app.Hist(:,9);
                % 
                % hold(app.Surv_Axis,'off');
                % scatter(app.Surv_Axis,t,CDF,'.')
                % hold(app.Surv_Axis,'on');
                % plot(app.Surv_Axis,t,CDFup,'b--')
                % plot(app.Surv_Axis,t,CDFlo,'b--')
                % 
                % plot(app.Surv_Axis,t,curve0,'b')
                % plot(app.Surv_Axis,t,curve1,'g')
                % plot(app.Surv_Axis,t,curve2,'k')
                % plot(app.Surv_Axis,t,curve3,'r')
                % plot(app.Surv_Axis,t,curve4,'y')
                % set(app.Surv_Axis,'xscale','log')
                % set(app.Surv_Axis,'yscale','log')
                % %     title('Fit 1 (Double Exponential)')
                % xlabel(app.Surv_Axis,'Time (s)')
                % ylabel(app.Surv_Axis,'Survival Probability')

                
            end
        end

        % Menu selected function: PieChart
        function PieChartMenuSelected(app, event)
            [fractions,resTimes] = PieChartsFromResTimeAnalysis_HistPB(app);

            if ~isempty(fractions)

                frac = fractions';
                res = resTimes';
    
                data = [frac(:,1),res(:,1)];
                if size(frac,2) == 3
                    data = [data,frac(:,2),res(:,2)];
                end
    
                num2clip(data);
                msgbox('Fractions and Residence Times have been copied to the clipboard');
            end
        end

        % Value changed function: ExpCheck
        function ExpCheckValueChanged(app, event)
            
            updateSurvPlot(app)

        end

        % Value changed function: BiExpCheck
        function BiExpCheckValueChanged(app, event)
            
            updateSurvPlot(app)

        end

        % Value changed function: PowerCheck
        function PowerCheckValueChanged(app, event)
            
            updateSurvPlot(app)

        end

        % Value changed function: PowerExpCheck
        function PowerExpCheckValueChanged(app, event)
            
            updateSurvPlot(app)

        end

        % Button pushed function: CopyHist
        function CopyHistButtonPushed(app, event)
            OUT = app.Hist;
            HistTable = array2table(OUT,'VariableNames',...
                {'Time (s)','Survival Probability','95% CI Lower bound','95% CI Upper bound'...
                'Mono-exponential Fit', 'Bi-exponential Fit', 'Power law Fit', ...
                'Power law + exponential Fit'});
                assignin('base','ResTimeAnalysis_HistPlusFits',HistTable);

                msgbox('The array with the histogram and all fits has been copied to the workspace as variable ResTimeAnalysis_HistPlusFits');
            % num2clip(app.Hist);

        end

        % Button pushed function: CopyAnPar
        function CopyAnParButtonPushed(app, event)
            OutPar(1) = app.Parameters.ThreshL;
            OutPar(2) = app.Parameters.ThreshH;
            OutPar(3) = app.Parameters.minBoundFrames;

            OutPar(4) = app.FrameTime;

            ParTable = array2table(OutPar,'VariableNames',...
                {'r_min','r_max','N_min','Frame Interval (s)'});
                assignin('base','ResTimeAnalysis_InputParams',ParTable);

                msgbox('The array with the analysis parameters has been copied to the workspace as variable ResTimeAnalysis_InputParams');

            % num2clip(OutPar);
        end

        % Button pushed function: CopyFitPar
        function CopyFitParButtonPushed(app, event)
            Ans = inputdlg('Which Fit Parameters to Copy: 1 - Exponential; 2 - Bi-Exponential; 3 - Power Law; 4 - Power Law + Exponential');
            FitSel = str2double(Ans);
            % app.FitPar(1,:) = [TF_Coef1,TF_Coef2, Pwr_Coef, Pwr_Exp_Coef, Analytic_Coef];
            %     app.FitPar(2,:) = [TF_Sigma1,TF_Sigma2, Pwr_Sigma, Pwr_Exp_Sigma, Analytic_Sigma];
            % alpha = coeff(1);
            % k = coeff(2);
            % f_p = coeff(3);
            % A = coeff(4);

            if FitSel == 1 %Exponential
                FitPar = app.FitPar(:,1:2);
                colNames = {'k_off','Bound Fraction'};
            elseif FitSel == 2 %Bi-exponential
                FitPar = app.FitPar(:,3:6);
                colNames = {'k_off1', 'k_off2', 'Fraction with k_off1','Bound Fraction'};
            elseif FitSel == 3 %Power law
                FitPar = app.FitPar(:,7:8);
                colNames = {'Power law exponent', 'Bound Fraction'};
            elseif FitSel == 4 %Power law + Exponential
                FitPar = app.FitPar(:,9:12);
                colNames = {'Power law exponent','k_off','Fraction in Power law','Bound Fraction'};
            else 
                errordlg('Fit selection must be a number between 1 and 4','Unknown Fit');
                return;
            end
            % FitPar = handles.FitPar;
            % num2clip(FitPar);
            ParTable = array2table(FitPar,'VariableNames',...
                colNames);
            assignin('base','ResTimeAnalysis_FitParams',ParTable);

            msgbox('The array with the Fit parameters has been copied to the workspace as variable ResTimeAnalysis_FitParams');
        end

        % Button pushed function: SaveFigure
        function SaveFigureButtonPushed(app, event)
            app.Hist = app.Hist;
            

            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .5*scrsz(3) .4*scrsz(4)]);
            t = app.PB.Curve(:,1);
            CDF = app.PB.Curve(:,2);
            subplot(1,2,1);
            scatter(t,CDF,'o','MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
            hold on
            if app.PBMethod == 0
                curve2 = CDF;
            elseif app.PBMethod == 1
                CDFup = app.PB.Curve(:,3);
                CDFlo = app.PB.Curve(:,4);
                curve2 = app.PB.Curve(:,5);
                plot(t,CDFup,'k--')
                plot(t,CDFlo,'k--')
                hold on
            elseif app.PBMethod == 2
                curve2 = app.PB.Curve(:,4);
            end

            %Plot the Photbleaching on the left


            % plot(t,curve2(t),'g')
            plot(t,curve2,'r')
            set(gca,'xscale','log')
            set(gca,'yscale','log')
            title('Photobleaching Kinetics')
            xlabel('Time (s)')
            ylabel('Survival Probability')
            hold off

            %Plot the Survival on the right
            subplot(1,2,2);
            final = app.final_timepoint;
            init =  app.init_timepoint;

            ExpPlot = get(app.ExpCheck,'Value');
            BiExpPlot = get(app.BiExpCheck,'Value');
            PwrPlot = get(app.PowerCheck,'Value');
            PwrExpPlot = get(app.PowerExpCheck,'Value');
            % AnalPlot = get(app.AnalyticCheck,'Value');


            hold off
            p1 = scatter(app.Hist(init:final,1),app.Hist(init:final,2),...
                'o','MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
            hold on
            if final < length(app.Hist(:,2))
                scatter(app.Hist(final+1:end,1),app.Hist(final+1:end,2),...
                    'o','MarkerEdgeColor',[0.65, 0.65, 0.65],'MarkerFaceColor',[0.65, 0.65, 0.65]);
            end

            p2 = plot(app.Hist(:,1),app.Hist(:,3),'k--');
            p3 = plot(app.Hist(:,1),app.Hist(:,4),'k--');

            legend_Str = {'Survival', 'Upper Bound', 'Lower Bound'};
            legend_subset = [p1 p2 p3];

            if ExpPlot
                p4 = plot(app.Hist(:,1),app.Hist(:,5),'b');
                legend_Str = [legend_Str, 'Exponential'];
                legend_subset = [legend_subset, p4];
            end
            if BiExpPlot
                p5 = plot(app.Hist(:,1),app.Hist(:,6),'g');
                legend_Str = [legend_Str, 'Bi-Exponential'];
                legend_subset = [legend_subset, p5];
            end
            if PwrPlot
                p6 = plot(app.Hist(:,1),app.Hist(:,7),'k');
                legend_Str = [legend_Str, 'Power-Law'];
                legend_subset = [legend_subset, p6];
            end
            if PwrExpPlot
                p7 = plot(app.Hist(:,1),app.Hist(:,8),'r');
                legend_Str = [legend_Str, 'Power-Law + Exp'];
                legend_subset = [legend_subset, p7];
            end
            
            set(gca,'Xscale','log');
            set(gca,'Yscale','log');
            title('Survival Times')
            xlabel('Time (s)')
            ylabel('Survival Probability')
            
            legend(legend_subset,legend_Str,'Location','SouthWest');

            DefaultName = app.PathName_TF;
            DefaultName = [DefaultName, 'GlobalSurvTHist'];
            FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
            [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
            if FileNameOut ~= 0
                saveas(h1, [PathNameOut,FileNameOut]);
            end


            hold off;
            box on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackAnalyzeResidenceTimesUIFigure = uifigure('Visible', 'off');
            colormap(app.QuantiTrackAnalyzeResidenceTimesUIFigure, 'parula');
            app.QuantiTrackAnalyzeResidenceTimesUIFigure.Name = 'QuantiTrack - Analyze Residence Times';
            app.FileMenu = uimenu(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save Analysis';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'O';
            app.LoadMenu.Text = 'Load Analyis';
            app.ToolsMenu = uimenu(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.PieChart = uimenu(app.ToolsMenu);
            app.PieChart.MenuSelectedFcn = createCallbackFcn(app, @PieChartMenuSelected, true);
            app.PieChart.Text = 'Generate Pie Chart';
            app.PB_Axis = uiaxes(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            xlabel(app.PB_Axis, 'X')
            ylabel(app.PB_Axis, 'Y')
            zlabel(app.PB_Axis, 'Z')
            app.Surv_Axis = uiaxes(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            xlabel(app.Surv_Axis, 'Time (s)')
            ylabel(app.Surv_Axis, 'Survival Probability')
            zlabel(app.Surv_Axis, 'Z')
            app.FitstodisplayPanel = uipanel(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            app.FitstodisplayPanel.Title = 'Fits to display';
            app.ExpCheck = uicheckbox(app.FitstodisplayPanel);
            app.ExpCheck.ValueChangedFcn = createCallbackFcn(app, @ExpCheckValueChanged, true);
            app.ExpCheck.Text = 'Exponential';
            app.ExpCheck.Value = true;
            app.BiExpCheck = uicheckbox(app.FitstodisplayPanel);
            app.BiExpCheck.ValueChangedFcn = createCallbackFcn(app, @BiExpCheckValueChanged, true);
            app.BiExpCheck.Text = 'Biexponential';
            app.BiExpCheck.Value = true;
            app.PowerCheck = uicheckbox(app.FitstodisplayPanel);
            app.PowerCheck.ValueChangedFcn = createCallbackFcn(app, @PowerCheckValueChanged, true);
            app.PowerCheck.Text = 'Power Law';
            app.PowerCheck.Value = true;
            app.PowerExpCheck = uicheckbox(app.FitstodisplayPanel);
            app.PowerExpCheck.ValueChangedFcn = createCallbackFcn(app, @PowerExpCheckValueChanged, true);
            app.PowerExpCheck.Text = 'Power Law + Exp';
            app.PowerExpCheck.Value = true;
            app.DatasetMetricsPanel = uipanel(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            app.DatasetMetricsPanel.Title = 'Dataset Metrics';
            app.totalTracksText = uilabel(app.DatasetMetricsPanel);
            app.totalTracksText.Text = 'Total Tracks:';
            app.boundParticlesText = uilabel(app.DatasetMetricsPanel);
            app.boundParticlesText.Text = 'Bound Particles:';
            app.totalParticlesText = uilabel(app.DatasetMetricsPanel);
            app.totalParticlesText.Text = 'Total Particles:';
            app.totalROIsText = uilabel(app.DatasetMetricsPanel);
            app.totalROIsText.Text = 'Total ROIs:';
            app.ParametersUsedPanel = uipanel(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            app.ParametersUsedPanel.Title = 'Parameters Used';
            app.frame2frameMaxText = uilabel(app.ParametersUsedPanel);
            app.frame2frameMaxText.Text = 'Max. Frame to Frame Jump:';
            app.End2EndMaxText = uilabel(app.ParametersUsedPanel);
            app.End2EndMaxText.Text = 'Max. End to End Jump: ';
            app.NminText = uilabel(app.ParametersUsedPanel);
            app.NminText.Text = 'Min. Bound Frames(Nmin):';
            app.BICvaluesPanel = uipanel(app.QuantiTrackAnalyzeResidenceTimesUIFigure);
            app.BICvaluesPanel.Title = 'BIC values';
            app.totalTracksText_2 = uilabel(app.BICvaluesPanel);
            app.totalTracksText_2.Text = 'Exponential:';
            app.boundParticlesText_2 = uilabel(app.BICvaluesPanel);
            app.boundParticlesText_2.Text = 'Biexponential';
            app.totalParticlesText_2 = uilabel(app.BICvaluesPanel);
            app.totalParticlesText_2.Text = 'Power Law:';
            app.totalROIsText_2 = uilabel(app.BICvaluesPanel);
            app.totalROIsText_2.Text = 'Power Law + Exp:';
            app.BIC_ExpText = uilabel(app.BICvaluesPanel);
            app.BIC_ExpText.Text = '';
            app.BIC_BiExpText = uilabel(app.BICvaluesPanel);
            app.BIC_BiExpText.Text = '';
            app.BIC_PwrText = uilabel(app.BICvaluesPanel);
            app.BIC_PwrText.Text = '';
            app.BIC_PwrExpText = uilabel(app.BICvaluesPanel);
            app.BIC_PwrExpText.Text = '';
            app.CopyHist = uibutton(app.QuantiTrackAnalyzeResidenceTimesUIFigure, 'push');
            app.CopyHist.ButtonPushedFcn = createCallbackFcn(app, @CopyHistButtonPushed, true);
            app.CopyHist.Text = 'Copy histogram and fits';
            app.CopyAnPar = uibutton(app.QuantiTrackAnalyzeResidenceTimesUIFigure, 'push');
            app.CopyAnPar.ButtonPushedFcn = createCallbackFcn(app, @CopyAnParButtonPushed, true);
            app.CopyAnPar.Text = 'Copy analysis parameters';
            app.CopyFitPar = uibutton(app.QuantiTrackAnalyzeResidenceTimesUIFigure, 'push');
            app.CopyFitPar.ButtonPushedFcn = createCallbackFcn(app, @CopyFitParButtonPushed, true);
            app.CopyFitPar.Text = 'Copy fit parameters';
            app.SaveFigure = uibutton(app.QuantiTrackAnalyzeResidenceTimesUIFigure, 'push');
            app.SaveFigure.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigure.Text = 'Save figure';
            % Show the figure after all components are created
            app.QuantiTrackAnalyzeResidenceTimesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyzeResTimes_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackAnalyzeResidenceTimesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackAnalyzeResidenceTimesUIFigure)
        end
    end
end