classdef AngleAnalysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackAngleAnalysisUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadAngleAnalysisResultsMenu  matlab.ui.container.Menu
        SaveAngleAnalysisMenu         matlab.ui.container.Menu
        CopySpatialHist               matlab.ui.control.Button
        CopyTimeHist                  matlab.ui.control.Button
        UpdatePlotsButton             matlab.ui.control.Button
        DatasetMetricsPanel           matlab.ui.container.Panel
        minTrackLenText               matlab.ui.control.Label
        totalROIsText                 matlab.ui.control.Label
        totalParticlesText            matlab.ui.control.Label
        totalTracksText               matlab.ui.control.Label
        DisplacementAngleAnisotropyHistogramPanel  matlab.ui.container.Panel
        NumberofDispBinsSpinner       matlab.ui.control.Spinner
        BinWidthLabel_2               matlab.ui.control.Label
        MaxDisplacementnmSpinner      matlab.ui.control.Spinner
        MaxDisplacementnmLabel        matlab.ui.control.Label
        MinDisplacementnmSpinner      matlab.ui.control.Spinner
        MinDisplacementnmLabel        matlab.ui.control.Label
        TemporalAngleAnisotropyHistogramPanel  matlab.ui.container.Panel
        NumberofTimeBinsSpinner       matlab.ui.control.Spinner
        BinWidthLabel                 matlab.ui.control.Label
        MaxTimesSpinner               matlab.ui.control.Spinner
        MaxTimesLabel                 matlab.ui.control.Label
        MinTimesSpinner               matlab.ui.control.Spinner
        MinTimesLabel                 matlab.ui.control.Label
        DoneButton                    matlab.ui.control.Button
        SaveFigure                    matlab.ui.control.Button
        CopyAllAngles                 matlab.ui.control.Button
        Copy1frameAngles              matlab.ui.control.Button
        DistAxes                      matlab.ui.control.UIAxes
        TimeAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Tracks % Description
        frameTime % Description
        angles % Description
        AC_t % Description
        AC_d % Description
        minTrkLen % Description
        angles1frame % Description
        pax_in % Description
        bootIter % Description
        fileName % Description
        nTracks % Description
        nTrackPts % Description
        nROIs % Description
        parFlag % Description
        useParallel % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                if length(varargin) > 7
                    pos_fig = varargin{8};
                    ChangeAppPosition(app.QuantiTrackAngleAnalysisUIFigure,pos_fig);
                    drawnow;
                end
                if length(varargin) > 6
                    app.parFlag = varargin{7};
                else
                    app.parFlag = 0;
                end
                if ~iscell(varargin{1}) %This is for the call from the Angle Analysis Button in QuantiTrack
                    app.Tracks = varargin{1};
                    app.minTrkLen = varargin{3};
                    app.frameTime = varargin{4};
                    app.fileName = varargin{5};
                    
                    if ~isempty(varargin{6})
                        params = varargin{6};
                        
                        binWidth_t = params(1);
                        binWidth_d = params(2);
                        loc_prec = params(3);


                    else
                        binWidth_t = [];
                        binCents_t = [];
                        binWidth_d = [];
                        binCents_d = [];
                        loc_prec = 0;
                        
                    end
                    app.bootIter = 0;


                    % app.Tracks(:,3) = (app.Tracks(:,3) - 1).*app.frameTime;
                    

                    d = uiprogressdlg(app.QuantiTrackAngleAnalysisUIFigure,'Title','Angle Analysis, Single File', 'Indeterminate','on');
                    d.Message = 'Analyzing Track angles';
                    drawnow;
                    app.angles = calcAngles3(app.Tracks,app.minTrkLen,app.frameTime,loc_prec,app.parFlag);
                    if ~isempty(binWidth_t)
                        binCents_t = min(app.angles(:,2)):binWidth_t:max(app.angles(:,2));
                    end
                    if ~isempty(binWidth_d)
                        binCents_d = min(app.angles(:,3)):binWidth_d:max(app.angles(:,3));
                    end
                    [app.AC_t, app.AC_d] = calcSpaceTimeAnisotropy(app.angles,binCents_t,binCents_d,app.bootIter);
                    
                    
                    %Update the histogram controls
                    app.MinTimesSpinner.Value = app.AC_t(1,1);
                    app.MaxTimesSpinner.Value = app.AC_t(end,1);
                    app.NumberofTimeBinsSpinner.Value = app.AC_t(2,1) - app.AC_t(1,1);

                    app.MinDisplacementnmSpinner.Value = 1000*app.AC_d(1,1);
                    app.MaxDisplacementnmSpinner.Value = 1000*app.AC_d(end,1);
                    app.NumberofDispBinsSpinner.Value = 1000*(app.AC_d(2,1) - app.AC_d(1,1));
                    
                    %Plot the histograms
                    plot(app.TimeAxes,app.AC_t(:,1),app.AC_t(:,2),'LineWidth',1.5);
                    xlabel(app.TimeAxes,'Time (s)');
                    ylabel(app.TimeAxes,'Anisotropy (log_{2}(0/180))');

                    plot(app.DistAxes,app.AC_d(:,1)*1000,app.AC_d(:,2),'LineWidth',1.5);
                    xlabel(app.DistAxes,'Displacement (nm)');
                    ylabel(app.DistAxes,'Anisotropy (log_{2}(0/180))');
                    
                    app.pax_in = polaraxes(app.QuantiTrackAngleAnalysisUIFigure);
                    set(app.pax_in,'Units','pixels');
                    set(app.pax_in,'Position',[35 216 243 243]);

                    app.angles1frame = app.angles(app.angles(:,2) == app.frameTime,:);
                    polarhistogram(app.pax_in,app.angles1frame(:,1),36,'Normalization','probability');

                    %Update the metrics
                    trkIDs = unique(app.Tracks(:,4));
                    app.nTracks = length(trkIDs);
                    app.totalTracksText.Text = ['Total Tracks: ', num2str(app.nTracks)];
                    app.nTrackPts = size(app.Tracks,1);
                    app.totalParticlesText.Text = ['Total Track points: ', num2str(app.nTrackPts)];
                    ROIids = unique(app.Tracks(:,5));
                    app.nROIs = length(ROIids);

                    app.totalROIsText.Text = ['Total ROIs: ', num2str(app.nROIs)];
                    app.minTrackLenText.Text = ['Min. Track Length: ', num2str(app.minTrkLen)];
                    
                    close(d);
                    drawnow;

                else%This is for the call from the Further Analysis -> Merge and analyze Angles in QuantiTrack
                    %parse the inputs
                    files = varargin{1};
                    path_in = varargin{2};
                    app.minTrkLen = varargin{3};
                    app.frameTime = varargin{4};
                    params = varargin{6};
                    binWidth_t = params(1);
                    binWidth_d = params(2);
                    app.bootIter = params(3);
                    loc_prec_use = params(4);

                    app.useParallel = varargin{7};

                    app.fileName = fullfile(path_in,'multfile.tif');
                    
                    app.angles = [];
                    app.nTracks = 0;
                    app.nTrackPts = 0;
                                        
                    nFiles = length(files);

                    if nFiles == 1
                        IN = load(fullfile(path_in,files{1}));
                        if isfield(IN,'trackTable')
                            % get the tracks of the selected files
                            % open each mat-files and retrieve useful information
                            [app.Tracks, ~,~,app.nROIs,~,~,~,~,~,~,~,~,loc_prec] = compileTracksFromTables(...
                                IN.trackTable,0,...
                                app.QuantiTrackAngleAnalysisUIFigure,...
                                'Tracking');
                        else
                            % get the tracks of the selected files
                            % open each mat-files and retrieve useful information
                            [app.Tracks, ~,~,app.nROIs] = compileTracksFromFiles(...
                                files,path_in,0,...
                                app.QuantiTrackAngleAnalysisUIFigure,...
                                'Tracking');
                        end
                    else %if more than one file is selected it should be tracking files
                        % get the tracks of the selected files
                        % open each mat-files and retrieve useful information
                        [app.Tracks, ~,~,app.nROIs,~,~,~,~,~,~,~,~,loc_prec] = compileTracksFromFiles(...
                            files,path_in,0,...
                            app.QuantiTrackAngleAnalysisUIFigure,...
                            'Tracking');
                    end
                    d = uiprogressdlg(app.QuantiTrackAngleAnalysisUIFigure,'Title','Calculating Angle Data');
                    
                    if loc_prec_use ~= 0
                        Loc_Prec = mean(loc_prec,"omitmissing");
                    else
                        Loc_Prec = 0;
                    end
                    for i = 1:length(app.Tracks)
                        
                        tracks = app.Tracks{i};
                        trkIDs = unique(tracks(:,4));
                        app.nTracks = app.nTracks + length(trkIDs);
                        app.nTrackPts = app.nTrackPts + size(tracks,1);
                        
                        angles_tmp = calcAngles3(tracks,app.minTrkLen,app.frameTime,Loc_Prec,app.parFlag);
                        app.angles = [app.angles; angles_tmp];
                        d.Value = i/length(app.Tracks);
                    end
                    close(d);
                    drawnow;
                    
                    d = uiprogressdlg(app.QuantiTrackAngleAnalysisUIFigure,'Title','Generating Histogram Data','Indeterminate','on');
                    drawnow
                    binCents_t = min(app.angles(:,2)):binWidth_t:max(app.angles(:,2));
                    while length(binCents_t) < 10
                        binWidth_t = binWidth_t./2;
                        binCents_t = min(app.angles(:,2)):binWidth_t:max(app.angles(:,2));
                    end

                    binCents_d = min(app.angles(:,3)):binWidth_d:max(app.angles(:,3));
                    while length(binCents_d) < 10
                        binWidth_d = binWidth_d./2;
                        binCents_d = min(app.angles(:,3)):binWidth_d:max(app.angles(:,3));
                    end
                    [app.AC_t, app.AC_d] = calcSpaceTimeAnisotropy(app.angles,binCents_t,binCents_d,app.bootIter,app.useParallel);
                    tmp = app.AC_t;
                    tmp(isnan(tmp(:,2)),:) = [];
                    tmp(isnan(tmp(:,3)),:) = [];
                    tmp(isnan(tmp(:,4)),:) = [];
                    app.AC_t = tmp;

                    tmp = app.AC_d;
                    tmp(isnan(tmp(:,2)),:) = [];
                    tmp(isnan(tmp(:,3)),:) = [];
                    tmp(isnan(tmp(:,4)),:) = [];
                    app.AC_d = tmp;
                    
                    close(d)
                    drawnow

                    %Update the histogram controls
                    app.MinTimesSpinner.Value = app.AC_t(1,1);
                    app.MaxTimesSpinner.Value = app.AC_t(end,1);
                    app.NumberofTimeBinsSpinner.Value = app.AC_t(2,1) - app.AC_t(1,1);

                    app.MinDisplacementnmSpinner.Value = 1000*app.AC_d(1,1);
                    app.MaxDisplacementnmSpinner.Value = 1000*app.AC_d(end,1);
                    app.NumberofDispBinsSpinner.Value = 1000*(app.AC_d(2,1) - app.AC_d(1,1));

                    %Plot the histograms
                    errorbar(app.TimeAxes,app.AC_t(:,1),app.AC_t(:,2),(app.AC_t(:,2) - app.AC_t(:,3)),...
                        (app.AC_t(:,4) - app.AC_t(:,2)),'LineWidth',1.5);
                    xlabel(app.TimeAxes,'Time (s)');
                    ylabel(app.TimeAxes,'Anisotropy (log_{2}(0/180))');

                    errorbar(app.DistAxes,app.AC_d(:,1)*1000,app.AC_d(:,2),(app.AC_d(:,2) - app.AC_d(:,3)),...
                        (app.AC_d(:,4) - app.AC_d(:,2)), 'LineWidth',1.5);
                    xlabel(app.DistAxes,'Displacement (nm)');
                    ylabel(app.DistAxes,'Anisotropy (log_{2}(0/180))');

                    app.pax_in = polaraxes(app.QuantiTrackAngleAnalysisUIFigure);
                    set(app.pax_in,'Units','pixels');
                    set(app.pax_in,'Position',[35 216 243 243]);

                    app.angles1frame = app.angles(app.angles(:,2) == app.frameTime,:);
                    polarhistogram(app.pax_in,app.angles1frame(:,1),36,'Normalization','probability');
                    
                    %Update the metrics
                    
                    app.totalTracksText.Text = ['Total Tracks: ', num2str(app.nTracks)];
                    app.totalParticlesText.Text = ['Total Track points: ', num2str(app.nTrackPts)];
                    
                    app.totalROIsText.Text = ['Total ROIs: ', num2str(app.nROIs)];
                    app.minTrackLenText.Text = ['Min. Track Length: ', num2str(app.minTrkLen)];
                    
                    %Warn the user that the bin width was adjusted
                    if binWidth_t ~= params(1)
                        msgbox(['Not enough temporal bins with specified bin width, we have reduced the value to ', num2str(binWidth_t)]);
                    end

                    if binWidth_d ~= params(2)
                        msgbox(['Not enough spatial bins with specified bin width, we have reduced the value to ', num2str(binWidth_d)]);
                    end

                end
            else
                app.Copy1frameAngles.Enable = 'off';
                app.CopyAllAngles.Enable = 'off';
                app.SaveFigure.Enable = 'off';
                app.SaveAngleAnalysisMenu.Enable = 'off';
                
            end

        end

        % Button pushed function: UpdatePlotsButton
        function UpdatePlotsButtonPushed(app, event)
            binCents_t = app.MinTimesSpinner.Value:app.NumberofTimeBinsSpinner.Value:app.MaxTimesSpinner.Value;
            binCents_d = (app.MinDisplacementnmSpinner.Value/1000):(app.NumberofDispBinsSpinner.Value/1000):(app.MaxDisplacementnmSpinner.Value/1000);
            
            d = uiprogressdlg(app.QuantiTrackAngleAnalysisUIFigure,'Title','Generating Histogram Data','Indeterminate','on');
            drawnow
            [app.AC_t, app.AC_d] = calcSpaceTimeAnisotropy(app.angles,binCents_t,binCents_d,app.bootIter);
            
            if app.bootIter == 0
                %Plot the histograms
                plot(app.TimeAxes,app.AC_t(:,1),app.AC_t(:,2),'LineWidth',1.5);
                xlabel(app.TimeAxes,'Time (s)');
                ylabel(app.TimeAxes,'Anisotropy (log_{2}(0/180))');

                plot(app.DistAxes,app.AC_d(:,1)*1000,app.AC_d(:,2),'LineWidth',1.5);
                xlabel(app.DistAxes,'Displacement (nm)');
                ylabel(app.DistAxes,'Anisotropy (log_{2}(0/180))');
            else
                %Plot the histograms
                errorbar(app.TimeAxes,app.AC_t(:,1),app.AC_t(:,2),(app.AC_t(:,2) - app.AC_t(:,3)),...
                        (app.AC_t(:,4) - app.AC_t(:,2)),'LineWidth',1.5);
                xlabel(app.TimeAxes,'Time (s)');
                ylabel(app.TimeAxes,'Anisotropy (log_{2}(0/180))');

                errorbar(app.DistAxes,app.AC_d(:,1)*1000,app.AC_d(:,2),(app.AC_d(:,2) - app.AC_d(:,3)),...
                        (app.AC_d(:,4) - app.AC_d(:,2)), 'LineWidth',1.5);
                xlabel(app.DistAxes,'Displacement (nm)');
                ylabel(app.DistAxes,'Anisotropy (log_{2}(0/180))');
            end
            close(d)

        end

        % Button pushed function: Copy1frameAngles
        function Copy1frameAnglesButtonPushed(app, event)
            % num2clip(app.angles1frame);
            angles1Frame = app.angles1frame(:,1);
            assignin('base','angles1Frame_out',angles1Frame);

            msgbox({'The Array containing the Single-frame angles';...
                'been copied to the workspace as variable angles1Frame_out'});
        end

        % Button pushed function: CopyAllAngles
        function CopyAllAnglesButtonPushed(app, event)
            % num2clip(app.angles);
            angles_allTable = array2table(app.angles,'VariableNames',...
                {'Angle', 'Time','Average Displacement'});
            assignin('base','anglesAll_out',angles_allTable);

            msgbox({'The Array containing all angles';...
                'has been copied to the workspace as variable anglesAll_out'});
        end

        % Button pushed function: CopyTimeHist
        function CopyTimeHistButtonPushed(app, event)
            % num2clip(app.AC_t);
            if size(app.AC_t,2) == 4
                AC_tTable = array2table(app.AC_t,'VariableNames',...
                    {'Time', 'Anisotropy Coefficient','Lower Bound','Upper Bound'});
            elseif size(app.AC_t,2) == 2
                AC_tTable = array2table(app.AC_t,'VariableNames',...
                {'Time', 'Anisotropy Coefficient'});
            end
            assignin('base','temporalAnisotropy_out',AC_tTable);

            msgbox({'The Array containing the time-dependent histogram';...
                'of anisotropy has been copied to the workspace as variable temporalAnisotropy_out'});
        end

        % Button pushed function: CopySpatialHist
        function CopySpatialHistButtonPushed(app, event)
            % num2clip(app.AC_d);
            
            if size(app.AC_d,2) == 4
                AC_dTable = array2table(app.AC_d,'VariableNames',...
                    {'Displacement','Anisotropy Coefficient','Lower Bound','Upper Bound'});
            elseif size(app.AC_d,2) == 2
                AC_dTable = array2table(app.AC_d,'VariableNames',...
                    {'Displacement','Anisotropy Coefficient'});
            end
            assignin('base','spatialAnisotropy_out',AC_dTable);

            msgbox({'The Array containing the spatial-dependent histogram';...
                'of anisotropy has been copied to the workspace as variable spatialAnisotropy_out'});
        end

        % Button pushed function: SaveFigure
        function SaveFigureButtonPushed(app, event)
            
            
            % Create the figure and save it.

            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .6*scrsz(3) .3*scrsz(4)]) ;


            % % Plot with the identified bound tracks;
            % subplot(1,3,1);
            % polarhistogram(app.angles1frame(:,1),36,'Normalization','probability');
            % 
            % 
            % title('Angle distribution for 1-frame');
%%%
           

            %Plot Temporal Anisotropy
            subplot(1, 3, 2);
            if app.bootIter == 0
                plot(app.AC_t(:,1),app.AC_t(:,2),'LineWidth',1.5);
                
            else
                errorbar(app.AC_t(:,1),app.AC_t(:,2),(app.AC_t(:,2) - app.AC_t(:,3)),...
                        (app.AC_t(:,4) - app.AC_t(:,2)),'LineWidth',1.5);
                
            end

            xlabel('Time (s)');
            ylabel('Anisotropy (log_{2}(0/180))');

            title('Temporal Angle Anisotropy');
            

            %Plot Spatial Anisotropy
            subplot(1, 3, 3);
            if app.bootIter == 0
                plot(app.AC_d(:,1),app.AC_d(:,2),'LineWidth',1.5);
                
            else
                errorbar(app.AC_d(:,1)*1000,app.AC_d(:,2),(app.AC_d(:,2) - app.AC_d(:,3)),...
                        (app.AC_d(:,4) - app.AC_d(:,2)),'LineWidth',1.5);
                
            end

            xlabel('Displacement (nm)');
            ylabel('Anisotropy (log_{2}(0/180))');
            title('Spatial Angle Anisotropy');
            
            pax_in = polaraxes(gcf);
            pax_pos = get(pax_in,'Position');
            pax_pos(1) = -0.15;
            pax_pos(3:4) = 0.9*pax_pos(3:4);
            set(pax_in,'Position',pax_pos);
            polarhistogram(pax_in,app.angles1frame(:,1),36,'Normalization','Probability');
            title(pax_in,'Angle distribution for 1-frame');

            % Save the figures
            DefaultName = app.fileName;
            SearchStr = '(.*)\.\w*';
            DefaultName = regexprep(DefaultName, SearchStr, '$1_Anisotropy');
            FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
            [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
            
            if FileNameOut ~= 0
                saveas(h1, fullfile(PathNameOut,FileNameOut));
            end
        end

        % Menu selected function: SaveAngleAnalysisMenu
        function SaveAngleAnalysisMenuSelected(app, event)
            Results.angles = app.angles;
            Results.angles1frame = app.angles1frame;
            Results.AC_t = app.AC_t;
            Results.AC_d = app.AC_d;
            Results.minTrkLen = app.minTrkLen;
            Results.frameTime = app.frameTime;
            Results.bootIter = app.bootIter;
            Results.fileName = app.fileName;
            Results.nTracks = app.nTracks;
            Results.nTrackPts = app.nTrackPts;
            Results.nROIs = app.nROIs;

            [file_out, path_out] = uiputfile('*.mat','Select File to Write',[app.fileName(1:end-4), '_AngleAnalysis.mat']);
            if file_out ~= 0
                save(fullfile(path_out,file_out),'Results','-v7.3');
            end
            
        end

        % Menu selected function: LoadAngleAnalysisResultsMenu
        function LoadAngleAnalysisResultsMenuSelected(app, event)
            [file_in, path_in] = uigetfile('*.mat','Select previous Angle Analysis to Open',pwd);

            if file_in ~= 0
                IN = load(fullfile(path_in,file_in));
                app.angles = IN.Results.angles;
                app.angles1frame = IN.Results.angles1frame;
                app.AC_t = IN.Results.AC_t;
                app.AC_d = IN.Results.AC_d;
                app.minTrkLen = IN.Results.minTrkLen;
                app.frameTime = IN.Results.frameTime;
                app.bootIter = IN.Results.bootIter;
                app.fileName = IN.Results.fileName;
                app.nTracks = IN.Results.nTracks;
                app.nTrackPts = IN.Results.nTrackPts;
                app.nROIs = IN.Results.nROIs;
                
                if app.bootIter == 0
                    %Update the histogram controls
                    app.MinTimesSpinner.Value = app.AC_t(1,1);
                    app.MaxTimesSpinner.Value = app.AC_t(end,1);
                    app.NumberofTimeBinsSpinner.Value = app.AC_t(2,1) - app.AC_t(1,1);

                    app.MinDisplacementnmSpinner.Value = 1000*app.AC_d(1,1);
                    app.MaxDisplacementnmSpinner.Value = 1000*app.AC_d(end,1);
                    app.NumberofDispBinsSpinner.Value = app.AC_d(2,1) - app.AC_d(1,1);
                    
                    %Plot the histograms
                    plot(app.TimeAxes,app.AC_t(:,1),app.AC_t(:,2),'LineWidth',1.5);
                    xlabel(app.TimeAxes,'Time (s)');
                    ylabel(app.TimeAxes,'Anisotropy (log_2(0/180))');

                    plot(app.DistAxes,app.AC_d(:,1)*1000,app.AC_d(:,2),'LineWidth',1.5);
                    xlabel(app.DistAxes,'Displacement (nm)');
                    ylabel(app.DistAxes,'Anisotropy (log_2(0/180))');
                    
                    app.pax_in = polaraxes(app.QuantiTrackAngleAnalysisUIFigure);
                    set(app.pax_in,'Units','pixels');
                    set(app.pax_in,'Position',[35 216 243 243]);

                    app.angles1frame = app.angles(app.angles(:,2) == app.frameTime,:);
                    polarhistogram(app.pax_in,app.angles1frame(:,1),36,'Normalization','probability');

                else
                    %Update the histogram controls
                    app.MinTimesSpinner.Value = app.AC_t(1,1);
                    app.MaxTimesSpinner.Value = app.AC_t(end,1);
                    app.NumberofTimeBinsSpinner.Value = app.AC_t(2,1) - app.AC_t(1,1);

                    app.MinDisplacementnmSpinner.Value = 1000*app.AC_d(1,1);
                    app.MaxDisplacementnmSpinner.Value = 1000*app.AC_d(end,1);
                    app.NumberofDispBinsSpinner.Value = app.AC_d(2,1) - app.AC_d(1,1);

                    %Plot the histograms
                    errorbar(app.TimeAxes,app.AC_t(:,1),app.AC_t(:,2),(app.AC_t(:,2) - app.AC_t(:,3)),...
                        (app.AC_t(:,4) - app.AC_t(:,2)),'LineWidth',1.5);
                    xlabel(app.TimeAxes,'Time (s)');
                    ylabel(app.TimeAxes,'Anisotropy (log_2(0/180))');

                    errorbar(app.DistAxes,app.AC_d(:,1)*1000,app.AC_d(:,2),(app.AC_d(:,2) - app.AC_d(:,3)),...
                        (app.AC_d(:,4) - app.AC_d(:,2)), 'LineWidth',1.5);
                    xlabel(app.DistAxes,'Displacement (nm)');
                    ylabel(app.DistAxes,'Anisotropy (log_2(0/180))');

                    app.pax_in = polaraxes(app.QuantiTrackAngleAnalysisUIFigure);
                    set(app.pax_in,'Units','pixels');
                    set(app.pax_in,'Position',[35 216 243 243]);

                    app.angles1frame = app.angles(app.angles(:,2) == app.frameTime,:);
                    polarhistogram(app.pax_in,app.angles1frame(:,1),36,'Normalization','probability');


                end
                %Update the metrics

                app.totalTracksText.Text = ['Total Tracks: ', num2str(app.nTracks)];
                % app.nTrackPts = size(app.Tracks,1);
                app.totalParticlesText.Text = ['Total Track points: ', num2str(app.nTrackPts)];
                % ROIids = unique(app.Tracks(:,5));
                % app.nROIs = length(ROIids);

                app.totalROIsText.Text = ['Total ROIs: ', num2str(app.nROIs)];
                app.minTrackLenText.Text = ['Min. Track Length: ', num2str(app.minTrkLen)];


            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            close(app.QuantiTrackAngleAnalysisUIFigure);

        end

        % Value changed function: MinTimesSpinner
        function MinTimesSpinnerValueChanged(app, event)
            value = app.MinTimesSpinner.Value;
            xlims = get(app.TimeAxes,'Xlim');
            xlims(1) = value;
            set(app.TimeAxes,'Xlim',xlims);
            
        end

        % Value changed function: MaxTimesSpinner
        function MaxTimesSpinnerValueChanged(app, event)
            value = app.MaxTimesSpinner.Value;
            xlims = get(app.TimeAxes,'Xlim');
            xlims(2) = value;
            set(app.TimeAxes,'Xlim',xlims);
        end

        % Value changed function: MinDisplacementnmSpinner
        function MinDisplacementnmSpinnerValueChanged(app, event)
            value = app.MinDisplacementnmSpinner.Value;
            xlims = get(app.DistAxes,'Xlim');
            xlims(1) = value;
            set(app.DistAxes,'Xlim',xlims);
        end

        % Value changed function: MaxDisplacementnmSpinner
        function MaxDisplacementnmSpinnerValueChanged(app, event)
            value = app.MaxDisplacementnmSpinner.Value;
            xlims = get(app.DistAxes,'Xlim');
            xlims(2) = value;
            set(app.DistAxes,'Xlim',xlims);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackAngleAnalysisUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackAngleAnalysisUIFigure.Name = 'QuantiTrack - Angle Analysis';
            app.FileMenu = uimenu(app.QuantiTrackAngleAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadAngleAnalysisResultsMenu = uimenu(app.FileMenu);
            app.LoadAngleAnalysisResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadAngleAnalysisResultsMenuSelected, true);
            app.LoadAngleAnalysisResultsMenu.Accelerator = 'o';
            app.LoadAngleAnalysisResultsMenu.Text = 'Load Angle Analysis Results';
            app.SaveAngleAnalysisMenu = uimenu(app.FileMenu);
            app.SaveAngleAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAngleAnalysisMenuSelected, true);
            app.SaveAngleAnalysisMenu.Accelerator = 's';
            app.SaveAngleAnalysisMenu.Text = 'Save Angle Analysis';
            app.TimeAxes = uiaxes(app.QuantiTrackAngleAnalysisUIFigure);
            app.DistAxes = uiaxes(app.QuantiTrackAngleAnalysisUIFigure);
            app.Copy1frameAngles = uibutton(app.QuantiTrackAngleAnalysisUIFigure, 'push');
            app.Copy1frameAngles.ButtonPushedFcn = createCallbackFcn(app, @Copy1frameAnglesButtonPushed, true);
            app.Copy1frameAngles.Text = 'Copy 1-frame Angles';
            app.CopyAllAngles = uibutton(app.QuantiTrackAngleAnalysisUIFigure, 'push');
            app.CopyAllAngles.ButtonPushedFcn = createCallbackFcn(app, @CopyAllAnglesButtonPushed, true);
            app.CopyAllAngles.Text = 'Copy Angle and Anisotropy Data';
            app.SaveFigure = uibutton(app.QuantiTrackAngleAnalysisUIFigure, 'push');
            app.SaveFigure.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigure.Text = 'Save Current Plots';
            app.DoneButton = uibutton(app.QuantiTrackAngleAnalysisUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'DONE';
            app.TemporalAngleAnisotropyHistogramPanel = uipanel(app.QuantiTrackAngleAnalysisUIFigure);
            app.TemporalAngleAnisotropyHistogramPanel.Title = 'Temporal Angle Anisotropy Histogram';
            app.MinTimesLabel = uilabel(app.TemporalAngleAnisotropyHistogramPanel);
            app.MinTimesLabel.Text = 'Min Time (s)';
            app.MinTimesSpinner = uispinner(app.TemporalAngleAnisotropyHistogramPanel);
            app.MinTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @MinTimesSpinnerValueChanged, true);
            app.MaxTimesLabel = uilabel(app.TemporalAngleAnisotropyHistogramPanel);
            app.MaxTimesLabel.Text = 'Max Time (s)';
            app.MaxTimesSpinner = uispinner(app.TemporalAngleAnisotropyHistogramPanel);
            app.MaxTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxTimesSpinnerValueChanged, true);
            app.BinWidthLabel = uilabel(app.TemporalAngleAnisotropyHistogramPanel);
            app.BinWidthLabel.Text = 'Bin Width (s)';
            app.NumberofTimeBinsSpinner = uispinner(app.TemporalAngleAnisotropyHistogramPanel);
            app.DisplacementAngleAnisotropyHistogramPanel = uipanel(app.QuantiTrackAngleAnalysisUIFigure);
            app.DisplacementAngleAnisotropyHistogramPanel.Title = 'Displacement Angle Anisotropy Histogram';
            app.MinDisplacementnmLabel = uilabel(app.DisplacementAngleAnisotropyHistogramPanel);
            app.MinDisplacementnmLabel.Text = 'Min Displacement (nm)';
            app.MinDisplacementnmSpinner = uispinner(app.DisplacementAngleAnisotropyHistogramPanel);
            app.MinDisplacementnmSpinner.ValueChangedFcn = createCallbackFcn(app, @MinDisplacementnmSpinnerValueChanged, true);
            app.MaxDisplacementnmLabel = uilabel(app.DisplacementAngleAnisotropyHistogramPanel);
            app.MaxDisplacementnmLabel.Text = 'Max Displacement (nm)';
            app.MaxDisplacementnmSpinner = uispinner(app.DisplacementAngleAnisotropyHistogramPanel);
            app.MaxDisplacementnmSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxDisplacementnmSpinnerValueChanged, true);
            app.BinWidthLabel_2 = uilabel(app.DisplacementAngleAnisotropyHistogramPanel);
            app.BinWidthLabel_2.Text = 'Bin Width (nm)';
            app.NumberofDispBinsSpinner = uispinner(app.DisplacementAngleAnisotropyHistogramPanel);
            app.DatasetMetricsPanel = uipanel(app.QuantiTrackAngleAnalysisUIFigure);
            app.DatasetMetricsPanel.Title = 'Dataset Metrics';
            app.totalTracksText = uilabel(app.DatasetMetricsPanel);
            app.totalTracksText.Text = 'Total Tracks:';
            app.totalParticlesText = uilabel(app.DatasetMetricsPanel);
            app.totalParticlesText.Text = 'Total Track Points:';
            app.totalROIsText = uilabel(app.DatasetMetricsPanel);
            app.totalROIsText.Text = 'Total ROIs:';
            app.minTrackLenText = uilabel(app.DatasetMetricsPanel);
            app.minTrackLenText.Text = 'Min Track Length: ';
            app.UpdatePlotsButton = uibutton(app.QuantiTrackAngleAnalysisUIFigure, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.CopyTimeHist = uibutton(app.QuantiTrackAngleAnalysisUIFigure, 'push');
            app.CopyTimeHist.ButtonPushedFcn = createCallbackFcn(app, @CopyTimeHistButtonPushed, true);
            app.CopyTimeHist.Text = 'Copy Temporal Histogram Data';
            app.CopySpatialHist = uibutton(app.QuantiTrackAngleAnalysisUIFigure, 'push');
            app.CopySpatialHist.ButtonPushedFcn = createCallbackFcn(app, @CopySpatialHistButtonPushed, true);
            app.CopySpatialHist.Text = 'Copy Spatial Histogram Data';
            % Show the figure after all components are created
            app.QuantiTrackAngleAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AngleAnalysis_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackAngleAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackAngleAnalysisUIFigure)
        end
    end
end