classdef DiffCoeffMerge_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackDiffusionAnalysisUIFigure  matlab.ui.Figure
        BICLabel                 matlab.ui.control.Label
        AICLabel                 matlab.ui.control.Label
        BestFittingGaussianMixtureByLabel  matlab.ui.control.Label
        FitResultsTextArea       matlab.ui.control.TextArea
        FitResultsTextAreaLabel  matlab.ui.control.Label
        DoneButton               matlab.ui.control.Button
        CopyFitParametersButton  matlab.ui.control.Button
        CopyHistogramofDiffusionCoefficientsButton  matlab.ui.control.Button
        SaveCurrentFigureButton  matlab.ui.control.Button
        SelectFItDropDown        matlab.ui.control.DropDown
        SelectFItDropDownLabel   matlab.ui.control.Label
        resAxes                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        logD
        frameTime
        avgNframes% Description
        binEdgesD % Description
        gauss1_param
        gauss2_param
        gauss3_param
        gauss1_ci
        gauss2_ci
        gauss3_ci
        gauss1_SSR
        gauss2_SSR
        gauss3_SSR% Description
        gauss1_fit
        gauss2_fit
        gauss3_fit
        gauss1_res
        gauss2_res
        gauss3_res% Description
        logDHist % Description
        gauss_AIC
        gauss_BIC
        maxframes
        % Description
        minTrkLen % Description
    end
    
    methods (Access = private)
        
        function [gauss2_param, gauss2_ci, gauss2_AIC, gauss2_BIC] = fit2Gauss(app)
            
            GMModel = fitgmdist(app.logD,2);
            gauss2_param = [GMModel.mu(1),sqrt(GMModel.Sigma(:,:,1)),GMModel.mu(2),sqrt(GMModel.Sigma(:,:,2)),GMModel.ComponentProportion(1)];
            gauss2_AIC = GMModel.AIC;
            gauss2_BIC = GMModel.BIC;
            if GMModel.Converged
                gauss2_ci = zeros(size(gauss2_param));
            else
                gauss2_ci = nan(size(gauss2_param));
            end
%             custpdf = @(data,mu1,sig1,mu2,sig2,f1) f1*normpdf(data,mu1,sig1) + (1-f1)*normpdf(data,mu2,sig2);
%             start = [app.gauss1_param(1)/2, ...
%                 app.gauss1_param(2),...
%                 2.*app.gauss1_param(1),...
%                 app.gauss1_param(2),...
%                 0.5];
%             [gauss2_param, gauss2_ci] = mle(app.logD,'pdf',custpdf,'start',start);
            
            
        end
        
        function [gauss3_param, gauss3_ci, gauss3_AIC, gauss3_BIC] = fit3Gauss(app)
            GMModel = fitgmdist(app.logD,3);
            gauss3_param = [GMModel.mu(1),sqrt(GMModel.Sigma(:,:,1)),GMModel.mu(2),sqrt(GMModel.Sigma(:,:,2)),GMModel.mu(3),sqrt(GMModel.Sigma(:,:,3)),...
                GMModel.ComponentProportion(1),GMModel.ComponentProportion(2)];
            gauss3_AIC = GMModel.AIC;
            gauss3_BIC = GMModel.BIC;
            if GMModel.Converged
                gauss3_ci = zeros(size(gauss3_param));
            else
                gauss3_ci = nan(size(gauss3_param));
            end
%             custpdf = @(data,mu1,sig1,mu2,sig2,mu3,sig3,f1,f2) f1*normpdf(data,mu1,sig1) + f2*normpdf(data,mu2,sig2) + (1-f1-f2)*normpdf(data,mu3,sig3);
%             start = [app.gauss2_param(1)/2, ...
%                 app.gauss2_param(2),...
%                 app.gauss2_param(3),...
%                 app.gauss2_param(4),...
%                 2*app.gauss2_param(3),...
%                 app.gauss2_param(4),...
%                 app.gauss2_param(5),...
%                 (1-app.gauss2_param(5))/2]; 
%             [gauss3_param, gauss3_ci] = mle(app.logD,'pdf',custpdf,'start',start);
        end
        
        function gauss2_pdf = gauss2(app)
            mu1 = app.gauss2_param(1);
            sig1 = app.gauss2_param(2);
            mu2 = app.gauss2_param(3);
            sig2 = app.gauss2_param(4);
            f1 = app.gauss2_param(5);
            
            gauss2_pdf(1,:) = f1*normpdf(app.binEdgesD,mu1,sig1) + (1-f1)*normpdf(app.binEdgesD,mu2,sig2);
            
            gauss2_pdf(1,:) = gauss2_pdf(1,:).*(app.binEdgesD(2)-app.binEdgesD(1));
            gauss2_pdf(2,:) = f1*normpdf(app.binEdgesD,mu1,sig1);
            gauss2_pdf(2,:) = gauss2_pdf(2,:).*(app.binEdgesD(2)-app.binEdgesD(1));
            
            gauss2_pdf(3,:) = (1-f1)*normpdf(app.binEdgesD,mu2,sig2);
            gauss2_pdf(3,:) = gauss2_pdf(3,:).*(app.binEdgesD(2)-app.binEdgesD(1));
            
            
        end
        
        function gauss3_pdf = gauss3(app)
            mu1 = app.gauss3_param(1);
            sig1 = app.gauss3_param(2);
            mu2 = app.gauss3_param(3);
            sig2 = app.gauss3_param(4);
            mu3 = app.gauss3_param(5);
            sig3 = app.gauss3_param(6);
            f1 = app.gauss3_param(7);
            f2 = app.gauss3_param(8);
            
            gauss3_pdf = f1*normpdf(app.binEdgesD,mu1,sig1) + f2*normpdf(app.binEdgesD,mu2,sig2) + (1-f1-f2)*normpdf(app.binEdgesD,mu3,sig3);
            
            gauss3_pdf = gauss3_pdf.*(app.binEdgesD(2)-app.binEdgesD(1));
            
            gauss3_pdf(2,:) = f1*normpdf(app.binEdgesD,mu1,sig1);
            gauss3_pdf(2,:) = gauss3_pdf(2,:).*(app.binEdgesD(2)-app.binEdgesD(1));
            
            gauss3_pdf(3,:) = f2*normpdf(app.binEdgesD,mu2,sig2);
            gauss3_pdf(3,:) = gauss3_pdf(3,:).*(app.binEdgesD(2)-app.binEdgesD(1));
            
            gauss3_pdf(4,:) = (1-f1-f2)*normpdf(app.binEdgesD,mu3,sig3);
            gauss3_pdf(4,:) = gauss3_pdf(4,:).*(app.binEdgesD(2)-app.binEdgesD(1));
        end
        
        function gauss1_pdf= gauss1(app)
            mu1 = app.gauss1_param(1);
            sig1 = app.gauss1_param(2);

            gauss1_pdf = normpdf(app.binEdgesD,mu1,sig1);
            
            
            gauss1_pdf = gauss1_pdf.*(app.binEdgesD(2)-app.binEdgesD(1));
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %Get input
            
            app.data.PathName = varargin{1};
            app.data.FileNames = varargin{2};
            app.data.AnalysisS = varargin{3};
            if nargin > 3
                pos_fig = varargin{4};
                ChangeAppPosition(app.QuantiTrackDiffusionAnalysisUIFigure,pos_fig);
                drawnow;
            end
            JHistBinSize = 0.02;
            JHistMaxFrameN = 20;
            maxJump = 50;
            app.frameTime = str2double(app.data.AnalysisS{4});
            app.maxframes = str2double(app.data.AnalysisS{5});
            app.minTrkLen = str2double(app.data.AnalysisS{6});
            binEdges = 0:JHistBinSize:maxJump;
            
            
            nFiles = length(app.data.FileNames);

            %if we have only 1 file it will likely be a trackTable
            if nFiles == 1
                IN = load(fullfile(app.data.PathName,app.data.FileNames{1}));
                if isfield(IN,'trackTable')
                    % get the tracks of the selected files
                    % open each mat-files and retrieve useful information
                    [Tracks,~,ROIChoice] = compileTracksFromTables(...
                        IN.trackTable,0,...
                        app.QuantiTrackDiffusionAnalysisUIFigure,...
                        'Tracking');
                else
                    % get the tracks of the selected files
                    % open each mat-files and retrieve useful information
                    [Tracks,~,ROIChoice] = compileTracksFromFiles(...
                        app.data.FileNames,app.data.PathName,0,...
                        app.QuantiTrackDiffusionAnalysisUIFigure,...
                        'Tracking');
                end
            else %if more than one file is selected it should be tracking files
                % get the tracks of the selected files
                % open each mat-files and retrieve useful information
                [Tracks,~,ROIChoice] = compileTracksFromFiles(...
                    app.data.FileNames,app.data.PathName,0,...
                    app.QuantiTrackDiffusionAnalysisUIFigure,...
                    'Tracking');
            end
            
            
            nFiles = length(Tracks);

            d = uiprogressdlg(app.QuantiTrackDiffusionAnalysisUIFigure,'Title','Analyzing Files');
            d.Value = 0;
            for i = 1:nFiles
                d.Message = ['Calculating MSDs on Tracking File ', num2str(i), ' of ', num2str(nFiles)];
               
                r_sq{i} = calculateMSD2(Tracks{i},app.frameTime,app.maxframes,app.minTrkLen);

                d.Value = i/nFiles;

                                                
            end
            close(d);

            r_sq_all= [];
            
            for i = 1:nFiles

                r_sq_all = cat(1,r_sq_all,r_sq{i});
            end

            D = r_sq_all;
            app.logD = log10(D(:,1));

            if strcmp(app.data.AnalysisS{1},'auto')
                binEdges_min = min(app.logD);
            else
                binEdges_min = str2double(app.data.AnalysisS{1});
            end
            if strcmp(app.data.AnalysisS{2},'auto')
                binEdges_max = max(app.logD);
            else
                binEdges_max = str2double(app.data.AnalysisS{2});
            end
            app.binEdgesD = linspace(binEdges_min,binEdges_max,str2double(app.data.AnalysisS{3}));
            app.UIAxes.NextPlot = 'replace';
            app.resAxes.NextPlot = 'replace';
            
            n = hist(app.logD,app.binEdgesD);
            app.logDHist = n./sum(n);
             
            
            bar(app.UIAxes,app.binEdgesD,app.logDHist)
            

            % Fit the logD data to a Gaussian Mixture model
            GMModel = fitgmdist(app.logD,1);
            app.gauss1_param = [GMModel.mu(1),sqrt(GMModel.Sigma(:,:,1))];
            app.gauss_AIC(1) = GMModel.AIC;
            app.gauss_BIC(1) = GMModel.BIC;
            
            
            if GMModel.Converged
                app.gauss1_ci = zeros(size(app.gauss1_param));
            else
                app.gauss1_ci = nan(size(app.gauss1_param));
            end
            [app.gauss2_param,app.gauss2_ci, app.gauss_AIC(2), app.gauss_BIC(2)] = fit2Gauss(app);

            
            [app.gauss3_param,app.gauss3_ci, app.gauss_AIC(3), app.gauss_BIC(3)] = fit3Gauss(app);

            
            app.gauss1_fit = normpdf(app.binEdgesD, app.gauss1_param(1),app.gauss1_param(2));
            app.gauss1_fit = app.gauss1_fit.*(app.binEdgesD(2)-app.binEdgesD(1));
            app.gauss1_res = app.gauss1_fit(1,:) - app.logDHist;
            app.gauss1_SSR = sum(app.gauss1_res.^2);
            if isnan(app.gauss2_ci)
                app.gauss_AIC(2) = nan;
                app.gauss_BIC(2) = nan;
            end
            if isnan(app.gauss3_ci)
                app.gauss_AIC(3) = nan;
                app.gauss_BIC(3) = nan;
            end
            
            app.gauss2_fit = gauss2(app);
            app.gauss2_res = app.gauss2_fit(1,:) - app.logDHist;
            app.gauss2_SSR = sum(app.gauss2_res.^2);
            
            app.gauss3_fit = gauss3(app);
            app.gauss3_res = app.gauss3_fit(1,:) - app.logDHist;
            app.gauss3_SSR = sum(app.gauss3_res.^2);
            
            [~,bestFit_AIC] = min(app.gauss_AIC);
            [~,bestFit_BIC] = min(app.gauss_BIC);
            
            
            hold(app.UIAxes,'on');
            if bestFit_AIC == 1
                plot(app.UIAxes,app.binEdgesD,app.gauss1_fit,'r','LineWidth',1.5);


                app.gauss1_res = app.gauss1_fit - app.logDHist;
                app.gauss1_SSR = sum(app.gauss1_res.^2);
                
                plot(app.resAxes,app.binEdgesD,app.gauss1_res,'r');
                hold(app.resAxes,"on");
                plot(app.resAxes,app.binEdgesD,zeros(1,size(app.binEdgesD,2)),'k--');
                
                xlim = app.UIAxes.XLim;
                app.resAxes.XLim = xlim;
                str{1,:} = '1 Component Diffusion Fit Results';
                str{2,:} = ['<D> = ', num2str(10.^(app.gauss1_param(1)),3)];
                str{3,:} = ['sigma = ',num2str(10.^(app.gauss1_param(2)))];
                str{4,:} = ['SSR = ', num2str(app.gauss1_SSR,3)];
                str{5,:} = ['N tracks = ', num2str(length(app.logD))];
                
                app.SelectFItDropDown.Value = '1 Component Diff. Fit';
                app.FitResultsTextArea.FontColor = [0,0,0];
                if isnan(app.gauss1_ci(1))
                    str{5,:} = 'Did not Converge';
                    app.FitResultsTextArea.FontColor = [1,0,0];
                end
                AICLabel = 'AIC: 1-component';
            elseif bestFit_AIC == 2
                plot(app.UIAxes,app.binEdgesD,app.gauss2_fit(1,:),'r','LineWidth',1.5);
                plot(app.UIAxes,app.binEdgesD,app.gauss2_fit(2,:),'k--','LineWidth',1);
                plot(app.UIAxes,app.binEdgesD,app.gauss2_fit(3,:),'k--','LineWidth',1);
                
                plot(app.resAxes,app.binEdgesD,app.gauss2_res,'r');
                hold(app.resAxes,'on');
                plot(app.resAxes,app.binEdgesD,zeros(1,size(app.binEdgesD,2)),'k--');
                
                str{1,:} = '2 Component Diffusion Fit Results';
                str{2,:} = ['<D1> = ', num2str(10.^(app.gauss2_param(1)),3)];
                str{3,:} = ['sigma1 = ',num2str(10.^(app.gauss2_param(2)))];
                str{4,:} = ['<D2> = ', num2str(10.^(app.gauss2_param(3)),3)];
                str{5,:} = ['sigma2 = ',num2str(10.^(app.gauss2_param(4)))];
                str{6,:} = ['f1 = ', num2str(app.gauss2_param(5),3)];
                str{7,:} = ['SSR = ', num2str(app.gauss2_SSR,3)];
                str{8,:} = ['N tracks = ', num2str(length(app.logD))];
                
                app.SelectFItDropDown.Value = '2 Component Diff. Fit';
                AICLabel = 'AIC: 2-component';
            elseif bestFit_AIC == 3
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(1,:),'r','LineWidth',1.5);
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(2,:),'k--','LineWidth',1);
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(3,:),'k--','LineWidth',1);
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(4,:),'k--','LineWidth',1);
                
                plot(app.resAxes,app.binEdgesD,app.gauss3_res,'r');
                hold(app.resAxes,'on');
                plot(app.resAxes,app.binEdgesD,zeros(1,size(app.binEdgesD,2)),'k--');
                
                str{1,:} = '3 Component Diffusion Fit Results';
                str{2,:} = ['<D1> = ', num2str(10.^(app.gauss3_param(1)),3)];
                str{3,:} = ['sigma1 = ',num2str(10.^(app.gauss3_param(2)))];
                str{4,:} = ['<D2> = ', num2str(10.^(app.gauss3_param(3)),3)];
                str{5,:} = ['sigma2 = ',num2str(10.^(app.gauss3_param(4)))];
                str{6,:} = ['<D3> = ', num2str(10.^(app.gauss3_param(5)),3)];
                str{7,:} = ['sigma3 = ',num2str(10.^(app.gauss3_param(6)))];
                str{8,:} = ['f1 = ', num2str(app.gauss3_param(7),3)];
                str{9,:} = ['f2 = ', num2str(app.gauss3_param(8),3)];
                str{10,:} = ['SSR = ', num2str(app.gauss3_SSR,3)];
                str{11,:} = ['N tracks = ', num2str(length(app.logD))];
                
                app.SelectFItDropDown.Value = '3 Component Diff. Fit';
                AICLabel = 'AIC: 3-component';
            end
            app.FitResultsTextArea.Value = str;
            if bestFit_BIC == 1
                BICLabel = 'BIC: 1-component';
            elseif bestFit_BIC == 2
                BICLabel = 'BIC: 2-component';
            elseif bestFit_BIC == 3
                BICLabel = 'BIC: 3-component';
            end
            app.AICLabel.Text = AICLabel;
            app.BICLabel.Text = BICLabel;


            xlim = app.UIAxes.XLim;
            app.resAxes.XLim = xlim;
            pos_hist = app.UIAxes.Position;
            x0 = pos_hist(1);
            w0 = pos_hist(3);
            pos_res = app.resAxes.Position;
            pos_res(1) = x0 + 25;
            pos_res(3) = w0 - 25;
            app.resAxes.Position = pos_res;
            app.resAxes.XLabel.String = 'log_{10}(D) \mum^2/s';
            app.resAxes.YLabel.String = 'residue';
            app.UIAxes.YLabel.String = 'frequency';
            app.UIAxes.XTickLabel = [];
            app.resAxes.YTickLabel = [];

            figname = app.QuantiTrackDiffusionAnalysisUIFigure.Name;
            figname = [figname ' ' ROIChoice{1}];
            app.QuantiTrackDiffusionAnalysisUIFigure.Name = figname;
        end

        % Value changed function: SelectFItDropDown
        function SelectFItDropDownValueChanged(app, event)
            value = app.SelectFItDropDown.Value;
            app.UIAxes.NextPlot = 'replace';
            app.resAxes.NextPlot = 'replace';
            
            if strcmp(value, '1 Component Diff. Fit')
                bar(app.UIAxes, app.binEdgesD,app.logDHist);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,app.binEdgesD,app.gauss1_fit,'r','LineWidth',1.5);
                
                plot(app.resAxes,app.binEdgesD,app.gauss1_res,'r');
                hold(app.resAxes,'on');
                plot(app.resAxes,app.binEdgesD,zeros(1,size(app.binEdgesD,2)),'k--');
                
                str{1,:} = '1 Component Diffusion Fit Results';
                str{2,:} = ['<D> = ', num2str(10.^(app.gauss1_param(1)),3)];
                str{3,:} = ['sigma = ',num2str(10.^(app.gauss1_param(2)))];
                str{4,:} = ['SSR = ', num2str(app.gauss1_SSR,3)];
                str{5,:} = ['N tracks = ', num2str(length(app.logD))];
                app.FitResultsTextArea.FontColor = [0,0,0];
                if isnan(app.gauss1_ci(1))
                    str{5,:} = 'Did not Converge';
                    app.FitResultsTextArea.FontColor = [1,0,0];
                end
                app.FitResultsTextArea.Value = str;
                
            elseif strcmp(value,'2 Component Diff. Fit')
                bar(app.UIAxes, app.binEdgesD,app.logDHist);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,app.binEdgesD,app.gauss2_fit(1,:),'r','LineWidth',1.5);
                plot(app.UIAxes,app.binEdgesD,app.gauss2_fit(2,:),'k--','LineWidth',1);
                plot(app.UIAxes,app.binEdgesD,app.gauss2_fit(3,:),'k--','LineWidth',1);
                
                plot(app.resAxes,app.binEdgesD,app.gauss2_res,'r');
                hold(app.resAxes,'on');
                plot(app.resAxes,app.binEdgesD,zeros(1,size(app.binEdgesD,2)),'k--');
                
                str{1,:} = '2 Component Diffusion Fit Results';
                str{2,:} = ['<D1> = ', num2str(10.^(app.gauss2_param(1)),3)];
                str{3,:} = ['sigma1 = ',num2str(10.^(app.gauss2_param(2)))];
                str{4,:} = ['<D2> = ', num2str(10.^(app.gauss2_param(3)),3)];
                str{5,:} = ['sigma2 = ',num2str(10.^(app.gauss2_param(4)))];
                str{6,:} = ['f1 = ', num2str(app.gauss2_param(5),3)];
                str{7,:} = ['SSR = ', num2str(app.gauss2_SSR,3)];
                str{8,:} = ['N tracks = ', num2str(length(app.logD))];
                app.FitResultsTextArea.FontColor = [0,0,0];
                if isnan(app.gauss2_ci(1))
                    str{9,:} = 'Did not Converge';
                    app.FitResultsTextArea.FontColor = [1,0,0];
                end
                app.FitResultsTextArea.Value = str;
            elseif strcmp(value,'3 Component Diff. Fit')
                bar(app.UIAxes, app.binEdgesD,app.logDHist);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(1,:),'r','LineWidth',1.5);
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(2,:),'k--','LineWidth',1);
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(3,:),'k--','LineWidth',1);
                plot(app.UIAxes,app.binEdgesD,app.gauss3_fit(4,:),'k--','LineWidth',1);
                
                plot(app.resAxes,app.binEdgesD,app.gauss3_res,'r');
                hold(app.resAxes,'on');
                plot(app.resAxes,app.binEdgesD,zeros(1,size(app.binEdgesD,2)),'k--');
                
                str{1,:} = '3 Component Diffusion Fit Results';
                str{2,:} = ['<D1> = ', num2str(10.^(app.gauss3_param(1)),3)];
                str{3,:} = ['sigma1 = ',num2str(10.^(app.gauss3_param(2)))];
                str{4,:} = ['<D2> = ', num2str(10.^(app.gauss3_param(3)),3)];
                str{5,:} = ['sigma2 = ',num2str(10.^(app.gauss3_param(4)))];
                str{6,:} = ['<D3> = ', num2str(10.^(app.gauss3_param(5)),3)];
                str{7,:} = ['sigma3 = ',num2str(10.^(app.gauss3_param(6)))];
                str{8,:} = ['f1 = ', num2str(app.gauss3_param(7),3)];
                str{9,:} = ['f2 = ', num2str(app.gauss3_param(8),3)];
                str{10,:} = ['SSR = ', num2str(app.gauss3_SSR,3)];
                str{11,:} = ['N tracks = ', num2str(length(app.logD))];
                app.FitResultsTextArea.FontColor = [0,0,0];
                if isnan(app.gauss3_ci(1))
                    str{12,:} = 'Did not Converge';
                    app.FitResultsTextArea.FontColor = [1,0,0];
                end
                app.FitResultsTextArea.Value = str;
            end
            
            
            xlim = app.UIAxes.XLim;
            app.resAxes.XLim = xlim;
            pos_hist = app.UIAxes.Position;
            x0 = pos_hist(1);
            w0 = pos_hist(3);
            pos_res = app.resAxes.Position;
            pos_res(1) = x0 + 25;
            pos_res(3) = w0 - 25;
            app.resAxes.Position = pos_res;
            app.resAxes.XLabel.String = 'log_{10}(D) \mum^2/s';
            app.resAxes.YLabel.String = 'residue';
            app.UIAxes.YLabel.String = 'frequency';
            app.UIAxes.XTickLabel = [];
            app.resAxes.YTickLabel = [];
        end

        % Button pushed function: 
        % CopyHistogramofDiffusionCoefficientsButton
        function CopyHistogramofDiffusionCoefficientsButtonPushed(app, event)
            D = [app.binEdgesD(:), app.logDHist(:),...
                app.gauss1_fit(:),app.gauss2_fit',app.gauss3_fit'];
            % num2clip(D);
            histTable = array2table(D,'VariableNames',...
                {'log10D','Frequency', '1-Comp Gaussian fit',...
                '2-Comp Gaussian fit', 'Component 1 of 2','Component 2 of 2',...
                '3-Comp Gaussian fit', 'Component 1 of 3','Component 2 of 3','Component 3 of 3'});
            assignin('base','DiffCoefHist_out',histTable);
            msgbox({'The Array containing the log Diffusion Coefficient histogram';...
                'and all three fits has been copied to the workspace as variable DiffCoefHist_out'});
        end

        % Button pushed function: SaveCurrentFigureButton
        function SaveCurrentFigureButtonPushed(app, event)
%             frameTime = app.frameTime;
%             
%             binSize = app.avgNframes;
            
                       
            DefaultName = app.data.FileNames{1};
            SearchStr = '(.*)\.\w*';
            
            % tlist = handles.JDH(2:end, 1);
            % rlist = handles.JDH(1, 2:end)';
            % rlist(1,2) =tlist(1);
            
            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .4*scrsz(3) .5*scrsz(4)]) ;
            
            Selection = app.SelectFItDropDown.Value;
            
            set(gca, 'FontSize', 10);
            bar(app.binEdgesD,app.logDHist);
            hold on;
            
            if strcmp(Selection, '1 Component Diff. Fit') % One component diffusion fit
                
                % plot the histogram of displacements and the fit in the current
                % axes
                
                
                plot(app.binEdgesD,app.gauss1_fit,'r', 'LineWidth', 1);
                hold off;
                xlabel('LogD [\mum^2/s]', 'FontSize', 10);
                ylabel('Counts', 'FontSize', 10);
                
                FitString{1} = 'One Component Diffusion Coefficient Fit';
                FitString{2} = ['n = ' num2str(length(app.logD))];
                FitString{3} = ['D = ', num2str((10^app.gauss1_param(1)),3),' \mum^2/s, \sigma = ', num2str(10^app.gauss1_param(2),3), ' \mum^2/s; '];
                FitString{4}= ['SSR = ' num2str(app.gauss1_SSR),'; \DeltaT = ', num2str(app.frameTime)];
                
                
                title(FitString,'FontSize', 10);
                legend({'Track Diffusion Coefficients';'One Component Fit'}, 'FontSize', 10,'Location','NorthWest');
                
                
                DefaultName = regexprep(DefaultName, SearchStr, '$1_Diffusion_1Cmp');
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end
                
            elseif strcmp(Selection, '2 Component Diff. Fit') % Two component diffusion fit
                
                % plot the histogram of displacements and the fit in the current
                % axes
                
                plot(app.binEdgesD,app.gauss2_fit(1,:),'r', 'LineWidth', 1);
                plot(app.binEdgesD,app.gauss2_fit(2,:),'k--','LineWidth',1);
                plot(app.binEdgesD,app.gauss2_fit(3,:),'k--','LineWidth',1);
                hold off;
                xlabel('LogD [\mum^2/s]', 'FontSize', 10);
                ylabel('Counts', 'FontSize', 10);
                
                FitString{1} = 'Two Component Diffusion Coefficient Fit';
                FitString{2} = ['n = ' num2str(length(app.logD))];
                FitString{3} = ['D_1 = ', num2str((10^app.gauss2_param(1)),3),' \mum^2/s, \sigma = ', num2str((10^app.gauss2_param(2)),3), ' \mum^2/s; ']
                FitString{4} = ['D_2 = ', num2str((10^app.gauss2_param(3)),3),' \mum^2/s, \sigma = ', num2str((10^app.gauss2_param(4)),3), ' \mum^2/s'];
                FitString{5} = ['f_1 = ', num2str(app.gauss2_param(5),3),' \pm ', num2str(app.gauss2_ci(5),2)];
                FitString{6}= ['SSR = ' num2str(app.gauss2_SSR),'; \DeltaT = ', num2str(app.frameTime)];
                
                
                title(FitString,'FontSize', 10);
                legend({'Track Diffusion Coefficients';'Two Component Fit';'Component 1'; 'Component 2'}, 'FontSize', 10,'Location','NorthWest');
                
                
                DefaultName = regexprep(DefaultName, SearchStr, '$1_Diffusion_2Cmp');
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end
                
            elseif strcmp(Selection, '3 Component Diff. Fit') % Three component diffusion fit
                
                % plot the histogram of displacements and the fit in the current
                % axes
                
                plot(app.binEdgesD,app.gauss3_fit(1,:),'r','LineWidth',1);
                plot(app.binEdgesD,app.gauss3_fit(2,:),'k--','LineWidth',1);
                plot(app.binEdgesD,app.gauss3_fit(3,:),'k--','LineWidth',1);
                plot(app.binEdgesD,app.gauss3_fit(4,:),'k--','LineWidth',1);
                hold off;
                xlabel('LogD [\mum^2/s]', 'FontSize', 10);
                ylabel('Counts', 'FontSize', 10);
                
                FitString{1} = 'Three Component Diffusion Coefficient Fit';
                FitString{2} = ['n = ' num2str(length(app.logD))];
                FitString{3} = ['D_1 = ', num2str((10^app.gauss3_param(1)),3),' \mum^2/s, \sigma = ', num2str((10^app.gauss3_param(2)),3), ' \mum^2/s; '];
                FitString{4} = ['D_2 = ', num2str((10^app.gauss3_param(3)),3),' \mum^2/s, \sigma = ', num2str((10^app.gauss3_param(4)),3), ' \mum^2/s; '];
                FitString{5} = ['D_3 = ', num2str((10^app.gauss3_param(5)),3),' \mum^2/s, \sigma = ', num2str((10^app.gauss3_param(6)),3), ' \mum^2/s'];
                FitString{6} = ['f_1 = ', num2str(app.gauss3_param(7),3),' \pm ', num2str(app.gauss3_ci(7),2), '; ',...
                    'f_2 = ', num2str(app.gauss3_param(8),3),' \pm ', num2str(app.gauss3_ci(8),2)];
                FitString{7}= ['SSR = ' num2str(app.gauss3_SSR),'; \DeltaT = ', num2str(app.frameTime)];
                
                
                title(FitString,'FontSize', 10);
                legend({'Track Diffusion Coefficients';'Two Component Fit';'Component 1'; 'Component 2'; 'Component 3'}, 'FontSize', 10,'Location','NorthWest');
                
                
                DefaultName = regexprep(DefaultName, SearchStr, '$1_Diffusion_3Cmp');
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end


            end
        end

        % Button pushed function: CopyFitParametersButton
        function CopyFitParametersButtonPushed(app, event)
            gauss1_par = 10.^app.gauss1_param;
            gauss1_ci2 = 10.^app.gauss1_ci;

            gauss2_par = app.gauss2_param;
            gauss2_par(1:4) = 10.^gauss2_par(1:4);
            gauss2_ci2 = app.gauss2_ci;
            gauss2_ci2(1:4) = 10.^gauss2_ci2(1:4);

            gauss3_par = app.gauss3_param;
            gauss3_par(1:6) = 10.^gauss3_par(1:6);
            gauss3_ci2 = app.gauss3_ci;
            gauss3_ci2(1:6) = 10.^gauss3_ci2(1:6);
            FitPar = [gauss1_par, gauss2_par, gauss3_par;...
                gauss1_ci2, gauss2_ci2, gauss3_ci2];

            % num2clip(FitPar);
          
            ParTable = array2table(FitPar,'VariableNames',...
                {'1-Comp Gaussian mu (mean and CI)','1-Comp Gaussian sigma (mean and CI)', ...
                '2-Comp Gaussian mu1 (mean and CI)','2-Comp Gaussian sigma1 (mean and CI)',...
                '2-Comp Gaussian mu2 (mean and CI)','2-Comp Gaussian sigma2 (mean and CI)','2-Comp Gaussian f1 (mean and CI)',...
                '3-Comp Gaussian mu1 (mean and CI)','3-Comp Gaussian sigma1 (mean and CI)',...
                '3-Comp Gaussian mu2 (mean and CI)','3-Comp Gaussian sigma2 (mean and CI)',...
                '3-Comp Gaussian mu3 (mean and CI)','3-Comp Gaussian sigma3 (mean and CI)',...
                '3-Comp Gaussian f1 (mean and CI)','3-Comp Gaussian f2 (mean and CI)'});
            assignin('base','DiffCoef_params_out',ParTable);
            msgbox({'The Array containing the Fit Parameters';...
                'has been copied to the workspace as variable DiffCoef_params_out'});
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            close(app.QuantiTrackDiffusionAnalysisUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackDiffusionAnalysisUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackDiffusionAnalysisUIFigure.Name = 'QuantiTrack - Diffusion Analysis';
            app.UIAxes = uiaxes(app.QuantiTrackDiffusionAnalysisUIFigure);
            title(app.UIAxes, 'Diffusion Coefficients')
            ylabel(app.UIAxes, 'Frequency')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.resAxes = uiaxes(app.QuantiTrackDiffusionAnalysisUIFigure);
            xlabel(app.resAxes, 'log_{10} (D)')
            ylabel(app.resAxes, 'residue')
            app.resAxes.YTickLabel = {'0'; '0.5'; '1'};
            app.SelectFItDropDownLabel = uilabel(app.QuantiTrackDiffusionAnalysisUIFigure);
            app.SelectFItDropDownLabel.Text = 'Select FIt:';
            app.SelectFItDropDown = uidropdown(app.QuantiTrackDiffusionAnalysisUIFigure);
            app.SelectFItDropDown.Items = {'1 Component Diff. Fit', '2 Component Diff. Fit', '3 Component Diff. Fit'};
            app.SelectFItDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFItDropDownValueChanged, true);
            app.SelectFItDropDown.Value = '1 Component Diff. Fit';
            app.SaveCurrentFigureButton = uibutton(app.QuantiTrackDiffusionAnalysisUIFigure, 'push');
            app.SaveCurrentFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentFigureButtonPushed, true);
            app.SaveCurrentFigureButton.Text = 'Save Current Figure';
            app.CopyHistogramofDiffusionCoefficientsButton = uibutton(app.QuantiTrackDiffusionAnalysisUIFigure, 'push');
            app.CopyHistogramofDiffusionCoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @CopyHistogramofDiffusionCoefficientsButtonPushed, true);
            app.CopyHistogramofDiffusionCoefficientsButton.Text = 'Copy Histogram of Diffusion Coefficients';
            app.CopyFitParametersButton = uibutton(app.QuantiTrackDiffusionAnalysisUIFigure, 'push');
            app.CopyFitParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CopyFitParametersButtonPushed, true);
            app.CopyFitParametersButton.Text = 'Copy Fit Parameters';
            app.DoneButton = uibutton(app.QuantiTrackDiffusionAnalysisUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.FitResultsTextAreaLabel = uilabel(app.QuantiTrackDiffusionAnalysisUIFigure);
            app.FitResultsTextAreaLabel.Text = 'Fit Results';
            app.FitResultsTextArea = uitextarea(app.QuantiTrackDiffusionAnalysisUIFigure);
            app.BestFittingGaussianMixtureByLabel = uilabel(app.QuantiTrackDiffusionAnalysisUIFigure);
            app.BestFittingGaussianMixtureByLabel.Text = 'Best Fitting Gaussian Mixture By:';
            app.AICLabel = uilabel(app.QuantiTrackDiffusionAnalysisUIFigure);
            app.AICLabel.Text = 'AIC: ';
            app.BICLabel = uilabel(app.QuantiTrackDiffusionAnalysisUIFigure);
            app.BICLabel.Text = 'BIC: ';
            % Show the figure after all components are created
            app.QuantiTrackDiffusionAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffCoeffMerge_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackDiffusionAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackDiffusionAnalysisUIFigure)
        end
    end
end