classdef MSD_Analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackMSDAnalysisUIFigure  matlab.ui.Figure
        RestoreDefTimeRangeButton       matlab.ui.control.Button
        ReFitButton                     matlab.ui.control.Button
        TimeRangeforFittingButtonGroup  matlab.ui.container.ButtonGroup
        secLabel_2                      matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        MaximumTimeCDSpinner            matlab.ui.control.Spinner
        MaximumSpinnerLabel             matlab.ui.control.Label
        MinimumTimeCDSpinner            matlab.ui.control.Spinner
        MinimumSpinnerLabel             matlab.ui.control.Label
        FitstoDisplayButtonGroup        matlab.ui.container.ButtonGroup
        PowerLawCheckBox                matlab.ui.control.CheckBox
        ConfinedDiffusionCheckBox       matlab.ui.control.CheckBox
        ConfinedDiffusionFitR_c21exp4DtR_c2PowerLawFitMSDtAtalphaLabel  matlab.ui.control.Label
        FittingResultPanel              matlab.ui.container.Panel
        Dum2sEditField                  matlab.ui.control.NumericEditField
        Dmum2sEditFieldLabel            matlab.ui.control.Label
        TotalTracksEditField            matlab.ui.control.NumericEditField
        TotalTracksEditField_2Label     matlab.ui.control.Label
        RcnmEditField                   matlab.ui.control.NumericEditField
        R_cnmEditFieldLabel             matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        sigma_locnmEditField            matlab.ui.control.NumericEditField
        sigma_locnmEditFieldLabel       matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        YScaleButtonGroup               matlab.ui.container.ButtonGroup
        LogButton_2                     matlab.ui.control.RadioButton
        LinearButton_2                  matlab.ui.control.RadioButton
        XScaleButtonGroup               matlab.ui.container.ButtonGroup
        LogButton                       matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        SaveFigureButton                matlab.ui.control.Button
        CopyMSDdatatoWorkspaceButton    matlab.ui.control.Button
        MSDAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Tracks % Description
        minTrkLen % Description
        frameTime % Description
        fileName % Description
        MMSD % Description
        alpha % Description
        Rc % Description
        D% Description
        Rc_fit % Description
        nROIs % Description
        nTracks % Description
        nTrackPts % Description
        firstNonfitPt % Description
        curFitIndRange % Description
        
        Pwr_Fit % Description
        sigmaFit % Description
    end
    
    methods (Access = private)
        
        function updateMSDPlot(app)
            curTrange = [app.MinimumTimeCDSpinner.Value, app.MaximumTimeCDSpinner.Value];
            app.curFitIndRange = curTrange./app.frameTime;
            app.MSDAxes.NextPlot = "replace";
            

            p1 = errorbar(app.MSDAxes,app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),1),app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),2),...
                app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),3),...
                'o','Color',[0 0 0],'MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
            hold(app.MSDAxes,'on');
            legend_Str = {'Experimental MSD'};
            legend_subset = p1;
            if app.curFitIndRange(1) > 1
                p2 = errorbar(app.MSDAxes,app.MMSD(1:app.curFitIndRange(1)-1,1),app.MMSD(1:app.curFitIndRange(1)-1,2),app.MMSD(1:app.curFitIndRange(1)-1,3),...
                    'o','Color',[0.65, 0.65, 0.65],'MarkerEdgeColor',[0.65, 0.65, 0.65],'MarkerFaceColor',[0.65, 0.65, 0.65]);
                legend_Str = {'MSD included in Fit', 'MSD excluded from Fit'};
                legend_subset = [p1 p2];
            end
            if app.curFitIndRange(2) < size(app.MMSD,1)
                p3 = errorbar(app.MSDAxes,app.MMSD(app.curFitIndRange(2)+1:end,1),app.MMSD(app.curFitIndRange(2)+1:end,2),app.MMSD(app.curFitIndRange(2)+1:end,3),...
                    'o','Color',[0.65, 0.65, 0.65],'MarkerEdgeColor',[0.65, 0.65, 0.65],'MarkerFaceColor',[0.65, 0.65, 0.65]);
                legend_Str = {'MSD included in Fit', 'MSD excluded from Fit'};
                legend_subset = [p1 p3];
            end
            selectedButton = app.XScaleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Log')
                app.MSDAxes.XScale = 'log';
            else
                app.MSDAxes.XScale = 'linear';
            end
            selectedButton = app.YScaleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Log')
                app.MSDAxes.YScale = 'log';
            else
                app.MSDAxes.YScale = 'linear';
            end
            drawnow;

            ylims = app.MSDAxes.YLim;

            if app.firstNonfitPt > 2
                [Pwr_Coef, Pwr_Sigma, app.Pwr_Fit] = MSD_anomalousD_fit(app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),1:2),1);
                app.alpha = [Pwr_Coef(1), Pwr_Sigma(1)];
                app.sigmaFit = [Pwr_Coef(3), Pwr_Sigma(3)];
                if app.PowerLawCheckBox.Value == 1
                    p4 = plot(app.MSDAxes,app.Pwr_Fit(:,1),app.Pwr_Fit(:,2), 'b','LineWidth',1.5);
                    legend_Str = [legend_Str, 'Power Law Fit'];
                    legend_subset = [legend_subset, p4];
                end
            else
                app.alpha = 0;
            end
            if app.firstNonfitPt > 4
                [~, Rc_param,~, Rc_CI, ~, app.Rc_fit] = fitMSD_Rc2(app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),1),app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),2),[1,4]);
                app.Rc = [Rc_param(1), Rc_CI(1)];
                app.D = [Rc_param(2), Rc_CI(2)];
                if app.ConfinedDiffusionCheckBox.Value == 1
                    p5 = plot(app.MSDAxes,app.Rc_fit(:,1),app.Rc_fit(:,2),'r','LineWidth',1.5);
                    legend_Str = [legend_Str, 'Confined Diffusion Fit'];
                    legend_subset = [legend_subset, p5];
                end
            else
                app.Rc = 0;
                app.D = 0;
                app.Rc_fit = [];
            end
            
            legend(app.MSDAxes,legend_subset,legend_Str,'Location','SouthEast');
            xlabel(app.MSDAxes,'Lag Time (s)')
            ylabel(app.MSDAxes,'MSD (\mum^2)');
            app.MSDAxes.XLim = [0, 1.1.*app.frameTime.*app.curFitIndRange(2)];
            app.MSDAxes.YLim = ylims;

            %update the fit results
            app.TotalTracksEditField.Value = length(app.Tracks);
            app.RcnmEditField.Value = app.Rc(1).*1000;
            app.aEditField.Value = app.alpha(1);
            app.sigma_locnmEditField.Value = 1000.*(sqrt(app.sigmaFit(1))/4);
            app.Dum2sEditField.Value = app.D(1);
            
            %Check if we should enable the restore default button
            if app.curFitIndRange(1) ~= app.MMSD(1,1) || app.curFitIndRange(2) ~= app.MMSD(app.firstNonfitPt-1,1)
                app.RestoreDefTimeRangeButton.Enable = "on";
            else
                app.RestoreDefTimeRangeButton.Enable = "off";
            end
            app.ReFitButton.Enable = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                if length(varargin) > 5
                    pos_fig = varargin{6};
                    ChangeAppPosition(app.QuantiTrackMSDAnalysisUIFigure,pos_fig);
                    drawnow;
                end
                app.minTrkLen = varargin{3};
                app.frameTime = varargin{4};
                app.fileName = varargin{5};
                if isnumeric(varargin{1}{1}) %This is for the call from the MSD Button in QuantiTrack
                    app.Tracks = varargin{1};
                    
                else
                    files = varargin{1};
                    path_in = varargin{2};
                    
                    app.fileName = fullfile(path_in,'multfile.tif');
                    %make sure the files variable is a cell array
                    if ~iscell(files)
                        files_tmp{1} = files;
                        files = files_tmp;
                    end
                    if files{1} == 0
                        close(app.QuantiTrackMSDAnalysisUIFigure);
                        return
                    end
                    nFiles = length(files);

                    if nFiles == 1
                        IN = load(fullfile(path_in,files{1}));
                        if isfield(IN,'trackTable')
                            % get the tracks of the selected files
                            % open each mat-files and retrieve useful information
                            [tracks_all, app.nTrackPts,~,app.nROIs] = compileTracksFromTables(...
                                IN.trackTable,0,...
                                app.QuantiTrackMSDAnalysisUIFigure,...
                                'Tracking');
                        else
                            % get the tracks of the selected files
                            % open each mat-files and retrieve useful information
                            [tracks_all, app.nTrackPts,~,app.nROIs] = compileTracksFromFiles(...
                                files,path_in,0,...
                                app.QuantiTrackMSDAnalysisUIFigure,...
                                'Tracking');
                        end
                    else %if more than one file is selected it should be tracking files
                        % get the tracks of the selected files
                        % open each mat-files and retrieve useful information
                        [tracks_all, app.nTrackPts,~,app.nROIs] = compileTracksFromFiles(...
                            files,path_in,0,...
                            app.QuantiTrackMSDAnalysisUIFigure,...
                            'Tracking');
                    end

                    trk_ind = 1;

                    app.Tracks = cell(1);

                    for i = 1:length(tracks_all)
                        curMovieTrks = tracks_all{i};
                        trkIDs = unique(curMovieTrks(:,4));
                        for j = 1:length(trkIDs)
                            curTrk = curMovieTrks(curMovieTrks(:,4) == trkIDs(j),:);
                            if size(curTrk,1) >= app.minTrkLen
                                app.Tracks{trk_ind,:} = [(curTrk(:,3) - 1).*app.frameTime, curTrk(:,1), curTrk(:,2)];
                                trk_ind = trk_ind + 1;
                            end
                        end
                    end
                end


                d = uiprogressdlg(app.QuantiTrackMSDAnalysisUIFigure,'Title','MSD Analysis', 'Indeterminate','on');
                d.Message = 'Calculating MSD Curve';
                drawnow;
                % Initialize the msdanalyzer class
                ma = msdanalyzer(2, 'µm', 's');
                ma = ma.addAll(app.Tracks);
                ma = ma.computeMSD;

                mmsd = ma.getMeanMSD;

                %remove Nans and infs
                mmsd = mmsd(~isnan(mmsd(:,3)),:);
                mmsd = mmsd(~isinf(mmsd(:,3)),:);

                % ========= BEGIN Edited by KW 01/30/26 ============== %
                
                
                app.MMSD = [mmsd(:,1:2) mmsd(:,3) mmsd(:,4) mmsd(:,5)];

                % ========= END Edited by KW 01/30/26 ============== %
                app.MMSD(1,:) = [];
                test = [mmsd(:,1:2) mmsd(:,3)./sqrt(mmsd(:,4)) mmsd(:,4)];
                test(1,:) = [];

                app.firstNonfitPt = find(test(:,3) >= 0.5*test(:,2),1,'first');
                if isempty(app.firstNonfitPt)
                    app.firstNonfitPt = size(app.MMSD,1)+1;
                end
                %Set the values, range and step size of the spinners
                app.MinimumTimeCDSpinner.Limits = [app.MMSD(1,1) app.MMSD(app.firstNonfitPt-1,1)];
                app.MinimumTimeCDSpinner.Step = app.frameTime;
                app.MinimumTimeCDSpinner.Value = app.MMSD(1,1);

                app.MaximumTimeCDSpinner.Limits = [app.MMSD(1,1) app.MMSD(end,1)];
                app.MaximumTimeCDSpinner.Step = app.frameTime;
                app.MaximumTimeCDSpinner.Value = app.MMSD(app.firstNonfitPt-1,1);

                updateMSDPlot(app);


                % errorbar(app.MSDAxes,app.MMSD(1:app.firstNonfitPt-1,1),app.MMSD(1:app.firstNonfitPt-1,2),app.MMSD(1:app.firstNonfitPt-1,3),...
                %     'o','MarkerEdgeColor',[0, 0, 0],'MarkerFaceColor',[0, 0, 0]);
                % hold(app.MSDAxes,'on');
                % errorbar(app.MSDAxes,app.MMSD(app.firstNonfitPt:end,1),app.MMSD(app.firstNonfitPt:end,2),app.MMSD(app.firstNonfitPt:end,3),'o','MarkerEdgeColor',[0.65, 0.65, 0.65],'MarkerFaceColor',[0.65, 0.65, 0.65]);
                % 
                % ylims = app.MSDAxes.YLim;
                % 
                % if app.firstNonfitPt > 2
                %     [Pwr_Coef, Pwr_Sigma, app.Pwr_Fit] = PwrLawGrowth_nlinfit(app.MMSD(1:app.firstNonfitPt-1,1:2),1);
                %     app.alpha = [Pwr_Coef(1), Pwr_Sigma(1)];
                %     plot(app.MSDAxes,app.Pwr_Fit(:,1),app.Pwr_Fit, 'b','LineWidth',1.5);
                % else
                %     app.alpha = 0;
                % end
                % if app.firstNonfitPt > 4
                %     [~, Rc_param,~, Rc_CI, ~, app.Rc_fit] = fitMSD_Rc2(app.MMSD(1:app.firstNonfitPt-1,1),app.MMSD(1:app.firstNonfitPt-1,2),[1,4]);
                %     app.Rc = [Rc_param(1), Rc_CI(1)];
                %     app.D = [Rc_param(2), Rc_CI(2)];
                %     plot(app.MSDAxes,app.Rc_fit(:,1),app.Rc_fit(:,2),'r','LineWidth',1.5);
                % else
                %     app.Rc = 0;
                %     app.D = 0;
                %     app.Rc_fit = [];
                % end
                % legend(app.MSDAxes,{'MSD included in Fit', 'MSD excluded from Fit','Power Law Fit','Confined Diffusion Fit'});
                % xlabel(app.MSDAxes,'Lag Time (s)')
                % ylabel(app.MSDAxes,'MSD (\mum^2)');
                % app.MSDAxes.XLim = [0, 1.1*app.curFitTrange(2)];
                % app.MSDAxes.YLim = ylims;
                % 
                % %update the fit results
                % app.TotalTracksEditField.Value = length(app.Tracks);
                % app.RcnmEditField.Value = app.Rc(1).*1000;
                % app.aEditField.Value = app.alpha(1);
                % app.Dum2sEditField.Value = app.D(1);

                close(d);
                drawnow;

%                 else%This is for the call from the Further Analysis -> Merge and analyze MSD in QuantiTrack
%                     %parse the inputs
%                     app.Tracks = [];
%                     app.minTrkLen = varargin{2};
%                     app.frameTime = varargin{3};
%                     app.fileName = varargin{4};
% 
% %                     ask the user to select files to analyze
%                     [files,path_in] = uigetfile('*.mat','Select Tracking Files or Table to Analyze',"MultiSelect","on");
%                     app.fileName = fullfile(path_in,'multfile.tif');
%                     %make sure the files variable is a cell array
%                     if ~iscell(files)
%                         files_tmp{1} = files;
%                         files = files_tmp;
%                     end
%                     if files{1} == 0
%                         return
%                     end
% 
% 
%                     app.nTracks = 0;
%                     app.nTrackPts = 0;
%                     app.nROIs = 0;
% 
% 
%                     d = uiprogressdlg(app.QuantiTrackMSDAnalysisUIFigure,'Title','Generating Histogram Data','Indeterminate','on');
%                     drawnow
%                     binCents_t = min(app.angles(:,2)):binWidth_t:max(app.angles(:,2));
%                     binCents_d = min(app.angles(:,3)):binWidth_d:max(app.angles(:,3));
% 
%                     [app.AC_t, app.AC_d] = calcSpaceTimeAnisotropy(app.angles,binCents_t,binCents_d,app.bootIter);
%                     close(d)
%                     drawnow
% 
%                     %Update the histogram controls
%                     app.MinTimesSpinner.Value = app.AC_t(1,1);
%                     app.MaxTimesSpinner.Value = app.AC_t(end,1);
%                     app.NumberofTimeBinsSpinner.Value = app.AC_t(2,1) - app.AC_t(1,1);
% 
%                     app.MinDisplacementnmSpinner.Value = 1000*app.AC_d(1,1);
%                     app.MaxDisplacementnmSpinner.Value = 1000*app.AC_d(end,1);
%                     app.NumberofDispBinsSpinner.Value = app.AC_d(2,1) - app.AC_d(1,1);
% 
%                     %Plot the histograms
%                     errorbar(app.TimeAxes,app.AC_t(:,1),app.AC_t(:,2),app.AC_t(:,3),'LineWidth',1.5);
%                     xlabel(app.TimeAxes,'Time (s)');
%                     ylabel(app.TimeAxes,'Anisotropy (log_2(0/180))');
% 
%                     errorbar(app.DistAxes,app.AC_d(:,1)*1000,app.AC_d(:,2),app.AC_d(:,3), 'LineWidth',1.5);
%                     xlabel(app.DistAxes,'Displacement (nm)');
%                     ylabel(app.DistAxes,'Anisotropy (log_2(0/180))');
% 
%                     app.pax_in = polaraxes(app.QuantiTrackAngleAnalysisUIFigure);
%                     set(app.pax_in,'Units','pixels');
%                     set(app.pax_in,'Position',[35 216 243 243]);
% 
%                     app.angles1frame = app.angles(app.angles(:,2) == app.frameTime,:);
%                     polarhistogram(app.pax_in,app.angles1frame(:,1),36,'Normalization','Probability');
% 
%                     %Update the metrics
% 
%                     app.totalTracksText.Text = ['Total Tracks: ', num2str(app.nTracks)];
%                     app.totalParticlesText.Text = ['Total Track points: ', num2str(app.nTrackPts)];
% 
%                     app.totalROIsText.Text = ['Total ROIs: ', num2str(app.nROIs)];
%                     app.minTrackLenText.Text = ['Min. Track Length: ', num2str(app.minTrkLen)];

                
            else
                app.CopyMSDdatatoWorkspaceButton.Enable = 'off';
                app.SaveFigureButton.Enable = 'off';
                app.SaveFigureButton.Enable = 'off';
                app.SaveAngleAnalysisMenu.Enable = 'off';
                
            end
        end

        % Selection changed function: XScaleButtonGroup
        function XScaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.XScaleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Log')
                app.MSDAxes.XScale = 'log';
            else
                app.MSDAxes.XScale = 'linear';
            end
            drawnow;

        end

        % Selection changed function: YScaleButtonGroup
        function YScaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.YScaleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Log')
                app.MSDAxes.YScale = 'log';
            else
                app.MSDAxes.YScale = 'linear';
            end
            drawnow;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            close(app.QuantiTrackMSDAnalysisUIFigure);
        end

        % Button pushed function: CopyMSDdatatoWorkspaceButton
        function CopyMSDdatatoWorkspaceButtonPushed(app, event)
            % num2clip(app.MMSD);

            % ===== Begin Edited KW 01/30/26 ========= %
            MSD_array = array2table(app.MMSD,'VariableNames',...
                {'Lag Time','MSD', 'Std. Dev.','Neff', 'N'});

            % ===== End Edited KW 01/30/26 ========= %
            assignin('base','MSD_out',MSD_array);

            msgbox('MSD data copied to the workspace as variable MSD_out')

        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            
            
            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .5*scrsz(3) .4*scrsz(4)]);
            p1 = errorbar(app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),1),app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),2),...
                app.MMSD(app.curFitIndRange(1):app.curFitIndRange(2),3),'o','Color',[0, 0, 0],'MarkerEdgeColor',[0, 0, 0] ,'MarkerFaceColor',[0, 0, 0]);
            hold(gca,'on');
            p2 = errorbar(app.MMSD(app.curFitIndRange(2)+1:end,1),app.MMSD(app.curFitIndRange(2)+1:end,2),app.MMSD(app.curFitIndRange(2)+1:end,3),...
                'o','Color',[0.65, 0.65, 0.65],'MarkerEdgeColor',[0.65, 0.65, 0.65],'MarkerFaceColor',[0.65, 0.65, 0.65]);
            % errorbar(app.MMSD(1:app.firstNonfitPt-1,1),app.MMSD(1:app.firstNonfitPt-1,2),app.MMSD(1:app.firstNonfitPt-1,3),'bo');
            % hold(gca,'on');
            % errorbar(app.MMSD(app.firstNonfitPt:end,1),app.MMSD(app.firstNonfitPt:end,2),app.MMSD(app.firstNonfitPt:end,3),'ro');
            legend_Str = {'MSD included in Fit', 'MSD excluded from Fit'};
            legend_subset = [p1 p2];
            ylims = get(gca,'YLim');

            if app.firstNonfitPt > 4
                if app.PowerLawCheckBox.Value == 1
                    p3 = plot(app.Pwr_Fit(:,1),app.Pwr_Fit(:,2),'b','LineWidth',1.5);
                    legend_Str = [legend_Str, 'Power Law Fit'];
                    legend_subset = [legend_subset, p3];
                end
                
                if app.ConfinedDiffusionCheckBox.Value == 1
                    p4 = plot(app.Rc_fit(:,1),app.Rc_fit(:,2),'r','LineWidth',1.5);
                    legend_Str = [legend_Str, 'Confined Diffusion Fit'];
                    legend_subset = [legend_subset, p4];
                end

            end

            xlabel('Lag Time (s)');
            ylabel('MSD (\mum^2)');
            set(gca,'XLim',[0, 1.1*app.frameTime*app.curFitIndRange(2)]);
            set(gca,'YLim',ylims);

            legend(legend_subset,legend_Str,'Location','SouthEast');

            selectedButton = app.XScaleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Log')
                set(gca,'XScale','log');
            else
                set(gca,'XScale','linear');
            end

            selectedButton = app.YScaleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Log')
                set(gca,'YScale','log');
            else
                set(gca,'YScale','linear');
            end
            % Save the figures
            DefaultName = app.fileName;
            SearchStr = '(.*)\.\w*';
            DefaultName = regexprep(DefaultName, SearchStr, '$1_MSD');
            FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
            [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);

            if FileNameOut ~= 0
                saveas(h1, [PathNameOut,FileNameOut]);
            end


        end

        % Value changed function: ConfinedDiffusionCheckBox
        function ConfinedDiffusionCheckBoxValueChanged(app, event)
           
            updateMSDPlot(app);


        end

        % Value changed function: PowerLawCheckBox
        function PowerLawCheckBoxValueChanged(app, event)
            updateMSDPlot(app);
            
        end

        % Value changed function: MinimumTimeCDSpinner
        function MinimumTimeCDSpinnerValueChanged(app, event)
            valueMin = round(app.MinimumTimeCDSpinner.Value/app.frameTime).*app.frameTime;
            app.MinimumTimeCDSpinner.Value = valueMin;
            valueMax = app.MaximumTimeCDSpinner.Value;

            if valueMin ~= app.curFitIndRange(1) || valueMax ~= app.curFitIndRange(2)
                app.ReFitButton.Enable = "on";
            else
                app.ReFitButton.Enable = "off";
            end
            
        end

        % Value changed function: MaximumTimeCDSpinner
        function MaximumTimeCDSpinnerValueChanged(app, event)
            valueMin = app.MinimumTimeCDSpinner.Value;
            valueMax = round(app.MaximumTimeCDSpinner.Value/app.frameTime).*app.frameTime;
            app.MaximumTimeCDSpinner.Value = valueMax;

            if valueMin ~= app.curFitIndRange(1) || valueMax ~= app.curFitIndRange(2)
                app.ReFitButton.Enable = "on";
            else
                app.ReFitButton.Enable = "off";
            end
            
        end

        % Button pushed function: ReFitButton
        function ReFitButtonPushed(app, event)
            updateMSDPlot(app);
        end

        % Button pushed function: RestoreDefTimeRangeButton
        function RestoreDefTimeRangeButtonPushed(app, event)
            app.MinimumTimeCDSpinner.Value = app.frameTime;
            app.MaximumTimeCDSpinner.Value = (app.firstNonfitPt - 1)*app.frameTime;
            app.ReFitButton.Enable = "on";
            app.RestoreDefTimeRangeButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackMSDAnalysisUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackMSDAnalysisUIFigure.Name = 'QuantiTrack - MSD Analysis';
            app.MSDAxes = uiaxes(app.QuantiTrackMSDAnalysisUIFigure);
            zlabel(app.MSDAxes, 'Z')
            app.CopyMSDdatatoWorkspaceButton = uibutton(app.QuantiTrackMSDAnalysisUIFigure, 'push');
            app.CopyMSDdatatoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CopyMSDdatatoWorkspaceButtonPushed, true);
            app.CopyMSDdatatoWorkspaceButton.Text = 'Copy MSD data to Workspace';
            app.SaveFigureButton = uibutton(app.QuantiTrackMSDAnalysisUIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.XScaleButtonGroup = uibuttongroup(app.QuantiTrackMSDAnalysisUIFigure);
            app.XScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XScaleButtonGroupSelectionChanged, true);
            app.XScaleButtonGroup.Title = 'X Scale';
            app.LinearButton = uiradiobutton(app.XScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogButton = uiradiobutton(app.XScaleButtonGroup);
            app.LogButton.Text = 'Log';
            app.YScaleButtonGroup = uibuttongroup(app.QuantiTrackMSDAnalysisUIFigure);
            app.YScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YScaleButtonGroupSelectionChanged, true);
            app.YScaleButtonGroup.Title = 'Y Scale';
            app.LinearButton_2 = uiradiobutton(app.YScaleButtonGroup);
            app.LinearButton_2.Text = 'Linear';
            app.LinearButton_2.Value = true;
            app.LogButton_2 = uiradiobutton(app.YScaleButtonGroup);
            app.LogButton_2.Text = 'Log';
            app.DoneButton = uibutton(app.QuantiTrackMSDAnalysisUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'DONE';
            app.FittingResultPanel = uipanel(app.QuantiTrackMSDAnalysisUIFigure);
            app.FittingResultPanel.Title = 'Fitting Result ';
            app.sigma_locnmEditFieldLabel = uilabel(app.FittingResultPanel);
            app.sigma_locnmEditFieldLabel.Interpreter = 'tex';
            app.sigma_locnmEditFieldLabel.Text = '\sigma_{loc} (nm)';
            app.sigma_locnmEditField = uieditfield(app.FittingResultPanel, 'numeric');
            app.sigma_locnmEditField.Editable = 'off';
            app.alphaEditFieldLabel = uilabel(app.FittingResultPanel);
            app.alphaEditFieldLabel.Interpreter = 'tex';
            app.alphaEditFieldLabel.Text = '\alpha';
            app.aEditField = uieditfield(app.FittingResultPanel, 'numeric');
            app.aEditField.Editable = 'off';
            app.R_cnmEditFieldLabel = uilabel(app.FittingResultPanel);
            app.R_cnmEditFieldLabel.Interpreter = 'tex';
            app.R_cnmEditFieldLabel.Text = 'R_c (nm)';
            app.RcnmEditField = uieditfield(app.FittingResultPanel, 'numeric');
            app.RcnmEditField.Editable = 'off';
            app.TotalTracksEditField_2Label = uilabel(app.FittingResultPanel);
            app.TotalTracksEditField_2Label.Interpreter = 'tex';
            app.TotalTracksEditField_2Label.Text = 'Total Tracks';
            app.TotalTracksEditField = uieditfield(app.FittingResultPanel, 'numeric');
            app.TotalTracksEditField.ValueDisplayFormat = '%.0f';
            app.TotalTracksEditField.Editable = 'off';
            app.Dmum2sEditFieldLabel = uilabel(app.FittingResultPanel);
            app.Dmum2sEditFieldLabel.Interpreter = 'tex';
            app.Dmum2sEditFieldLabel.Text = 'D (\mum^2/s)';
            app.Dum2sEditField = uieditfield(app.FittingResultPanel, 'numeric');
            app.Dum2sEditField.Editable = 'off';
            app.ConfinedDiffusionFitR_c21exp4DtR_c2PowerLawFitMSDtAtalphaLabel = uilabel(app.QuantiTrackMSDAnalysisUIFigure);
            app.ConfinedDiffusionFitR_c21exp4DtR_c2PowerLawFitMSDtAtalphaLabel.Interpreter = 'tex';
            app.ConfinedDiffusionFitR_c21exp4DtR_c2PowerLawFitMSDtAtalphaLabel.Text = {'Confined Diffusion Fit: MSD(t) = R_c^2 (1 - exp(-4Dt/R_c^2 )'; 'Power Law Fit: MSD(t) = Ct^{\alpha} + 4\sigma_{loc}^2'};
            app.FitstoDisplayButtonGroup = uibuttongroup(app.QuantiTrackMSDAnalysisUIFigure);
            app.FitstoDisplayButtonGroup.Title = 'Fits to Display';
            app.ConfinedDiffusionCheckBox = uicheckbox(app.FitstoDisplayButtonGroup);
            app.ConfinedDiffusionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfinedDiffusionCheckBoxValueChanged, true);
            app.ConfinedDiffusionCheckBox.Text = 'Confined Diffusion';
            app.ConfinedDiffusionCheckBox.Value = true;
            app.PowerLawCheckBox = uicheckbox(app.FitstoDisplayButtonGroup);
            app.PowerLawCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerLawCheckBoxValueChanged, true);
            app.PowerLawCheckBox.Text = 'Power Law';
            app.TimeRangeforFittingButtonGroup = uibuttongroup(app.QuantiTrackMSDAnalysisUIFigure);
            app.TimeRangeforFittingButtonGroup.Title = 'Time Range for Fitting';
            app.MinimumSpinnerLabel = uilabel(app.TimeRangeforFittingButtonGroup);
            app.MinimumSpinnerLabel.Text = 'Minimum';
            app.MinimumTimeCDSpinner = uispinner(app.TimeRangeforFittingButtonGroup);
            app.MinimumTimeCDSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumTimeCDSpinnerValueChanged, true);
            app.MaximumSpinnerLabel = uilabel(app.TimeRangeforFittingButtonGroup);
            app.MaximumSpinnerLabel.Text = 'Maximum';
            app.MaximumTimeCDSpinner = uispinner(app.TimeRangeforFittingButtonGroup);
            app.MaximumTimeCDSpinner.ValueChangedFcn = createCallbackFcn(app, @MaximumTimeCDSpinnerValueChanged, true);
            app.secLabel = uilabel(app.TimeRangeforFittingButtonGroup);
            app.secLabel.Text = 'sec';
            app.secLabel_2 = uilabel(app.TimeRangeforFittingButtonGroup);
            app.secLabel_2.Text = 'sec';
            app.ReFitButton = uibutton(app.QuantiTrackMSDAnalysisUIFigure, 'push');
            app.ReFitButton.ButtonPushedFcn = createCallbackFcn(app, @ReFitButtonPushed, true);
            app.ReFitButton.Enable = 'off';
            app.ReFitButton.Text = 'Re-fit MSD';
            app.RestoreDefTimeRangeButton = uibutton(app.QuantiTrackMSDAnalysisUIFigure, 'push');
            app.RestoreDefTimeRangeButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefTimeRangeButtonPushed, true);
            app.RestoreDefTimeRangeButton.Enable = 'off';
            app.RestoreDefTimeRangeButton.Text = 'Restore Default Time Range';
            % Show the figure after all components are created
            app.QuantiTrackMSDAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSD_Analysis_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackMSDAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackMSDAnalysisUIFigure)
        end
    end
end