classdef SpotOn_Parameters_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackSpotOnUIFigure       matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadtrackTableMenu              matlab.ui.container.Menu
        BootstrapSamplesSpinner         matlab.ui.control.Spinner
        BootstrapSamplesSpinnerLabel    matlab.ui.control.Label
        UseParallelprocessingCheckBox   matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        RunSpotOnButton                 matlab.ui.control.Button
        AnalysisParametersPanel         matlab.ui.container.Panel
        GeneratePlotsCheckBox           matlab.ui.control.CheckBox
        SavePlotsCheckBox               matlab.ui.control.CheckBox
        MaxJumptoPlotumSpinner          matlab.ui.control.Spinner
        MaxJumptoPlotumSpinnerLabel     matlab.ui.control.Label
        MaxJumptoAnalyzeumSpinner       matlab.ui.control.Spinner
        MaxJumptoAnalyzeumSpinnerLabel  matlab.ui.control.Label
        TimepointstouseSpinner          matlab.ui.control.Spinner
        TimepointstouseSpinnerLabel     matlab.ui.control.Label
        UseEntireTracksCheckBox         matlab.ui.control.CheckBox
        JumpBinWidthumSpinner           matlab.ui.control.Spinner
        JumpBinWidthumSpinnerLabel      matlab.ui.control.Label
        TimepointstoAnalyzeSpinner      matlab.ui.control.Spinner
        TimepointstoAnalyzeSpinnerLabel  matlab.ui.control.Label
        GapstoCloseSpinner              matlab.ui.control.Spinner
        GapstoCloseSpinnerLabel         matlab.ui.control.Label
        DepthofFocusumSpinner           matlab.ui.control.Spinner
        DepthofFocusumSpinnerLabel      matlab.ui.control.Label
        FrameTimemsSpinner              matlab.ui.control.Spinner
        FrameTimemsSpinnerLabel         matlab.ui.control.Label
        ModelPanel                      matlab.ui.container.Panel
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        UseWeightsCheckBox              matlab.ui.control.CheckBox
        FitIterationsSpinner            matlab.ui.control.Spinner
        FitIterationsSpinnerLabel       matlab.ui.control.Label
        MaxLDfree_3                     matlab.ui.control.Spinner
        D_Boundrangeum2sSpinner         matlab.ui.control.Spinner
        D_Boundrangeum2sSpinnerLabel    matlab.ui.control.Label
        MaxLDfree_2                     matlab.ui.control.Spinner
        D_Free2rangeum2sSpinner         matlab.ui.control.Spinner
        D_Free2rangeum2sSpinnerLabel    matlab.ui.control.Label
        MaxLDfree                       matlab.ui.control.Spinner
        D_Freerangeum2sSpinner          matlab.ui.control.Spinner
        D_Freerangeum2sSpinnerLabel     matlab.ui.control.Label
        ModelComplexityButtonGroup      matlab.ui.container.ButtonGroup
        StateButton_2                   matlab.ui.control.RadioButton
        StateButton                     matlab.ui.control.RadioButton
        FitsinglecellsCheckBox          matlab.ui.control.CheckBox
        ModeltofitDropDown              matlab.ui.control.DropDown
        ModeltofitDropDownLabel         matlab.ui.control.Label
        LocalizationErrorPanel          matlab.ui.container.Panel
        MaxLabel_2                      matlab.ui.control.Label
        MinLabel_2                      matlab.ui.control.Label
        LocalizationErrorumSpinner      matlab.ui.control.Spinner
        LocalizationErrorumSpinnerLabel  matlab.ui.control.Label
        LocalizationErrorRangeumSpinner  matlab.ui.control.Spinner
        LocalizationErrorRangeumSpinnerLabel  matlab.ui.control.Label
        MaxLocError                     matlab.ui.control.Spinner
        FitLocalizationErrorCheckBox    matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        PathName % path to the file being analyzed
        FileName
        defFrameTime;
        useParallel
        OrigSpotOnTable % Description
        origTrackTable % Description
        Tracks % Description
        NParticles % Description
        NParticlesTracked % Description
        condChosen % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.useParallel = varargin{3};
            if app.useParallel == 1
                
                app.UseParallelprocessingCheckBox.Value = 1;
            else
                
                app.UseParallelprocessingCheckBox.Value = 0;

            end
            if length(varargin) > 3
                pos_fig = varargin{4};
                ChangeAppPosition(app.QuantiTrackSpotOnUIFigure,pos_fig);

                drawnow;
            end
            if length(varargin) > 1 && ~isempty(varargin{2})
                app.PathName = varargin{1};
                app.FileName = varargin{2};
                
                IN = load(fullfile(app.PathName,app.FileName));
                
                if isfield(IN,'trackTable')
                    app.origTrackTable = IN.trackTable;
    
                    [app.Tracks, app.NParticles, ROIChoice,~,app.NParticlesTracked,~,~,~,~,~,~,app.condChosen] = compileTracksFromTables(...
                        IN.trackTable,0,...
                        app.QuantiTrackSpotOnUIFigure,...
                        'Tracking');
                    nMovies = length(app.Tracks);
    
                    app.defFrameTime = 1000.*IN.trackTable.interval(1);
                    app.FrameTimemsSpinner.Value = app.defFrameTime;
                    trackedPar = struct;
                    trkInd = 1;
                    for j = 1:nMovies
                        curIDs = unique(app.Tracks{j}(:,4));
                        for k = 1:length(curIDs)
                            curTrks = app.Tracks{j};
                            
                            trackedPar(trkInd).xy = curTrks(curTrks(:,4) == curIDs(k),1:2);
                            trackedPar(trkInd).Frame = curTrks(curTrks(:,4) == curIDs(k),3);
                            trackedPar(trkInd).TimeStamp = app.defFrameTime.*curTrks(curTrks(:,4) == curIDs(k),3);
                            trkInd = trkInd + 1;
                        end
                    end
                    trackedPar = {trackedPar};
                    cell_protein = IN.trackTable.cell_protein{1};
                    condition = app.condChosen;
                    pixelSize = IN.trackTable.pixelSize(1);
                    exposure = IN.trackTable.exposure(1);
                    interval = app.defFrameTime;
                    N_cells = length(app.Tracks);
                    N_tracks = trkInd - 1;
                    N_detected_particles = 0;
                    N_tracked_particles = 0;
    
                    
                    for k = 1:nMovies
                        N_detected_particles = N_detected_particles + sum(app.NParticles{k}(:,2));
                        N_tracked_particles = N_tracked_particles + sum(app.NParticlesTracked{k}(:,2));
                    end
                    C = {cell_protein, condition, pixelSize, exposure, interval, ...
                        N_cells, N_tracks, N_detected_particles, N_tracked_particles,trackedPar};
    
                    spotOnTable = cell2table(C,'VariableNames', ["cell_protein", ...
                        "condition", "pixelSize", "exposure", "interval", ...
                        "N_cells", "N_tracks", "N_detected_particles", ...
                        "N_tracked_particles", "trackedPar"]);

                    app.OrigSpotOnTable = spotOnTable;


                    figname = app.QuantiTrackSpotOnUIFigure.Name;
                    figname = [figname ' - ' app.FileName,' ',app.condChosen, ' ', ROIChoice{1}];
                    app.QuantiTrackSpotOnUIFigure.Name = figname;
                end
            end


        end

        % Value changed function: FitLocalizationErrorCheckBox
        function FitLocalizationErrorCheckBoxValueChanged(app, event)
            value = app.FitLocalizationErrorCheckBox.Value;
            if value == 1
                app.LocalizationErrorRangeumSpinner.Enable = "on";
                app.MaxLocError.Enable = "on";
                app.LocalizationErrorumSpinner.Enable = "off";

            else
                app.LocalizationErrorRangeumSpinner.Enable = "off";
                app.MaxLocError.Enable = "off";
                app.LocalizationErrorumSpinner.Enable = "on";
            end

        end

        % Value changed function: UseEntireTracksCheckBox
        function UseEntireTracksCheckBoxValueChanged(app, event)
            value = app.UseEntireTracksCheckBox.Value;
            if value == 1
                app.TimepointstoAnalyzeSpinner.Enable = "off";
            else
                app.TimepointstoAnalyzeSpinner.Enable = "on";
            end
        end

        % Selection changed function: ModelComplexityButtonGroup
        function ModelComplexityButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelComplexityButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'3 State')
                app.D_Free2rangeum2sSpinner.Visible = "on";
                app.D_Free2rangeum2sSpinnerLabel.Visible = "on";
                app.MaxLDfree_2.Visible = "on";
            else
                app.D_Free2rangeum2sSpinner.Visible = "off";
                app.D_Free2rangeum2sSpinnerLabel.Visible = "off";
                app.MaxLDfree_2.Visible = "off";
            end
        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.QuantiTrackSpotOnUIFigure);
            
        end

        % Button pushed function: RunSpotOnButton
        function RunSpotOnButtonPushed(app, event)
            selectedModel = app.ModelComplexityButtonGroup.SelectedObject;
            selectedFit = app.ModeltofitDropDown.Value;
            if strcmp(selectedFit,'PDF')
                ModelFit = 1;
            else
                ModelFit = 2;
            end
            if strcmp(selectedModel.Text,'3 State')
                NumberOfStates = 3;
            else
                NumberOfStates = 2;
            end
            Params = struct('TimeGap',app.FrameTimemsSpinner.Value,...
                'dZ',app.DepthofFocusumSpinner.Value,...
                'GapsAllowed',app.GapstoCloseSpinner.Value,...
                'TimePoints',app.TimepointstoAnalyzeSpinner.Value,...
                'BinWidth',app.JumpBinWidthumSpinner.Value, ...
                'UseEntireTraj',app.UseEntireTracksCheckBox.Value, ...
                'DoPlots',app.GeneratePlotsCheckBox.Value, ...
                'UseWeights',app.UseWeightsCheckBox.Value, ...
                'JumpsToConsider',app.TimepointstouseSpinner.Value,...
                'MaxJumpPlotPDF',app.MaxJumptoAnalyzeumSpinner.Value,...
                'MaxJumpPlotCDF',app.MaxJumptoAnalyzeumSpinner.Value,...
                'MaxJump',app.MaxJumptoPlotumSpinner.Value,...
                'SavePlot',app.SavePlotsCheckBox.Value,...
                'ModelFit',ModelFit,...
                'DoSingleCellFit',app.FitsinglecellsCheckBox.Value,...
                'FitIterations',app.FitIterationsSpinner.Value,...
                'FitLocError',app.FitLocalizationErrorCheckBox.Value,...
                'FitLocErrorRange',[app.LocalizationErrorRangeumSpinner.Value, app.MaxLocError.Value],...
                'LocError',app.LocalizationErrorumSpinner.Value,...
                'NumberOfStates',NumberOfStates,...
                'D_Free_2State',[app.D_Freerangeum2sSpinner.Value, app.MaxLDfree.Value],...
                'D_Bound_2State',[app.D_Boundrangeum2sSpinner.Value, app.MaxLDfree_3.Value],...
                'D_Free1_3State',[app.D_Freerangeum2sSpinner.Value, app.MaxLDfree.Value],...
                'D_Free2_3State',[app.D_Free2rangeum2sSpinner.Value, app.MaxLDfree_2.Value],...
                'D_Bound_3State',[app.D_Boundrangeum2sSpinner.Value, app.MaxLDfree_3.Value]);

            
            curTime = datetime('now','TimeZone','local','Format','yyyy-MM-dd''_T''HHmm');
            save_dir = fullfile(app.PathName,['Spot-On_analysis_',char(curTime)]);
            spoton_mat_dir = fullfile(save_dir,'Spot-On_mat_files');

            %Create the folders where things will be saved
            mkdir(save_dir);
            mkdir(spoton_mat_dir);
            
            %Save the full dataset
            trackedPar = app.OrigSpotOnTable.trackedPar{1};
            
            xyt = cell(1,length(trackedPar));
            TrkInd = 1;
            d = uiprogressdlg(app.QuantiTrackSpotOnUIFigure,'Title','Spot-On',...
                'Message','Saving data in Spot-On format','Value',0);%,'Cancelable','on');
            for i = 1:length(app.Tracks)
                %get the unique track IDs
                TrkIDs_present = unique(app.Tracks{i}(:,4));
                for j = 1:length(TrkIDs_present)
                    % if d.CancelRequested
                    %     curDir = pwd;
                    %     cd(spoton_mat_dir);
                    %     files_created = dir;
                    %     for k = 1:length(files_created)
                    %         if ~files_created(k).isdir
                    %             delete(files_created(k).name);
                    %         end
                    %     end
                    %     cd(curDir);
                    %     rmdir(spoton_mat_dir);
                    %     rmdir(save_dir);
                    %     break
                    % end
                    curTrk = app.Tracks{i}(app.Tracks{i}(:,4) == TrkIDs_present(j),1:3);
                    xyt{TrkInd} = curTrk;
                    TrkInd = TrkInd + 1;
                end
                d.Value = i./length(app.Tracks);
            end
            trackTable = app.origTrackTable;
            

            d = uiprogressdlg(app.QuantiTrackSpotOnUIFigure,'Title','Spot-On',...
                'Message','Saving data in Spot-On format','Indeterminate','on');%,'Cancelable','on');
            for i = 1:height(trackTable)
                % if d.CancelRequested
                %     curDir = pwd;
                %     cd(spoton_mat_dir);
                %     files_created = dir;
                %     for k = 1:length(files_created)
                %         if ~files_created(k).isdir
                %             delete(files_created(k).name);
                %         end
                %     end
                %     cd(curDir);
                %     rmdir(spoton_mat_dir);
                %     rmdir(save_dir);
                %     break
                % end
                trackTable.cell_protein{i} = sprintf('%s_bootstrap_original',trackTable.cell_protein{i});
                
                
            end
            

            spotOnTable = app.OrigSpotOnTable;
            numRows = height(app.origTrackTable);

            spotOnTable.cell_protein{1} = trackTable.cell_protein{1};
            save_file = sprintf('Spot-On_input_%s_%s.mat',trackTable.cell_protein{1},...
                app.condChosen);
             
            save(fullfile(spoton_mat_dir,save_file),'trackedPar','xyt','trackTable','spotOnTable');
            close(d);
            %extract the bootstrapped samples 
            bootSamples = app.BootstrapSamplesSpinner.Value;
            d = uiprogressdlg(app.QuantiTrackSpotOnUIFigure,'Title','Bootstrap','Value',0,...
                'Message','Generating Bootstrap Samples');
            clear trackedPar;
            for i = 1:bootSamples
                bootstrapIndices = randi(numRows, numRows, 1);
                trackTable = app.origTrackTable(bootstrapIndices,:);
                for j=1:height(trackTable)
                    trackTable.cell_protein{j} = sprintf('%s_bootstrap_%d', trackTable.cell_protein{j}, i);
                end
                bootTracks = app.Tracks(bootstrapIndices);
                bootNpart = app.NParticles(bootstrapIndices);
                bootNpartTracked = app.NParticlesTracked(bootstrapIndices);


                trackedPar = struct;
                trkInd = 1;
                for j = 1:numRows
                    curIDs = unique(bootTracks{j}(:,4));
                    for k = 1:length(curIDs)
                        curTrks = bootTracks{j};

                        trackedPar(trkInd).xy = curTrks(curTrks(:,4) == curIDs(k),1:2);
                        trackedPar(trkInd).Frame = curTrks(curTrks(:,4) == curIDs(k),3);
                        trackedPar(trkInd).TimeStamp = app.defFrameTime.*curTrks(curTrks(:,4) == curIDs(k),3);
                        trkInd = trkInd + 1;
                    end
                end
                trackedPar = {trackedPar};
                cell_protein = trackTable.cell_protein{1};
                condition = app.condChosen;
                pixelSize = trackTable.pixelSize(1);
                exposure = trackTable.exposure(1);
                interval = app.defFrameTime;
                N_cells = length(bootTracks);
                N_tracks = trkInd - 1;
                N_detected_particles = 0;
                N_tracked_particles = 0;


                for k = 1:numRows
                    N_detected_particles = N_detected_particles + sum(bootNpart{k}(:,2));
                    N_tracked_particles = N_tracked_particles + sum(bootNpartTracked{k}(:,2));
                end
                C = {cell_protein, condition, pixelSize, exposure, interval, ...
                    N_cells, N_tracks, N_detected_particles, N_tracked_particles,trackedPar};


                spotOnTable = cell2table(C,'VariableNames', ["cell_protein", ...
                    "condition", "pixelSize", "exposure", "interval", ...
                    "N_cells", "N_tracks", "N_detected_particles", ...
                    "N_tracked_particles", "trackedPar"]);
                xyt = cell(1,length(trackedPar));
                TrkInd = 1;
                for j = 1:length(bootTracks)
                    %get the unique track IDs
                    TrkIDs_present = unique(bootTracks{j}(:,4));
                    for k = 1:length(TrkIDs_present)
                        curTrk = bootTracks{j}(bootTracks{j}(:,4) == TrkIDs_present(k),1:3);
                        xyt{TrkInd} = curTrk;
                        TrkInd = TrkInd + 1;
                    end
                end

                save_file = sprintf('Spot-On_input_%s_%s.mat',trackTable.cell_protein{1},...
                    app.condChosen);
                trackedPar = trackedPar{1};
                save(fullfile(spoton_mat_dir,save_file),'trackedPar','xyt','trackTable','spotOnTable');

                d.Value = i/bootSamples;


            end
            close(d);

            summary_table = QTprocessSpotOnFiles(spoton_mat_dir,Params,app.useParallel,app.QuantiTrackSpotOnUIFigure);

            savename    = sprintf('%s_%s', extractBefore(summary_table.cell_protein{1}, '_boo'),...
                summary_table.condition{1});

            save(fullfile(save_dir, sprintf('bootstrap_summary_table_%s.mat', savename)), 'summary_table')
            if app.SavePlotsCheckBox.Value == 1
                movefile([spoton_mat_dir,filesep,'SavedPlots'],[save_dir,filesep,'SavedPlots']);
                % if app.GeneratePlotsCheckBox.Value == 1
                %     close all;
                % end
            end

            
        end

        % Menu selected function: LoadtrackTableMenu
        function LoadtrackTableMenuSelected(app, event)
            [fileName,pathName] = uigetfile('.mat',...
                'Select trackTable file for the analysis with Spot On',...
                trackSaveDir,'MultiSelect','off');
            if fileName ~= 0
                app.PathName = pathName;
                app.FileName = fileName;
                
                IN = load(fullfile(app.PathName,app.FileName));
                
                if isfield(IN,'trackTable')
                    app.origTrackTable = IN.trackTable;
    
                    [app.Tracks, app.NParticles, ROIChoice,~,app.NParticlesTracked,~,~,~,~,~,~,app.condChosen] = compileTracksFromTables(...
                        IN.trackTable,0,...
                        app.QuantiTrackSpotOnUIFigure,...
                        'Tracking');
                    nMovies = length(app.Tracks);
    
                    app.defFrameTime = IN.trackTable.interval(1);
                    app.FrameTimemsSpinner.Value = app.defFrameTime;
                    trackedPar = struct;
                    trkInd = 1;
                    for j = 1:nMovies
                        curIDs = unique(app.Tracks{j}(:,4));
                        for k = 1:length(curIDs)
                            curTrks = app.Tracks{j};
                            
                            trackedPar(trkInd).xy = curTrks(curTrks(:,4) == curIDs(k),1:2);
                            trackedPar(trkInd).Frame = curTrks(curTrks(:,4) == curIDs(k),3);
                            trackedPar(trkInd).TimeStamp = app.defFrameTime.*curTrks(curTrks(:,4) == curIDs(k),3);
                            trkInd = trkInd + 1;
                        end
                    end
                    cell_protein = IN.trackTable.cell_protein{1};
                    condition = app.condChosen;
                    pixelSize = IN.trackTable.pixelSize(1);
                    exposure = IN.trackTable.exposure(1);
                    interval = app.defFrameTime;
                    N_cells = length(app.Tracks);
                    N_tracks = trkInd - 1;
                    N_detected_particles = 0;
                    N_tracked_particles = 0;
    
                    
                    for k = 1:nMovies
                        N_detected_particles = N_detected_particles + sum(app.NParticles{k}(:,2));
                        N_tracked_particles = N_tracked_particles + sum(app.NParticlesTracked{k}(:,2));
                    end
                    C = {cell_protein, condition, pixelSize, exposure, interval, ...
                        N_cells, N_tracks, N_detected_particles, N_tracked_particles,trackedPar};
    
    
                    app.OrigSpotOnTable = cell2table(C,'VariableNames', ["cell_protein", ...
                        "condition", "pixelSize", "exposure", "interval", ...
                        "N_cells", "N_tracks", "N_detected_particles", ...
                        "N_tracked_particles", "trackedPar"]);


                    figname = app.QuantiTrackSpotOnUIFigure.Name;
                    figname = [figname ' - ' app.FileName,' ',app.condChosen, ' ', ROIChoice{1}];
                    app.QuantiTrackSpotOnUIFigure.Name = figname;
                end
            end
        end

        % Value changed function: UseParallelprocessingCheckBox
        function UseParallelprocessingCheckBoxValueChanged(app, event)
            value = app.UseParallelprocessingCheckBox.Value;
            if value == 0
                d = uiprogressdlg(app.QuantiTrackSpotOnUIFigure,'Title','Parallel processing',...
                    'Indeterminate','on','Message','Disconnecting from parallel pool');

                delete(gcp('nocreate'));
                app.useParallel = 0;
                
                close(d);
            else
                d = uiprogressdlg(app.QuantiTrackSpotOnUIFigure,'Title','Parallel processing',...
                    'Indeterminate','on','Message','Connecting to parallel pool');
                parpool;
                app.useParallel = 1;
                
                close(d);

            end
        end

        % Value changed function: GeneratePlotsCheckBox
        function GeneratePlotsCheckBoxValueChanged(app, event)
            value = app.GeneratePlotsCheckBox.Value;
            if value == 0
                app.SavePlotsCheckBox.Value = 0;
                app.SavePlotsCheckBox.Enable = "off";
            else
                app.SavePlotsCheckBox.Enable = "on";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackSpotOnUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackSpotOnUIFigure.Name = 'QuantiTrack-SpotOn';
            app.FileMenu = uimenu(app.QuantiTrackSpotOnUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadtrackTableMenu = uimenu(app.FileMenu);
            app.LoadtrackTableMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadtrackTableMenuSelected, true);
            app.LoadtrackTableMenu.Text = 'Load trackTable';
            app.LocalizationErrorPanel = uipanel(app.QuantiTrackSpotOnUIFigure);
            app.LocalizationErrorPanel.Title = 'Localization Error';
            app.FitLocalizationErrorCheckBox = uicheckbox(app.LocalizationErrorPanel);
            app.FitLocalizationErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @FitLocalizationErrorCheckBoxValueChanged, true);
            app.FitLocalizationErrorCheckBox.Text = 'Fit Localization Error';
            app.FitLocalizationErrorCheckBox.Value = true;
            app.MaxLocError = uispinner(app.LocalizationErrorPanel);
            app.MaxLocError.Step = 0.001;
            app.MaxLocError.Value = 0.075;
            app.LocalizationErrorRangeumSpinnerLabel = uilabel(app.LocalizationErrorPanel);
            app.LocalizationErrorRangeumSpinnerLabel.Text = 'Localization Error Range (um)';
            app.LocalizationErrorRangeumSpinner = uispinner(app.LocalizationErrorPanel);
            app.LocalizationErrorRangeumSpinner.Step = 0.001;
            app.LocalizationErrorRangeumSpinner.Value = 0.01;
            app.LocalizationErrorumSpinnerLabel = uilabel(app.LocalizationErrorPanel);
            app.LocalizationErrorumSpinnerLabel.Enable = 'off';
            app.LocalizationErrorumSpinnerLabel.Text = 'Localization Error (um)';
            app.LocalizationErrorumSpinner = uispinner(app.LocalizationErrorPanel);
            app.LocalizationErrorumSpinner.Step = 0.001;
            app.LocalizationErrorumSpinner.Enable = 'off';
            app.LocalizationErrorumSpinner.Value = 0.035;
            app.MinLabel_2 = uilabel(app.LocalizationErrorPanel);
            app.MinLabel_2.Text = 'Min.';
            app.MaxLabel_2 = uilabel(app.LocalizationErrorPanel);
            app.MaxLabel_2.Text = 'Max.';
            app.ModelPanel = uipanel(app.QuantiTrackSpotOnUIFigure);
            app.ModelPanel.Title = 'Model';
            app.ModeltofitDropDownLabel = uilabel(app.ModelPanel);
            app.ModeltofitDropDownLabel.Text = 'Model to fit';
            app.ModeltofitDropDown = uidropdown(app.ModelPanel);
            app.ModeltofitDropDown.Items = {'PDF', 'CDF'};
            app.ModeltofitDropDown.Value = 'CDF';
            app.FitsinglecellsCheckBox = uicheckbox(app.ModelPanel);
            app.FitsinglecellsCheckBox.Text = 'Fit single cells';
            app.ModelComplexityButtonGroup = uibuttongroup(app.ModelPanel);
            app.ModelComplexityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelComplexityButtonGroupSelectionChanged, true);
            app.ModelComplexityButtonGroup.Title = 'Model Complexity';
            app.StateButton = uiradiobutton(app.ModelComplexityButtonGroup);
            app.StateButton.Text = '2 State';
            app.StateButton.Value = true;
            app.StateButton_2 = uiradiobutton(app.ModelComplexityButtonGroup);
            app.StateButton_2.Text = '3 State';
            app.D_Freerangeum2sSpinnerLabel = uilabel(app.ModelPanel);
            app.D_Freerangeum2sSpinnerLabel.Text = 'D_Free range (um^2/s)';
            app.D_Freerangeum2sSpinner = uispinner(app.ModelPanel);
            app.D_Freerangeum2sSpinner.Step = 0.001;
            app.D_Freerangeum2sSpinner.Value = 0.01;
            app.MaxLDfree = uispinner(app.ModelPanel);
            app.MaxLDfree.Step = 0.001;
            app.MaxLDfree.Value = 75;
            app.D_Free2rangeum2sSpinnerLabel = uilabel(app.ModelPanel);
            app.D_Free2rangeum2sSpinnerLabel.Visible = 'off';
            app.D_Free2rangeum2sSpinnerLabel.Text = 'D_Free2 range (um^2/s)';
            app.D_Free2rangeum2sSpinner = uispinner(app.ModelPanel);
            app.D_Free2rangeum2sSpinner.Step = 0.001;
            app.D_Free2rangeum2sSpinner.Visible = 'off';
            app.D_Free2rangeum2sSpinner.Value = 0.0001;
            app.MaxLDfree_2 = uispinner(app.ModelPanel);
            app.MaxLDfree_2.Step = 0.001;
            app.MaxLDfree_2.Visible = 'off';
            app.MaxLDfree_2.Value = 75;
            app.D_Boundrangeum2sSpinnerLabel = uilabel(app.ModelPanel);
            app.D_Boundrangeum2sSpinnerLabel.Text = 'D_Bound range (um^2/s)';
            app.D_Boundrangeum2sSpinner = uispinner(app.ModelPanel);
            app.D_Boundrangeum2sSpinner.Step = 0.001;
            app.D_Boundrangeum2sSpinner.Value = 1e-05;
            app.MaxLDfree_3 = uispinner(app.ModelPanel);
            app.MaxLDfree_3.Step = 0.001;
            app.MaxLDfree_3.Value = 0.05;
            app.FitIterationsSpinnerLabel = uilabel(app.ModelPanel);
            app.FitIterationsSpinnerLabel.Text = 'Fit Iterations';
            app.FitIterationsSpinner = uispinner(app.ModelPanel);
            app.FitIterationsSpinner.Step = 0.001;
            app.FitIterationsSpinner.Value = 5;
            app.UseWeightsCheckBox = uicheckbox(app.ModelPanel);
            app.UseWeightsCheckBox.Text = 'Use Weights';
            app.UseWeightsCheckBox.Value = true;
            app.MinLabel = uilabel(app.ModelPanel);
            app.MinLabel.Text = 'Min.';
            app.MaxLabel = uilabel(app.ModelPanel);
            app.MaxLabel.Text = 'Max.';
            app.AnalysisParametersPanel = uipanel(app.QuantiTrackSpotOnUIFigure);
            app.AnalysisParametersPanel.Title = 'Analysis Parameters';
            app.FrameTimemsSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.FrameTimemsSpinnerLabel.Text = 'Frame Time (ms)';
            app.FrameTimemsSpinner = uispinner(app.AnalysisParametersPanel);
            app.FrameTimemsSpinner.Value = 12;
            app.DepthofFocusumSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.DepthofFocusumSpinnerLabel.Text = 'Depth of Focus (um)';
            app.DepthofFocusumSpinner = uispinner(app.AnalysisParametersPanel);
            app.DepthofFocusumSpinner.Step = 0.001;
            app.DepthofFocusumSpinner.Value = 0.7;
            app.GapstoCloseSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.GapstoCloseSpinnerLabel.Text = 'Gaps to Close';
            app.GapstoCloseSpinner = uispinner(app.AnalysisParametersPanel);
            app.GapstoCloseSpinner.Value = 1;
            app.TimepointstoAnalyzeSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.TimepointstoAnalyzeSpinnerLabel.Text = 'Time-points to Analyze';
            app.TimepointstoAnalyzeSpinner = uispinner(app.AnalysisParametersPanel);
            app.TimepointstoAnalyzeSpinner.Value = 7;
            app.JumpBinWidthumSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.JumpBinWidthumSpinnerLabel.Text = 'Jump Bin Width (um)';
            app.JumpBinWidthumSpinner = uispinner(app.AnalysisParametersPanel);
            app.JumpBinWidthumSpinner.Step = 0.001;
            app.JumpBinWidthumSpinner.Value = 0.01;
            app.UseEntireTracksCheckBox = uicheckbox(app.AnalysisParametersPanel);
            app.UseEntireTracksCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEntireTracksCheckBoxValueChanged, true);
            app.UseEntireTracksCheckBox.Text = 'Use Entire Tracks';
            app.UseEntireTracksCheckBox.Value = true;
            app.TimepointstouseSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.TimepointstouseSpinnerLabel.Enable = 'off';
            app.TimepointstouseSpinnerLabel.Text = 'Time-points to use ';
            app.TimepointstouseSpinner = uispinner(app.AnalysisParametersPanel);
            app.TimepointstouseSpinner.Enable = 'off';
            app.TimepointstouseSpinner.Value = 5;
            app.MaxJumptoAnalyzeumSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.MaxJumptoAnalyzeumSpinnerLabel.Text = 'Max. Jump to Analyze (um)';
            app.MaxJumptoAnalyzeumSpinner = uispinner(app.AnalysisParametersPanel);
            app.MaxJumptoAnalyzeumSpinner.Step = 0.001;
            app.MaxJumptoAnalyzeumSpinner.Value = 10;
            app.MaxJumptoPlotumSpinnerLabel = uilabel(app.AnalysisParametersPanel);
            app.MaxJumptoPlotumSpinnerLabel.Text = 'Max. Jump to Plot (um)';
            app.MaxJumptoPlotumSpinner = uispinner(app.AnalysisParametersPanel);
            app.MaxJumptoPlotumSpinner.Step = 0.001;
            app.MaxJumptoPlotumSpinner.Value = 2;
            app.SavePlotsCheckBox = uicheckbox(app.AnalysisParametersPanel);
            app.SavePlotsCheckBox.Text = 'Save Plots';
            app.SavePlotsCheckBox.Value = true;
            app.GeneratePlotsCheckBox = uicheckbox(app.AnalysisParametersPanel);
            app.GeneratePlotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeneratePlotsCheckBoxValueChanged, true);
            app.GeneratePlotsCheckBox.Text = 'Generate Plots';
            app.GeneratePlotsCheckBox.Value = true;
            app.RunSpotOnButton = uibutton(app.QuantiTrackSpotOnUIFigure, 'push');
            app.RunSpotOnButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpotOnButtonPushed, true);
            app.RunSpotOnButton.Text = 'Run Spot-On';
            app.CancelButton = uibutton(app.QuantiTrackSpotOnUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UseParallelprocessingCheckBox = uicheckbox(app.QuantiTrackSpotOnUIFigure);
            app.UseParallelprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseParallelprocessingCheckBoxValueChanged, true);
            app.UseParallelprocessingCheckBox.Text = 'Use Parallel processing';
            app.BootstrapSamplesSpinnerLabel = uilabel(app.QuantiTrackSpotOnUIFigure);
            app.BootstrapSamplesSpinnerLabel.Text = 'Bootstrap Samples';
            app.BootstrapSamplesSpinner = uispinner(app.QuantiTrackSpotOnUIFigure);
            app.BootstrapSamplesSpinner.Step = 0.001;
            app.BootstrapSamplesSpinner.Value = 100;
            % Show the figure after all components are created
            app.QuantiTrackSpotOnUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpotOn_Parameters_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackSpotOnUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackSpotOnUIFigure)
        end
    end
end