classdef MergeTrackTables_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackMergeTrackTableUIFigure  matlab.ui.Figure
        MergeTablesButton            matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        BaseDirectoryEditField       matlab.ui.control.EditField
        BaseDirectoryEditFieldLabel  matlab.ui.control.Label
        RemoveButton                 matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        trackTablefilestoaddtoCombinedTableListBox  matlab.ui.control.ListBox
        trackTablefilestoaddtoCombinedTableListBoxLabel  matlab.ui.control.Label
        FolderscontainingtrackTablefilesListBox  matlab.ui.control.ListBox
        FolderscontainingtrackTablefilesListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        base_dir % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                if length(varargin) > 1
                    pos_fig = varargin{2};
                    ChangeAppPosition(app.QuantiTrackMergeTrackTableUIFigure,pos_fig);
                    drawnow;
                end
                app.base_dir = varargin{1};
                app.BaseDirectoryEditField.Value = app.base_dir;
                
                dir_list = dir(fullfile(app.base_dir, '**', 'trackTable*.mat'));
                if ~isempty(dir_list)
                    for i = 1:length(dir_list)
                        filename_tmp{i,:} = fullfile(dir_list(i).folder,dir_list(i).name);
                    end
                    app.FolderscontainingtrackTablefilesListBox.Items = filename_tmp;
                    app.FolderscontainingtrackTablefilesListBox.Value = filename_tmp{1,:};
                    app.AddButton.Enable = 'on';
                end


            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            value = app.FolderscontainingtrackTablefilesListBox.Value;
            %First get the list already added
            folds_tmp = app.trackTablefilestoaddtoCombinedTableListBox.Items;
            
            %add the entries selected
            folds_tmp = [folds_tmp, value];
            app.trackTablefilestoaddtoCombinedTableListBox.Items = folds_tmp;
            
            app.FolderscontainingtrackTablefilesListBox.Value = {};
            folds_tmp = app.FolderscontainingtrackTablefilesListBox.Items;
            for i = 1:length(value)
                if strcmp(value,folds_tmp{:,i})
                    folds_tmp(:,i) = [];
                    continue;
                end
            end
            app.FolderscontainingtrackTablefilesListBox.Items = folds_tmp;
            
            if isempty(app.FolderscontainingtrackTablefilesListBox.Items)
                app.AddButton.Enable = 'off';
            else
                app.FolderscontainingtrackTablefilesListBox.Value = folds_tmp{:,1};
            end
            app.RemoveButton.Enable = 'on';
            app.MergeTablesButton.Enable = 'on';


        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            value = app.trackTablefilestoaddtoCombinedTableListBox.Value;
            %First get the list already added
            folds_tmp = app.FolderscontainingtrackTablefilesListBox.Items;
            
            %add the entries selected
            folds_tmp = [folds_tmp, value];
            app.FolderscontainingtrackTablefilesListBox.Items = folds_tmp;
            
            app.trackTablefilestoaddtoCombinedTableListBox.Value = {};
            folds_tmp = app.trackTablefilestoaddtoCombinedTableListBox.Items;
            for i = 1:length(value)
                if strcmp(value,folds_tmp{:,i})
                    folds_tmp(:,i) = [];
                    continue;
                end
            end
            app.trackTablefilestoaddtoCombinedTableListBox.Items = folds_tmp;
            
            if isempty(app.trackTablefilestoaddtoCombinedTableListBox.Items)
                app.RemoveButton.Enable = 'off';
                app.MergeTablesButton.Enable = 'off';
            else
                app.trackTablefilestoaddtoCombinedTableListBox.Value = folds_tmp{:,1};
            end

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.base_dir = uigetdir(pwd,'Select base directory with track tables');
            if app.base_dir == 0
                return;
            end
            app.BaseDirectoryEditField.Value = app.base_dir;

            dir_list = dir(fullfile(app.base_dir, '**', 'trackTable*.mat'));
            if ~isempty(dir_list)
                for i = 1:length(dir_list)
                    filename_tmp{i,:} = fullfile(dir_list(i).folder,dir_list(i).name);
                end
                app.FolderscontainingtrackTablefilesListBox.Items = filename_tmp;
                app.FolderscontainingtrackTablefilesListBox.Value = filename_tmp{1,:};
                app.AddButton.Enable = 'on';
            end
        end

        % Button pushed function: MergeTablesButton
        function MergeTablesButtonPushed(app, event)
            files2merge = app.trackTablefilestoaddtoCombinedTableListBox.Items;
            
            for i = 1:length(files2merge)
                input       = load(files2merge{i});

                t           = input.trackTable;

                if i==1
                    trackTable  = t;
                else
                    last_cellID     = trackTable.cellID(height(trackTable));
                    last_trackID    = trackTable.trackID{height(trackTable)}(end);
                    last_movieID    = trackTable.movieID(height(trackTable));
                    t.cellID        = t.cellID + last_cellID;
                    t.movieID       = t.movieID + last_movieID;

                    for m=1:height(t)
                        t.trackID{m}    = t.trackID{m} + last_trackID;
                    end

                    trackTable      = [trackTable; t];
                end

            end
            def_fname = sprintf('trackTable_combined_%s.mat', datestr(now, 'yyyy-mm-dd_THHMM'));

            [file_out,path_out] = uiputfile('*.mat','Select Save Location',fullfile(app.base_dir,def_fname));
            if file_out ~= 0
                save(fullfile(path_out,file_out),'trackTable','-v7.3');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackMergeTrackTableUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackMergeTrackTableUIFigure.Name = 'QuantiTrack - Merge Track Table';
            app.FolderscontainingtrackTablefilesListBoxLabel = uilabel(app.QuantiTrackMergeTrackTableUIFigure);
            app.FolderscontainingtrackTablefilesListBoxLabel.Text = 'Folders containing trackTable files';
            app.FolderscontainingtrackTablefilesListBox = uilistbox(app.QuantiTrackMergeTrackTableUIFigure);
            app.FolderscontainingtrackTablefilesListBox.Items = {};
            app.FolderscontainingtrackTablefilesListBox.Multiselect = 'on';
            app.FolderscontainingtrackTablefilesListBox.Value = {};
            app.trackTablefilestoaddtoCombinedTableListBoxLabel = uilabel(app.QuantiTrackMergeTrackTableUIFigure);
            app.trackTablefilestoaddtoCombinedTableListBoxLabel.Text = 'trackTable files to add to Combined Table';
            app.trackTablefilestoaddtoCombinedTableListBox = uilistbox(app.QuantiTrackMergeTrackTableUIFigure);
            app.trackTablefilestoaddtoCombinedTableListBox.Items = {};
            app.trackTablefilestoaddtoCombinedTableListBox.Multiselect = 'on';
            app.trackTablefilestoaddtoCombinedTableListBox.Value = {};
            app.AddButton = uibutton(app.QuantiTrackMergeTrackTableUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.QuantiTrackMergeTrackTableUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove';
            app.BaseDirectoryEditFieldLabel = uilabel(app.QuantiTrackMergeTrackTableUIFigure);
            app.BaseDirectoryEditFieldLabel.Text = 'Base Directory';
            app.BaseDirectoryEditField = uieditfield(app.QuantiTrackMergeTrackTableUIFigure, 'text');
            app.BrowseButton = uibutton(app.QuantiTrackMergeTrackTableUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.MergeTablesButton = uibutton(app.QuantiTrackMergeTrackTableUIFigure, 'push');
            app.MergeTablesButton.ButtonPushedFcn = createCallbackFcn(app, @MergeTablesButtonPushed, true);
            app.MergeTablesButton.Enable = 'off';
            app.MergeTablesButton.Text = 'Merge Tables';
            % Show the figure after all components are created
            app.QuantiTrackMergeTrackTableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MergeTrackTables_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackMergeTrackTableUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackMergeTrackTableUIFigure)
        end
    end
end