classdef summaryTableGen_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackSummaryTableUIFigure  matlab.ui.Figure
        GenerateTableButton          matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        BaseDirectoryEditField       matlab.ui.control.EditField
        BaseDirectoryEditFieldLabel  matlab.ui.control.Label
        RemoveButton                 matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        PostprocessingfilestoaddtoSummaryTableListBox  matlab.ui.control.ListBox
        PostprocessingfilestoaddtoSummaryTableListBoxLabel  matlab.ui.control.Label
        FolderscontainingpostprocessingfilesListBox  matlab.ui.control.ListBox
        FolderscontainingpostprocessingfilesListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        base_dir % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                if length(varargin) > 1
                    pos_fig = varargin{2};
                    ChangeAppPosition(app.QuantiTrackSummaryTableUIFigure,pos_fig);
                    drawnow;
                end
                app.base_dir = varargin{1};
                app.BaseDirectoryEditField.Value = app.base_dir;
                
                dir_list = dir(fullfile(app.base_dir, '**', 'postProcessing*.mat'));
                if ~isempty(dir_list)
                    for i = 1:length(dir_list)
                        folds_tmp{i,:} = dir_list(i).folder;
                    end
                    app.FolderscontainingpostprocessingfilesListBox.Items = folds_tmp;
                    app.FolderscontainingpostprocessingfilesListBox.Value = folds_tmp{1,:};
                    app.AddButton.Enable = 'on';
                end


            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            value = app.FolderscontainingpostprocessingfilesListBox.Value;
            %First get the list already added
            folds_tmp = app.PostprocessingfilestoaddtoSummaryTableListBox.Items;
            
            %add the entries selected
            folds_tmp = [folds_tmp, value];
            app.PostprocessingfilestoaddtoSummaryTableListBox.Items = folds_tmp;
            
            app.FolderscontainingpostprocessingfilesListBox.Value = {};
            folds_tmp = app.FolderscontainingpostprocessingfilesListBox.Items;
            for i = 1:length(value)
                if strcmp(value,folds_tmp{:,i})
                    folds_tmp(:,i) = [];
                    continue;
                end
            end
            app.FolderscontainingpostprocessingfilesListBox.Items = folds_tmp;
            
            if isempty(app.FolderscontainingpostprocessingfilesListBox.Items)
                app.AddButton.Enable = 'off';
            else
                app.FolderscontainingpostprocessingfilesListBox.Value = folds_tmp{:,1};
            end
            app.RemoveButton.Enable = 'on';
            app.GenerateTableButton.Enable = 'on';


        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            value = app.PostprocessingfilestoaddtoSummaryTableListBox.Value;
            %First get the list already added
            folds_tmp = app.FolderscontainingpostprocessingfilesListBox.Items;
            
            %add the entries selected
            folds_tmp = [folds_tmp, value];
            app.FolderscontainingpostprocessingfilesListBox.Items = folds_tmp;
            
            app.PostprocessingfilestoaddtoSummaryTableListBox.Value = {};
            folds_tmp = app.PostprocessingfilestoaddtoSummaryTableListBox.Items;
            for i = 1:length(value)
                if strcmp(value,folds_tmp{:,i})
                    folds_tmp(:,i) = [];
                    continue;
                end
            end
            app.PostprocessingfilestoaddtoSummaryTableListBox.Items = folds_tmp;
            
            if isempty(app.PostprocessingfilestoaddtoSummaryTableListBox.Items)
                app.RemoveButton.Enable = 'off';
                app.GenerateTableButton.Enable = 'off';
            else
                app.PostprocessingfilestoaddtoSummaryTableListBox.Value = folds_tmp{:,1};
            end

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.base_dir = uigetdir(pwd,'Select base directory with pEM results');
            if app.base_dir == 0
                return;
            end
            app.BaseDirectoryEditField.Value = app.base_dir;

            dir_list = dir(fullfile(app.base_dir, '**', 'postProcessing*.mat'));
            if ~isempty(dir_list)
                for i = 1:length(dir_list)
                    folds_tmp{i,:} = dir_list(i).folder;
                end
                app.FolderscontainingpostprocessingfilesListBox.Items = folds_tmp;
                app.FolderscontainingpostprocessingfilesListBox.Value = folds_tmp{1,:};
                app.AddButton.Enable = 'on';
            else
                app.FolderscontainingpostprocessingfilesListBox.Items = [];
                app.FolderscontainingpostprocessingfilesListBox.Value = [];
                app.AddButton.Enable = 'off';

            end
            
        end

        % Button pushed function: GenerateTableButton
        function GenerateTableButtonPushed(app, event)
            folds = app.PostprocessingfilestoaddtoSummaryTableListBox.Items;
            
            for i = 1:length(folds)
                postProc_file = dir(fullfile(folds{i},'postProcessing*.mat'));
                IN = load(fullfile(folds{i},postProc_file(1).name),'meta_analysis');
                %in case 
                if ~ismember('bootstrapCI_alpha',IN.meta_analysis.Properties.VariableNames)
                    bootstrap_ans = questdlg('Do you want to calcluate Confidence Intervals of the Population fractions',...
                        'No Existing CI data','Yes','No','Yes');

                    if strcmp(bootstrap_ans,'No')
                        for j = 1:height(IN.meta_analysis)
                            IN.meta_analysis.bootstrapCI_resamples{j} = 0;
                            IN.meta_analysis.bootstrapCI_alpha{j} = 0;
                            IN.meta_analysis.bootstrapCI_popFracMat{j} = [];
                            IN.meta_analysis.bootstrapCI_ciProps_deltaPP{j} = [];
                        end
                    else
                        prompt = {'Number of resamples used in Bootstrap:'};
                        dlgtitle = 'CI Resampling';
                        dims = [1 70];
                        definput = {'1000'};
                        resampParam = inputdlg(prompt,dlgtitle,dims,definput);
                        if isempty(resampParam)
                            return;
                        end
                        nResamples = str2double(resampParam{1});
                        alpha = 0.95;

                        for j = 1:height(IN.meta_analysis)
                            [popFracMat, ciProps] = QT_confidence_intervals_pEM_fractions(nResamples, alpha, ...
                                IN.meta_analysis.trackID{j}, IN.meta_analysis.splitID{j}, ...
                                IN.meta_analysis.optimalState{j}, IN.meta_analysis.posteriorProb{j}, IN.meta_analysis.deltaPP_Thresh{j}, ...
                                IN.meta_analysis.states_deltaPP{j}, IN.meta_analysis.props_deltaPP{j});
                            IN.meta_analysis.bootstrapCI_resamples{j} = nResamples;
                            IN.meta_analysis.bootstrapCI_alpha{j} = alpha;
                            IN.meta_analysis.bootstrapCI_popFracMat{j} = popFracMat;
                            IN.meta_analysis.bootstrapCI_ciProps_deltaPP{j} = ciProps;
                        end
                    end
                end

                if i == 1
                    summary_table = IN.meta_analysis;
                else
                    summary_table = [summary_table; IN.meta_analysis];
                end

            end
            def_fname = sprintf('combined_summary_table_%s.mat', datestr(now, 'yyyy-mm-dd_THHMM'));

            [file_out,path_out] = uiputfile('*.mat','Select Save Location',fullfile(app.base_dir,def_fname));
            if file_out ~= 0
                save(fullfile(path_out,file_out),'summary_table','-v7.3');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackSummaryTableUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackSummaryTableUIFigure.Name = 'QuantiTrack - SummaryTable';
            app.FolderscontainingpostprocessingfilesListBoxLabel = uilabel(app.QuantiTrackSummaryTableUIFigure);
            app.FolderscontainingpostprocessingfilesListBoxLabel.Text = 'Folders containing post-processing files';
            app.FolderscontainingpostprocessingfilesListBox = uilistbox(app.QuantiTrackSummaryTableUIFigure);
            app.FolderscontainingpostprocessingfilesListBox.Items = {};
            app.FolderscontainingpostprocessingfilesListBox.Multiselect = 'on';
            app.FolderscontainingpostprocessingfilesListBox.Value = {};
            app.PostprocessingfilestoaddtoSummaryTableListBoxLabel = uilabel(app.QuantiTrackSummaryTableUIFigure);
            app.PostprocessingfilestoaddtoSummaryTableListBoxLabel.Text = 'Post-processing files to add to Summary Table';
            app.PostprocessingfilestoaddtoSummaryTableListBox = uilistbox(app.QuantiTrackSummaryTableUIFigure);
            app.PostprocessingfilestoaddtoSummaryTableListBox.Items = {};
            app.PostprocessingfilestoaddtoSummaryTableListBox.Multiselect = 'on';
            app.PostprocessingfilestoaddtoSummaryTableListBox.Value = {};
            app.AddButton = uibutton(app.QuantiTrackSummaryTableUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.QuantiTrackSummaryTableUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove';
            app.BaseDirectoryEditFieldLabel = uilabel(app.QuantiTrackSummaryTableUIFigure);
            app.BaseDirectoryEditFieldLabel.Text = 'Base Directory';
            app.BaseDirectoryEditField = uieditfield(app.QuantiTrackSummaryTableUIFigure, 'text');
            app.BrowseButton = uibutton(app.QuantiTrackSummaryTableUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.GenerateTableButton = uibutton(app.QuantiTrackSummaryTableUIFigure, 'push');
            app.GenerateTableButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTableButtonPushed, true);
            app.GenerateTableButton.Enable = 'off';
            app.GenerateTableButton.Text = 'Generate Table';
            % Show the figure after all components are created
            app.QuantiTrackSummaryTableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = summaryTableGen_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackSummaryTableUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackSummaryTableUIFigure)
        end
    end
end