classdef LoadSingleMovieOMERO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadOMEROMovieUIFigure       matlab.ui.Figure
        PlanesLabel                  matlab.ui.control.Label
        WidthNheightLabel            matlab.ui.control.Label
        FramesLabel                  matlab.ui.control.Label
        ChannelSelectionButtonGroup  matlab.ui.container.ButtonGroup
        Ch4Button                    matlab.ui.control.ToggleButton
        Ch3Button                    matlab.ui.control.ToggleButton
        Ch2Button                    matlab.ui.control.ToggleButton
        Ch1Button                    matlab.ui.control.ToggleButton
        Status                       matlab.ui.control.Label
        PortEditField                matlab.ui.control.NumericEditField
        PortEditFieldLabel           matlab.ui.control.Label
        LoadMovieButton              matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        OMEROServerDropDown          matlab.ui.control.DropDown
        OMEROServerDropDownLabel     matlab.ui.control.Label
        ImagesListBox                matlab.ui.control.ListBox
        ImagesListBoxLabel           matlab.ui.control.Label
        DatasetsListBox              matlab.ui.control.ListBox
        DatasetsListBoxLabel         matlab.ui.control.Label
        ProjectsListBox              matlab.ui.control.ListBox
        ProjectsListBoxLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        OMEROservers % Structure containing the available OMERO servers & their ports
        curOMEROserver %Current sesrver to which we will connect
        curOMEROport  %Port on which to connect to the server
        OMEROuser   %user name to use to connect
        OMEROpwd    %password to connect
        OMEROtimer  %to keep the OMERO connection alive
        ProjectNames    %OMERO Project names available to the user
        ProjectIDs      %OMERO Project IDs available to the user
        DatasetNames    %OMERO Dataset names available in the chosen Project
        DatasetIDs      %OMERO Dataset IDs available in the chosen Project
        ImageNames      %OMERO Image Names available in the chosen Dataset
        ImageIDs      %OMERO Image Ids available in the chosen Dataset
        saveUserPwdFlag %Flag to determine whether to store the username and password
        OMEROsession
        OMEROclient
        curImgID
        ImStack %imstack to return to Tracking GUI
        curChan % Image Channel selected
        ChannelNames % Description
    end
    
    methods (Access = private)
        
        function adjustChannelButtonSizes(app)
            %Get ChannelNames
            T = editChannelNames(app.OMEROsession,app.curImgID);
            %convert to a struct
            ChannelNames_struct = table2struct(T);
            nChans = length(ChannelNames_struct);

            if nChans == 1
                app.Ch1Button.Position = [12, 8, 100, 192];
            elseif nChans == 2
                app.Ch2Button.Position = [12, 8, 100, 90];
                app.Ch1Button.Position = [12,110, 100, 90];
            elseif nChans == 3
                app.Ch3Button.Position = [12, 8, 100, 56];
                app.Ch2Button.Position = [12, 76, 100, 56];
                app.Ch1Button.Position = [12, 144, 100, 56];
            elseif nChans == 4
                app.Ch4Button.Position = [12, 8, 100, 39];
                app.Ch3Button.Position = [12, 59, 100, 39];
                app.Ch2Button.Position = [12, 110, 100, 39];
                app.Ch1Button.Position = [12, 161, 100, 39];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            %Get previously used OMERO servers
            servers_tmp = load('OMEROserverInfo.mat');
            app.OMEROservers = servers_tmp.OMEROserverInfo;
            for i = 1:size(app.OMEROservers,1)
                serverStr{i,:} = app.OMEROservers(i).IP;
            end
            serverStr{size(app.OMEROservers,1)+1,:} = 'Add New...';
            app.OMEROServerDropDown.Items = serverStr;
            app.OMEROServerDropDown.Value = serverStr{1,:};
            app.PortEditField.Value = app.OMEROservers(1).Port;
            if nargin > 1

                app.OMEROuser = varargin{1};
                app.OMEROpwd = varargin{2};
                app.curOMEROserver = varargin{3};

                app.curOMEROport = varargin{4};
                app.OMEROServerDropDown.Value = app.curOMEROserver;
                app.PortEditField.Value = app.curOMEROport;
                app.OMEROclient = loadOmero(app.curOMEROserver,app.curOMEROport);
                try
                    app.OMEROsession = app.OMEROclient.createSession(app.OMEROuser, app.OMEROpwd);
                catch ME
                    if strcmp(ME.identifier,'MATLAB:Java:GenericException')
                        errordlg("Something isn't working with the connection","Error connecting to OMERO server")
                        return
                    end
                end
%                 app.OMEROclient.enableKeepAlive(300);
                OMEROprojects = getProjects(app.OMEROsession);
                for i = 1 : numel(OMEROprojects)
                    prjName_tmp = OMEROprojects(i).getName().getValue();
                    prjNames{i,:} = prjName_tmp.toCharArray;
                    prjIDs{i,:} = OMEROprojects(i).getId().getValue();
                end
                app.ProjectNames = prjNames;
                app.ProjectIDs = prjIDs;
                app.ProjectsListBox.Items = app.ProjectNames;
                app.ProjectsListBox.ItemsData = app.ProjectIDs;
                app.ProjectsListBox.Enable = 'on';
                %Try to go back to the last image loaded
                if ~isempty(getappdata(0,'OMEROProjectID'))
                    app.ProjectsListBox.Value = getappdata(0,'OMEROProjectID');
                    loaded_projects = getProjects(app.OMEROsession, app.ProjectsListBox.Value);
                    project_1 = loaded_projects(1);
                    % Get all the datasets in the Project
                    datasets = project_1.linkedDatasetList;
                    for i = 1:numel(datasets)
                        dataset_1 = datasets.get(i-1);
                        setName_tmp = dataset_1.getName().getValue();
                        setNames{i,:} = setName_tmp.toCharArray;
                        setIDs{i,:} = dataset_1.getId().getValue();
                    end
                    app.DatasetsListBox.Value = {};
                    app.DatasetNames = setNames;
                    app.DatasetIDs = setIDs;
                    app.DatasetsListBox.Items = app.DatasetNames;
                    app.DatasetsListBox.ItemsData = app.DatasetIDs;
                    app.DatasetsListBox.Enable = 'on';

                    %Reset the imaging listbox
                    app.ImagesListBox.Value = {};
                    app.ImagesListBox.Items = {'Choose a Dataset'};
                    
                    app.ImagesListBox.Enable = 'off';
                    %disable the Load Movie Button
                    app.LoadMovieButton.Enable = 0;
                    %Try to go back to the last dataset used
                    if ~isempty(getappdata(0,'OMERODatasetID'))
                        app.DatasetsListBox.Value = getappdata(0,'OMERODatasetID');
                        curSetID = app.DatasetsListBox.Value;
                        dataset_1 = getDatasets(app.OMEROsession, curSetID,true);
            
                        % Retrieve all the images in the datasets as a Java List (index will start at 0)
                        imageList = dataset_1.linkedImageList;
                        % convert it to a Matlab list for convenience
                        images = toMatlabList(imageList);
                        % Iterate through the images
                        if isempty(images)
                            imgNames = {};
                            imgIDs = [];
                        else
                            for i = 1 : numel(images)
                                image = images(i);
                                imgname_tmp = image.getName().getValue(); % image's name
                                imgNames{i,:} = imgname_tmp.toCharArray;
                                imgIDs(i,:) = image.getId().getValue(); % image's id
                            end
                        end
                        app.ImagesListBox.Value = {};
                        app.ImageNames = imgNames;
                        app.ImageIDs = imgIDs;
                        app.ImagesListBox.Items = app.ImageNames;
                        app.ImagesListBox.ItemsData = app.ImageIDs;
                        app.ImagesListBox.Enable = 'on';
                        %disable the Load Movie Button
                        app.LoadMovieButton.Enable = 0;
                        %Try to go back to the last image used
                        if ~isempty(getappdata(0,'OMEROImageID'))
                            app.ImagesListBox.Value = getappdata(0,'OMEROImageID');
                            app.curImgID = app.ImagesListBox.Value;
                            image = getImages(app.OMEROsession,app.curImgID);
                            pixels = image.getPrimaryPixels();
                            sizeC = pixels.getSizeC().getValue();
                            sizeZ = pixels.getSizeZ().getValue();
                            sizeT = pixels.getSizeT().getValue();

                            sizeX = pixels.getSizeX().getValue();
                            sizeY = pixels.getSizeY().getValue();

                            %add the Stats to the labels
                            app.FramesLabel.Visible = 'on';
                            app.WidthNheightLabel.Visible = 'on';
                            app.PlanesLabel.Visible = 'on';

                            app.PlanesLabel.Text = [num2str(sizeZ), ' planes'];
                            app.FramesLabel.Text = [num2str(sizeT), ' time-points'];
                            app.WidthNheightLabel.Text = [num2str(sizeX), ' x ', num2str(sizeY), ' pixels'];
                            app.ChannelSelectionButtonGroup.Enable = 'on';
                            %Get ChannelNames
                            T = editChannelNames(app.OMEROsession,app.curImgID);
                            %convert to a struct
                            ChannelNames_struct = table2struct(T);
                            app.ChannelNames = cell(length(ChannelNames_struct),1);
                            for i = 1:length(ChannelNames_struct)
                                app.ChannelNames{i,:} = ChannelNames_struct(i).Name;
                                if strcmp(app.ChannelNames{i,:},'Default')
                                    app.ChannelNames{i,:} = ['Ch ', num2str(i)];
                                end
                            end
                            app.Ch1Button.Enable = 0;
                            app.Ch1Button.Visible = 0;
                            app.Ch2Button.Enable = 0;
                            app.Ch2Button.Visible = 0;
                            app.Ch3Button.Enable = 0;
                            app.Ch3Button.Visible = 0;
                            app.Ch4Button.Enable = 0;
                            app.Ch4Button.Visible = 0;
                            if sizeC > 0
                                app.Ch1Button.Enable = 1;
                                app.Ch1Button.Visible = 1;
                                app.Ch1Button.Value = 1;
                                app.Ch1Button.Text = app.ChannelNames{1,:};
                                app.curChan = 0;
                                %Enable the Load Movie Button
                                app.LoadMovieButton.Enable = 1;
                            end
                            if sizeC > 1
                                app.Ch2Button.Enable = 1;
                                app.Ch2Button.Visible = 1;
                                app.Ch2Button.Text = app.ChannelNames{2,:};
                            end
                            if sizeC > 2
                                app.Ch3Button.Enable = 1;
                                app.Ch3Button.Visible = 1;
                                app.Ch3Button.Text = app.ChannelNames{3,:};
                            end
                            if sizeC > 3
                                app.Ch4Button.Enable = 1;
                                app.Ch4Button.Visible = 1;
                                app.Ch4Button.Text = app.ChannelNames{4,:};
                            end
                            app.curChan = getappdata(0,'OMEROChannelSelect');
                            if app.curChan == 0
                                app.ChannelSelectionButtonGroup.SelectedObject = app.Ch1Button;
                            elseif app.curChan == 1
                                app.ChannelSelectionButtonGroup.SelectedObject = app.Ch2Button;
                            elseif app.curChan == 2
                                app.ChannelSelectionButtonGroup.SelectedObject = app.Ch3Button;
                            elseif app.curChan == 3
                                app.ChannelSelectionButtonGroup.SelectedObject = app.Ch3Button;
                            end
                            adjustChannelButtonSizes(app);
                            app.LoadMovieButton.Enable = 1;
                        end
                    end
                end
                    
%%
                app.saveUserPwdFlag = 1;
            end
            

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %Start a connection to the OMERO server
            app.curOMEROserver = app.OMEROServerDropDown.Value;
            app.curOMEROport = app.PortEditField.Value;
            app.OMEROclient = loadOmero(app.curOMEROserver,app.curOMEROport);
            %If the username and password haven't been set previously, ask
            %for login credentials
            if isempty(app.OMEROuser)
                app.OMEROuser = usernameDlg;
                app.OMEROpwd = passcodeDlg;
                try
                    app.OMEROsession = app.OMEROclient.createSession(app.OMEROuser, app.OMEROpwd);
                catch ME
                    if strcmp(ME.identifier,'MATLAB:Java:GenericException')
                        errordlg("Something isn't working with the connection","Error connecting to OMERO server")
                        return
                    end
                end
                saveUP_ans = questdlg("Would you like to save this Username & Password for future analysis in this MATLAB session?","Save Credentials","Yes","No","Yes");
                if strcmp(saveUP_ans,"Yes")
                    app.saveUserPwdFlag = 1;
                else
                    app.saveUserPwdFlag = 0;
                end
            else
                try
                    app.OMEROsession = app.OMEROclient.createSession(app.OMEROuser, app.OMEROpwd);
                catch ME
                    if strcmp(ME.identifier,'MATLAB:Java:GenericException')
                        errordlg("Something isn't working with the connection","Error connecting to OMERO server")
                        return
                    end
                end

            end


%             app.OMEROclient.enableKeepAlive(300); 
            OMEROprojects = getProjects(app.OMEROsession);
            for i = 1 : numel(OMEROprojects)
                prjName_tmp = OMEROprojects(i).getName().getValue();
                prjNames{i,:} = prjName_tmp.toCharArray;
                prjIDs{i,:} = OMEROprojects(i).getId().getValue();
            end
            app.ProjectNames = prjNames;
            app.ProjectIDs = prjIDs;
            app.ProjectsListBox.Items = app.ProjectNames;
            app.ProjectsListBox.ItemsData = app.ProjectIDs;
            app.ProjectsListBox.Enable = 'on';
            %Reset the dataset listbox
            app.DatasetsListBox.ItemsData = {};
            app.DatasetsListBox.Items = {'Choose a Project'};
            app.DatasetsListBox.Enable = 'off';

            %Reset the imaging listbox
            
            app.ImagesListBox.ItemsData = {};
            app.ImagesListBox.Items = {'Choose a Dataset'};
            app.ImagesListBox.Enable = 'off';
            %Reset Image Stats labels
            app.FramesLabel.Visible = 'off';
            app.WidthNheightLabel.Visible = 'off';
            app.PlanesLabel.Visible = 'off';


        end

        % Value changed function: ProjectsListBox
        function ProjectsListBoxValueChanged(app, event)
            curProjID = app.ProjectsListBox.Value;
            %Get the project again with loaded images
            loaded_projects = getProjects(app.OMEROsession, curProjID);
            project_1 = loaded_projects(1);
            % Get all the datasets in the Project
            datasets = project_1.linkedDatasetList;
            for i = 1:numel(datasets)
                dataset_1 = datasets.get(i-1);
                setName_tmp = dataset_1.getName().getValue();
                setNames{i,:} = setName_tmp.toCharArray;
                setIDs{i,:} = dataset_1.getId().getValue(); 
            end
            app.DatasetsListBox.Value = {};
            app.DatasetNames = setNames;
            app.DatasetIDs = setIDs;
            app.DatasetsListBox.Items = app.DatasetNames;
            app.DatasetsListBox.ItemsData = app.DatasetIDs;
            app.DatasetsListBox.Enable = 'on';

            %Reset the imaging listbox
            app.ImagesListBox.Value = {};
            app.ImagesListBox.ItemsData = {};
            app.ImagesListBox.Items = {'Choose a Dataset'};
            app.ImagesListBox.Enable = 'off';
            
            %disable the Load Movie Button
            app.LoadMovieButton.Enable = 0;

            %Reset Image Stats labels
            app.FramesLabel.Visible = 'off';
            app.WidthNheightLabel.Visible = 'off';
            app.PlanesLabel.Visible = 'off';
            
        end

        % Value changed function: DatasetsListBox
        function DatasetsListBoxValueChanged(app, event)
            curSetID = app.DatasetsListBox.Value;
            dataset_1 = getDatasets(app.OMEROsession, curSetID,true);
            
            % Retrieve all the images in the datasets as a Java List (index will start at 0)
            imageList = dataset_1.linkedImageList;
            % convert it to a Matlab list for convenience
            images = toMatlabList(imageList);
            % Iterate through the images
            if isempty(images)
                imgNames = {};
                imgIDs = [];
            else
                for i = 1 : numel(images)
                    image = images(i);
                    imgname_tmp = image.getName().getValue(); % image's name
                    imgNames{i,:} = imgname_tmp.toCharArray;
                    imgIDs(i,:) = image.getId().getValue(); % image's id
                end
            end
            app.ImagesListBox.Value = {};
            app.ImageNames = imgNames;
            app.ImageIDs = imgIDs;
            app.ImagesListBox.Items = app.ImageNames;
            app.ImagesListBox.ItemsData = app.ImageIDs;
            app.ImagesListBox.Enable = 'on';
            
            %disable the Load Movie Button
            app.LoadMovieButton.Enable = 0;

            %Reset Image Stats labels
            app.FramesLabel.Visible = 'off';
            app.WidthNheightLabel.Visible = 'off';
            app.PlanesLabel.Visible = 'off';

        end

        % Value changed function: ImagesListBox
        function ImagesListBoxValueChanged(app, event)
            app.curImgID = app.ImagesListBox.Value;
            image = getImages(app.OMEROsession,app.curImgID);
            pixels = image.getPrimaryPixels();
            sizeC = pixels.getSizeC().getValue();
            sizeZ = pixels.getSizeZ().getValue();
            sizeT = pixels.getSizeT().getValue();

            sizeX = pixels.getSizeX().getValue();
            sizeY = pixels.getSizeY().getValue();

            %add the Stats to the labels
            app.FramesLabel.Visible = 'on';
            app.WidthNheightLabel.Visible = 'on';
            app.PlanesLabel.Visible = 'on';

            app.PlanesLabel.Text = [num2str(sizeZ), ' planes'];
            app.FramesLabel.Text = [num2str(sizeT), ' time-points'];
            app.WidthNheightLabel.Text = [num2str(sizeX), ' x ', num2str(sizeY), ' pixels'];

            %Get ChannelNames
            T = editChannelNames(app.OMEROsession,app.curImgID);
            %convert to a struct
            ChannelNames_struct = table2struct(T);
            app.ChannelNames = cell(length(ChannelNames_struct),1);
            for i = 1:length(ChannelNames_struct)
                app.ChannelNames{i,:} = ChannelNames_struct(i).Name;
                if strcmp(app.ChannelNames{i,:},'Default')
                    app.ChannelNames{i,:} = ['Ch ', num2str(i)];
                end
            end
            app.ChannelSelectionButtonGroup.Enable = 'on';
            app.Ch1Button.Enable = 0;
            app.Ch1Button.Visible = 0;
            app.Ch2Button.Enable = 0;
            app.Ch2Button.Visible = 0;
            app.Ch3Button.Enable = 0;
            app.Ch3Button.Visible = 0;
            app.Ch4Button.Enable = 0;
            app.Ch4Button.Visible = 0;
            if sizeC > 0
                app.Ch1Button.Enable = 1;
                app.Ch1Button.Visible = 1;
                app.Ch1Button.Value = 1;
                app.Ch1Button.Text = app.ChannelNames{1,:};
                app.curChan = 0;
                %Enable the Load Movie Button
                app.LoadMovieButton.Enable = 1;
            end
            if sizeC > 1
                app.Ch2Button.Enable = 1;
                app.Ch2Button.Visible = 1;
                app.Ch2Button.Text = app.ChannelNames{2,:};
            end
            if sizeC > 2
                app.Ch3Button.Enable = 1;
                app.Ch3Button.Visible = 1;
                app.Ch3Button.Text = app.ChannelNames{3,:};
            end
            if sizeC > 3
                app.Ch4Button.Enable = 1;
                app.Ch4Button.Visible = 1;
                app.Ch4Button.Text = app.ChannelNames{4,:};
            end
            adjustChannelButtonSizes(app);
            %Enable the Load Movie Button
            app.LoadMovieButton.Enable = 1;
        end

        % Button pushed function: LoadMovieButton
        function LoadMovieButtonPushed(app, event)
            %convert OMERO image into a stack for Tracking GUI
%             app.ImStack = load2D_OMEROstack(app.OMEROsession,app.curImgID,app.Status);
            %Get the image properties
            tic
            image = getImages(app.OMEROsession,app.curImgID);
            pixels = image.getPrimaryPixels();
            % sizeZ = pixels.getSizeZ().getValue();
            sizeT = pixels.getSizeT().getValue();
%             sizeC = pixels.getSizeC().getValue();
            sizeX = pixels.getSizeX().getValue();
            sizeY = pixels.getSizeY().getValue();
            [~,index] = ismember(app.ImagesListBox.Value,app.ImagesListBox.ItemsData);
            imName = convertCharsToStrings(app.ImagesListBox.Items{index});
            setappdata(0,'OMEROimName',imName);
            savedir = [pwd,filesep,'OMERO_temp_save'];
            saveIM = fullfile(savedir,char(imName));
            mkdir(savedir);
            app.Status.Text = 'Downloaing Image...';
            drawnow;
            tic
            exportImageAsOMETIFF(app.OMEROsession,app.curImgID,saveIM);
            t1 = toc


% 
%             for i = 1:sizeT
%                 app.Status.Text = ['Reading Image ', num2str(i), ' of ',num2str(sizeT)];
%                 drawnow;
%                 imStack(i).data = uint16(getPlane(app.OMEROsession,app.curImgID,0,app.curChan,i-1));
%                 imStack(i).height = sizeY;
%                 imStack(i).width = sizeX;
%             end
            app.ImStack = 1;
            %%
            setappdata(0,'OMEROimStack',app.ImStack);
            setappdata(0,'OMEROProjectID',app.ProjectsListBox.Value);
            setappdata(0,'OMERODatasetID',app.DatasetsListBox.Value);
            setappdata(0,'OMEROImageID',app.curImgID);
            setappdata(0,'OMEROChannelSelect',app.curChan)
            setappdata(0,'OMEROimName',imName);
            if app.saveUserPwdFlag == 1
                setappdata(0,'OMEROuser',app.OMEROuser);
                setappdata(0,'OMEROpwd',app.OMEROpwd);
                setappdata(0,'OMEROserverIP',app.curOMEROserver);
                setappdata(0,'OMEROserverPort',app.curOMEROport);
            else
                setappdata(0,'OMEROuser',[]);
                setappdata(0,'OMEROpwd',[]);
            end
            app.OMEROclient.closeSession();
            clear app.OMEROclient;
            clear app.OMEROsession;
            clear imStack;
            clear i;
            clear image;
            clear pixels;
            clear sizeT;
            clear sizeX;
            clear sizeY;
            

%             unloadOmero();
            app.delete;

        end

        % Close request function: LoadOMEROMovieUIFigure
        function LoadOMEROMovieUIFigureCloseRequest(app, event)
            delete(app)
            app.OMEROclient.closeSession();
            clear app.OMEROclient;
            clear app.OMEROsession;
            unloadOmero();
            setappdata(0,'OMEROimStack',[])
            app.delete;
        end

        % Value changed function: OMEROServerDropDown
        function OMEROServerDropDownValueChanged(app, event)
            value = app.OMEROServerDropDown.Value;
            if strcmp(value,'Add New...')
                %get the location of the OMEROserverInfo.mat file
                OMEROserverFilePath = fileparts(which('OMEROserverInfo.mat'));
                
                %set up the inputdlg
                defPar(1) = '';
                defPar(2) = '4064';

                defaults = {defPar(1),defPar(2)};
                prompt = {'IP address', 'Port'};
                dlgtitle = 'Enter the server info';

                NewServer = inputdlg(prompt,dlgtitle,1,defaults);

                if isempty(NewServer)
                    return
                else
                    OMEROserverInfo(1).IP = NewServer{1};
                    OMEROserverInfo(1).Port = str2double(NewServer{2});

                    oldServerInfo = load('OMEROserverInfo.mat');
                    for i = 1:length(oldServerInfo.OMEROserverInfo)
                        OMEROserverInfo(i+1) = oldServerInfo.OMERserverInfo(i);
                    end
                    
                    save(fullfile(OMEROserverFilePath,'OMEROserverInfo.mat'),"OMEROserverInfo");
                    
                    %update the dropdown menu & Port name
                    app.OMEROservers = OMEROserverInfo;
                    for i = 1:size(app.OMEROservers,1)
                        serverStr{i,:} = app.OMEROservers(i).IP;
                    end
                    serverStr{size(app.OMEROservers,1)+1,:} = 'Add New...';
                    app.OMEROServerDropDown.Items = serverStr;
                    app.OMEROServerDropDown.Value = serverStr{1,:};
                    app.PortEditField.Value = app.OMEROservers(1).Port;


                end


            else
                app.curOMEROserver = value;
                for i = 1:size(app.OMEROservers,1)
                    if strcmp(app.OMEROservers(i).IP,value)
                        app.PortEditField.Value = app.OMEROservers(i).Port;
                        break
                    end
                end
            end
        end

        % Selection changed function: ChannelSelectionButtonGroup
        function ChannelSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelSelectionButtonGroup.SelectedObject;
            %Get ChannelNames
            T = editChannelNames(app.OMEROsession,app.curImgID);
            %convert to a struct
            ChannelNames_struct = table2struct(T);
            test_vec = cell(length(ChannelNames_struct),1);
            for i = 1:length(ChannelNames_struct)
                test_vec{i,:} = ChannelNames_struct(i).Name;
                if strcmp(test_vec{i,:},'Default')
                    test_vec{i,:} = ['Ch ', num2str(i)];
                end
            end
            [~,index] = ismember(test_vec,selectedButton.Text);
            app.curChan = find(index) - 1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadOMEROMovieUIFigure = uifigure('Visible', 'off');
            app.LoadOMEROMovieUIFigure.Name = 'Load OMERO Movie';
            app.LoadOMEROMovieUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadOMEROMovieUIFigureCloseRequest, true);
            app.ProjectsListBoxLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.ProjectsListBoxLabel.Enable = 'off';
            app.ProjectsListBoxLabel.Text = 'Projects';
            app.ProjectsListBox = uilistbox(app.LoadOMEROMovieUIFigure);
            app.ProjectsListBox.Items = {'Connect First'};
            app.ProjectsListBox.ValueChangedFcn = createCallbackFcn(app, @ProjectsListBoxValueChanged, true);
            app.ProjectsListBox.Enable = 'off';
            app.ProjectsListBox.Value = {};
            app.DatasetsListBoxLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.DatasetsListBoxLabel.Enable = 'off';
            app.DatasetsListBoxLabel.Text = 'Datasets';
            app.DatasetsListBox = uilistbox(app.LoadOMEROMovieUIFigure);
            app.DatasetsListBox.Items = {'Choose a Project'};
            app.DatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetsListBoxValueChanged, true);
            app.DatasetsListBox.Enable = 'off';
            app.DatasetsListBox.Value = {};
            app.ImagesListBoxLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.ImagesListBoxLabel.Enable = 'off';
            app.ImagesListBoxLabel.Text = 'Images';
            app.ImagesListBox = uilistbox(app.LoadOMEROMovieUIFigure);
            app.ImagesListBox.Items = {'Choose a Dataset'};
            app.ImagesListBox.ValueChangedFcn = createCallbackFcn(app, @ImagesListBoxValueChanged, true);
            app.ImagesListBox.Enable = 'off';
            app.ImagesListBox.Value = {};
            app.OMEROServerDropDownLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.OMEROServerDropDownLabel.Text = 'OMERO Server';
            app.OMEROServerDropDown = uidropdown(app.LoadOMEROMovieUIFigure);
            app.OMEROServerDropDown.Items = {'165.112.226.159', 'Add new...'};
            app.OMEROServerDropDown.ValueChangedFcn = createCallbackFcn(app, @OMEROServerDropDownValueChanged, true);
            app.OMEROServerDropDown.Value = '165.112.226.159';
            app.ConnectButton = uibutton(app.LoadOMEROMovieUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LoadMovieButton = uibutton(app.LoadOMEROMovieUIFigure, 'push');
            app.LoadMovieButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMovieButtonPushed, true);
            app.LoadMovieButton.Enable = 'off';
            app.LoadMovieButton.Text = 'Load Movie';
            app.PortEditFieldLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.LoadOMEROMovieUIFigure, 'numeric');
            app.PortEditField.Limits = [0 Inf];
            app.PortEditField.Value = 4064;
            app.Status = uilabel(app.LoadOMEROMovieUIFigure);
            app.Status.Text = '';
            app.ChannelSelectionButtonGroup = uibuttongroup(app.LoadOMEROMovieUIFigure);
            app.ChannelSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelSelectionButtonGroupSelectionChanged, true);
            app.ChannelSelectionButtonGroup.Enable = 'off';
            app.ChannelSelectionButtonGroup.Title = 'Channel Selection';
            app.Ch1Button = uitogglebutton(app.ChannelSelectionButtonGroup);
            app.Ch1Button.Enable = 'off';
            app.Ch1Button.Text = 'Ch 1';
            app.Ch1Button.Value = true;
            app.Ch2Button = uitogglebutton(app.ChannelSelectionButtonGroup);
            app.Ch2Button.Enable = 'off';
            app.Ch2Button.Text = 'Ch 2';
            app.Ch3Button = uitogglebutton(app.ChannelSelectionButtonGroup);
            app.Ch3Button.Enable = 'off';
            app.Ch3Button.Text = 'Ch 3';
            app.Ch4Button = uitogglebutton(app.ChannelSelectionButtonGroup);
            app.Ch4Button.Enable = 'off';
            app.Ch4Button.Text = 'Ch 4';
            app.FramesLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.FramesLabel.Visible = 'off';
            app.FramesLabel.Text = 'Frames';
            app.WidthNheightLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.WidthNheightLabel.Visible = 'off';
            app.WidthNheightLabel.Text = 'WidthNheight';
            app.PlanesLabel = uilabel(app.LoadOMEROMovieUIFigure);
            app.PlanesLabel.Visible = 'off';
            app.PlanesLabel.Text = 'Planes:';
            % Show the figure after all components are created
            app.LoadOMEROMovieUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadSingleMovieOMERO(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadOMEROMovieUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadOMEROMovieUIFigure)
        end
    end
end