classdef AnalyzeJumpHistogramSingle_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackAnalyzeJumpHistogramSingleUIFigure  matlab.ui.Figure
        DoneButton              matlab.ui.control.Button
        CopyFitParams           matlab.ui.control.Button
        CopyFit                 matlab.ui.control.Button
        Copy2DHist              matlab.ui.control.Button
        SaveFigure              matlab.ui.control.Button
        SelectFit               matlab.ui.control.DropDown
        SelectFitDropDownLabel  matlab.ui.control.Label
        ShowFitResults          matlab.ui.control.Label
        axes1                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileName % Description
        ROIname
        FJH
        JDH
        FitPar % Description
        frameTime % Description
        binSize % Description
        BIC % Description
    end
    
    methods (Access = private)
        
        function [tlist, rlist, JDH,jd1] = calculateJDH(~,tracks, nJumps,bin_edges,timeStep)
            % Calculate JDH
            % Calculate the Jump histogram distibution from experimental tracks.
            % The calculation is calculated for different time lags (DeltaT,
            % 2DeltaT,...).
            % INPUT:
            % tracks: tracks as produced by the track.m routine. In brief
            %         tracks(:,1) =  x_coordinate (in microns)
            %         tracks(:,2) =  y_coordinate (in microns)
            %         tracks(:,3) =  frame identifier
            %         tracks(:,4) =  particle identifier
            %
            % nJumps: longest time window for jumps calculation
            %         (i.e. nJumps = 10 calculates the jumps for
            %          up to 10 frames time interval)
            %
            % bin_edges: vector containing the edges of the Jump distance histogram
            %            bins
            %
            % timeStep: scalar the time between different frames
            %
            % Normalize Flag: if 0 each jump is equally contributing to the histogram
            %               : if 1 the jump contribute as 1/(LT-n) (with LT being the
            %                      length of the track and n being the number of frames
            %                      for which the current jump is calculated.
            %                      This allow to prevent overweighting of the Jump
            %                      Histograms at shorter intervals (which produce more
            %                      jumps).
            %
            % Plot Flag: if 0 no plot is produced
            %           if 1 a xyz plot is produced
            %           if 2 an "heat map" plot is produced
            %
            % OUTPUT
            % tlist = [t1; t2; ...t_maxFrameN)
            % rList is a column vector vector with the coordinates corresponding to the centers of
            % the histogram bins: rlist = [r1; r2; ...]
            % JDH is a matrix with the Jump distance histograms with the following
            % structure:
            %
            %           |  JDH(r1, t1)      JDH(r2, t1)    ...      JDH(rmax, t1)   |
            %           |  JDH(r1, t2)      JDH(r2, t2)    ...      JDH(rmax, t2)   |
            %  JDH    = |      ...              ...        ...           ...        |
            %           |  JDH(r1, tmax)    JDH(r2, tmax)   ...     JDH(rmax, tmax) |
            %
            %--------------------------------------------------------------------------


            % CALCULATE JUMP HISTOGRAM DISTRIBUTION

            % Initialize useful variables
            jd = [];                                    % temp variable containing the jumps;
            JDH = zeros(nJumps, length(bin_edges)-1);     % Initialize Jump histogram distribution;
            nTracks = max(tracks(:,4));                 % number of tracks;
            TrackLength = zeros(nTracks, 1);            % Initialize vector containing track length;
            
            nJump4D = 1;
            
            jd1 = [];
            for j = 1:nJumps    % for loop on different jump sizes
                % (1 frame, 2 frames, etc)

                for i = 1:nTracks       % loop on the different tracks.

                    idx = find(tracks(:,4) == i);       % find the track identified by i
                    TrackLength(i) = length(idx);       % compute the length of the i-th track

                    if  TrackLength(i) >j

                        jd_temp_x = tracks(idx,1);      % Calculate jumps for the i-th
                        jd_temp_y = tracks(idx,2);      % track with the j-th interval

                        jd_temp = sqrt((jd_temp_x(j+1:end)- jd_temp_x(1:end-j)).^2 + ...
                            (jd_temp_y(j+1:end)- jd_temp_y(1:end-j)).^2);

                        jd =  cat(1,jd,jd_temp);


                    end


                end
                if ~isempty(jd) 

                    JDH(j,:) = histcounts(jd,  bin_edges);           % calculate the JDH for
                    % each interval at the
                end                                             % end of the loop on the
                % different tracks

                                                        % end of the loop on the
                % different tracks
                if j == nJump4D
                    jd1 = jd;
                end
                jd = [];

            end


            % CALCULATE r AND t;

            rlist = bin_edges(1:end-1)+(bin_edges(2)-bin_edges(1))/2;
            rlist = rlist';
            tlist = (1:nJumps)*timeStep;
            tlist = tlist';
            
        end
        
        function BIC_tmp = ModelSelection_log_fun(~,totalTracks,FJH,curve1,curve2,curve3,period)
            sigma=(period/2)/totalTracks;
            
            BIC_tmp(1)=1*2+2*sum(((log10(curve1)-log10(FJH)).^2)/(2*sigma));
            BIC_tmp(2)=1*4+2*sum(((log10(curve2)-log10(FJH)).^2)/(2*sigma));
            BIC_tmp(3)=1*6+2*sum(((log10(curve3)-log10(FJH)).^2)/(2*sigma));
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            Tracks = varargin{1};                    % Import tracks.
            Parameters = varargin{2};                % Import stacks of images.
            app.frameTime = varargin{3};                 % Import FrameTime
            maxJump = varargin{4};
            app.FileName = varargin{5};
            app.ROIname = varargin{6};

            if length(varargin) > 6
                pos_fig = varargin{7};
                ChangeAppPosition(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure,pos_fig);
                drawnow;
            end
            
            %Create a progress bar
            d = uiprogressdlg(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure,'Title','Jump Analysis','Indeterminate','on');
            d.Message = 'Processing Tracks...';
            drawnow
            app.binSize = Parameters.JHistBinSize;
            maxFrame = Parameters.JHistMaxFrameN;

            binEdges = 0:app.binSize:maxJump;

            % Initial value for the diffusion coefficient
            D0 = 1;

            % Calculate time-dependent histogram of displacements
            [tlist, rlist, JDH1] = ...
                calculateJDH(app,Tracks,maxFrame, binEdges,app.frameTime);

            % Isolate first histogram of displacements;
            app.FJH = [];
            app.FJH(:,1) = rlist(:);
            app.FJH(:,2) = (JDH1(1,:))';

            % Fit first histogram of displacements;
            [FJH_Coef1Cmp,FJH_Fit1Cmp,FJH_Sigma1Cmp] = JDHfixedT_1cmp_fit(app.FJH,app.frameTime, D0);
            [FJH_Coef2Cmp,FJH_Fit2Cmp, FJH_Sigma2Cmp] = JDHfixedT_2cmp_fit(app.FJH,app.frameTime, [10*D0, D0]);
            [FJH_Coef3Cmp,FJH_Fit3Cmp, FJH_Sigma3Cmp] = JDHfixedT_3cmp_fit(app.FJH,app.frameTime, [10*D0, D0, 0.1*D0]);
            
            period = app.FJH(2,1) - app.FJH(1,1);
            app.BIC = ModelSelection_log_fun(app,length(unique(Tracks(:,4))),app.FJH(:,2),FJH_Fit1Cmp(:,2),FJH_Fit2Cmp(:,2),FJH_Fit3Cmp(:,2),period);


            % Add fits of first histogram of displeacements to FJH.
            app.FJH(:,3) = FJH_Fit1Cmp(:,2);
            app.FJH(:,4) = FJH_Fit2Cmp(:,2);
            app.FJH(:,5) = FJH_Fit3Cmp(:,2);

            % Plot the time dependent histogram of displacements in the axes;
            colormap(app.axes1,'jet');
            imagesc(app.axes1,rlist, tlist, JDH1);
            set(app.axes1, 'FontSize', 12);

            xlabel(app.axes1,'Jump distance [\mum]');
            ylabel(app.axes1,'Time [s]');
            title(app.axes1,'Time-dependent histogram of displacements');
            colorbar(app.axes1,'location','EastOutside','XTick',0.5,'XTickLabel','Counts');

            axis(app.axes1,'xy')
            axis(app.axes1,'tight');
%             set(app.axes1,'Xlim',[0 maxJump]);
%             set(app.axes1,'Ylim',[app.frameTime, max(tlist)]);

            % Pass the output to handles
            figname = get(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure,'Name');
            figname = [figname ' ' app.ROIname];
            app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure.Name = figname;
%             

            app.JDH = zeros(maxFrame + 1, length(binEdges));
            app.JDH(2:end,2:end) = JDH1;
            app.JDH(2:end, 1) = tlist;
            app.JDH(1, 2:end) = rlist;
            
            app.FitPar = [FJH_Coef1Cmp, FJH_Coef2Cmp, FJH_Coef3Cmp;...
                FJH_Sigma1Cmp, 0, FJH_Sigma2Cmp, 0, FJH_Sigma3Cmp, 0];
%             app.frameTime =frameTime;
%             app.binSize = binSize;
            FitString{1} = 'BIC values:';
            FitString{2} =  ['1-Component Fit: ', num2str(app.BIC(1),'%3.3e')];
            FitString{3} =  ['2-Component Fit: ', num2str(app.BIC(2),'%3.3e')];
            FitString{4} =  ['3-Component Fit: ', num2str(app.BIC(3),'%3.3e')];
            
            app.ShowFitResults.Text = FitString;

            drawnow;

            %Store the important values
            OUTvars{1} = app.JDH;
            OUTvars{2} = app.FJH;
            OUTvars{3} = app.FitPar;
            setappdata(0,'QuantiTrackJumpHist_out',OUTvars);
            close(d);

        end

        % Button pushed function: Copy2DHist
        function Copy2DHistButtonPushed(app, event)
            % num2clip(app.JDH);
            columnNames = cell(size(app.JDH(1,:)));
            columnNames{1} = 'Time';
            for i = 2:length(app.JDH(1,:))
                columnNames{i} = num2str(app.JDH(1,i));
            end
            
            JDH_array = array2table(app.JDH(2:end,:),'VariableNames', columnNames);
            assignin('base','JDH_out',JDH_array);
            msgbox({'The Array containing the time-dependent histogram';...
                'of displacements has been copied to the workspace';... 
                '(visible after closing Analysis tool)'});
        end

        % Button pushed function: CopyFit
        function CopyFitButtonPushed(app, event)
            % num2clip(app.FJH);
            
            FJH_Fit_array = array2table(app.FJH,'VariableNames',...
                {'Jump Distance','Counts','1-Component Fit', ...
                '2-Component Fit', '3-Component Fit'});
            assignin('base','FJH_Fit_out',FJH_Fit_array);
            msgbox({'The Array containing the fitted first jump histogram';...
                'has been copied to the workspace';... 
                '(visible after closing Analysis tool)'});
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            close(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure);
        end

        % Button pushed function: SaveFigure
        function SaveFigureButtonPushed(app, event)
            %This button saves the figure currently shown.
                        
            DefaultName = app.FileName;
            SearchStr = '(.*)\.\w*';

            tlist = app.JDH(2:end, 1);
            rlist = app.JDH(1, 2:end)';
            rlist(1,2) =tlist(1);

            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .4*scrsz(3) .5*scrsz(4)]) ;
            colormap(h1,'jet')
            Selection = app.SelectFit.Value;

            if strcmp(Selection,'2D Histogram of Displacements')

                imagesc(rlist(:,1), tlist, app.JDH(2:end,2:end));
                set(gca, 'FontSize', 12);

                xlabel('Jump distance [\mum]');
                ylabel('Time [s]');
                title('Time-dependent histogram of displacements');
                colorbar('location','EastOutside','XTick',0.5,'XTickLabel','Counts');

                axis ij

                % Save the figures

                DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIname '_JDH']);
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end

            elseif strcmp(Selection, 'One Compnent Diffusion Fit') % One component diffusion fit

                % plot the histogram of displacements and the fit in the current
                % axes

                set(gca, 'FontSize', 12);
                bar(app.FJH(:,1),app.FJH(:,2),'EdgeColor', [0 0 0], ...
                    'FaceColor','none', 'BarWidth', 1, 'LineWidth', 0.75);
                hold on;
                plot(app.FJH(:,1),app.FJH(:,3),'r', 'LineWidth', 1);
                hold off;
                xlabel('Jump distance [\mum]', 'FontSize', 12);
                ylabel('Counts', 'FontSize', 12);

                FitString{1} = 'One component diffusion fit of the first Jump Histogram';
                FitString{2} = ['n = ' num2str(app.FitPar(1,1),3),' \pm ', num2str(app.FitPar(2,1),2),'; ',...
                    'D = ', num2str(app.FitPar(1,2),3),' \pm ', num2str(app.FitPar(2,2),2), ' \mum^2/s; '];
                FitString{3}= ['SSR = ' num2str(app.FitPar(1,3)),'; \DeltaT = ', num2str(app.frameTime)];


                title(FitString,'FontSize', 12);
                legend({'Displacement Histogram';'One Component Fit'}, 'FontSize', 12);
                xlim ([0 max(app.FJH(:,1)) + app.binSize/2]);

                DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIname '_FJH_1CMP']);
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end

            elseif strcmp(Selection, 'Two Components Diffusion Fit') % Two component diffusion fit

                % plot the histogram of displacements and the fit in the current
                % axes

                FirstCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,4)* app.FitPar(1,7), app.FitPar(1,5)],rlist);
                SecondCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,4)* (1 - app.FitPar(1,7)), app.FitPar(1,6)],rlist);


                set(gca, 'FontSize', 12);
                bar(app.FJH(:,1),app.FJH(:,2),'EdgeColor', [0 0 0], ...
                    'FaceColor','none', 'BarWidth', 1, 'LineWidth', 0.75);
                hold on;
                plot(app.FJH(:,1),app.FJH(:,4),'r', 'LineWidth', 1);
                plot(app.FJH(:,1),FirstCMP, '--k','LineWidth', 1);
                plot(app.FJH(:,1),SecondCMP, '-.k','LineWidth', 1);
                hold off;
                xlabel('Jump distance [\mum]', 'FontSize', 12);
                ylabel('Counts', 'FontSize', 12);
                FitString{1} = 'Two components diffusion fit of the first Jump Histogram';
                FitString{2} = ['n = ' num2str(app.FitPar(1,4),3),' \pm ', num2str(app.FitPar(2,4),2),'; ',...
                    'D_1 = ', num2str(app.FitPar(1,5),3),' \pm ', num2str(app.FitPar(2,5),2), ' \mum^2/s; ',...
                    'D_2 = ', num2str(app.FitPar(1,6),3),' \pm ', num2str(app.FitPar(2,6),2), ' \mum^2/s; '];
                FitString{3}= ['f_1 = ', num2str(app.FitPar(1,7),3),' \pm ', num2str(app.FitPar(2,7),2), ...
                    '; SSR = ' num2str(app.FitPar(1,8)),'; \DeltaT = ', num2str(app.frameTime)];


                title(FitString,'FontSize', 12);
                legend({'Displacement Histogram';'Two Components Fit';...
                    'First Component'; 'Second Component'}, 'FontSize', 12);
                xlim ([0 max(app.FJH(:,1)) + app.binSize/2]);

                DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIname '_FJH_2CMP']);
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end

            elseif strcmp(Selection, 'Three Components Diffusion Fit') % Three component diffusion fit

                % plot the histogram of displacements and the fit in the current
                % axes

                FirstCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,9)* app.FitPar(1,13), app.FitPar(1,10)],rlist);
                SecondCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,9)* app.FitPar(1,14), app.FitPar(1,11)],rlist);
                ThirdCMP = JDHfixedT_1cmp_fun...
                    ([app.FitPar(1,9)* (1 - app.FitPar(1,13) - app.FitPar(1,14)), app.FitPar(1,12)],rlist);


                set(gca, 'FontSize', 12);
                bar(app.FJH(:,1),app.FJH(:,2),'EdgeColor', [0 0 0], ...
                    'FaceColor','none', 'BarWidth', 1, 'LineWidth', 0.75);
                hold on;
                plot(app.FJH(:,1),app.FJH(:,5),'r', 'LineWidth', 1);
                plot(app.FJH(:,1),FirstCMP, '--k','LineWidth', 1);
                plot(app.FJH(:,1),SecondCMP, '-.k','LineWidth', 1);
                plot(app.FJH(:,1),ThirdCMP, ':k','LineWidth', 1);
                hold off;
                xlabel('Jump distance [\mum]', 'FontSize', 12);
                ylabel('Counts', 'FontSize', 12);

                FitString{1} = 'Three components diffusion fit of the first Jump Histogram';
                FitString{2} = ['n = ' num2str(app.FitPar(1,9),3),' \pm ', num2str(app.FitPar(2,9),2),'; ',...
                    'D_1 = ', num2str(app.FitPar(1,10),3),' \pm ', num2str(app.FitPar(2,10),2), ' \mum^2/s; ',...
                    'D_2 = ', num2str(app.FitPar(1,11),3),' \pm ', num2str(app.FitPar(2,11),2), ' \mum^2/s; ',...
                    'D_3 = ', num2str(app.FitPar(1,12),3),' \pm ', num2str(app.FitPar(2,12),2), ' \mum^2/s; '];
                FitString{3}= ['f_1 = ', num2str(app.FitPar(1,13),3),' \pm ', num2str(app.FitPar(2,13),2), ...
                    '; f_2 = ', num2str(app.FitPar(1,14),3),' \pm ', num2str(app.FitPar(2,14),2),...
                    '; SSR = ' num2str(app.FitPar(1,15)),'; \DeltaT = ', num2str(app.frameTime)];

                title(FitString,'FontSize', 12);
                legend({'Displacement Histogram';'Three Components Fit';...
                    'First Component'; 'Second Component';'Third Component'}, 'FontSize', 12);
                xlim ([0 max(app.FJH(:,1)) + app.binSize/2]);

                DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIname '_FJH_3CMP']);
                FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
                [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
                if FileNameOut ~= 0
                    saveas(h1, [PathNameOut,FileNameOut]);
                end


            end
        end

        % Button pushed function: CopyFitParams
        function CopyFitParamsButtonPushed(app, event)
            % num2clip(app.FitPar);

            FitPar_table = array2table(app.FitPar,'VariableNames',...
                {'Scaling Factor - 1 Cmp (C.I.)', 'D - 1 Cmp (C.I.)','SSR - 1 Cmp',...
                'Scaling Factor - 2 Cmp (C.I.)', 'D1 - 2 Cmp (C.I.)', 'D2 - 2 Cmp (C.I.)', 'f1 - 2 Cmp (C.I.)', 'SSR - 2 Cmp',...
                'Scaling Factor - 3 Cmp (C.I.)', 'D1 - 3 Cmp (C.I.)', 'D2 - 3 Cmp (C.I.)', 'D3 - 3 Cmp (C.I.)', 'f1 - 3 Cmp (C.I.)', 'f2 - 3 Cmp (C.I.)', 'SSR - 3 Cmp'});
            assignin('base','FitPar_out',FitPar_table);
            msgbox({'The Array containing the Fit Parameters';...
                'has been copied to the workspace';... 
                '(visible after closing Analysis tool)'});
        end

        % Value changed function: SelectFit
        function SelectFitValueChanged(app, event)
            
            tlist = app.JDH(2:end, 1);
            rlist = app.JDH(1, 2:end)';
            rlist(1,2) =tlist(1);


            Selection = app.SelectFit.Value;
            hold(app.axes1,'off');
            app.axes1.NextPlot = 'replace';

            if strcmp(Selection,'2D Histogram of Displacements')
                
                imagesc(app.axes1,rlist(:,1), tlist, app.JDH(2:end,2:end));
                colormap(app.axes1,'jet')
                set(app.axes1, 'FontSize', 12);

                xlabel(app.axes1,'Jump distance [\mum]');
                ylabel(app.axes1,'Time [s]');
                title(app.axes1,'Time-dependent histogram of displacements');
                colorbar(app.axes1,'location','EastOutside','XTick',0.5,'XTickLabel','Counts');

                axis(app.axes1,'xy');
                axis(app.axes1,'tight');
                
                FitString{1} = 'BIC values:';
                FitString{2} =  ['1-Component Fit: ', num2str(app.BIC(1),'%3.3e')];
                FitString{3} =  ['2-Component Fit: ', num2str(app.BIC(2),'%3.3e')];
                FitString{4} =  ['3-Component Fit: ', num2str(app.BIC(3),'%3.3e')];

                app.ShowFitResults.Text = FitString;


            elseif strcmp(Selection, 'One Compnent Diffusion Fit') % One component diffusion fit

                % plot the histogram of displacements and the fit in the current
                % axes

                set(app.axes1, 'FontSize', 12);
                bar(app.axes1,app.FJH(:,1),app.FJH(:,2),'EdgeColor', [0 0 0], ...
                    'FaceColor','none', 'BarWidth', 1, 'LineWidth', 0.75);
                hold(app.axes1,'on');
                plot(app.axes1,app.FJH(:,1),app.FJH(:,3),'r', 'LineWidth', 1);
                hold(app.axes1,'off');
                xlabel(app.axes1,'Jump distance [\mum]', 'FontSize', 12);
                ylabel(app.axes1,'Counts', 'FontSize', 12);
                title(app.axes1,{'Fit of the first histogram of displacements';...
                    'One Component diffusion Fit';...
                    ['\DeltaT = ', num2str(app.frameTime), ' s']},'FontSize', 12);
                legend(app.axes1,{'Displacement Histogram';'One Component Fit'}, 'FontSize', 12);
                xlim(app.axes1,[0 max(app.FJH(:,1)) + app.binSize/2]);

                % Display fitting results

                FitString{1} = 'Estimated Parameters of the one component diffusion fit:';
                FitString{2} = ['n = ' num2str(app.FitPar(1,1),3),' +/- ', num2str(app.FitPar(2,1),2)];
                FitString{3} = ['D = ' num2str(app.FitPar(1,2),3),' +/- ', num2str(app.FitPar(2,2),2), ' um2/s'];
                FitString{4} = ['SSR = ' num2str(app.FitPar(1,3));];
                FitString{5} = ['BIC = ' num2str(app.BIC(1),'%3.3e')];

                app.ShowFitResults.Text = FitString;
                

            elseif strcmp(Selection, 'Two Components Diffusion Fit') % Two component diffusion fit

                % plot the histogram of displacements and the fit in the current
                % axes

                FirstCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,4)* app.FitPar(1,7), app.FitPar(1,5)],rlist);
                SecondCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,4)* (1 - app.FitPar(1,7)), app.FitPar(1,6)],rlist);


                set(app.axes1, 'FontSize', 12);
                bar(app.axes1,app.FJH(:,1),app.FJH(:,2),'EdgeColor', [0 0 0], ...
                    'FaceColor','none', 'BarWidth', 1, 'LineWidth', 0.75);
                hold(app.axes1, 'on');
                plot(app.axes1, app.FJH(:,1),app.FJH(:,4),'r', 'LineWidth', 1);
                plot(app.axes1, app.FJH(:,1),FirstCMP, '--k','LineWidth', 1);
                plot(app.axes1, app.FJH(:,1),SecondCMP, '-.k','LineWidth', 1);
                hold(app.axes1, 'off');
                xlabel(app.axes1,'Jump distance [\mum]', 'FontSize', 12);
                ylabel(app.axes1,'Counts', 'FontSize', 12);
                title(app.axes1,{'Fit of the first histogram of displacements';...
                    'Two Components diffusion Fit';...
                    ['\DeltaT = ', num2str(app.frameTime), ' s']},'FontSize', 12);
                legend(app.axes1,{'Displacement Histogram';'Two Components Fit';...
                    'First Component'; 'Second Component'}, 'FontSize', 12);
                xlim (app.axes1,[0 max(app.FJH(:,1)) + app.binSize/2]);

                % Display fitting results

                FitString{1} = 'Estimated Parameters of the two components diffusion fit:';
                FitString{2} = ['n = ' num2str(app.FitPar(1,4),3),' +/- ', num2str(app.FitPar(2,4),2)];
                FitString{3} = ['D1 = ' num2str(app.FitPar(1,5),3),' +/- ', num2str(app.FitPar(2,5),2), ' um2/s'];
                FitString{4} = ['D2 = ' num2str(app.FitPar(1,6),3),' +/- ', num2str(app.FitPar(2,6),2), ' um2/s'];
                FitString{5} = ['f1 = ' num2str(app.FitPar(1,7),3),' +/- ', num2str(app.FitPar(2,7),2)];
                FitString{6} = ['SSR = ' num2str(app.FitPar(1,8));];
                FitString{7} = ['BIC = ' num2str(app.BIC(2),'%3.3e')];
                app.ShowFitResults.Text = FitString;

            elseif strcmp(Selection, 'Three Components Diffusion Fit') % Three component diffusion fit

                % plot the histogram of displacements and the fit in the current
                % axes

                FirstCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,9)* app.FitPar(1,13), app.FitPar(1,10)],rlist);
                SecondCMP = ...
                    JDHfixedT_1cmp_fun([app.FitPar(1,9)* app.FitPar(1,14), app.FitPar(1,11)],rlist);
                ThirdCMP = JDHfixedT_1cmp_fun...
                    ([app.FitPar(1,9)* (1 - app.FitPar(1,13) - app.FitPar(1,14)), app.FitPar(1,12)],rlist);


                set(app.axes1, 'FontSize', 12);
                bar(app.axes1,app.FJH(:,1),app.FJH(:,2),'EdgeColor', [0 0 0], ...
                    'FaceColor','none', 'BarWidth', 1, 'LineWidth', 0.75);
                hold(app.axes1,'on');
                plot(app.axes1, app.FJH(:,1),app.FJH(:,5),'r', 'LineWidth', 1);
                plot(app.axes1, app.FJH(:,1),FirstCMP, '--k','LineWidth', 1);
                plot(app.axes1, app.FJH(:,1),SecondCMP, '-.k','LineWidth', 1);
                plot(app.axes1, app.FJH(:,1),ThirdCMP, ':k','LineWidth', 1);
                hold(app.axes1,'off');
                xlabel(app.axes1,'Jump distance [\mum]', 'FontSize', 12);
                ylabel(app.axes1,'Counts', 'FontSize', 12);
                title(app.axes1,{'Fit of the first histogram of displacements';...
                    'Three Components diffusion Fit';...
                    ['\DeltaT = ', num2str(app.frameTime), ' s']},'FontSize', 12);
                legend(app.axes1,{'Displacement Histogram';'Three Components Fit';...
                    'First Component'; 'Second Component';'Third Component'}, 'FontSize', 12);
                xlim (app.axes1,[0 max(app.FJH(:,1)) + app.binSize/2]);

                % Display fitting results

                FitString{1} = 'Estimated Parameters of the three components diffusion fit:';
                FitString{2} = ['n = ' num2str(app.FitPar(1,9),3),' +/- ', num2str(app.FitPar(2,9),2)];
                FitString{3} = ['D1 = ' num2str(app.FitPar(1,10),3),' +/- ', num2str(app.FitPar(2,10),2), ' um2/s'];
                FitString{4} = ['D2 = ' num2str(app.FitPar(1,11),3),' +/- ', num2str(app.FitPar(2,11),2), ' um2/s'];
                FitString{5} = ['D3 = ' num2str(app.FitPar(1,12),3),' +/- ', num2str(app.FitPar(2,12),2), ' um2/s'];
                FitString{6} = ['f1 = ' num2str(app.FitPar(1,13),3),' +/- ', num2str(app.FitPar(2,13),2)];
                FitString{7} = ['f2 = ' num2str(app.FitPar(1,14),3),' +/- ', num2str(app.FitPar(2,14),2)];
                FitString{8} = ['SSR = ' num2str(app.FitPar(1,15));];
                FitString{9} = ['BIC = ' num2str(app.BIC(3),'%3.3e')];
                app.ShowFitResults.Text = FitString;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure.Name = 'QuantiTrack - Analyze Jump Histogram Single';
            app.axes1 = uiaxes(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure);
            app.ShowFitResults = uilabel(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure);
            app.ShowFitResults.Interpreter = 'latex';
            app.ShowFitResults.Text = 'Fit Results';
            app.SelectFitDropDownLabel = uilabel(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure);
            app.SelectFitDropDownLabel.Text = 'Select Fit';
            app.SelectFit = uidropdown(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure);
            app.SelectFit.Items = {'2D Histogram of Displacements', 'One Compnent Diffusion Fit', 'Two Components Diffusion Fit', 'Three Components Diffusion Fit'};
            app.SelectFit.ValueChangedFcn = createCallbackFcn(app, @SelectFitValueChanged, true);
            app.SelectFit.Value = '2D Histogram of Displacements';
            app.SaveFigure = uibutton(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure, 'push');
            app.SaveFigure.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigure.Text = 'Save Current Figure';
            app.Copy2DHist = uibutton(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure, 'push');
            app.Copy2DHist.ButtonPushedFcn = createCallbackFcn(app, @Copy2DHistButtonPushed, true);
            app.Copy2DHist.Text = 'Copy Time Dependent Histogram of Displacements';
            app.CopyFit = uibutton(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure, 'push');
            app.CopyFit.ButtonPushedFcn = createCallbackFcn(app, @CopyFitButtonPushed, true);
            app.CopyFit.Text = 'Copy First Histogram of Displacements with Fits';
            app.CopyFitParams = uibutton(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure, 'push');
            app.CopyFitParams.ButtonPushedFcn = createCallbackFcn(app, @CopyFitParamsButtonPushed, true);
            app.CopyFitParams.Text = 'Copy Fit Parameters';
            app.DoneButton = uibutton(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'DONE';
            % Show the figure after all components are created
            app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyzeJumpHistogramSingle_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackAnalyzeJumpHistogramSingleUIFigure)
        end
    end
end