classdef BoundMolSingle_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackBoundMoleculeAnalysisUIFigure  matlab.ui.Figure
        Close_button        matlab.ui.control.Button
        SaveFigures         matlab.ui.control.Button
        CopyResTimeHist     matlab.ui.control.Button
        Copy_BoundF         matlab.ui.control.Button
        ResidenceTime_plot  matlab.ui.control.UIAxes
        BoundF_plot         matlab.ui.control.UIAxes
        BoundTracks_plot    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Tracks % Description
        Parameters % Description
        FrameTime % Description
        NParticles % Description
        NFrames % Description
        FileName % Description
        ROIname % Description
        ImmTracks % Description
        BoundFraction % Description
        TrackLengthHist % Description
        Answer % Description
    end
    
    methods (Access = private)
        
        function TrackLengthHist = calculateTrackLength(~,tracks, frameTime, minBound)
            % This function compute the length of each track and calculate a cumulative
            % histogram of the track lengths. The histogram is corrected for
            % photobleaching, assuming that an exponential bleaching decay.
            %
            % Input
            % tracks contains the information about the tracks
            % in particular track(:, 4) represents the track identifier
            % BleachRate is a scalar indicating the photobleaching rate [units of s^-1]
            % FrameTime is a scalar indicating the time between two points in the track

            if size(tracks,2) == 8
                partInd = 4;
            elseif size(tracks,2) == 9
                partInd = 5;
            end

            nTracks = max(tracks(:,partInd));
            TrackLength = zeros(nTracks,1);

            for i = 1:nTracks
                idx = find(tracks(:,partInd) == i);  % find the track identified by i
                TrackLength(i) = length(idx);
            end


            % Compute Number of tracks longer than N-frames
            LongestTrack = max(TrackLength);
            ShortestTrack = minBound;
            TrackLengthHist = zeros(LongestTrack-ShortestTrack + 2,2);
            TrackLengthHist(:,1) = ((ShortestTrack-1): LongestTrack)* frameTime;

            for  i = 1:nTracks
                TrackLengthHist(1:TrackLength(i)- ShortestTrack + 1,2) = ...
                    TrackLengthHist(1:TrackLength(i) - ShortestTrack + 1,2)+1;
            end
            
        end
        
        function [filTracks, allFilTracks] = calculateImmobileTracks(~,Tracks, ThresholdL, minFrame, ThresholdH)
            % calculateImmobileTracks
            % --------------------------------------------------
            % Find the segments of the tracks that correspond to
            % jumps shorter than Threshold.
            % ------
            % INPUT:
            % ------
            % tracks: Tracks as produced by the track.m routine. In brief
            %         Tracks(:,1) =  x_coordinate (in microns)
            %         Tracks(:,2) =  y_coordinate (in microns)
            %         Tracks(:,3) =  frame identifier
            %         Tracks(:,4) =  particle identifier
            % ------
            % OUTPUT:
            % ------
            % filTracks: Immobile track segments:
            %       filTracks(:,1) = x-Coordinate (in microns)
            %       filTracks(:,2) = y-Coordinate (in microns)
            %       filTracks(:,3) = frame identifier
            %       filTracks(:,4) = segment identifier
            %       filTracks(:,5) = particle identifier
            % Different segments with the same particle index belong
            % to the same original track


            allFilTracks = [];

            previous = 0;  % previous = 0 when the previous jump didn't meet
            % the filter criterium (jump shorter than 3 * locAcc),
            % 1 if it did.
            particleIx = 0;

            %% Identify particles that Jump Less than ThresholdL from frame to frame;
            if size(Tracks,2) == 5 || size(Tracks,2) == 7
                partInd = 4;
            elseif size(Tracks,2) == 6 || size(Tracks,2) == 8
                partInd = 5;
            end

            for i = 1:max(Tracks(:,partInd))
                
                trackTemp = Tracks(Tracks(:,partInd) == i,:);
                %      if size(Tracks,2) == 5 || size(Tracks,2) == 7
                jd_temp = sqrt((trackTemp(2:end,1)- trackTemp(1:end-1,1)).^2 + ...
                    (trackTemp(2:end,2)- trackTemp(1:end-1,2)).^2);
                %      elseif size(Tracks,2) == 6 || size(Tracks,2) == 8
                %          jd_temp = sqrt((trackTemp(2:end,1)- trackTemp(1:end-1,1)).^2 + ...
                %                     (trackTemp(2:end,2)- trackTemp(1:end-1,2)).^2 + ...
                %                     (trackTemp(2:end,3)- trackTemp(1:end-1,3)).^2);
                %      end

                for j = 1: length(jd_temp)

                    if jd_temp(j) < ThresholdL && ~previous
                        particleIx = particleIx +1;
                        previous = 1;

                        catTrack = trackTemp ([j j+1],1:(partInd-1));
                        catTrack(:,partInd) = particleIx;
                        catTrack(:,partInd+1:partInd+3) = trackTemp ([j j+1],partInd+1:partInd+3);
                        catTrack(:,partInd+4) = trackTemp([j j+1], partInd);
                        allFilTracks = [allFilTracks ; catTrack];


                    elseif jd_temp(j) < ThresholdL  && previous
                        catTrack = trackTemp (j+1,1:(partInd-1));
                        catTrack(:,partInd) = particleIx;
                        catTrack(:,partInd+1:partInd+3) = trackTemp (j+1,partInd+1:partInd+3);
                        catTrack(:,partInd+4) = trackTemp (j+1,partInd);
                        allFilTracks = [allFilTracks ; catTrack];

                    else
                        previous = 0;
                    end
                end
                previous = 0;
            end

            %% Discard Tracks shorter than minFrame

            filTracks = discardTracks(allFilTracks,minFrame);

            if  ~isempty(filTracks) && ThresholdH > 0
                %% Identify Tracks that jump less than ThresholdH between minFrame frames


                for i = 1:max(filTracks(:,partInd))

                    idx = find(filTracks(:,partInd)== i);
                    trackTemp = filTracks(idx,:);
                    %         if size(Tracks,2) == 5 || size(Tracks,2) == 7
                    Test = sqrt((trackTemp(minFrame:end,1)- trackTemp(1:end-minFrame+1,1)).^2 + ...
                        (trackTemp(minFrame:end,2)- trackTemp(1:end-minFrame+1,2)).^2);
                    %         elseif size(Tracks,2) == 6 || size(Tracks,2) == 8
                    %             Test = sqrt((trackTemp(minFrame:end,1)- trackTemp(1:end-minFrame+1,1)).^2 + ...
                    %                     (trackTemp(minFrame:end,2)- trackTemp(1:end-minFrame+1,2)).^2 + ...
                    %                     (trackTemp(minFrame:end,3)- trackTemp(1:end-minFrame+1,3)).^2);
                    %         end

                    if max(Test) > ThresholdH
                        filTracks(idx,:) = [];
                    end
                end


                %% Reassign Particle index of filTracks
                TestAccum = [];
                Test = filTracks(2:end,partInd) - filTracks(1:end-1,partInd) > 0;
                for i = 1:length(Test)
                    TestAccum(i) = sum(int16(Test(1:i)));
                end
                TestAccum = [1, TestAccum + 1];

                filTracks(:,partInd) = TestAccum;

            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Import input
            app.Tracks = varargin{1};        % Import tracks.
            app.Parameters = varargin{2};    % Import Parameters.
            app.FrameTime = varargin{3};     % Import Frametime
            app.NParticles = varargin{4};    % Import NParticles
            app.NFrames = varargin{5};       % Import number of frames.
            app.FileName = varargin{6};      % Import FileName.
            app.ROIname = varargin{7};
            if length(varargin) > 7
                pos_fig = varargin{8};
                ChangeAppPosition(app.QuantiTrackBoundMoleculeAnalysisUIFigure,pos_fig);
                drawnow;
            end
            d = uiprogressdlg(app.QuantiTrackBoundMoleculeAnalysisUIFigure,'Title','Bound Analysis, Single File', 'Indeterminate','on');
            d.Message = 'Analyzing Track angles';
            drawnow;
            % Identify Immobile Particles;
            if size(app.Tracks,2) < 8
                app.Answer = questdlg({'Do you want to normalize the histogram';...
                    'of  residence times to the bound fraction?'});

                [ImmTracks_tmp, ~] = calculateImmobileTracks...
                    (app,app.Tracks, app.Parameters.ThreshL,...
                    app.Parameters.minBoundFrames, app.Parameters.ThreshH);


                % Plot immobile tracks;

                
                axis(app.BoundTracks_plot,'ij');
                hold(app.BoundTracks_plot, 'on');
                for i = 1:max(app.Tracks(:,4))
                    idx = find(app.Tracks(:,4)==i);
                    plot(app.BoundTracks_plot, app.Tracks(idx,1), app.Tracks(idx,2),'-ok', 'MarkerSize', 5);
                end


                if  ~isempty(ImmTracks_tmp)
                    for i = 1:max(ImmTracks_tmp(:,4))
                        idx = find(ImmTracks_tmp(:,4)==i);
                        plot(app.BoundTracks_plot, ImmTracks_tmp(idx,1), ImmTracks_tmp(idx,2),'-og', 'MarkerSize', 5);
                    end

                end

                title(app.BoundTracks_plot, {'Identified Bound segments for the following settings';...
                    ['Max Jump between consecutive frames = ',...
                    num2str(app.Parameters.ThreshL), '\mum'];
                    ['Minimum number of consecutive frames = ',...
                    num2str(app.Parameters.minBoundFrames)];...
                    ['Max Jump within ', num2str(app.Parameters.minBoundFrames),...
                    ' frames = ', num2str(app.Parameters.ThreshH), '\mum']});

                xlabel(app.BoundTracks_plot, 'x [\mum]');
                ylabel(app.BoundTracks_plot, 'y [\mum]');
                app.BoundTracks_plot.XTickMode = 'auto';
                app.BoundTracks_plot.YTickMode = 'auto';
                box(app.BoundTracks_plot, 'on');
                hold(app.BoundTracks_plot,  'off');
                figname = app.QuantiTrackBoundMoleculeAnalysisUIFigure.Name;
                figname = [figname ' ' app.ROIname];
                app.QuantiTrackBoundMoleculeAnalysisUIFigure.Name = figname;


                % Calculate bound fraction;
                BoundFraction_tmp(:,1) = 1:app.NFrames;
                if isempty(ImmTracks_tmp)
                    BoundFraction_tmp(:,2) = 0;
                    TrackLengthHist_tmp = [0 0];
                else
                    BoundFraction_tmp(:,2) = zeros(1,app.NFrames);
                    % CountNumber of bound molecules per frame;
                    for i = 1: app.NFrames
                        BoundFraction_tmp(i, 2) = sum(ImmTracks_tmp(:,3) == i);
                    end


                    BoundFraction_tmp(:,2) = BoundFraction_tmp(:,2)./app.NParticles(:,2);
                    
                    BoundFraction_tmp(BoundFraction_tmp(:,2) == Inf, 2) = 1;


                    % Calculate histogram of bound particles (cumulative)
                    TrackLengthHist_tmp = ...
                        calculateTrackLength(app, ImmTracks_tmp, app.FrameTime,app.Parameters.minBoundFrames);

                    % Reduce the cumulative histogram to the exact histogram of residence
                    % times
                    TrackLengthHist_tmp(1:end-1,2) = ...
                        (TrackLengthHist_tmp(1:end-1,2) - TrackLengthHist_tmp(2:end,2));

                    switch app.Answer
                        case 'Yes'
                            % Normalize the histogram of the residence times to the bound fraction;
                            TrackLengthHist_tmp(:,2) = ...
                                TrackLengthHist_tmp(:,2)*mean(BoundFraction_tmp(:,2),'omitnan')/sum(TrackLengthHist_tmp(:,2));
                    end

                end

                app.ImmTracks = ImmTracks_tmp;
                app.BoundFraction = BoundFraction_tmp;
                app.TrackLengthHist = TrackLengthHist_tmp;
                

                %Plot Bound fraction;
                
                plot(app.BoundF_plot, app.BoundFraction(:,1), app.BoundFraction(:,2));
                title(app.BoundF_plot, {'Bound fraction over Time'; ...
                    ['Average Bound Fraction = ', num2str(mean(app.BoundFraction(:,2),'omitnan'))]});
                xlabel(app.BoundF_plot, 'Frame');
                ylabel(app.BoundF_plot, 'Bound Fraction');

                
                loglog(app.ResidenceTime_plot, app.TrackLengthHist(:,1), app.TrackLengthHist(:,2),'LineWidth',1.5);
                title(app.ResidenceTime_plot, 'Histogram of residence times');
                xlabel(app.ResidenceTime_plot, 'Residence Time (s)');
                switch app.Answer
                    case 'Yes'
                        ylabel(app.ResidenceTime_plot, 'Normalized Frequency');
                    case 'No'
                        ylabel(app.ResidenceTime_plot, 'Counts');
                end
                app.BoundF_plot.XTickMode = 'auto';
                app.BoundF_plot.YTickMode = 'auto';
            else
                ImmTracks_tmp = app.Tracks(app.Tracks(:,2) >= app.Parameters.minBoundFrames,:);
                ImmTracks_tmp = ImmTracks_tmp(ImmTracks_tmp(:,3) <= app.Parameters.ThreshL,:);
                if ~isempty(ImmTracks_tmp)
                    TrackLength = ImmTracks_tmp(:,2);
                    LongestTrack = max(TrackLength);
                    ShortestTrack = app.Parameters.minBoundFrames;

                    TrackLengthHist_tmp = zeros(LongestTrack-ShortestTrack + 1,2);
                    TrackLengthHist_tmp(:,1) = (ShortestTrack: LongestTrack)* app.FrameTime;
                    %         TrackLengthHist(:,2) = hist(TrackLength,(ShortestTrack:LongestTrack));
                    for  i = 1:length(TrackLength)
                        TrackLengthHist_tmp(1:TrackLength(i)- ShortestTrack + 1,2) = ...
                            TrackLengthHist_tmp(1:TrackLength(i) - ShortestTrack + 1,2)+1;
                    end
                    % Reduce the cumulative histogram to the exact histogram of residence
                    % times
                    TrackLengthHist_tmp(1:end-1,2) = ...
                        (TrackLengthHist_tmp(1:end-1,2) - TrackLengthHist_tmp(2:end,2));
                else
                    TrackLengthHist_tmp = [];
                end
                app.ImmTracks = ImmTracks_tmp;
                app.BoundFraction = [];
                app.TrackLengthHist = TrackLengthHist_tmp;
                
                if ~isempty(app.TrackLengthHist)
                    bar(app.ResidenceTime_plot, app.TrackLengthHist(:,1), app.TrackLengthHist(:,2));
                    title(app.ResidenceTime_plot, 'Histogram of residence times');
                    xlabel(app.ResidenceTime_plot, 'Residence Time (s)');
                    ylabel(app.ResidenceTime_plot, 'Counts');
                    app.ResidenceTime_plot.XTickMode = 'auto';
                    app.ResidenceTime_plot.YTickMode = 'auto';
                else
                    errordlg('No data fits the criteria. Adjust the Min. Frames, or Maximum Jump and try again','No data for histogram');

                end


            end
            %Store the important values
            OUTvars{1} = app.BoundFraction;
            OUTvars{2} = app.TrackLengthHist;
            OUTvars{3} = app.ImmTracks;
            setappdata(0,'QuantiTrackBoundMol_out',OUTvars);
            close(d);
        end

        % Button pushed function: Close_button
        function Close_buttonButtonPushed(app, event)
            close(app.QuantiTrackBoundMoleculeAnalysisUIFigure);
            
        end

        % Button pushed function: Copy_BoundF
        function Copy_BoundFButtonPushed(app, event)
            % num2clip(app.BoundFraction);
            BF_table = array2table(app.BoundFraction,'VariableNames',...
                {'Frame Number','Bound Fraction'});
            assignin('base','BoundFrac_out',BF_table);
            msgbox({'The Array containing the bound fraction';...
                ' has been copied to the workspace';... 
                '(visible after closing Bound Molecule tool)'});
        end

        % Button pushed function: CopyResTimeHist
        function CopyResTimeHistButtonPushed(app, event)
            num2clip(app.TrackLengthHist);
            switch app.Answer
                case 'Yes'
                    y_lab = 'Normalized Frequency';
                case 'No'
                    y_lab = 'Counts';
            end
            ResTime_table = array2table(app.TrackLengthHist,'VariableNames',...
                {'Time',y_lab});
            assignin('base','ResTimeHist_out',ResTime_table);

            msgbox({'The Array containing the histogram of residence times';...
                ' has been copied to the workspace';... 
                '(visible after closing Bound Molecule tool)'});
        end

        % Button pushed function: SaveFigures
        function SaveFiguresButtonPushed(app, event)
            % Create the figure and save it.
            DefaultName = app.FileName;
            SearchStr = '(.*)\.\w*';
            DefaultName = regexprep(DefaultName, SearchStr, ['$1_' app.ROIname '_BoundTracks']);
            FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
            [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);

            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .6*scrsz(3) .3*scrsz(4)]) ;


            % Plot with the identified bound tracks;
            ax1 = subplot(1,3,1);
            axis(ax1, 'ij');
            hold(ax1,'on');

            for i = 1:max(app.Tracks(:,4))
                
                plot(ax1,app.Tracks(app.Tracks(:,4)==i,1), app.Tracks(app.Tracks(:,4)==i,2),'-ok', 'MarkerSize', 3);
            end


            if  ~isempty(app.ImmTracks)
                for i = 1:max(app.ImmTracks(:,4))
                    
                    plot(app.ImmTracks(app.ImmTracks(:,4)==i,1), app.ImmTracks(app.ImmTracks(:,4)==i,2),'-og', 'MarkerSize', 3);
                end

            end

            title(ax1,{'Identified Bound segments for the following settings';...
                ['Max Jump between consecutive frames = ',...
                num2str(app.Parameters.ThreshL), '\mum'];
                ['Minimum number of consecutive frames = ',...
                num2str(app.Parameters.minBoundFrames)];...
                ['Max Jump within ', num2str(app.Parameters.minBoundFrames),...
                ' frames = ', num2str(app.Parameters.ThreshH), '\mum']});

            xlabel(ax1,'x [\mum]');
            ylabel(ax1,'y [\mum]');
            box(ax1,'on');
            hold(ax1,'off');

            %Plot Bound fraction;
            ax2 = subplot(1, 3, 2);
            plot(ax2, app.BoundFraction(:,1), app.BoundFraction(:,2));
            title(ax2, {'Bound fraction over Time'; ...
                ['Average Bound Fraction = ', num2str(mean(app.BoundFraction(:,2),'omitnan'))]});
            xlabel(ax2, 'Frame');
            ylabel(ax2, 'Bound Fraction');

            % Plot Histogram of residence times;
            ax3 = subplot(1, 3, 3);
            loglog(ax3, app.TrackLengthHist(:,1), app.TrackLengthHist(:,2),'LineWidth',1.5);
            title(ax3, 'Histogram of residence times');
            xlabel(ax3, 'Residence Time (s)');
            switch app.Answer
                case 'Yes'
                    ylabel(ax3, 'Normalized Frequency');
                case 'No'
                    ylabel(ax3, 'Counts');
            end

            
            if FileNameOut ~= 0
                saveas(h1, fullfile(PathNameOut,FileNameOut));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackBoundMoleculeAnalysisUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackBoundMoleculeAnalysisUIFigure.Name = 'QuantiTrack - Bound Molecule Analysis';
            app.BoundTracks_plot = uiaxes(app.QuantiTrackBoundMoleculeAnalysisUIFigure);
            app.BoundTracks_plot.XTick = [];
            app.BoundTracks_plot.YTick = [];
            app.BoundF_plot = uiaxes(app.QuantiTrackBoundMoleculeAnalysisUIFigure);
            app.BoundF_plot.XTick = [];
            app.BoundF_plot.YTick = [];
            app.ResidenceTime_plot = uiaxes(app.QuantiTrackBoundMoleculeAnalysisUIFigure);
            app.Copy_BoundF = uibutton(app.QuantiTrackBoundMoleculeAnalysisUIFigure, 'push');
            app.Copy_BoundF.ButtonPushedFcn = createCallbackFcn(app, @Copy_BoundFButtonPushed, true);
            app.Copy_BoundF.Text = 'Copy Bound Fraction to Clipboard';
            app.CopyResTimeHist = uibutton(app.QuantiTrackBoundMoleculeAnalysisUIFigure, 'push');
            app.CopyResTimeHist.ButtonPushedFcn = createCallbackFcn(app, @CopyResTimeHistButtonPushed, true);
            app.CopyResTimeHist.Text = 'Copy Residence Time Histogram';
            app.SaveFigures = uibutton(app.QuantiTrackBoundMoleculeAnalysisUIFigure, 'push');
            app.SaveFigures.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFigures.Text = 'Save Figures';
            app.Close_button = uibutton(app.QuantiTrackBoundMoleculeAnalysisUIFigure, 'push');
            app.Close_button.ButtonPushedFcn = createCallbackFcn(app, @Close_buttonButtonPushed, true);
            app.Close_button.Tag = 'QuantiTrack - Bound Molecule Analysis';
            app.Close_button.Text = 'DONE';
            % Show the figure after all components are created
            app.QuantiTrackBoundMoleculeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundMolSingle_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackBoundMoleculeAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackBoundMoleculeAnalysisUIFigure)
        end
    end
end