classdef preProcess_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackPreprocessTracksUIFigure  matlab.ui.Figure
        Done            matlab.ui.control.Button
        SaveFigures     matlab.ui.control.Button
        CopyHistogram   matlab.ui.control.Button
        CopyTracks      matlab.ui.control.Button
        CopyNParticles  matlab.ui.control.Button
        MakeMovie       matlab.ui.control.Button
        Track_nn2_Hist  matlab.ui.control.UIAxes
        Track_nn_Hist   matlab.ui.control.UIAxes
        Hist2           matlab.ui.control.UIAxes
        Hist1           matlab.ui.control.UIAxes
        NParticlesAxes  matlab.ui.control.UIAxes
        TrackAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Tracks % Description
        Stack
        Centroids
        pixelSize
        frameTime
        NFrames
        FileName
        ROIpos
        SumImage
        IntensityHist % Description
        Tracks_um % Description
        Intensity % Description
        Nparticles % Description
        NA % Description
        lambda % Description
        NparticlesTracked % Description
        maxJump % Description
        SNR % Description
        track_nn % Description
        track_nn2 % Description
        NN_Hist % Description
        NN2_Hist % Description
        nROIs % Description
        filtStack % Description
        QTmainFigH % Description
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Import data from the workspace
            app.Tracks = varargin{1};        % Import tracks.
            app.Stack = varargin{2};                % Import stacks of images.
            app.filtStack = varargin{3};    %filtered stack
            app.Centroids = varargin{4};     % Import Centroids
            app.pixelSize = varargin{5};     % Import pixelsize
            app.NFrames = varargin{6};       % Import number of frames.
            app.FileName = varargin{7};      %Import FileName.
            app.ROIpos = varargin{8};
            app.NA = varargin{9};
            app.lambda = varargin{10};
            app.frameTime = varargin{11};
            app.maxJump = varargin{12};
            app.QTmainFigH = varargin{13};

            % Find Axes
           
            if length(varargin) > 13
                pos_fig = varargin{14};
                ChangeAppPosition(app.QuantiTrackPreprocessTracksUIFigure,pos_fig);
                drawnow;
            end
            d = uiprogressdlg(app.QuantiTrackPreprocessTracksUIFigure,'Title','QuantiTrack','Indeterminate','on');
            d.Message = 'Processing Tracks...';
            drawnow
            % Plot All the Tracks.

            app.SumImage = zeros(length(app.Stack(1).data(:,1)),...
                length(app.Stack(1).data(1,:)));


            for imageIx =  1:app.NFrames
                app.SumImage = app.SumImage +  double(app.Stack(imageIx).data);
            end
            
            colormap(app.TrackAxes,'gray');
            imagesc(app.TrackAxes,app.SumImage);
            axis(app.TrackAxes,'image');
            hold(app.TrackAxes,'on');
            
            
            for i = 1:1:max(app.Tracks(:,4))
                ix = find(app.Tracks(:,4)==i);
                plot(app.TrackAxes,app.Tracks(ix,1),app.Tracks(ix,2),'LineWidth',1);

            end

            box(app.TrackAxes,'on');
            title(app.TrackAxes,'Tracks of identified particles');
            xlabel(app.TrackAxes,'X-position [px]');
            ylabel(app.TrackAxes,'Y-position [px]');

            hold(app.TrackAxes,'off');
            if size(app.Centroids,2) > 12
                ROIindx = 13;
            elseif size(app.Centroids,2) > 6 && size(app.Centroids,2) < 13
                ROIindx = 7;
            else
                ROIindx = 0;
            end

            if ROIindx > 0
                app.nROIs = length(app.ROIpos);
            else
                app.nROIs = 1;
            end

            % Calculate Number of particles for each frame
            
            app.Nparticles(:,2:app.nROIs+1) = CalculateNparticles(app.Centroids, app.NFrames,app.nROIs);
            app.Nparticles(:,1) = 1:length(app.Nparticles(:,2));

            app.NparticlesTracked(:,2:app.nROIs+1) = CalculateTrackedParticles(app.Tracks,app.NFrames,app.nROIs);
            app.NparticlesTracked(:,1) = 1:length(app.Nparticles(:,2));
            
            plot(app.NParticlesAxes,sum(app.Nparticles(:,2:end),2), 'k');
            hold(app.NParticlesAxes,'on');
            plot(app.NParticlesAxes,sum(app.NparticlesTracked(:,2:end),2),'r');

            legend(app.NParticlesAxes,'Detected Particles','Tracked Particles');
            xlabel(app.NParticlesAxes,'Frame');
            ylabel(app.NParticlesAxes,'Number of particles');
            title(app.NParticlesAxes,'Number of detected particles per frame');

            % Add Airy Intensity to Tracks
            params.lambda = app.lambda;
            params.NA = app.NA;

            app.Tracks = AddAiryIntensity(app.Tracks, ...
                app.pixelSize, app.Stack,params);

            % Calculate histograms of intensities
            app.IntensityHist = [];
            binCents = 50:50:10000;
            binEdges = 25:50:10025;
            app.IntensityHist(:,1) = binCents(:);
            app.IntensityHist(:,2) = (histcounts(app.Tracks(:,6),binEdges))';

            % app.IntensityHist(:,3) = binCents(:);
            app.IntensityHist(:,3) = (histcounts(app.Tracks(:,7),binEdges))';

            % app.IntensityHist(:,5) = binCents(:);
            app.IntensityHist(:,4) = (histcounts(app.Tracks(:,6) - app.Tracks(:,7),binEdges))';
            
            % Plot histograms of Intensity and Background

            bar(app.Hist1,app.IntensityHist(:,1),app.IntensityHist(:,3),'b','EdgeColor', 'none')
            hold(app.Hist1,'on');
            bar(app.Hist1,app.IntensityHist(:,1),app.IntensityHist(:,2),'r', 'EdgeColor', 'none','FaceAlpha',0.75)
            hold(app.Hist1,'off');
            maxPlot = app.IntensityHist(find(app.IntensityHist(:,2) ~= 0, 1,'last'),1);
            if isempty(maxPlot)
                maxPlot = 50;
            end
            if find(app.IntensityHist(:,3) > 0,1,'first') > 2
                minPlot = app.IntensityHist(find(app.IntensityHist(:,3) > 0, 1,'first') - 2,1);
            else
                minPlot = app.IntensityHist(1,1);
            end
            if minPlot == maxPlot
                minPlot = minPlot - 25;
                maxPlot = maxPlot + 25;
            end

            xlim(app.Hist1,[minPlot maxPlot]);


            title(app.Hist1,{'Intensity and Background histograms', 'for tracked particles'});
            xlabel(app.Hist1,'Intensity [AU]')
            ylabel(app.Hist1,'Counts')
            legend(app.Hist1,'Background', 'Particle Intensity','Location','east');

            % KS 2-sample statistic between signal and local background
            [~, ks_p, ks_stat] = kstest2(app.Tracks(:,6), app.Tracks(:,7));
            text(app.Hist1, 0.98, 0.90, ...
                sprintf('KS = %.3f (p = %.2g)', ks_stat, ks_p), ...
                'Units', 'normalized', ...
                'HorizontalAlignment', 'right', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 10, 'FontWeight', 'bold');
            
            %Calculate SNR and the distribution
            app.SNR = (app.Tracks(:,6) - app.Tracks(:,7))./sqrt(app.Tracks(:,6));
            SNRlt1_5 = app.SNR(app.SNR<= 1.5);
            fraclt1_5 = length(SNRlt1_5)./length(app.SNR);


            binCents_SNR = linspace(0.1,100,length(binCents));
            ind1_5 = find(binCents_SNR <= 1.5, 1,'last');

            dBin = binCents_SNR(2) - binCents_SNR(1);
            binEdges_SNR = (binCents_SNR(1) - dBin/2):dBin:(binCents_SNR(end) + dBin/2);
            app.IntensityHist(:,5) = binCents_SNR(:);
            app.IntensityHist(:,6) = (histcounts(app.SNR,binEdges_SNR))';
            [maxBinVal, maxBin] = max(app.IntensityHist(:,6));
            
            %Plot the histogram
            bar(app.Hist2,app.IntensityHist(ind1_5+1:end,5),app.IntensityHist(ind1_5+1:end,6),'b','EdgeColor', 'none');
            hold(app.Hist2,'on');
            bar(app.Hist2,app.IntensityHist(1:ind1_5,5),app.IntensityHist(1:ind1_5,6),'r','EdgeColor', 'none');
            maxPlot = app.IntensityHist(find(app.IntensityHist(:,6) ~= 0, 1,'last'),5);
            xlim(app.Hist2,[0 maxPlot]);

            if app.IntensityHist(maxBin,5) > (maxPlot - app.IntensityHist(maxBin,5))
                textX = 0.5;
            else
                textX = app.IntensityHist(maxBin,5) + 0.5;
            end
            text(app.Hist2,textX,maxBinVal-5,[num2str(100.*fraclt1_5,3),'% SNR < 1.5']);
            xlabel(app.Hist2,'SNR');
            ylabel(app.Hist2,'Counts');
            title(app.Hist2,{'Signal to Noise histogram', 'for tracked particles'});


            %Calculate the frame-to-frame displacement in pixels of the
            %tracked particles and thte second closest neighbor 
            [app.track_nn,  app.track_nn2] = calcTrack_NN(app.Tracks,app.Centroids);
            
            app.track_nn2 = app.track_nn2(app.track_nn2(:,2) >= 0,2);

            % 1-frame jump distances in nm (10-nm bins).
            track_nn_nm = app.track_nn(:) .* app.pixelSize .* 1000;
            maxJump_nm = app.maxJump * app.pixelSize * 1000;
            bin_width_nm = 10;
            max_data_nm = max(max(track_nn_nm, [], 'omitnan'), maxJump_nm);
            
            edge_hi_nm = ceil(max_data_nm / bin_width_nm) * bin_width_nm;
            nnBinEdges = 0:bin_width_nm:edge_hi_nm;
            binCenters = nnBinEdges(1:end-1) + bin_width_nm/2;
            app.NN_Hist = [];
            app.NN_Hist(:,1) = binCenters(:);
            app.NN_Hist(:,2) = histcounts(track_nn_nm, nnBinEdges)';
            % Plot the jump distance
            bar(app.Track_nn_Hist, app.NN_Hist(:,1), app.NN_Hist(:,2), 1.0, 'b', 'EdgeColor', 'none');
            hold(app.Track_nn_Hist, 'on');
            xline(app.Track_nn_Hist, maxJump_nm, '--', ...
                sprintf('MJ = %.0f nm', maxJump_nm), ...
                'Color', [0.85 0.325 0.098], 'LineWidth', 1.5, ...
                'LabelHorizontalAlignment', 'left', 'LabelVerticalAlignment', 'top');
            hold(app.Track_nn_Hist, 'off');
            xlim(app.Track_nn_Hist, [0, edge_hi_nm]);
            xlabel(app.Track_nn_Hist, '1-frame jump distance (nm)');
            ylabel(app.Track_nn_Hist, 'Counts');
            title(app.Track_nn_Hist, 'Verification of Max. Jump Value')
            
            %Get the fraction of 2nd nearest neighbor under the maxmimum
            %jump
            frac_nn2_ltMJ = length(app.track_nn2(app.track_nn2<= app.maxJump)) ./ length(app.track_nn2);
            per_nn2_ltMJ = 100*frac_nn2_ltMJ;


            app.NN2_Hist(:,1) = 0.5:max(app.track_nn2)-0.5;
            if ~isempty(app.track_nn2)

                nn2BinEdges = 0:max(app.track_nn2);
                app.NN2_Hist(:,2) = histcounts(app.track_nn2,nn2BinEdges);
            
                maxJ_bin = find(app.NN2_Hist(:,1) > app.maxJump,1,'first');

                %Plot the 2nd nearest neighbor
                bar(app.Track_nn2_Hist,app.NN2_Hist(maxJ_bin:end,1),app.NN2_Hist(maxJ_bin:end,2),'b','EdgeColor','none');
                hold(app.Track_nn2_Hist,'on');
                bar(app.Track_nn2_Hist,app.NN2_Hist(1:maxJ_bin-1,1),app.NN2_Hist(1:maxJ_bin-1,2),'r','EdgeColor','none');
                xlim(app.Track_nn2_Hist,[0,app.NN2_Hist(end,1)+0.5]);
                text(app.Track_nn2_Hist,max(app.track_nn2)/2,3*max(app.NN2_Hist(:,2))/4,[num2str(per_nn2_ltMJ,3), '% within ', num2str(app.maxJump), ' px']);
                xlabel(app.Track_nn2_Hist,'Distance to 2^{nd} Nearest Neighbor (px)');
                ylabel(app.Track_nn2_Hist,'Counts');
                title(app.Track_nn2_Hist,{'Probabilty of two particles; within Max. Jump distance'})
            else
                warndlg('Not enough Particles to get a 2nd nearest neighbor');
            end

            % Convert the Tracks to microns
            app.Tracks_um = [];
            app.Tracks_um(:,1:2) = app.Tracks(:,1:2)*app.pixelSize;
            app.Tracks_um(:,3:7) = app.Tracks(:,3:7);
            app.Intensity = app.Tracks_um(:,6:7);
            
            set(app.Done,'Enable','on')
            % Update handles structure
            drawnow;

            %Store the important values
            OUTvars{1} = app.Tracks_um;
            OUTvars{2} = app.Nparticles;
            OUTvars{3} = app.NparticlesTracked;
            OUTvars{4} = app.IntensityHist;
            OUTvars{5} = app.SNR;
            OUTvars{6} = app.track_nn;
            OUTvars{7} = app.track_nn2;
            setappdata(app.QTmainFigH,'QuantiTrackPrePro_out',OUTvars);

            close(d);
            drawnow;
            
        end

        % Button pushed function: MakeMovie
        function MakeMovieButtonPushed(app, event)
            
            %Ask if the user wants to use the raw or filtered images
            image2use = questdlg('Do you want to use the raw images or the filtered images','Raw or filtered','Raw','Filtered','Raw');
            if strcmp(image2use,'Raw')
                stack = app.Stack;
            else
                stack = app.filtStack;
            end
            nFrames = app.NFrames;
            clim = [min(stack(end).data(:)),...
                max(stack(end).data(:))];

            green = gray;
            green(:,1) = 0;
            green(:,3) = 0;

            %Ask if user wants a 2D or 3D movie
            movieType = questdlg('Do you want a 2D or 3D movie?','Movie Type','2D','3D','Cancel','2D');
            if strcmp(movieType,'Cancel')
                return
            end
            % movieType = '2D';

            % Save the movie
            DefaultName = app.FileName;
            SearchStr = '(.*)\.\w*';
            DefaultName = regexprep(DefaultName, SearchStr, '$1_Movie');
            FilterSpec = {'*.mp4'};
            [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Movie',DefaultName);


            if FileNameOut ~= 0
                clim_low = min(stack(end).data(:));
                clim_high = max(stack(end).data(:));

                [nrows, ncols] = size(stack(1).data);
                if strcmp(movieType,'2D')
                    defaults = {num2str(clim_low),num2str(clim_high),'20', '0', '50','1',num2str(nrows),'1',num2str(ncols),'2','1'};
                    prompt = {'Movie Black Level','Movie White Level', ...
                        'Minimum Track Length to display', ...
                        'Maximum Time-point to display in Movie (0 = all)', ...
                        'Movie Frame Rate (1/s)','Minimum X','Maximum X'...
                        'Minimum Y','Maximum Y','Scale Bar Length (um)', ...
                        'Time-stamp? (0 = No, 1 = Yes)'};
                else
                    defaults = {num2str(clim_low),num2str(clim_high),...
                        '20', ...
                        '0', ...
                        '50', '1',num2str(nrows),...
                        '1',num2str(ncols),...
                        '1',...
                        '1'};
                    prompt = {'Movie Black Level','Movie White Level', ...
                        'Minimum Track Length to display', ...
                        'Maximum Time-point to display in Movie (0 = all)', ...
                        'Movie Frame Rate (Max. 172) (1/s)','Minimum X','Maximum X'...
                        'Minimum Y','Maximum Y',...
                        'Figure Background Graylevel',...
                        'Axes Background Graylevel'};
                end
                dlgtitle = 'Movie parameters';
                MovParam = inputdlg(prompt,dlgtitle,1,defaults);
                if isempty(MovParam)
                    return
                end
                globMN = str2double(MovParam{1});
                globMX = str2double(MovParam{2});
                minTrkLen = str2double(MovParam{3});
                maxFrame = str2double(MovParam{4});
                movFrameRate = str2double(MovParam{5});
                if movFrameRate > 172
                    f = warndlg('Selected Frame rate exceeds maximum allowed. Setting to maximum value of 172','Frame Rate adjusted');
                    waitfor(f);
                    movFrameRate = 172;
                end
                minX = str2double(MovParam{6});
                maxX = str2double(MovParam{7});
                minY = str2double(MovParam{8});
                maxY = str2double(MovParam{9});
                
                
                if strcmp(movieType,'2D')
                    scalebar_length = str2double(MovParam{10});
                    timestamp_flag = str2double(MovParam{11});
                    
                else
                    figGray = str2double(MovParam{10});
                    axGray = str2double(MovParam{11});
                    fig_bgColor = [figGray figGray figGray];
                    ax_bgColor = [axGray axGray axGray];
                    if fig_bgColor < 0.5
                        textColor = [1 1 1];
                    else
                        textColor = [0 0 0];
                    end
                end

                
%                 %parse the tracking data
%                 tracks = app.Tracks;
% 
%                 if maxFrame == 0 || maxFrame > length(stack)
%                     maxFrame = max(tracks(:,3));
%                 end
%                 tracks = tracks(tracks(:,3) <= maxFrame,:);
%                 trkIDs = unique(tracks(:,4));
%                 trkLen = zeros(length(trkIDs),1);
%                 for i = 1:length(trkIDs)
%                     curTrk = tracks(tracks(:,4) == trkIDs(i),1);
%                     trkLen(i) = length(curTrk);
%                 end
%                 keepIDs = find(trkLen >= minTrkLen);
%                 trkLen_keep = trkLen(keepIDs);
%                 keepIDs = trkIDs(keepIDs);
% 
% 
% 
% %                 line_cmap = jet((max(trkLen_keep) - min(trkLen_keep))+1);
%                 line_cmap = jet(201);
%                 tracks2 = [];
% 
%                 for j = 1:length(keepIDs)
%                     curTrk = tracks(tracks(:,4) == keepIDs(j),:);
%                     tracks2 = [tracks2; curTrk];
%                 end
%                 colorbins = linspace(minTrkLen,200,201);
% 
% 
%                 v = VideoWriter(fullfile(PathNameOut,FileNameOut),'MPEG-4');
%                 v.Quality = 100;
%                 v.FrameRate = movFrameRate;
%                 open(v);
% 
%                 movieFig = figure;
                % set(movieFig,'Color',fig_bgColor);
                if strcmp(movieType,'2D')

                    makeFlatTrackMovie(stack,app.Tracks,app.ROIpos,app.frameTime,...
                        app.pixelSize,fullfile(PathNameOut, FileNameOut), globMN,globMX,...
                        minTrkLen,maxFrame,movFrameRate,minX,maxX,minY,maxY,...
                        scalebar_length,timestamp_flag);
                else

                    makePerspectiveTrackMovie(stack,app.Tracks,app.frameTime,...
                        fullfile(PathNameOut, FileNameOut), globMN,globMX,...
                        minTrkLen,maxFrame,movFrameRate,minX,maxX,minY,maxY,...
                        fig_bgColor,ax_bgColor,textColor);
                end
                figure(app.QuantiTrackPreprocessTracksUIFigure);
            end
        end

        % Button pushed function: CopyNParticles
        function CopyNParticlesButtonPushed(app, event)
            % num2clip(app.Nparticles);
            Npart = [app.Nparticles, app.NparticlesTracked(:,2:end)];

            columnNames = cell((2*app.nROIs)+1,1);
            columnNames{1} = 'Frame Number';
            for i = 1:app.nROIs
                columnNames{i+1} = ['ROI ', num2str(i), ' particles'];
                columnNames{i+app.nROIs + 1} = ['ROI ', num2str(i), ' tracked particles'];

            end

            NpartTable = array2table(Npart,'VariableNames',columnNames);
            assignin('base','NParticles_out',NpartTable);
            
            msgbox({'The Array containing the Number of particles';...
                'per frame has been copied to the workspace as variable NParticles_out';... 
                '(visible after closing PreProcess tool)'});
        end

        % Button pushed function: CopyTracks
        function CopyTracksButtonPushed(app, event)
            % num2clip(app.Tracks_um);
            trackTable = array2table(app.Tracks_um,'VariableNames',...
                {'X-position (um)','Y-position (um)','Frame Number','Track ID','ROI Number','Intensity','Background'});
            assignin('base','Tracks_out',trackTable);

            msgbox({'The Table containing the tracks';...
                'has been copied to the workspace as variable Tracks_out';... 
                '(visible after closing PreProcess tool)'});

        end

        % Button pushed function: CopyHistogram
        function CopyHistogramButtonPushed(app, event)
            % num2clip(app.IntensityHist);
            intensityTable = array2table(app.IntensityHist,'VariableNames',...
                {'Bins','Intensity Counts', 'Background Counts',...
                'BG Subtracted Intensity Counts', 'SNR Bins','SNR Counts'});
            assignin('base','intensityHist_out',intensityTable);
            msgbox({'The Table containing the histograms of intensities';...
                'has been copied to the workspace as variable intensityHist_out';... 
                '(visible after closing PreProcess tool)'});
        end

        % Button pushed function: SaveFigures
        function SaveFiguresButtonPushed(app, event)
             % Save the figures
            DefaultName = app.FileName;
            SearchStr = '(.*)\.\w*';
            DefaultName = regexprep(DefaultName, SearchStr, '$1_preprocess');
            FilterSpec = {'*.fig';'*.ai';'*.bpm';'*.eps';'*.jpeg';'*.pdf';'*.tif'};
            [FileNameOut,PathNameOut] = uiputfile(FilterSpec,'Save Figure',DefaultName);
            
            % Plot Tracks
            scrsz = get(0,'ScreenSize');
            h1 = figure('Position',[.15*scrsz(3) .15*scrsz(4) .75*scrsz(3) .75*scrsz(4)]) ;
            
            %Display image and tracks
            subplot(2,3,1)

            colormap(gray);
            imagesc(app.SumImage);
            axis image
            hold on;
            hold all;


            for i = 1:1:max(app.Tracks(:,4))
                ix = find(app.Tracks(:,4)==i);
                plot(app.Tracks(ix,1),app.Tracks(ix,2),'LineWidth',1);

            end

            box on;
            title('Tracks of identified particles');
            xlabel('X-position [\mum]');
            ylabel('Y-position [\mum]');

            hold off;

            %Plot Particle intensities and Background intensities
            subplot(2,3,2);
            bar(app.IntensityHist(:,1),app.IntensityHist(:,3),'b','EdgeColor', 'none')
            hold on
            bar(app.IntensityHist(:,1),app.IntensityHist(:,2),'r', 'EdgeColor', 'none','FaceAlpha',0.75)
            hold off
            maxPlot = app.IntensityHist(find(app.IntensityHist(:,2) ~= 0, 1,'last'),1);
            if isempty(maxPlot)
                maxPlot = 50;
            end
            xlim(gca,[0 maxPlot]);


            title({'Intensity and Background histograms', 'for tracked particles'});
            xlabel('Intensity [AU]')
            ylabel('Counts')
            legend('Background', 'Particle Intensity','Location','east');
            % KS 2-sample statistic between signal and local background
            [~, ks_p, ks_stat] = kstest2(app.Tracks(:,6), app.Tracks(:,7));
            text(0.98, 0.90, ...
                sprintf('KS = %.3f (p = %.2g)', ks_stat, ks_p), ...
                'Units', 'normalized', ...
                'HorizontalAlignment', 'right', ...
                'VerticalAlignment', 'top', ...
                'FontSize', 10, 'FontWeight', 'bold');
            %Plot the jump distance
            subplot(2,3,3);
            
            maxJump_nm = app.maxJump * app.pixelSize * 1000;
            bar(app.NN_Hist(:,1),app.NN_Hist(:,2),1.0,'b','EdgeColor', 'none');
            hold on
            xline(maxJump_nm, '--', sprintf('MJ = %.0f nm', maxJump_nm), ...
                'Color', [0.85 0.325 0.098], 'LineWidth', 1.5, ...
                'LabelHorizontalAlignment', 'left', 'LabelVerticalAlignment', 'top');
            hold off
            xlim(gca, [0, app.NN_Hist(end,1) + 5]);
            xlabel('1-frame jump distance (nm)');
            ylabel('Counts');
            title('Verification of Max. Jump Value')
            
            %Plot Number of detected and number of tracked particles over
            %time
            subplot(2,3,4);

            plot(sum(app.Nparticles(:,2:end),2), 'k');
            hold on
            plot(sum(app.NparticlesTracked(:,2:end),2),'r');
            xlabel('Frame');
            ylabel('Number of particles');
            title('Number of detected particles per frame');

           
            subplot(2,3,5);
            
            SNRlt1_5 = app.SNR(app.SNR<= 1.5);
            fraclt1_5 = length(SNRlt1_5)./length(app.SNR);

            ind1_5 = find(app.IntensityHist(:,5) <= 1.5, 1,'last');

            
            [maxBinVal, maxBin] = max(app.IntensityHist(:,6));
            
            %Plot the histogram
            bar(app.IntensityHist(ind1_5+1:end,5),app.IntensityHist(ind1_5+1:end,6),'b','EdgeColor', 'none');
            hold(gca,'on');
            bar(app.IntensityHist(1:ind1_5,5),app.IntensityHist(1:ind1_5,6),'r','EdgeColor', 'none');
            maxPlot = app.IntensityHist(find(app.IntensityHist(:,6) ~= 0, 1,'last'),5);
            xlim(gca,[0 maxPlot]);

            if app.IntensityHist(maxBin,5) > (maxPlot - app.IntensityHist(maxBin,5))
                textX = 1;
            else
                textX = app.IntensityHist(maxBin,5) + 5;
            end
            text(textX,maxBinVal-5,[num2str(100.*fraclt1_5,3),'% SNR < 1.5']);
            xlabel('SNR');
            ylabel('Counts');
            title({'Signal to Noise histogram', 'for tracked particles'});

            %%%%
            
            subplot(2,3,6);
            %Get the fraction of 2nd nearest neighbor under the maxmimum
            %jump
            frac_nn2_ltMJ = length(app.track_nn2(app.track_nn2<= app.maxJump)) ./ length(app.track_nn2);
            per_nn2_ltMJ = 100*frac_nn2_ltMJ;


            maxJ_bin = find(app.NN2_Hist(:,1) > app.maxJump,1,'first');

            %Plot the 2nd nearest neighbor
            bar(app.NN2_Hist(maxJ_bin:end,1),app.NN2_Hist(maxJ_bin:end,2),'b','EdgeColor','none');
            hold(gca,'on');
            bar(app.NN2_Hist(1:maxJ_bin-1,1),app.NN2_Hist(1:maxJ_bin-1,2),'r','EdgeColor','none');
            xlim(gca,[0,app.NN2_Hist(end,1)+0.5]);
            text(max(app.track_nn2)/2,3*max(app.NN2_Hist(:,2))/4,[num2str(per_nn2_ltMJ,3), '% within ', num2str(app.maxJump), ' px']);
            xlabel('Distance to 2^{nd} Nearest Neighbor (px)');
            ylabel('Counts');
            title({'Probabilty of two particles'; 'within Max. Jump distance'})

            %%%


            if FileNameOut ~= 0
                saveas(h1, [PathNameOut,FileNameOut]);
            end
        end

        % Button pushed function: Done
        function DoneButtonPushed(app, event)
            close(app.QuantiTrackPreprocessTracksUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackPreprocessTracksUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackPreprocessTracksUIFigure.Name = 'QuantiTrack - Preprocess Tracks';
            app.TrackAxes = uiaxes(app.QuantiTrackPreprocessTracksUIFigure);
            app.NParticlesAxes = uiaxes(app.QuantiTrackPreprocessTracksUIFigure);
            app.Hist1 = uiaxes(app.QuantiTrackPreprocessTracksUIFigure);
            app.Hist2 = uiaxes(app.QuantiTrackPreprocessTracksUIFigure);
            app.Track_nn_Hist = uiaxes(app.QuantiTrackPreprocessTracksUIFigure);
            app.Track_nn2_Hist = uiaxes(app.QuantiTrackPreprocessTracksUIFigure);
            app.MakeMovie = uibutton(app.QuantiTrackPreprocessTracksUIFigure, 'push');
            app.MakeMovie.ButtonPushedFcn = createCallbackFcn(app, @MakeMovieButtonPushed, true);
            app.MakeMovie.Text = 'Make Movie of Tracks';
            app.CopyNParticles = uibutton(app.QuantiTrackPreprocessTracksUIFigure, 'push');
            app.CopyNParticles.ButtonPushedFcn = createCallbackFcn(app, @CopyNParticlesButtonPushed, true);
            app.CopyNParticles.Text = 'Copy Number of Particles/Frame to Workspace';
            app.CopyTracks = uibutton(app.QuantiTrackPreprocessTracksUIFigure, 'push');
            app.CopyTracks.ButtonPushedFcn = createCallbackFcn(app, @CopyTracksButtonPushed, true);
            app.CopyTracks.Text = 'Copy Tracks to Workspace';
            app.CopyHistogram = uibutton(app.QuantiTrackPreprocessTracksUIFigure, 'push');
            app.CopyHistogram.ButtonPushedFcn = createCallbackFcn(app, @CopyHistogramButtonPushed, true);
            app.CopyHistogram.Text = 'Copy Intensity Histograms to Workspace';
            app.SaveFigures = uibutton(app.QuantiTrackPreprocessTracksUIFigure, 'push');
            app.SaveFigures.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFigures.Text = 'Save Figures';
            app.Done = uibutton(app.QuantiTrackPreprocessTracksUIFigure, 'push');
            app.Done.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.Done.Text = 'DONE';
            % Show the figure after all components are created
            app.QuantiTrackPreprocessTracksUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preProcess_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackPreprocessTracksUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackPreprocessTracksUIFigure)
        end
    end
end