classdef BeadCalibration_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackBeadAlignmentUIFigure  matlab.ui.Figure
        SaveButton                  matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        TranTypePop                 matlab.ui.control.DropDown
        DropDownLabel               matlab.ui.control.Label
        Image3Lambda                matlab.ui.control.NumericEditField
        Image2Lambda                matlab.ui.control.NumericEditField
        FilenameLabel               matlab.ui.control.Label
        Image1Lambda                matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel  matlab.ui.control.Label
        Image3Name                  matlab.ui.control.Label
        Image2Name                  matlab.ui.control.Label
        Image1Name                  matlab.ui.control.Label
        Image3Load                  matlab.ui.control.Button
        Image2Load                  matlab.ui.control.Button
        Image1Load                  matlab.ui.control.Button
        LoadRaw                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data % Description
        Software % Description
        mainFigH % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                pos_fig = varargin{1};
                ChangeAppPosition(app.QuantiTrackBeadAlignmentUIFigure,pos_fig);
                drawnow;
                if length(varargin) > 1
                    app.mainFigH = varargin{2};
                end
            end
            app.Data.I1 = [];
            app.Data.I2 = [];
            app.Data.I3 = [];
            app.Data.Filepath = [];

            drawnow;

        end

        % Button pushed function: Image1Load
        function Image1LoadButtonPushed(app, event)
            
            [fname, pname] = uigetfile('*.tif','Open Primary Image, to which other images will be aligned.');
            figure(app.QuantiTrackBeadAlignmentUIFigure);
            if fname ~= 0
                app.Data.FilePath = pname;
                app.Data.I1 = imread([pname, fname]);
                app.Data.I1 = double(app.Data.I1);
                app.Data.I1 = (app.Data.I1 - min(app.Data.I1(:)))./(max(app.Data.I1(:)) - min(app.Data.I1(:)));
                app.Image1Name.Text = fname;
                app.Image2Load.Enable = 'on';
                lambda = inputdlg('Enter the wavelength in nm of this image','Channel Wavelength',1,{'488'});
                app.Image1Lambda.Value = str2double(lambda{1});
                app.Image1Lambda.Enable = 'on';
                set(app.Image1Lambda,'Enable','on');

            end
        end

        % Button pushed function: Image2Load
        function Image2LoadButtonPushed(app, event)
            
            [fname, pname] = uigetfile('*.tif','Open First Secondary Image',app.Data.FilePath);
            figure(app.QuantiTrackBeadAlignmentUIFigure);
            if fname ~= 0

                app.Data.I2 = imread([pname, fname]);
                app.Data.I2 = double(app.Data.I2);
                app.Data.I2 = (app.Data.I2 - min(app.Data.I2(:)))./(max(app.Data.I2(:)) - min(app.Data.I2(:)));
                app.Image2Name.Text = fname;
                app.Image3Load.Enable = 'on';
                lambda = inputdlg('Enter the wavelength in nm of this image','Channel Wavelength',1,{'561'});
                app.Image2Lambda.Value = str2double(lambda{1});
                app.Image2Lambda.Enable = 'on';
                app.TranTypePop.Enable = 'on';
                app.RunButton.Enable = 'on';
            end
        end

        % Button pushed function: Image3Load
        function Image3LoadButtonPushed(app, event)
            [fname, pname] = uigetfile('*.tif','Open Other Secondary Image',app.Data.FilePath);
            figure(app.QuantiTrackBeadAlignmentUIFigure);
            if fname ~= 0

                app.Data.I3 = imread([pname, fname]);
                app.Data.I3 = double(app.Data.I3);
                app.Data.I3 = (app.Data.I3 - min(app.Data.I3(:)))./(max(app.Data.I3(:)) - min(app.Data.I3(:)));
                set(app.Image3Name,'String',fname);
                %     set(handles.Image3Load,'Enable','on');
                lambda = inputdlg('Enter the wavelength in nm of this image','Channel Wavelength',1,{'647'});
                app.Image3Lambda.Value = str2double(lambda{1});
                app.Image3Lambda.Enable = 'on';
                app.TranTypePop.Enable = 'on';
                app.RunButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadRaw
        function LoadRawButtonPushed(app, event)
           
            [fname, pname] = uigetfile('*.tif','Open Image Stack');
            figure(app.QuantiTrackBeadAlignmentUIFigure);
            if fname ~= 0
                %re-initialize buttons and displays
                app.RunButton.Enable = 'off';
                app.Image2Load.Enable = 'off';
                app.Image3Load.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.TranTypePop.Enable = 'off';

                app.Image1Name.Text = '';
                app.Image2Name.Text = '';
                app.Image3Name.Text = '';
                
                app.Image1Lambda.Value = [];
                app.Image1Lambda.Enable = 'off';
                app.Image2Lambda.Value = [];
                app.Image2Lambda.Enable = 'off';
                app.Image3Lambda.Value = [];
                app.Image3Lambda.Enable = 'off';

                drawnow;

                %save the image name and path to handles structure
                app.Data.ImageFilename = fname;
                app.Data.ImageFilepath = pname;


                channelOrderStrCell = inputdlg('Enter the channel order for the loaded image stack as comma seperataed values','Channel Order',1,{'647,561,488'});
                [ImageStack, nImages] = TIFread(fullfile(pname,fname));
                app.Data.RawImages = ImageStack;
                channelOrderStr = channelOrderStrCell{1,:};
                %Determine how many channels are specified
                commaPos = strfind(channelOrderStr,',');
                fname2 = cell(3,1);
                if length(commaPos) == 1
                    nChan = 2;
                    wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                    wl1_str = num2str(wl1_num);
                    fname2{1,:} = [app.Data.ImageFilename(1:end-4),'_',wl1_str,'.tif'];
                    wl2_num = str2double(channelOrderStr(commaPos(1)+1:end));
                    wl2_str = num2str(wl2_num);
                    fname2{2,:} = [app.Data.ImageFilename(1:end-4),'_',wl2_str,'.tif'];

                    app.Data.wls = [wl1_num;wl2_num];

                elseif length(commaPos) == 2
                    nChan = 3;
                    wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                    wl1_str = num2str(wl1_num);
                    fname2{1,:} = [app.Data.ImageFilename(1:end-4),'_',wl1_str,'.tif'];
                    wl2_num = str2double(channelOrderStr(commaPos(1)+1:commaPos(2)-1));
                    wl2_str = num2str(wl2_num);
                    fname2{2,:} = [app.Data.ImageFilename(1:end-4),'_',wl2_str,'.tif'];
                    wl3_num = str2double(channelOrderStr(commaPos(2)+1:end));
                    wl3_str = num2str(wl3_num);
                    fname2{3,:} = [app.Data.ImageFilename(1:end-4),'_',wl3_str,'.tif'];

                    app.Data.wls = [wl1_num;wl2_num;wl3_num];
                elseif isempty(commaPos)
                    errordlg('A single channel was specified, or commas were missing','Unrecognized string format');
                elseif length(commaPos) > 2
                    errordlg('Splitting can currently be done for a maximum of 3 wavelengths ','Too many input channels');
                end

                %Initialize image files
                app.Data.ImAll = cell(nChan,1);
                counter = ones(nChan,1);
                matlabpath = path;
                sc_pos = strfind(matlabpath,pathsep);

                app.Software.TempSaveDir = [matlabpath(1:sc_pos(1)-1), filesep, 'tmp',filesep]; %temporary save directory to avoid the error when saving to a folder that is open in Windows explorer
                TempDirExists = dir(app.Software.TempSaveDir);
                if isempty(TempDirExists)
                    mkdir(app.Software.TempSaveDir);
                end

                for i = 1:nImages
                    im_tmp = app.Data.RawImages(i).data;
                    if rem(i,nChan) == 1

                        app.Data.I1(:,:,counter(1)) = im_tmp;
                        counter(1) = counter(1) + 1;
                        fnum = 1;
                    elseif rem(i,nChan) == 2

                        app.Data.I2(:,:,counter(2)) = im_tmp;
                        counter(2) = counter(2) + 1;
                        fnum = 2;
                    elseif rem(i,nChan) == 0 && nChan == 2

                        app.Data.I2(:,:,counter(2)) = im_tmp;
                        counter(2) = counter(2) + 1;
                        fnum = 2;
                    elseif rem(i,nChan) == 0 && nChan == 3

                        app.Data.I3(:,:,counter(3)) = im_tmp;
                        counter(3) = counter(3) + 1;
                        fnum = 3;
                    end
                    
                end
                % Create the images to be used for registration (either the only image
                % or a max. projection).
                if nImages == nChan
                    app.Data.I1 = app.Data.I1(:,:,1);

                    app.Data.I2 = app.Data.I2(:,:,1);

                    if nChan > 2
                        app.Data.I3 = app.Data.I3(:,:,1);

                    end
                else
                    app.Data.I1 = max(app.Data.I1,[],3);
                    app.Data.I2 = max(app.Data.I2,[],3);
                    if nChan > 2
                        app.Data.I3 = max(app.Data.I3,[],3);
                    end
                end
                app.Data.I1 = double(app.Data.I1);
                app.Data.I1 = (app.Data.I1 - min(app.Data.I1(:)))./(max(app.Data.I1(:)) - min(app.Data.I1(:)));
                app.Data.I2 = double(app.Data.I2);
                app.Data.I2 = (app.Data.I2 - min(app.Data.I2(:)))./(max(app.Data.I2(:)) - min(app.Data.I2(:)));
                if nChan > 2
                    app.Data.I3 = double(app.Data.I3);
                    app.Data.I3 = (app.Data.I3 - min(app.Data.I3(:)))./(max(app.Data.I3(:)) - min(app.Data.I3(:)));
                end
                app.Image1Lambda.Value = app.Data.wls(1);
                app.Image1Lambda.Enable = 'on';
                app.Image2Lambda.Enable = 'on';
                app.Image3Lambda.Enable = 'on';
                app.Image1Name.Text = [fname, ' Chan 1'];
                app.Image1Load.Enable  = 'on';
                app.Image2Lambda.Value = app.Data.wls(2);
                app.Image2Name.Text = [fname, ' Chan 2'];
                app.Image2Load.Enable = 'on';
                app.Image3Load.Enable = 'on';
                if nChan > 2
                    app.Image3Lambda.Value = app.Data.wls(3);
                    app.Image3Name.Text = [fname, ' Chan 3'];
                end
                app.RunButton.Enable = 'on';
                app.TranTypePop.Enable = 'on';
                

            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            if isempty(app.Data.I1)
                errordlg('No Primary image loaded');
                return;
            end
            if isempty(app.Data.I2)
                errordlg('No Secondary image loaded');
                return
            end
            [movingPts1, fixedPts1] = cpselect(app.Data.I2,app.Data.I1,'Wait',true);
            tmp = movingPts1;
            tmp(:,6) = ones(length(movingPts1),1);
            tmp(:,7) = ones(length(movingPts1),1);
            stack(1).data = app.Data.I2;
            Particles = peak_fit_psf(stack,...
                tmp,3,3,0);
            movingPts1 = Particles(:,10:11);

            tmp = fixedPts1;
            tmp(:,6) = ones(length(fixedPts1),1);
            tmp(:,7) = ones(length(fixedPts1),1);
            stack(1).data = app.Data.I1;
            Particles = peak_fit_psf(stack,...
                tmp,3,3,0);
            fixedPts1 = Particles(:,10:11);


            %Determine the type of transformation to do
            TranType = app.TranTypePop.Value;
            
            RegMat{1,:} = fitgeotrans(movingPts1,fixedPts1,TranType);
            % RegMat{1,:} = TwoColorImageRegistration(handles.Data.I1,handles.Data.I2,TranType);
            wavelengths(1,:) = [app.Image1Lambda.Value, app.Image2Lambda.Value];

            % eval(['RegMat_', get(handles.Image1Lambda,'String'), '_', get(handles.Image2Lambda,'String'), ' = TwoColorImageRegistration(handles.Data.I1,handles.Data.I2,TranType);']);

            if ~isempty(app.Data.I3)
                [movingPts2, fixedPts2] = cpselect(app.Data.I3,app.Data.I1,movingPts1,fixedPts1,'Wait',true);
                tmp = movingPts2;
                tmp(:,6) = ones(length(movingPts2),1);
                tmp(:,7) = ones(length(movingPts2),1);
                stack(1).data = app.Data.I3;
                Particles = peak_fit_psf(stack,...
                    tmp,3,3,0);
                movingPts2 = Particles(:,10:11);

                tmp = fixedPts2;
                tmp(:,6) = ones(length(fixedPts2),1);
                tmp(:,7) = ones(length(fixedPts2),1);
                stack(1).data = app.Data.I1;
                Particles = peak_fit_psf(stack,...
                    tmp,3,3,0);
                fixedPts2 = Particles(:,10:11);

                RegMat{2,:} = fitgeotrans(movingPts2,fixedPts2,TranType);
                %     RegMat{2,:} = TwoColorImageRegistration(handles.Data.I1,handles.Data.I3,TranType);
                %     [movingPts3, fixedPts3] = cpselect(handles.Data.I3,handles.Data.I2,movingPts2,movingPts1,'Wait',true);
                movingPts3 = movingPts2;
                fixedPts3 = movingPts1;
                RegMat{3,:} = fitgeotrans(movingPts3,fixedPts3,TranType);
                %     RegMat{3,:} = TwoColorImageRegistration(handles.Data.I2,handles.Data.I3,TranType);
                wavelengths(2,:) = [app.Image1Lambda.Value, app.Image3Lambda.Value];
                wavelengths(3,:) = [app.Image2Lambda.Value, app.Image3Lambda.Value];


            end
            app.Data.RegMat = RegMat;
            app.Data.wavelengths = wavelengths;
            app.SaveButton.Enable = 'on';
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            CalibPath = app.Data.ImageFilepath;
            Im1Lambda = num2str(app.Image1Lambda.Value);
            
            Im2Lambda = num2str(app.Image2Lambda.Value);
            

            defName = [CalibPath, filesep, 'Calibration_', date, '_', Im1Lambda,'_',Im2Lambda];
            if ~isempty(app.Data.I3)
                Im3Lambda = num2str(app.Image3Lambda.Value);
                
                defName = [defName, '_', Im3Lambda];
            end
            defName = [defName,'.mat'];

            RegMat = app.Data.RegMat;

            wavelengths = app.Data.wavelengths;

            [fname,pname] = uiputfile('*.mat','Save Calibration Data',defName);

            if fname ~= 0
                save([pname, fname],'RegMat','wavelengths');
                if ~isempty(app.mainFigH)
                    setappdata(app.mainFigH,'RegFullFile',fullfile(pname,fname));
                end
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackBeadAlignmentUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackBeadAlignmentUIFigure.Name = 'QuantiTrack - Bead Alignment';
            app.LoadRaw = uibutton(app.QuantiTrackBeadAlignmentUIFigure, 'push');
            app.LoadRaw.ButtonPushedFcn = createCallbackFcn(app, @LoadRawButtonPushed, true);
            app.LoadRaw.Text = 'Load Multichannel Image Stack';
            app.Image1Load = uibutton(app.QuantiTrackBeadAlignmentUIFigure, 'push');
            app.Image1Load.ButtonPushedFcn = createCallbackFcn(app, @Image1LoadButtonPushed, true);
            app.Image1Load.Text = 'Load Image 1';
            app.Image2Load = uibutton(app.QuantiTrackBeadAlignmentUIFigure, 'push');
            app.Image2Load.ButtonPushedFcn = createCallbackFcn(app, @Image2LoadButtonPushed, true);
            app.Image2Load.Enable = 'off';
            app.Image2Load.Text = 'Load Image 2';
            app.Image3Load = uibutton(app.QuantiTrackBeadAlignmentUIFigure, 'push');
            app.Image3Load.ButtonPushedFcn = createCallbackFcn(app, @Image3LoadButtonPushed, true);
            app.Image3Load.Enable = 'off';
            app.Image3Load.Text = 'Load Image 3';
            app.Image1Name = uilabel(app.QuantiTrackBeadAlignmentUIFigure);
            app.Image1Name.Text = '';
            app.Image2Name = uilabel(app.QuantiTrackBeadAlignmentUIFigure);
            app.Image2Name.Text = '';
            app.Image3Name = uilabel(app.QuantiTrackBeadAlignmentUIFigure);
            app.Image3Name.Text = '';
            app.WavelengthnmEditFieldLabel = uilabel(app.QuantiTrackBeadAlignmentUIFigure);
            app.WavelengthnmEditFieldLabel.Enable = 'off';
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.Image1Lambda = uieditfield(app.QuantiTrackBeadAlignmentUIFigure, 'numeric');
            app.Image1Lambda.AllowEmpty = 'on';
            app.Image1Lambda.Enable = 'off';
            app.FilenameLabel = uilabel(app.QuantiTrackBeadAlignmentUIFigure);
            app.FilenameLabel.Text = 'Filename';
            app.Image2Lambda = uieditfield(app.QuantiTrackBeadAlignmentUIFigure, 'numeric');
            app.Image2Lambda.AllowEmpty = 'on';
            app.Image2Lambda.Enable = 'off';
            app.Image3Lambda = uieditfield(app.QuantiTrackBeadAlignmentUIFigure, 'numeric');
            app.Image3Lambda.AllowEmpty = 'on';
            app.Image3Lambda.Enable = 'off';
            app.DropDownLabel = uilabel(app.QuantiTrackBeadAlignmentUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.TranTypePop = uidropdown(app.QuantiTrackBeadAlignmentUIFigure);
            app.TranTypePop.Items = {'affine', 'nonreflectivesimilarity', 'similarity'};
            app.TranTypePop.Value = 'affine';
            app.RunButton = uibutton(app.QuantiTrackBeadAlignmentUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run Calibration';
            app.SaveButton = uibutton(app.QuantiTrackBeadAlignmentUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save Calibration File';
            % Show the figure after all components are created
            app.QuantiTrackBeadAlignmentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeadCalibration_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackBeadAlignmentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackBeadAlignmentUIFigure)
        end
    end
end