classdef CalcNmin_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackNminCalculatorUIFigure  matlab.ui.Figure
        N_minLabel                  matlab.ui.control.Label
        CalcButton                  matlab.ui.control.Button
        PthEdit                     matlab.ui.control.Spinner
        ProbabilityThresholdSpinnerLabel  matlab.ui.control.Label
        DiffCoefEdit                matlab.ui.control.Spinner
        DiffusionCoefficientum2sSpinnerLabel  matlab.ui.control.Label
        T_intEdit                   matlab.ui.control.Spinner
        FrameIntervalsSpinnerLabel  matlab.ui.control.Label
        RmaxEdit                    matlab.ui.control.Spinner
        r_maxumSpinnerLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                pos_fig = varargin{1};
                ChangeAppPosition(app.QuantiTrackNminCalculatorUIFigure,pos_fig);
                drawnow;

            end
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            rmax = app.RmaxEdit.Value;
            T_int = app.T_intEdit.Value;
            D = app.DiffCoefEdit.Value;
            P_th = app.PthEdit.Value;

            [Nmin,~] = calculateNmin(rmax,T_int,D,P_th);
            app.N_minLabel.Text = ['N_min = ', num2str(Nmin)];

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackNminCalculatorUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackNminCalculatorUIFigure.Name = 'QuantiTrack - Nmin Calculator';
            app.r_maxumSpinnerLabel = uilabel(app.QuantiTrackNminCalculatorUIFigure);
            app.r_maxumSpinnerLabel.Text = 'r_max (um)';
            app.RmaxEdit = uispinner(app.QuantiTrackNminCalculatorUIFigure);
            app.RmaxEdit.Value = 0.22;
            app.FrameIntervalsSpinnerLabel = uilabel(app.QuantiTrackNminCalculatorUIFigure);
            app.FrameIntervalsSpinnerLabel.Text = 'Frame Interval (s)';
            app.T_intEdit = uispinner(app.QuantiTrackNminCalculatorUIFigure);
            app.T_intEdit.Value = 0.2;
            app.DiffusionCoefficientum2sSpinnerLabel = uilabel(app.QuantiTrackNminCalculatorUIFigure);
            app.DiffusionCoefficientum2sSpinnerLabel.Text = 'Diffusion Coefficient (um^2/s)';
            app.DiffCoefEdit = uispinner(app.QuantiTrackNminCalculatorUIFigure);
            app.DiffCoefEdit.Value = 0.5;
            app.ProbabilityThresholdSpinnerLabel = uilabel(app.QuantiTrackNminCalculatorUIFigure);
            app.ProbabilityThresholdSpinnerLabel.Text = 'Probability Threshold';
            app.PthEdit = uispinner(app.QuantiTrackNminCalculatorUIFigure);
            app.PthEdit.Value = 0.01;
            app.CalcButton = uibutton(app.QuantiTrackNminCalculatorUIFigure, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calculate';
            app.N_minLabel = uilabel(app.QuantiTrackNminCalculatorUIFigure);
            app.N_minLabel.Text = 'N_min = ';
            % Show the figure after all components are created
            app.QuantiTrackNminCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalcNmin_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackNminCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackNminCalculatorUIFigure)
        end
    end
end