classdef ROI_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIfigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadImageMenu                matlab.ui.container.Menu
        SaveROIsMenu                 matlab.ui.container.Menu
        ROIsMenu                     matlab.ui.container.Menu
        SetautoROIparametersMenu     matlab.ui.container.Menu
        FindsmallmovingROIsMenu      matlab.ui.container.Menu
        FindlargeimmobileROIsMenu    matlab.ui.container.Menu
        ImportROIsfromMetamorphMenu  matlab.ui.container.Menu
        ImportROIsfromImageJMenu     matlab.ui.container.Menu
        ChanneltoTrackDropDown       matlab.ui.control.DropDown
        ChanneltoTrackDropDownLabel  matlab.ui.control.Label
        AutoThreshLabel              matlab.ui.control.Label
        ProjectionTypeDropDown       matlab.ui.control.DropDown
        ProjectionTypeDropDownLabel  matlab.ui.control.Label
        DeleteROIbutton              matlab.ui.control.Button
        nFrameText                   matlab.ui.control.Label
        text7                        matlab.ui.control.Label
        frameSlider                  matlab.ui.control.Slider
        ROIClassCur_text             matlab.ui.control.Label
        ChangeROIClass               matlab.ui.control.Button
        text5                        matlab.ui.control.Label
        text4                        matlab.ui.control.Label
        text3                        matlab.ui.control.Label
        WhiteValEdit                 matlab.ui.control.EditField
        BlackValEdit                 matlab.ui.control.EditField
        BlackValSlider               matlab.ui.control.Slider
        WhiteValSlider               matlab.ui.control.Slider
        AdjCntr                      matlab.ui.control.Button
        ROIList                      matlab.ui.control.ListBox
        text2                        matlab.ui.control.Label
        CircDiamText                 matlab.ui.control.EditField
        CircleROIbutton              matlab.ui.control.Button
        Savebutton                   matlab.ui.control.Button
        ROIbutton                    matlab.ui.control.Button
        LoadImage                    matlab.ui.control.Button
        ImAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % storage for image
        Process %storage of ROIs
        Current %storage for current values
        out
        Profiles % Description
        curProfileID % Description
        
        useParallel % Description
        Parameters % Description
        trackChanName % Description
        fname_baseStr % Description
    end
    
    methods (Access = private)
        
        function loadImageFromFile(app)
            %Choose file
            [fname, pname] = uigetfile('*.tif','Select an Image file');
            drawnow;
            figure(app.UIfigure);
            
            if fname ~= 0
                
                answer = questdlg('What type of projection do you want to use for the Reference Image',...
                    'Projection Type','Sum','Maximum','Entire Stack','Sum');
                figure(app.UIfigure);
                d1 = uiprogressdlg(app.UIfigure,'Title','Load File', 'Message','Loading Image Stack...','Indeterminate','on');
                im = TIFread_3d(fullfile(pname,fname));
                if isnan(str2double(fname(end - 4)))
                    if isnan(str2double(fname(end-12)))
                        strBase_endOffset = 4;
                        refName = '';
                    else
                        strBase_endOffset = 16;
                        refName = fname((end - strBase_endOffset+2):(end-4));
                    end
                else
                    strBase_endOffset = 8;
                    refName = fname((end - strBase_endOffset+2):(end-4));
                end

                strBase = fname(1:end - strBase_endOffset);
                strBase_length = length(strBase);

                allImFiles = dir(fullfile(pname,[strBase,'*.tif']));
                
                prevUsedChannel = 0;

                app.fname_baseStr = strBase;

                if length(allImFiles) == 1
                    ChanNames = {''};
                    app.ChanneltoTrackDropDown.Items = ChanNames;
                    app.ChanneltoTrackDropDown.Value = '';
                    app.ChanneltoTrackDropDown.Enable = 'off';
                    app.ChanneltoTrackDropDownLabel.Enable = 'off';
                else
                    ChanNum = 1;
                    
                    for i = 1:length(allImFiles)
                        curChan = allImFiles(i,:).name(strBase_length+2:end-4);
                        if ~isempty(curChan) && ~isnan(str2double(curChan(1)))
                            ChanNames{ChanNum,:} = curChan; 
                            if strcmp(curChan,app.trackChanName)
                                prevUsedChannel = ChanNum;
                            end
                            ChanNum = ChanNum + 1;
                        end
                    end
                    app.ChanneltoTrackDropDown.Enable = 'on';
                    app.ChanneltoTrackDropDownLabel.Enable = 'on';
                    app.ChanneltoTrackDropDown.Items = ChanNames;
                    if isempty(app.trackChanName)
                        app.ChanneltoTrackDropDown.Value = refName;
                    else
                        if prevUsedChannel > 0
                            app.ChanneltoTrackDropDown.ValueIndex = prevUsedChannel;
                        end
                    end
                    
                end


                close(d1);
                set(app.BlackValSlider,'Min',0);
                set(app.WhiteValSlider,'Min',0);
                app.data.fname = fname;
                app.data.pname = pname;
                app.data.image = im;
                if strcmp(answer,'Sum')
                    im_sum = double(im(:,:,1));
                    for i = 2:size(im,3)
                        im_sum = im_sum + double(im(:,:,i));
                    end
                    nFrames = 1;
            
                    
                    %set max slider values, and slider steps
                    app.BlackValSlider.Limits = [0,65535*size(im,3)];
                    app.WhiteValSlider.Limits = [0,65535*size(im,3)];
                    
                    app.frameSlider.Enable = 'off';
                    app.nFrameText.Visible = 'off';
                    app.nFrameText.Text = '0/0';
                    app.text7.Visible = 'off'; %"Frame"
                    app.ProjectionTypeDropDown.Value = 'Sum';
                elseif strcmp(answer,'Maximum')
                    im_sum = max(im,[],3);
                    nFrames = 1;
                    %set max slider values, and slider steps
                    app.BlackValSlider.Limits = [0,65535];
                    app.WhiteValSlider.Limits = [0,65535];
                    
                    app.frameSlider.Enable = 'off';
                    app.nFrameText.Visible = 'off';
                    app.nFrameText.Text = '0/0';
                    app.text7.Visible = 'off'; %"Frame"
                    app.ProjectionTypeDropDown.Value = 'Maximum';
                else
                    im_sum = im(:,:,1);
                    nFrames = size(im,3);
                     %set max slider values, and slider steps
                    app.BlackValSlider.Limits = [0,65535];
                    app.WhiteValSlider.Limits = [0,65535];
                    app.frameSlider.Enable = 'on';
                    app.frameSlider.Limits = [1, nFrames];
                    app.frameSlider.Value = 1;
                    
                    app.nFrameText.Visible = 'on';
                    app.nFrameText.Text = ['1/',num2str(nFrames)];
                    app.text7.Visible = 'on';
                    app.ProjectionTypeDropDown.Value = 'Entire Stack';
                end
                app.data.SumImage = im_sum;
                app.data.nFrames = nFrames;
                app.Current.Ix = 1;
                app.Current.clims = [min(im_sum(:)),max(im_sum(:))];
                app.BlackValEdit.Value = num2str(app.Current.clims(1));
                app.WhiteValEdit.Value = num2str(app.Current.clims(2));
                app.BlackValSlider.Value = app.Current.clims(1);
                app.WhiteValSlider.Value = app.Current.clims(2);
                
%                 axes(app.ImAxes);
                imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);
                
                
                app.ImAxes.XLim = [0 size(im_sum,2)];
                app.ImAxes.YLim = [0 size(im_sum,1)];
                
                colormap(app.ImAxes,"gray");
%                 axis image;
                app.Process = [];
                app.Process.ROIClass = [];
                app.Process.ROILabel = [];
                app.ROIList.Items = {};
                app.ROIList.ItemsData = [];
                app.ROIbutton.Enable = 'on';
                app.CircleROIbutton.Enable = 'on';
                app.ProjectionTypeDropDown.Enable = 'on';
                app.DeleteROIbutton.Enable = 'off';
                app.Savebutton.Enable = 'off';
                app.ChangeROIClass.Enable = 'off';
                
                app.SaveROIsMenu.Enable = 'off';
                app.ImportROIsfromMetamorphMenu.Enable = 'on';
                app.ImportROIsfromImageJMenu.Enable = 'on';
                app.FindsmallmovingROIsMenu.Enable = 'on';
                app.FindlargeimmobileROIsMenu.Enable = 'on';

                figName = 'QuantiTrack-ROI Tool';

                app.UIfigure.Name = [figName,': ',fname];


            end
            figure(app.UIfigure);
        end
        
        function saveROIs2File(app)
            if isfield(app.data,'pname')
                defPath = app.data.pname;
            else
                defPath = pwd;
            end
            
            app.trackChanName = app.ChanneltoTrackDropDown.Value;
            if strcmp(app.trackChanName,'')
                ROIsaveName = ['mask-',app.fname_baseStr,'.mat'];
            else
                ROIsaveName = ['mask-',app.fname_baseStr,'_',app.trackChanName,'.mat'];
            end
            % [save_file,save_path] = uiputfile('*.mat','Choose Save location',fullfile(defPath,ROIsaveName));
            save_path = defPath;
            save_file = ROIsaveName;
            if save_file ~= 0
                d1 = uiprogressdlg(app.UIfigure,'Title','Save ROIs', 'Message','Saving ROIs...','Indeterminate','on');
                

                ROIpos = app.Process.ROIpos;
                ROIimage = app.Process.ROIimage;
                if app.data.nFrames > 1
                    dim = size(app.data.image);
                    for i = 1:size(ROIimage,1)
                        for j = 1:size(ROIimage,2)
                            if isempty(ROIimage{i,j})
            
                                ROIimage{i,j} = false(dim(1),dim(2));
                            end
                        end
                    end
            
                    if size(ROIimage,1) < app.data.nFrames
                        for j = size(ROIimage,2)+1:app.data.nFrames
                            for i = 1:size(ROIimage,1)
                                ROIimage{i,j} = false(dim(1),dim(2));
                            end
                        end
                    end
                end
                ROIlabel = app.Process.ROIlabel;
                ROIClass = app.Process.ROIClass;
                save(fullfile(save_path,save_file),'ROIpos','ROIimage','ROIlabel','ROIClass');
                close(d1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ROI_GUI_OpeningFcn(app, varargin)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to ROI_GUI (see VARARGIN)
%             
%             % Choose default command line output for ROI_GUI
%             app.output = hObject;
           
%             % Update handles structure
%             guidata(hObject, handles);
            app.Profiles = varargin{1};
            app.curProfileID = varargin{2};
            app.useParallel = varargin{3};

            %Parameters for automatic ROI detection
            app.Parameters.autoROI.morphRadius = app.Profiles(app.curProfileID).autoROI_params.Morphradius;
            app.Parameters.autoROI.filtWindow = app.Profiles(app.curProfileID).autoROI_params.filtWindow;
            app.Parameters.autoROI.ROIradius = app.Profiles(app.curProfileID).autoROI_params.ROIradius;
            app.Parameters.autoROI.sumTH = app.Profiles(app.curProfileID).autoROI_params.sumTH;
            app.Parameters.autoROI.indTH = app.Profiles(app.curProfileID).autoROI_params.indTH;

        end

        % Button pushed function: AdjCntr
        function AdjCntr_Callback(app, event)
            % hObject    handle to AdjCntr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.BlackValSlider.Value = min(app.data.SumImage(:));
            app.WhiteValSlider.Value = max(app.data.SumImage(:));
            
            app.BlackValEdit.Value = num2str(round(double(min(app.data.SumImage(:)))*100)/100);
            app.WhiteValEdit.Value = num2str(round(double(max(app.data.SumImage(:)))*100)/100);
            app.Current.clims = [min(app.data.SumImage(:)), max(app.data.SumImage(:))];
            set(app.ImAxes,'NextPlot','replacechildren');
            imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);       % plot image
            % axis image;
            
            if isfield(app.Process,'ROIpos')
                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
            
            end
            
        end

        % Value changed function: BlackValEdit
        function BlackValEdit_Callback(app, event)
            % hObject    handle to BlackValEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BlackValEdit as text
            %        str2double(get(hObject,'String')) returns contents of BlackValEdit as a double
            
            MaxBrightnessStack = app.BlackValSlider.Limits(2);
            
            BlackLevel = str2double(app.BlackValEdit.Value);
            %Make sure we don't try to set the brightness outside of the slider limits
            if BlackLevel < 0
                BlackLevel = 0;
            elseif BlackLevel > MaxBrightnessStack
                BlackLevel = MaxBrightnessStack-0.1;
            end
            
            app.BlackValEdit.Value = num2str(round(double(BlackLevel)*100)/100);
            
            WhiteLevel = str2double(app.WhiteValEdit.Value);
            
            if BlackLevel >= WhiteLevel
                WhiteLevel = BlackLevel+0.1;
                app.WhiteValEdit.Value = num2str(round(double(WhiteLevel)*100)/100);
            end
            
            app.BlackValSlider.Value = BlackLevel;
            app.WhiteValSlider.Value = WhiteLevel;
            app.Current.clims = [BlackLevel WhiteLevel];
            
            
            set(app.ImAxes,'NextPlot','replacechildren');
            imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);       % plot image
            % axis image;
            %Plot ROI
            
            if isfield(app.Process,'ROIpos')
                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
            
            end
            
        end

        % Value changed function: BlackValSlider
        function BlackValSlider_Callback(app, event)
            % hObject    handle to BlackValSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            MaxBrightnessStack = app.BlackValSlider.Limits(2);
            
            BlackLevel = app.BlackValSlider.Value;
            WhiteLevel = app.WhiteValSlider.Value;
            
            if BlackLevel == MaxBrightnessStack
                BlackLevel = MaxBrightnessStack -0.1;
            end
            if BlackLevel >= WhiteLevel
                WhiteLevel = BlackLevel+0.1;
                app.WhiteValSlider.Value = WhiteLevel;
            end
            
            app.BlackValEdit.Value = num2str(round(double(BlackLevel)*100)/100);
            app.WhiteValEdit.Value = num2str(round(double(WhiteLevel)*100)/100);
            app.Current.clims = [BlackLevel WhiteLevel];
            
            
            set(app.ImAxes,'NextPlot','replacechildren');
            imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);       % plot image
            % axis image;
            
            
            if isfield(app.Process,'ROIpos')
                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
            
            end
            
        end

        % Button pushed function: ChangeROIClass
        function ChangeROIClass_Callback(app, event)
            % hObject    handle to ChangeROIClass (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isempty(app.Process.ROIClass) || (~isempty(app.Process.ROIClass{1,:}) && app.Process.ROIClass{1,:}{1}(1) == 0)
                ReallyAdd = questdlg('No classifications exist for current dataset, Do you want to add them?','Add Classifications','OK', 'Cancel','Cancel');
            
            else
                ReallyAdd = 'OK';
            end
            
            if strcmp(ReallyAdd,'OK')


                curSel = get(app.ROIList,'Value');
                if isempty(app.Process.ROIClass) || (~isempty(app.Process.ROIClass{1,:}) && app.Process.ROIClass{1,:}{1}(1) == 0)
                    app.Process.ROIClass = cell(size(app.Process.ROIimage));
                    ClassName = inputdlg('Specify the name of the new class:','New Classification',1,{'Nucleus'});
                    app.Process.ROIClass{curSel,:} = ClassName(1);
                    app.Process.AllROIClasses = ClassName(1);
                    app.ROIClassCur_text.Text = ClassName{1};
                else
                    ROIstring = app.Process.AllROIClasses;
                    ROIstring{end+1,1} = 'New...';
                    ButPos = get(hObject,'Position');
                    figPos = get(gcf,'Position');
                    XYPos(1) = (figPos(1) + ButPos(1)*figPos(3));
                    XYPos(2) = (figPos(2) + ButPos(2)*figPos(4));
                    ROIidx = ROIClassChooseDlg(ROIstring,XYPos);
                    if ROIidx < size(ROIstring,1)
                        app.Process.ROIClass{curSel,:} = ROIstring{ROIidx,:};
                    else
                        ClassName = inputdlg('Specify the name of the new class:','New Classification',1,{'Nucleus'});
                        app.Process.ROIClass{curSel,:} = ClassName{1};
                        %Need to verify that it is actually new
                        isnew = 1;
                        for i = 1:size(app.Process.AllROIClasses,1)
                            if strcmpi(ClassName{1},app.Process.AllROIClasses{i,:})
                                isnew = 0;
                                break;
                            end
                        end
                        if isnew == 1
                            app.Process.AllROIClasses{end+1,:} = ClassName{1};
                        else
                            app.Process.ROIClass{curSel,:} = app.Process.AllROIClasses{i,:};
                        end
                    end
                end
                app.ROIClassCur_text.Text = app.Process.ROIClass{curSel,:};
                
            end
        end

        % Button pushed function: CircleROIbutton
        function CircleROIbutton_Callback(app, event)
            % hObject    handle to CircleROIbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(app.Process,'ROIpos') && ~isempty(app.Process.ROIpos)
                nROIs = size(app.Process.ROIpos,1);
                if app.data.nFrames > 1
                    curFrame = round(get(app.frameSlider,'Value'));
                    if size(app.Process.ROIpos,2) >= curFrame
                        ROI = app.Process.ROIpos(:,app.Current.Ix);
                    else
                        ROI = [];
                    end
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
            
            else
                nROIs = 0;
            end
            
            diam = str2double(app.CircDiamText.Value);
            app.ROIbutton.Enable = 0;
            app.CircleROIbutton.Enable = 0;
            
            
            hPoint = drawpoint(app.ImAxes);
            
            newPos = hPoint.Position;
            delete(hPoint);
            
            x1 = -diam/2:0.1:diam/2;
            x2 = -1*x1;
            
            y1 = sqrt((diam/2)^2 - x1.^2);
            y2 = -1*y1;
            x = [x1'; x2'];
            
            y = [y1';y2'];
            x(end) = [];
            y(end) = [];
            x = x + newPos(1);
            y = y + newPos(2);
            x = [x;x(1)];
            y = [y;y(1)];
            if min(x) < 1 || min(y) < 1 || max(x) > size(app.data.image,1) || max(y) > size(app.data.image,2)
                errordlg('New ROI is too close to the image edge','Invalid ROI placement');
                return
            else
                newROIim = false(size(app.data.image(:,:,1)));
                for i = round(min(x)):round(max(x))
                    for j = round(min(y)):round(max(y))
                        if sqrt((i - newPos(1)).^2 + (j - newPos(2)).^2) <= diam/2
                            newROIim(j,i) = 1;
                        end
                    end
                end
                app.out.ROI = newROIim;


%                 axes(app.ImAxes)
            %     hold on
            %     plot(x,y,'r');
            %     hold off;
            end
            if nROIs > 0 && app.data.nFrames > 1
                newOrCurrent = questdlg('Do you want to add to the current ROI or create a new one?','Append or Create','Current','New','Current');
            else
                newOrCurrent = 'New';
            end
            if strcmp(newOrCurrent,'New')
                ROI_label_def = {['ROI ' num2str(nROIs+1)]};
                ROI_label = inputdlg('Create a label for the new ROI:','ROI label',1,ROI_label_def);
                addROI = nROIs+1;
            else
                addROI = nROIs;
                ROI_label = '';
            end
            
            if ~isempty(ROI_label) || strcmp(newOrCurrent,'Current')
                ROI_list = app.ROIList.Items;
                if isempty(ROI_list)
                    ROI_list = cell(1);
                    ROI_list{1,:} = ROI_label{1,:};
                else
                    if strcmp(newOrCurrent,'New')
                        ROI_list{1,nROIs+1} = ROI_label{1,:};
                    end
                end
                app.ROIList.Items = ROI_list;
                app.ROIList.ItemsData = 1:length(ROI_list);
%                 app.ROIList.Items = length(app.ROIList.ItemsData);
                app.ROIList.ValueIndex = addROI;
                if isfield(app.Process,'ROIpos') && ~isempty(app.Process.ROIpos)
                    if app.data.nFrames == 1
                        for j = 1:size(app.Process.ROIpos,2)
            
                            app.Process.ROIpos{nROIs+1,j} = [x,y];
                            app.Process.ROIimage{nROIs+1,j} = newROIim;
                        end
                    else
                        curFrame = get(app.frameSlider,'Value');
                        app.Process.ROIpos{addROI,curFrame} = [x,y];
                        app.Process.ROIimage{addROI,curFrame} = newROIim;
            
                    end
                else
                    app.Process.ROIpos{nROIs+1,1} = [x,y];
                    app.Process.ROIimage{nROIs+1,1} = newROIim;
                end
                app.Process.ROIlabel = app.ROIList.Items;
%                 delete(hPoly);
                set(app.ImAxes,'NextPlot','replacechildren');
                imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);
                %     axis image;
                if isfield(app.Process,'ROIpos') && ~isempty(app.Process.ROIpos)
                    if size(app.Process.ROIpos,2) > 1
                        ROI = app.Process.ROIpos(:,app.Current.Ix);
                    else
                        ROI = app.Process.ROIpos(:,1);
                    end
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
                %Add classes if desired
                if isempty(app.Process.ROIClass)
            
                    AddClassAns = questdlg('Do you want to separate the ROIs into 2 or more classifications?', 'Classify ROIs','Yes','No','Cancel','No');
                    if strcmp(AddClassAns,'Cancel')
%                         delete(hPoly);
                        app.roiButton.Enable = 'on';
                        app.roiRemovePush.Enable = ROIrem_enable;
                        app.roiNamePush.Enable = ROIname_enable;
                    elseif strcmp(AddClassAns,'No')
                        app.Process.ROIClass = {0};
                        app.ROIClassCur_text.Text = 'Not defined';
                        app.ChangeROIClass.Enable = 'on';
                    else
                        ClassName = inputdlg('Specify the name of the new class:','New Classification',1,{'Nucleus'});
                        app.Process.ROIClass = cell(size(app.Process.ROIpos));
                        app.Process.ROIClass{end,:} = ClassName(1);
                        app.Process.AllROIClasses = ClassName(1);
                        app.ROIClassCur_text.Text = ClassName{1};
                        app.ChangeROIClass.Enable = 'on';
                    end
                elseif iscell(app.Process.ROIClass{1,:})
                    ROIstring = app.Process.AllROIClasses;
                    ROIstring{end+1,1} = 'New...';
                    ROIidx = ROIClassChooseDlg(ROIstring);
                    if ROIidx < size(ROIstring,1)
                        app.Process.ROIClass{end+1,:} = ROIstring{ROIidx,:};
                    else
                        ClassName = inputdlg('Specify the name of the new class:','New Classification',1,{'Nucleus'});
                        %Need to verify that it is really new
                        isnew = 1;
                        for i = 1:size(app.Process.AllROIClasses,1)
                            if strcmpi(ClassName{1},app.Process.AllROIClasses{i,:})
                                isnew = 0;
                                break;
                            end
                        end
                        if isnew == 1
                            app.Process.AllROIClasses{end+1,:} = ClassName{1};
                            app.Process.ROIClass{end+1,:} = ClassName{1};
                        else
                            app.Process.ROIClass{end+1,:} = app.Process.AllROIClasses{i,:};
                        end
            
            
                    end
                    app.ROIClassCur_text.Text = app.Process.ROIClass{end,:};
                    app.ChangeROIClass.Enable = 'on';
                else
                    app.ROIClassCur_text.Text = 'Not defined';
                    app.ChangeROIClass.Enable = 'on';
                end
                app.DeleteROIbutton.Enable = 'on';
                app.Savebutton.Enable = 'on';
                app.SaveROIsMenu.Enable = 'on';
            else
%                 delete(hPoint);
                app.ROIButton.Enable = 'on';
%                 app.roiRemovePush.Enable = ROIrem_enable;
%                 app.roiNamePush.Enable = ROIname_enable;
                set(app.ImAxes,'NextPlot','replacechildren');
                imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);
            
            
                if isfield(app.Process,'ROIpos')
                    if size(app.Process.ROIpos,2) > 1
                        ROI = app.Process.ROIpos(:,app.Current.Ix);
                    else
                        ROI = app.Process.ROIpos(:,1);
                    end
                    plotROI(ROI,app.ImAxes,addROI);
            
                end
            
            
            end
            app.ROIbutton.Enable = 'on';
            app.CircleROIbutton.Enable = 'on';
            
        end

        % Button pushed function: DeleteROIbutton
        function DeleteROIbutton_Callback(app, event)
            % hObject    handle to DeleteROIbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curSel = get(app.ROIList,'Value');
            
            app.Process.ROIpos(curSel) = [];
            app.Process.ROIimage(curSel) = [];
            app.Process.ROIlabel(curSel) = [];
            
            ROIlist = app.ROIList.Items;
%             lastInd = length(ROIlist);
%             if curSel == lastInd
%                 if curSel > 1
%                     app.ROIList.Value = curSel - 1;
%                 else
%                     app.ROIList.Value = [];
%                 end
%             end
            ROIlist(curSel) = [];
            app.ROIList.ItemsData(curSel) = [];
            app.ROIList.Items = ROIlist;
            app.ROIList.ItemsData = 1:length(ROIlist);
            plotROI(app.Process.ROIpos,app.ImAxes,curSel);
            if isempty(app.ROIList.Items)
                app.Savebutton.Enable = 'off';
                app.SaveROIsMenu.Enable = 'off';
                app.DeleteROIbutton.Enable = 0;
            end
            
        end

        % Button pushed function: LoadImage
        function LoadImage_Callback(app, event)
            % hObject    handle to LoadImage (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            loadImageFromFile(app);
            
        end

        % Value changed function: ROIList
        function ROIList_Callback(app, event)
            % hObject    handle to ROIList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ROIList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ROIList
            curSel = app.ROIList.ValueIndex;
            if size(app.Process.ROIpos,2) > 1
                ROI = app.Process.ROIpos(:,app.Current.Ix);
            else
                ROI = app.Process.ROIpos(:,1);
            end
            plotROI(ROI,app.ImAxes,curSel);
            if ~isempty(app.Process.ROIClass) && ~isempty(app.Process.ROIClass{1,:}) ...
                    && length(app.Process.ROIClass)>= curSel && ~isempty(app.Process.ROIClass{curSel,:})
                if iscell(app.Process.ROIClass{1,:})
                    app.ROIClassCur_text.Text = app.Process.ROIClass{curSel,:};
                else
                    app.ROIClassCur_text.Text = 'Not Defined';
                end
            else
                app.ROIClassCur_text.Text = 'Not Defined';
            end
        end

        % Button pushed function: ROIbutton
        function ROIbutton_Callback(app, event)
            % hObject    handle to ROIbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %draw the ROI
            if isfield(app.Process,'ROIpos')
                nROIs = size(app.Process.ROIpos,1);
                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
            
            else
                nROIs = 0;
            end
            app.ROIbutton.Enable = 0;
            app.CircleROIbutton.Enable = 0;
            hPoly = drawpolygon(app.ImAxes);
            
            ROI_label_def = {['ROI ' num2str(nROIs+1)]};
            ROI_label = inputdlg('Create a label for the new ROI:','ROI label',1,ROI_label_def);
            
            if ~isempty(ROI_label)
                ROI_list = app.ROIList.Items;
                if isempty(ROI_list)
                    ROI_list = cell(1);
                    ROI_list{1,:} = ROI_label{1,:};
                else
                    ROI_list{1,nROIs+1} = ROI_label{1,:};
                end
                app.ROIList.Items = ROI_list;
                app.ROIList.ItemsData = 1:length(ROI_list);
                app.ROIList.ValueIndex = nROIs+1;
                if isfield(app.Process,'ROIpos')
                    for j = 1:size(app.Process.ROIpos,2)
                        app.Process.ROIpos{nROIs+1,j} = hPoly.Position;
                        app.Process.ROIimage{nROIs+1,j} = createMask(hPoly);
                    end
                else
                    app.Process.ROIpos{nROIs+1,1} = hPoly.Position;
                    app.Process.ROIimage{nROIs+1,1} = createMask(hPoly);
                end
                app.Process.ROIlabel = app.ROIList.Items';
                delete(hPoly);
                set(app.ImAxes,'NextPlot','replacechildren');
                imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);
                %     axis image;
                if isfield(app.Process,'ROIpos')
                    if size(app.Process.ROIpos,2) > 1
                        ROI = app.Process.ROIpos(:,app.Current.Ix);
                    else
                        ROI = app.Process.ROIpos(:,1);
                    end
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
                %Add classes if desired
                if isempty(app.Process.ROIClass)
            
                    AddClassAns = questdlg('Do you want to separate the ROIs into 2 or more classifications?', 'Classify ROIs','Yes','No','Cancel','No');
                    if strcmp(AddClassAns,'Cancel')
                        delete(hPoly);
                        app.ROIButton.Enable = 'on';
                        app.DeleteROIbutton.Enable = ROIrem_enable;
%                         app.roiNamePush.Enable = ROIname_enable;
                    elseif strcmp(AddClassAns,'No')
                        app.Process.ROIClass = {0};
                        app.ROIClassCur_text.Text = 'Not defined';
                        app.ChangeROIClass.Enable = 'on';
                    else
                        ClassName = inputdlg('Specify the name of the new class:','New Classification',1,{'Nucleus'});
                        app.Process.ROIClass = cell(size(app.Process.ROIpos));
                        app.Process.ROIClass{end,:} = ClassName(1);
                        app.Process.AllROIClasses = ClassName(1);
                        app.ROIClassCur_text.Text = ClassName{1};
                        app.ChangeROIClass.Enable = 'on';
                    end
                elseif iscell(app.Process.ROIClass{1,:})
                    ROIstring = app.Process.AllROIClasses;
                    ROIstring{end+1,1} = 'New...';
                    ROIidx = ROIClassChooseDlg(ROIstring);
                    if ROIidx < size(ROIstring,1)
                        app.Process.ROIClass{end+1,:} = ROIstring{ROIidx,:};
                    else
                        ClassName = inputdlg('Specify the name of the new class:','New Classification',1,{'Nucleus'});
                        %Need to verify that it is really new
                        isnew = 1;
                        for i = 1:size(app.Process.AllROIClasses,1)
                            if strcmpi(ClassName{1},app.Process.AllROIClasses{i,:})
                                isnew = 0;
                                break;
                            end
                        end
                        if isnew == 1
                            app.Process.AllROIClasses{end+1,:} = ClassName{1};
                            app.Process.ROIClass{end+1,:} = ClassName{1};
                        else
                            app.Process.ROIClass{end+1,:} = app.Process.AllROIClasses{i,:};
                        end
            
            
                    end
                    app.ROIClassCur_text.Text = app.Process.ROIClass{end,:};
                    app.ChangeROIClass.Enable = 'on';
                else
                    app.ROIClassCur_text.Text = 'Not defined';
                    app.ChangeROIClass.Enable = 'on';
                end
                app.DeleteROIbutton.Enable = 'on';
                app.Savebutton.Enable = 'on';
                app.SaveROIsMenu.Enable = 'on';
                
            else
                delete(hPoly);
                app.roiButton.Enable = 'on';
                app.roiRemovePush.Enable = ROIrem_enable;
                app.roiNamePush.Enable = ROIname_enable;
                set(app.ImAxes,'NextPlot','replacechildren');
                imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);
            
            
                if isfield(app.Process,'ROIpos')
                    if size(app.Process.ROIpos,2) > 1
                        ROI = app.Process.ROIpos(:,app.Current.Ix);
                    else
                        ROI = app.Process.ROIpos(:,1);
                    end
                    plotROI(ROI,app.ImAxes);
            
                end
            
            
            end
            app.ROIbutton.Enable = 'on';
            app.CircleROIbutton.Enable = 'on';
        end

        % Button pushed function: Savebutton
        function Savebutton_Callback(app, event)
            % hObject    handle to Savebutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            saveROIs2File(app);

        end

        % Value changed function: WhiteValEdit
        function WhiteValEdit_Callback(app, event)
            % hObject    handle to WhiteValEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of WhiteValEdit as text
            %        str2double(get(hObject,'String')) returns contents of WhiteValEdit as a double
            MaxBrightnessStack = app.BlackValSlider.Limits(2);
            
            WhiteLevel = str2double(app.WhiteValEdit.Value);
            %Make sure we don't try to set the brightness outside of the slider limits
            if WhiteLevel < 0
                WhiteLevel = 0.1;
            elseif WhiteLevel > MaxBrightnessStack
                WhiteLevel = MaxBrightnessStack;
            end
            
            app.WhiteValEdit.Value = num2str(round(double(WhiteLevel)*100)/100);
            BlackLevel = str2double(app.BlackValEdit.Value);
            
            if WhiteLevel <= BlackLevel
                BlackLevel = WhiteLevel-0.1;
                app.BlackValEdit.Value = num2str(round(double(BlackLevel)*100)/100);
            end
            
            app.BlackValSlider.Value = BlackLevel;
            app.WhiteValSlider.Value = WhiteLevel;
            app.Current.clims = [BlackLevel WhiteLevel];
            
            set(app.ImAxes,'NextPlot','replacechildren');
            imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);       % plot image
            % axis image;
            
            
            if isfield(app.Process,'ROIpos')
                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
            
            end
            
        end

        % Value changed function: WhiteValSlider
        function WhiteValSlider_Callback(app, event)
            % hObject    handle to WhiteValSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            BlackLevel = app.BlackValSlider.Value;
            WhiteLevel = app.WhiteValSlider.Value;
            if WhiteLevel == 0
                WhiteLevel = 0.1;
            end
            
            if WhiteLevel <= BlackLevel
                BlackLevel = WhiteLevel-0.1;
                app.BlackValSlider.Value = BlackLevel;
            end
            
            app.BlackValEdit.Value = num2str(round(double(BlackLevel)*100)/100);
            app.WhiteValEdit.Value = num2str(round(double(WhiteLevel)*100)/100);
            app.Current.clims = [BlackLevel WhiteLevel];
            
            set(app.ImAxes,'NextPlot','replacechildren');
            imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);       % plot image
            % axis image;
            %Plot ROI
            
            if isfield(app.Process,'ROIpos')
                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
            
            end
           
        end

        % Value changed function: frameSlider
        function frameSlider_Callback(app, event)
            % hObject    handle to frameSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            curFrame = round(app.frameSlider.Value);
            app.nFrameText.Text = [num2str(curFrame),'/',num2str(app.data.nFrames)];
            app.Current.Ix = curFrame;
%             axes(app.ImAxes);
            imagesc(app.ImAxes,app.data.image(:,:,curFrame),app.Current.clims);
            colormap(app.ImAxes,gray);
%             axis image;
            
            if isfield(app.Process,'ROIpos') && size(app.Process.ROIpos,2) >= curFrame
                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
            else
                ROI = [];
            end
            plotROI(ROI,app.ImAxes);
            
            
        end

        % Value changed function: ProjectionTypeDropDown
        function ProjectionTypeDropDownValueChanged(app, event)
            value = app.ProjectionTypeDropDown.Value;
            im = app.data.image;
                if strcmp(value,'Sum')
                    im_sum = double(im(:,:,1));
                    for i = 2:size(im,3)
                        im_sum = im_sum + double(im(:,:,i));
                    end
                    nFrames = 1;
            
                    
                    %set max slider values, and slider steps
                    app.BlackValSlider.Limits = [0,65535*size(im,3)];
                    app.WhiteValSlider.Limits = [0,65535*size(im,3)];
                    
                    app.frameSlider.Enable = 'off';
                    app.nFrameText.Visible = 'off';
                    app.nFrameText.Text = '0/0';
                    app.text7.Visible = 'off'; %"Frame"
            
                elseif strcmp(value,'Maximum')
                    im_sum = max(im,[],3);
                    nFrames = 1;
                    %set max slider values, and slider steps
                    app.BlackValSlider.Limits = [0,65535];
                    app.WhiteValSlider.Limits = [0,65535];
                    
                    app.frameSlider.Enable = 'off';
                    app.nFrameText.Visible = 'off';
                    app.nFrameText.Text = '0/0';
                    app.text7.Visible = 'off'; %"Frame"
                    
                else
                    im_sum = im(:,:,1);
                    nFrames = size(im,3);
                     %set max slider values, and slider steps
                    app.BlackValSlider.Limits = [0,65535];
                    app.WhiteValSlider.Limits = [0,65535];
                    app.frameSlider.Enable = 'on';
                    app.frameSlider.Limits = [1, nFrames];
                    app.frameSlider.Value = 1;
                    
                    app.nFrameText.Visible = 'on';
                    app.nFrameText.Text = ['1/',num2str(nFrames)];
                    app.text7.Visible = 'on';
                    
                end
                app.data.SumImage = im_sum;
                app.data.nFrames = nFrames;
                app.Current.Ix = 1;
                app.Current.clims = [min(im_sum(:)),max(im_sum(:))];
                app.BlackValEdit.Value = num2str(app.Current.clims(1));
                app.WhiteValEdit.Value = num2str(app.Current.clims(2));
                app.BlackValSlider.Value = app.Current.clims(1);
                app.WhiteValSlider.Value = app.Current.clims(2);
                
%                 axes(app.ImAxes);
                imagesc(app.ImAxes,app.data.SumImage, app.Current.clims);
                
                
                app.ImAxes.XLim = [0 size(im_sum,2)];
                app.ImAxes.YLim = [0 size(im_sum,1)];
                if ~isempty(app.Process.ROIpos)
                    curSel = app.ROIList.ValueIndex;
                    if isempty(curSel) || curSel == 0
                        curSel = 1;
                        app.ROIList.ValueIndex = 1;
                    end
                    plotROI(app.Process.ROIpos,app.ImAxes,curSel);
                end
                
                colormap(app.ImAxes,"gray");
        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
            loadImageFromFile(app);
        end

        % Menu selected function: SaveROIsMenu
        function SaveROIsMenuSelected(app, event)
            saveROIs2File(app);
        end

        % Menu selected function: ImportROIsfromMetamorphMenu
        function ImportROIsfromMetamorphMenuSelected(app, event)
            [roiFile,filepath] = uigetfile('*.log','Open Metamorph ROI log file',app.data.pname);

            if ~iscell(roiFile)
                roiFile2{1,:} = roiFile;
                roiFile = roiFile2;
            end

            if roiFile{1,:} ~= 0
                imageStr = inputdlg('Enter the pattern to search for in the Outline file','File pattern',1,{app.data.fname(1:7)});

                fid = fopen([filepath,filesep,roiFile{1,:}],'r');
                ind = 1;
                str_ind = 1;
                tmp = fscanf(fid,'%s',1);
                while ~strcmp(tmp,'')

                    if strcmp(tmp,'Calibrations:')
                        for m = 1:19
                            tmp = fscanf(fid,'%s',1);
                        end
                    end

                    if rem(ind,7) == 1
                        Im_names{str_ind,:} = tmp;
                        ind = ind + 1;
                    elseif rem(ind,7) == 2
                        RegName{str_ind,:} = tmp;
                        ind = ind + 1;
                    elseif rem(ind,7) == 3
                        Area(str_ind,:) = str2double(tmp);
                        ind = ind + 1;
                    elseif rem(ind,7) == 4
                        Left(str_ind,:) = str2double(tmp);
                        ind = ind + 1;
                    elseif rem(ind,7) == 5
                        Top(str_ind,:) = str2double(tmp);
                        ind = ind + 1;
                    elseif rem(ind,7) == 6
                        Width(str_ind,:) = str2double(tmp);
                        ind = ind + 1;
                    else
                        Height(str_ind,:) = str2double(tmp);
                        ind = ind + 1;
                        str_ind = str_ind + 1;
                    end
                    tmp = fscanf(fid,'%s',1);
                end

                fclose(fid);

                %Determine the image names within the file
                im_name = unique(Im_names);
                for i = 1:length(im_name)
                    str_ind_find = strfind(im_name,imageStr{1,:});
                    if ~isempty(str_ind_find)
                        str_ind2 = i;
                        break
                    end
                end
                im_ind = find(strcmp(im_name{str_ind2,:},Im_names));
                ImROIs.Left = Left(im_ind,:);
                ImROIs.Top = Top(im_ind,:);
                ImROIs.Width = Width(im_ind,:);
                ImROIs.Height = Height(im_ind,:);
                radii = ImROIs.Width/2;
                centers = [(ImROIs.Left + (ImROIs.Width/2)),(ImROIs.Top + (ImROIs.Width/2))];
                hold on
                ROI_list = cell(length(im_ind),1);
                for j = 1:length(im_ind)
                    hCirc = viscircles(centers(j,:),radii(j,:));
                    CircData = get(hCirc,'Children');
                    x = CircData.XData;
                    x = round(x);
                    y = CircData.YData;
                    y = round(y);
                    y(isnan(x)) = [];
                    x(isnan(x)) = [];
                    x(isnan(y)) = [];
                    y(isnan(y)) = [];
                    app.Process.ROIpos{j,:} = [x',y'];

                    ROI_list{j,:} = ['ROI',num2str(j)];
                    [height,width] = size(app.data.imageStack(1).data);

                    [meshX, meshY] = meshgrid(1:width, 1:height);
                    ROIimage_tmp = zeros(width,height);
                    CheckMatrix = (centers(j,1) - meshX).^2 + (centers(j,2)-meshY).^2;
                    idx = find (CheckMatrix<= radii(j,:).^2);
                    ROIimage_tmp(idx) = 1;
                    app.Process.ROIimage{j,:} = ROIimage_tmp;


                end
                hold off
                app.ROIList.Items = ROI_list;
                
                app.Process.ROIlabel = ROI_list;
                set(app.ImAxes,'NextPlot','replacechildren');
                imagesc(app.Current.Image,app.Current.clims);

                if size(app.Process.ROIpos,2) > 1
                    ROI = app.Process.ROIpos(:,app.Current.Ix);
                else
                    ROI = app.Process.ROIpos(:,1);
                end
                plotROI(ROI,app.ImAxes);
                
            end
        end

        % Menu selected function: ImportROIsfromImageJMenu
        function ImportROIsfromImageJMenuSelected(app, event)
            %Check if ROI data already exists
            if isfield(app.Process,'ROIpos')
                dlgout = questdlg('Existing ROI data, how to proceed?','Import ROIs','Append', 'Overwrite','Cancel','Cancel');
                if strcmp(dlgout,'Cancel')
                    return
                elseif strcmp(dlgout,'Append')
                    firstROInum = length(app.Process.ROIpos) + 1;
                else
                    firstROInum = 1;

                end
            else
                dlgout = 'Overwrite';
                firstROInum = 1;
            end

            if isfield(app.data,'pathName')
                defPath = app.data.pathName;
            else
                defPath = pwd;
            end

            % select the zip file containing the ROIs from ImageJ
            [zipName,zipLocation] = uigetfile('*.zip','Select ImageJ ROI file',defPath,'MultiSelect','off');

            if zipName == 0
                return
            else
                ROIzipfile = fullfile(zipLocation,zipName);
            end
            imgSize = [app.data.imageStack(1).height app.data.imageStack(1).width];

            [ROIpos, ROIimage,ROIlabel] = QT_importROIs_ImageJ(ROIzipfile,imgSize,firstROInum);

            if strcmp(dlgout,'Append')
                app.Process.ROIpos = [app.Process.ROIpos; ROIpos];
                app.Process.ROIlabel = [app.Process.ROIlabel; ROIlabel];
                app.Process.ROIimage = [app.Process.ROIimage; ROIimage];
            else
                app.Process.ROIpos = ROIpos;
                app.Process.ROIlabel = ROIlabel;
                app.Process.ROIimage = ROIimage;
            end
            app.ROIList.Items = ROI_list;
            app.ROIList.ValueIndex = 1;
            
            if size(app.Process.ROIpos,2) > 1
                ROI = app.Process.ROIpos(:,app.Current.Ix);
            else
                ROI = app.Process.ROIpos(:,1);
            end

            set(app.ROIClassCur_text,'String','Not Defined');

            plotROI(ROI, app.ImAxes);
            app.DeleteROIbutton.Enable = 'on';
            
            app.ChangeROIClass.Enable = 'on';

          
            drawnow;
        end

        % Menu selected function: SetautoROIparametersMenu
        function SetautoROIparametersMenuSelected(app, event)
            QT_location = getQuantiTrackLoc();
            paramTab = 7; %Opens with Auto ROI tab
            profileEditor_gui = QuantiTrack_SetParams_App(app.UIfigure, app.Profiles,...
                app.curProfileID,QT_location, paramTab, get(gcf,'Position'));

            waitfor(profileEditor_gui);

            %Retrieve the preprocessing results
            OUTvars = getappdata(app.UIfigure,'QuantiTrackParamEditorVals');
            app.Profiles = OUTvars{1};
            app.curProfileID = OUTvars{2};

            %Parameters for automatic ROI detection
            app.Parameters.autoROI.morphRadius = app.Profiles(app.curProfileID).autoROI_params.Morphradius;
            app.Parameters.autoROI.filtWindow = app.Profiles(app.curProfileID).autoROI_params.filtWindow;
            app.Parameters.autoROI.ROIradius = app.Profiles(app.curProfileID).autoROI_params.ROIradius;
            app.Parameters.autoROI.sumTH = app.Profiles(app.curProfileID).autoROI_params.sumTH;
            app.Parameters.autoROI.indTH = app.Profiles(app.curProfileID).autoROI_params.indTH;


        end

        % Menu selected function: FindsmallmovingROIsMenu
        function FindsmallmovingROIsMenuSelected(app, event)
            %Check if ROI data already exists
            if isfield(app.Process,'ROIpos')
                dlgout = questdlg('Existing ROI data will be overwritten, Continue?','Automatic ROIs','OK','Cancel','Cancel');
                if strcmp(dlgout,'Cancel')
                    return
                end
            end

            
            % [RefFile, RefPath] = uigetfile('*.tif','Open Reference Image',defPath);
            if ~isempty(app.data.fname)
                d1 = uiprogressdlg(app.UIfigure,'Title','Moving ROIs ', 'Message','Locating mobile ROIs...','Indeterminate','on');
                drawnow;
                [RefIm, nImRef] = TIFread(fullfile(app.data.pname,app.data.fname));
                
                drawnow;
                R = app.Parameters.autoROI.ROIradius;
                currDisk = app.Parameters.autoROI.morphRadius;
                WindowSz = app.Parameters.autoROI.filtWindow ;
                Thresh = app.Parameters.autoROI.indTH;
                th1 = app.Parameters.autoROI.sumTH;

                [ROIpos, ROIimage,Cent,~,~,Thresh,th1,th2] =autoROIs_moving(RefIm,nImRef,R,WindowSz,currDisk,Thresh,th1,app.useParallel);
                app.Parameters.autoROI.sumTH = max(th1);
                app.Parameters.autoROI.indTH = max(Thresh);

                if isempty(ROIpos)
                    warndlg('No ROIs detected, so nothing has been added to the ROI list','No ROIs');
                else

                    app.Process.ROIpos = ROIpos;
                    app.Process.ROIimage = ROIimage;
                    app.Process.ROICentroid = Cent;
                    app.Process.autoROIparamUsed = [Thresh,th1,th2];
                    app.Process.ROIlabel = cell(size(ROIpos,1),1);
                    for i = 1:size(ROIpos,1)
                        d = num2str(i);

                        app.Process.ROIlabel{i,:} = ['ROI', d];
                    end
                    app.ROIList.Items = app.Process.ROIlabel;
                    
                    app.ROIList.ValueIndex = 1;
                    
                    if size(app.Process.ROIpos,2) > 1
                        ROI = app.Process.ROIpos(:,app.Current.Ix);
                    else
                        ROI = app.Process.ROIpos(:,1);
                    end
                    plotROI(ROI,app.ImAxes);
                    
                    app.DeleteROIbutton.Enable = 'on';
                    app.ChangeROIClass.Enable = 'on';
                    app.SaveROIsMenu.Enable = 'on';
                    app.SaveROIsMenu.Enable = 'on';
                    for i = 1:size(ROIimage,1)
                        for j = 1:size(ROIimage,2)
                            app.Process.ROIarea_px(i,j) = sum(sum(ROIimage{i,j}));
                        end
                    end
                end
                close(d1);
                drawnow;
                
            end
        end

        % Menu selected function: FindlargeimmobileROIsMenu
        function FindlargeimmobileROIsMenuSelected(app, event)
            %Check if ROI data already exists
            if isfield(app.Process,'ROIpos')
                dlgout = questdlg('Existing ROI data will be overwritten, Continue?','Automatic ROIs','OK','Cancel','Cancel');
                if strcmp(dlgout,'Cancel')
                    return
                end
            end


            %Allow user to choose the reference image
            % [RefFile, RefPath] = uigetfile('*.tif','Open Reference Image',defPath);
            if ~isempty(app.data.fname)
                d1 = uiprogressdlg(app.UIfigure,'Title','Stationary ROIs ', 'Message','Locating ROIs...','Indeterminate','on');
                drawnow;
                
                [RefIm, nImRef] = TIFread(fullfile(app.data.pname,app.data.fname));
                
                R = app.Parameters.autoROI.ROIradius;
                currDisk = app.Parameters.autoROI.morphRadius;
                WindowSz = app.Parameters.autoROI.filtWindow ;
                Thresh = app.Parameters.autoROI.indTH;
                th1 = app.Parameters.autoROI.sumTH;
                RefStack_3d = zeros(size(RefIm(1).data,1),size(RefIm(1).data,2),nImRef);
                for i = 1:nImRef
                    RefStack_3d(:,:,i) = double(RefIm(i).data);

                end
                app.Process.RefImage = sum(RefStack_3d,3);

                [ROIpos, ROIimage,th1_used] =autoROIs3(RefIm,nImRef,R,WindowSz,currDisk,th1);
                if isempty(ROIpos)
                    warndlg('No ROIs detected, so nothing has been added to the ROI list','No ROIs');
                else
                    %Adjust the threshold parameter if desired
                    if th1 == 0  && max(th1_used) ~= th1
                        app.AutoThreshLabel.Text = ['Threshold value:', num2str(max(th1_used),5)];
                    %     Th_save = questdlg('Do you want to save the threshold used for this image?','Threshold','Yes','No','Yes');
                    %     if strcmp(Th_save,'Yes')
                    %         app.Parameters.autoROI.sumTH = max(th1_used);
                    % 
                    %     end
                    else
                        app.AutoThreshLabel.Text = '';
                    end
                    UseClasses = questdlg('Do you want to classify ROIs?','Classify ROIs','Yes','No','No');
                    if strcmp(UseClasses,'Yes')
                        prompt = {'Enter Class name'};
                        title = 'ROI Classification';
                        def = {'Class1'};
                       
                        NewClass1 = inputdlg(prompt,title,1,def);
                        if isempty(NewClass1)
                            return
                        end
                        ROIClass = cell(size(ROIpos,1),1);
                        AllROIClasses{1,:} = NewClass1(1,:);
                        for i = 1:size(ROIpos,1)
                            ROIClass{i,:} = NewClass1(1,:);
                        end
                    end
                    
                    outsideROIset = questdlg('Do you want to track outside of the detected ROIs?','Add external ROI','Yes','No','Yes');
                    %Add additional ROI for areas outside detected ROIs

                    if strcmp(outsideROIset,'Yes')
                        ROIimage{end+1,:} = ones(size(ROIimage{1,:}));
                        [fr,fc] = find(ROIimage{end,:} == 1, 1, 'first');
                        B = bwtraceboundary(ROIimage{end,:},[fr,fc],'n');
                        ROIpos{end+1,:} = [B(:,2),B(:,1)];
                        if strcmp(UseClasses,'Yes')
                            prompt = {'Enter Class name'};
                            title = 'ROI Classification';
                            def = {'Class2'};

                            NewClass2 = inputdlg(prompt,title,1,def);
                            ROIClass{end+1,:} = NewClass2(1,:);
                            AllROIClasses{2,:} = NewClass2(1,:);
                        end
                    end
                    
                    app.Process.ROIpos = ROIpos;
                    app.Process.ROIimage = ROIimage;
                    app.Process.autoROIparamUsed = [Thresh,th1_used];
                    app.Process.ROIlabel = cell(size(ROIpos,1),1);
                    for i = 1:size(ROIpos,1)
                        d = num2str(i);

                        app.Process.ROIlabel{i,:} = ['ROI', d];
                    end
                    app.ROIList.Items = app.Process.ROIlabel;
                    app.ROIList.ValueIndex = 1;
                    
                    if size(app.Process.ROIpos,2) > 1
                        ROI = app.Process.ROIpos(:,app.Current.Ix);
                    else
                        ROI = app.Process.ROIpos(:,1);
                    end
                    if strcmp(UseClasses,'Yes')
                        app.Process.ROIClass = ROIClass;
                        app.Process.AllROIClasses = AllROIClasses;
                        app.ROIClassCur_text.Text = app.Process.ROIClass{1,:};
                    else
                        app.ROIClassCur_text.Text = 'Not Defined';
                    end
                    plotROI(ROI, app.ImAxes);
                    app.DeleteROIbutton.Enable = 'on';
                    app.ChangeROIClass.Enable = 'on';

                    app.Savebutton.Enable = 'on';
                    app.SaveROIsMenu.Enable = 'on';
                    
                end

                close(d1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIfigure = uifigure('Visible', 'off');
            app.UIfigure.Name = 'QuantiTrack-ROI Tool';
            app.FileMenu = uimenu(app.UIfigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Accelerator = 'o';
            app.LoadImageMenu.Text = 'Load Image';
            app.SaveROIsMenu = uimenu(app.FileMenu);
            app.SaveROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveROIsMenuSelected, true);
            app.SaveROIsMenu.Enable = 'off';
            app.SaveROIsMenu.Accelerator = 's';
            app.SaveROIsMenu.Text = 'Save ROIs';
            app.ROIsMenu = uimenu(app.UIfigure);
            app.ROIsMenu.Text = 'ROIs';
            app.SetautoROIparametersMenu = uimenu(app.ROIsMenu);
            app.SetautoROIparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SetautoROIparametersMenuSelected, true);
            app.SetautoROIparametersMenu.Accelerator = 'p';
            app.SetautoROIparametersMenu.Text = 'Set autoROI parameters';
            app.FindsmallmovingROIsMenu = uimenu(app.ROIsMenu);
            app.FindsmallmovingROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @FindsmallmovingROIsMenuSelected, true);
            app.FindsmallmovingROIsMenu.Enable = 'off';
            app.FindsmallmovingROIsMenu.Accelerator = 'm';
            app.FindsmallmovingROIsMenu.Text = 'Find small moving ROIs';
            app.FindlargeimmobileROIsMenu = uimenu(app.ROIsMenu);
            app.FindlargeimmobileROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @FindlargeimmobileROIsMenuSelected, true);
            app.FindlargeimmobileROIsMenu.Enable = 'off';
            app.FindlargeimmobileROIsMenu.Accelerator = 'f';
            app.FindlargeimmobileROIsMenu.Text = 'Find large immobile ROIs';
            app.ImportROIsfromMetamorphMenu = uimenu(app.ROIsMenu);
            app.ImportROIsfromMetamorphMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportROIsfromMetamorphMenuSelected, true);
            app.ImportROIsfromMetamorphMenu.Enable = 'off';
            app.ImportROIsfromMetamorphMenu.Separator = 'on';
            app.ImportROIsfromMetamorphMenu.Text = 'Import ROIs from Metamorph';
            app.ImportROIsfromImageJMenu = uimenu(app.ROIsMenu);
            app.ImportROIsfromImageJMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportROIsfromImageJMenuSelected, true);
            app.ImportROIsfromImageJMenu.Enable = 'off';
            app.ImportROIsfromImageJMenu.Text = 'Import ROIs from ImageJ';
            app.ImAxes = uiaxes(app.UIfigure);
            app.ImAxes.XLim = [0 1024];
            app.ImAxes.YLim = [0 1024];
            app.LoadImage = uibutton(app.UIfigure, 'push');
            app.LoadImage.ButtonPushedFcn = createCallbackFcn(app, @LoadImage_Callback, true);
            app.LoadImage.Text = 'Load Image';
            app.ROIbutton = uibutton(app.UIfigure, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbutton_Callback, true);
            app.ROIbutton.Enable = 'off';
            app.ROIbutton.Text = 'Draw ROI';
            app.Savebutton = uibutton(app.UIfigure, 'push');
            app.Savebutton.ButtonPushedFcn = createCallbackFcn(app, @Savebutton_Callback, true);
            app.Savebutton.Enable = 'off';
            app.Savebutton.Text = 'Save ROIs';
            app.CircleROIbutton = uibutton(app.UIfigure, 'push');
            app.CircleROIbutton.ButtonPushedFcn = createCallbackFcn(app, @CircleROIbutton_Callback, true);
            app.CircleROIbutton.Enable = 'off';
            app.CircleROIbutton.Text = 'Circle ROI';
            app.CircDiamText = uieditfield(app.UIfigure, 'text');
            app.CircDiamText.Value = '10';
            app.text2 = uilabel(app.UIfigure);
            app.text2.Text = 'DIameter (px)';
            app.ROIList = uilistbox(app.UIfigure);
            app.ROIList.Items = {};
            app.ROIList.ValueChangedFcn = createCallbackFcn(app, @ROIList_Callback, true);
            app.ROIList.Value = {};
            app.AdjCntr = uibutton(app.UIfigure, 'push');
            app.AdjCntr.ButtonPushedFcn = createCallbackFcn(app, @AdjCntr_Callback, true);
            app.AdjCntr.Text = 'Auto Scale';
            app.WhiteValSlider = uislider(app.UIfigure);
            app.WhiteValSlider.Limits = [0 1];
            app.WhiteValSlider.MajorTicks = [];
            app.WhiteValSlider.ValueChangedFcn = createCallbackFcn(app, @WhiteValSlider_Callback, true);
            app.WhiteValSlider.MinorTicks = [];
            app.BlackValSlider = uislider(app.UIfigure);
            app.BlackValSlider.Limits = [0 1];
            app.BlackValSlider.MajorTicks = [];
            app.BlackValSlider.ValueChangedFcn = createCallbackFcn(app, @BlackValSlider_Callback, true);
            app.BlackValSlider.MinorTicks = [];
            app.BlackValEdit = uieditfield(app.UIfigure, 'text');
            app.BlackValEdit.ValueChangedFcn = createCallbackFcn(app, @BlackValEdit_Callback, true);
            app.BlackValEdit.Value = '0';
            app.WhiteValEdit = uieditfield(app.UIfigure, 'text');
            app.WhiteValEdit.ValueChangedFcn = createCallbackFcn(app, @WhiteValEdit_Callback, true);
            app.WhiteValEdit.Value = '1000';
            app.text3 = uilabel(app.UIfigure);
            app.text3.Text = 'Black';
            app.text4 = uilabel(app.UIfigure);
            app.text4.Text = 'White';
            app.text5 = uilabel(app.UIfigure);
            app.text5.Text = 'Current ROI Class:';
            app.ChangeROIClass = uibutton(app.UIfigure, 'push');
            app.ChangeROIClass.ButtonPushedFcn = createCallbackFcn(app, @ChangeROIClass_Callback, true);
            app.ChangeROIClass.Enable = 'off';
            app.ChangeROIClass.Text = 'Update...';
            app.ROIClassCur_text = uilabel(app.UIfigure);
            app.ROIClassCur_text.Text = '';
            app.frameSlider = uislider(app.UIfigure);
            app.frameSlider.Limits = [0 1];
            app.frameSlider.MajorTicks = [];
            app.frameSlider.ValueChangedFcn = createCallbackFcn(app, @frameSlider_Callback, true);
            app.frameSlider.MinorTicks = [];
            app.frameSlider.Enable = 'off';
            app.text7 = uilabel(app.UIfigure);
            app.text7.Visible = 'off';
            app.text7.Text = 'Frame';
            app.nFrameText = uilabel(app.UIfigure);
            app.nFrameText.Visible = 'off';
            app.nFrameText.Text = '0/0';
            app.DeleteROIbutton = uibutton(app.UIfigure, 'push');
            app.DeleteROIbutton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIbutton_Callback, true);
            app.DeleteROIbutton.Enable = 'off';
            app.DeleteROIbutton.Text = 'Delete ROI';
            app.ProjectionTypeDropDownLabel = uilabel(app.UIfigure);
            app.ProjectionTypeDropDownLabel.Text = 'Projection Type';
            app.ProjectionTypeDropDown = uidropdown(app.UIfigure);
            app.ProjectionTypeDropDown.Items = {'Sum', 'Maximum', 'Entire Stack'};
            app.ProjectionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionTypeDropDownValueChanged, true);
            app.ProjectionTypeDropDown.Enable = 'off';
            app.ProjectionTypeDropDown.Value = 'Sum';
            app.AutoThreshLabel = uilabel(app.UIfigure);
            app.AutoThreshLabel.Text = '';
            app.ChanneltoTrackDropDownLabel = uilabel(app.UIfigure);
            app.ChanneltoTrackDropDownLabel.Enable = 'off';
            app.ChanneltoTrackDropDownLabel.Text = 'Channel to Track';
            app.ChanneltoTrackDropDown = uidropdown(app.UIfigure);
            app.ChanneltoTrackDropDown.Items = {};
            app.ChanneltoTrackDropDown.Enable = 'off';
            app.ChanneltoTrackDropDown.Value = {};
            % Show the figure after all components are created
            app.UIfigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROI_GUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIfigure)

            % Execute the startup function
            runStartupFcn(app, @(app)ROI_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIfigure)
        end
    end
end