classdef SplitAndAlignImages_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackSplitAlignChannelsUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadImageMenu                 matlab.ui.container.Menu
        LoadRegistrationMenu          matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        NewRegistrationfromBeadsMenu  matlab.ui.container.Menu
        BatchAlignmentMenu            matlab.ui.container.Menu
        StatusText                    matlab.ui.control.Label
        SplitRegisterButton           matlab.ui.control.Button
        RegisterButton                matlab.ui.control.Button
        SplitColorsButton             matlab.ui.control.Button
        CutFirstFrame                 matlab.ui.control.CheckBox
        CalibFileSelectButton         matlab.ui.control.Button
        ImageFileSelectButton         matlab.ui.control.Button
        CalibFileText                 matlab.ui.control.EditField
        AlignmentFileEditFieldLabel   matlab.ui.control.Label
        ImageFileText                 matlab.ui.control.EditField
        ImageFileEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        Reg % Description
        Data % Description
        Software % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                pos_fig = varargin{1};
                ChangeAppPosition(app.QuantiTrackSplitAlignChannelsUIFigure,pos_fig);
                drawnow;
            end
            % Choose default command line output for SplitAndAlignImages_GUI
            app.Reg.file = '';
            app.Data.ImageFilename = '';
            app.Data.ImAlign = [];
            app.Data.ImAll = [];
            

            drawnow;
        end

        % Menu selected function: BatchAlignmentMenu
        function BatchAlignmentMenuSelected(app, event)
            
            %Ask if we should use the currently loaded Calibration file
            if ~strcmp(app.Reg.file,'')
                CurRegChoose = questdlg(['Do you want to use the loaded Calibration file: ',app.Reg.file,' or a different one?'],'Calibration file','Current','Select','Current');
            else
                CurRegChoose = 'Select';
            end
            
            defaults = {'1', ...
                '0', ...
                '647,561,488', ...
                '0', ...
                '0'};
            prompt = {'First frame to use',...
                'Last frame to use (0 = use all)',...
                'Channel Order (comma separated)',...
                'Channel 2 skip frames',...
                'Channel 3 skip frames'};
            dlgtitle = 'Parameters for the Batch splitting';
            BatchParam = inputdlg(prompt,dlgtitle, 1, defaults);
            if isempty(BatchParam)
                return
            end

            % CutFirstChoose = questdlg('Do you want to remove the first frame from each movie?', 'Cut First Frame','Yes','No','No');
            
            frame1 = str2double(BatchParam{1});
            
            % lastIm_str = inputdlg('Cut-off image stack at frame (0 = use all):','Limit Image Stack',1,{'0'});
            lastImSet = str2double(BatchParam{2});
            channelOrderStr = BatchParam{3,:};
            %Determine how many channels are specified
            commaPos = strfind(channelOrderStr,',');

            if length(commaPos) == 1
                nChan = 2;
                wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                wl1_str = num2str(wl1_num);

                wl2_num = str2double(channelOrderStr(commaPos(1)+1:end));
                wl2_str = num2str(wl2_num);


                wluse = [wl1_num;wl2_num];

            elseif length(commaPos) == 2
                nChan = 3;
                wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                wl1_str = num2str(wl1_num);

                wl2_num = str2double(channelOrderStr(commaPos(1)+1:commaPos(2)-1));
                wl2_str = num2str(wl2_num);

                wl3_num = str2double(channelOrderStr(commaPos(2)+1:end));
                wl3_str = num2str(wl3_num);


                wluse = [wl1_num;wl2_num;wl3_num];
            elseif isempty(commaPos)
                errordlg('A single channel was specified, or commas were missing','Unrecognized string format');
            elseif length(commaPos) > 2
                errordlg('Splitting can currently be done for a maximum of 3 wavelengths ','Too many input channels');
            end
                    
            Chan2skip = str2double(BatchParam{4});
            Chan3skip = str2double(BatchParam{5});


            % if strcmp(CutFirstChoose,'Yes')
            %     frame1 = 2;
            % else
            %     frame1 = 1;
            % end
            
            if strcmp(CurRegChoose,'Select') %if the user wants to use a different file, open the dialog selection tool and load in
                [fname,pname] = uigetfile('*.mat','Open Calibration File');
                figure(app.QuantiTrackSplitAlignChannelsUIFigure);
                if fname ~= 0
                    app.StatusText.Text = ['Loading Alignment data: ',fullfile(pname,fname),'...'];
                    
                    drawnow
                    RegData = load(fullfile(pname,fname));
                    RegMat = RegData.RegMat;
                    Reg_wls = RegData.wavelengths;
                    app.StatusText.Text = ['Loading Alignment data: ',fullfile(pname,fname),'...Done'];
                    
                    drawnow
                    continueFlag = 1;
                else
                    continueFlag = 0;
                    app.StatusText.Text = 'Batch Processing Aborted';
                    drawnow
                end

            else
                RegMat = app.Reg.RegMat;
                Reg_wls = app.Reg.wls;
                continueFlag = 1;
            end

            if continueFlag == 1
                if size(Reg_wls,1) == 3
                    wl = [Reg_wls(1,1);Reg_wls(1,2);Reg_wls(2,2)];
                elseif size(Reg_wls,1) == 1
                    wl = [Reg_wls(1,1);Reg_wls(1,2)];
                end
                %     wl = [Reg_wls(1,1);Reg_wls(1,2);Reg_wls(2,2)];
                FileRootPath = uigetdir(pwd,'Select Root Folder containing images');
                figure(app.QuantiTrackSplitAlignChannelsUIFigure);
                if FileRootPath ~= 0
                    app.StatusText.Text = 'Recusively searching Root Folder for images...';
                    drawnow
                    Dcur = pwd;
                    Folds2Search = {FileRootPath};
                    InternalFolds = {FileRootPath};
                    %Recursively search for possible images to load
                    while ~isempty(InternalFolds)
                        InternalFolds_new = {};
                        fold_ind = 1;
                        for i = 1:length(InternalFolds)
                            cd(InternalFolds{i,:});

                            FoldCont = dir;
                            FoldCont(~[FoldCont.isdir]) = [];
                            tf = ismember({FoldCont.name},{'.','..'});
                            FoldCont(tf) = [];
                            if ~isempty(FoldCont)
                                for j = 1:size(FoldCont,1)
                                    InternalFolds_new = [InternalFolds_new; [InternalFolds{i,:}, filesep, FoldCont(j).name]];
                                end
                            end
                        end
                        InternalFolds = InternalFolds_new;
                        Folds2Search = [Folds2Search; InternalFolds];
                    end
                    IMpaths = {};
                    IMfiles = {};
                    for i = 1:size(Folds2Search,1)

                        cd(Folds2Search{i,:})
                        imsPresent = dir('*.tif');
                        if ~isempty(imsPresent)
                            for j = 1:size(imsPresent,1)
                                IMpaths = [IMpaths; Folds2Search(i)];
                                IMfiles = [IMfiles; imsPresent(j).name];
                            end
                        end
                    end

                    app.StatusText.Text = ['Found ', num2str(size(IMfiles,1)), ' images'];
                    drawnow

                    % channelOrderStrCell = inputdlg('Enter the channel order for the loaded image stack as comma separated values','Channel Order',1,{'647,561,488'});
                    % if isempty(channelOrderStrCell)
                    %     return
                    % end
                    % channelOrderStr = channelOrderStrCell{1,:};
                    
                    
                    %in case the alignment has been done before we don't
                    %want to re-split and align these
                    IMfiles_tmp = {};
                    IMpaths_tmp = {};
                    if length(commaPos) == 1
                        
                        for i = 1:length(IMfiles)
                            if isempty(strfind(IMfiles{i,:}, [wl1_str,'.tif'])) && isempty(strfind(IMfiles{i,:}, [wl2_str,'.tif'])) && isempty(strfind(IMfiles{i,:}, [wl2_str,'_aligned.tif']))
                                IMfiles_tmp = [IMfiles_tmp; IMfiles{i,:}];
                                IMpaths_tmp = [IMpaths_tmp; IMpaths{i,:}];
                            end
                        end
                    elseif length(commaPos) == 2
                        for i = 1:length(IMfiles)
                            if isempty(strfind(IMfiles{i,:}, [wl1_str,'.tif'])) && isempty(strfind(IMfiles{i,:}, [wl2_str,'.tif'])) && isempty(strfind(IMfiles{i,:}, [wl2_str,'_aligned.tif'])) && isempty(strfind(IMfiles{i,:}, [wl3_str,'.tif'])) && isempty(strfind(IMfiles{i,:}, [wl3_str,'_aligned.tif']))
                                IMfiles_tmp = [IMfiles_tmp; IMfiles{i,:}];
                                IMpaths_tmp = [IMpaths_tmp; IMpaths{i,:}];
                            end
                        end
                    end
                    IMfiles = IMfiles_tmp;
                    IMpaths = IMpaths_tmp;


                    %Initialize image files
                    app.Data.ImAll = cell(nChan,1);
                    counter = ones(nChan,1);
                    matlabpath = path;
                    sc_pos = strfind(matlabpath,pathsep);

                    app.Software.TempSaveDir = [matlabpath(1:sc_pos(1)-1), filesep, 'tmp',filesep]; %temporary save directory to avoid the error when saving to a folder that is open in Windows explorer
                    TempDirExists = dir(app.Software.TempSaveDir);
                    if isempty(TempDirExists)
                        mkdir(app.Software.TempSaveDir);
                    end
                    for ImIx = 1:size(IMfiles,1)
                        

                        clear ImAll;
                        clear ImAlign;
                        counter = ones(nChan,1);
                        app.StatusText.Text = ['Reading Image ', num2str(ImIx), ' of ', num2str(size(IMfiles,1)),'...'];
                        drawnow
                        [imageStack,nImages] = TIFread([IMpaths{ImIx,:},filesep,IMfiles{ImIx,:}]);
                        %remove the end of the stack if specified
                        % if lastIm > 0
                        %     lastIm = min(lastIm,nImages);
                        %     imageStack = imageStack(1:lastIm);
                        %     nImages = lastIm;
                        % end
                        app.StatusText.Text = ['Reading Image ', num2str(ImIx), ' of ', num2str(size(IMfiles,1)),'...Done'];
                        drawnow
                        fname2 = cell(3,1);
                        if length(commaPos) == 1

                            fname2{1,:} = [IMfiles{ImIx,:}(1:end-4),'_',wl1_str,'.tif'];

                            fname2{2,:} = [IMfiles{ImIx,:}(1:end-4),'_',wl2_str,'.tif'];

                        elseif length(commaPos) == 2

                            fname2{1,:} = [IMfiles{ImIx,:}(1:end-4),'_',wl1_str,'.tif'];

                            fname2{2,:} = [IMfiles{ImIx,:}(1:end-4),'_',wl2_str,'.tif'];

                            fname2{3,:} = [IMfiles{ImIx,:}(1:end-4),'_',wl3_str,'.tif'];


                        end
                        app.StatusText.Text = ['Splitting Image ', num2str(ImIx), ' of ', num2str(size(IMfiles,1)),'...'];
                        drawnow

                        Chan2Ind = 2:Chan2skip+2:nImages;
                        Chan3Ind = 3:Chan3skip+3:nImages;

                        for i = 1:nImages
                            im_tmp = imageStack(i).data;
                            if ~isempty(find(Chan2Ind == i,1))
                                ImAll{2}(:,:,counter(2)) = im_tmp;
                                counter(2) = counter(2) + 1;
                                fnum = 2;
                            else
                                if nChan == 3
                                    if ~isempty(find(Chan3Ind == i,1))
                                        ImAll{3}(:,:,counter(3)) = im_tmp;
                                        counter(3) = counter(3) + 1;
                                        fnum = 3;
                                    else
                                        ImAll{1}(:,:,counter(1)) = im_tmp;
                                        counter(1) = counter(1) + 1;
                                        fnum = 1;
                                    end
                                else
                                    ImAll{1}(:,:,counter(1)) = im_tmp;
                                    counter(1) = counter(1) + 1;
                                    fnum = 1;
                                end
                            end

                            % if rem(i,nChan) == 1
                            % 
                            %     ImAll{1}(:,:,counter(1)) = im_tmp;
                            %     counter(1) = counter(1) + 1;
                            %     fnum = 1;
                            % elseif rem(i,nChan) == 2
                            % 
                            %     ImAll{2}(:,:,counter(2)) = im_tmp;
                            %     counter(2) = counter(2) + 1;
                            %     fnum = 2;
                            % elseif rem(i,nChan) == 0 && nChan == 2
                            % 
                            %     ImAll{2}(:,:,counter(2)) = im_tmp;
                            %     counter(2) = counter(2) + 1;
                            %     fnum = 2;
                            % elseif rem(i,nChan) == 0 && nChan == 3
                            % 
                            %     ImAll{3}(:,:,counter(3)) = im_tmp;
                            %     counter(3) = counter(3) + 1;
                            %     fnum = 3;
                            % end


                        end

                        for i = 1:nChan
                            lastIm = lastImSet;
                            if lastIm > 0
                                lastIm = min(lastIm,counter(i)-1);
                            else
                                lastIm = counter(i)-1;
                            end
                            for j = frame1:lastIm
                                im_tmp2 = ImAll{i}(:,:,j);
                                if j == frame1
                                    imwrite(im_tmp2,[app.Software.TempSaveDir, fname2{i,:}],'WriteMode','overwrite');
                                else
                                    imwrite(im_tmp2,[app.Software.TempSaveDir, fname2{i,:}],'WriteMode','append');
                                end
                            end

                            movefile([app.Software.TempSaveDir, filesep, fname2{i,:}],[IMpaths{ImIx,:}, filesep, fname2{i,:}]);
                        end
                        Im2Align = ImAll;
                        app.StatusText.Text = ['Aligning Image ', num2str(ImIx), ' of ', num2str(size(IMfiles,1)),'...'];
                        drawnow
                        
                        for i = 1:size(wluse,1)
                            if wluse(i) ~= Reg_wls(1)
                                Reg_ind = find(Reg_wls(:,1) == wl(1) & Reg_wls(:,2) == wluse(i));
                                RegMat_use = RegMat{Reg_ind,:};
                                Roriginal = imref2d([size(Im2Align{1},1),size(Im2Align{1},2)]);
                                for j = frame1:size(Im2Align{i},3)
                                    ImAlign{i}(:,:,j) = imwarp(Im2Align{i}(:,:,j),RegMat_use,'OutputView',Roriginal);
                                    imwrite(ImAlign{i}(:,:,j),[app.Software.TempSaveDir,IMfiles{ImIx,:}(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],'WriteMode','append');
                                end
                                movefile([app.Software.TempSaveDir, IMfiles{ImIx,:}(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],...
                                    [IMpaths{ImIx,:}, filesep, IMfiles{ImIx,:}(1:end-4),'_',num2str(wluse(i)),'_aligned.tif']);
                                
                            end
                        end
                    end


                    cd(Dcur);
                    app.StatusText.Text = 'Batch Processing Complete';
                    drawnow
                else
                    app.StatusText.Text = 'Batch Processing Aborted';
                    drawnow

                end
            end
        end

        % Button pushed function: CalibFileSelectButton
        function CalibFileSelectButtonPushed(app, event)
            
            CalibPath = pwd;
            [fname, pname] = uigetfile(fullfile(CalibPath,'*.mat'),'Open Mat file with Registration info');
            figure(app.QuantiTrackSplitAlignChannelsUIFigure);

            if fname ~= 0
                app.Reg.file = fullfile(pname,fname);
                app.CalibFileText.Value = app.Reg.file;
                app.StatusText.Text = ['Loading Alignment data: ',fullfile(pname,fname),'...'];
                drawnow
                RegData = load(fullfile(pname,fname));
                app.StatusText.Text = ['Loading Alignment data: ',fullfile(pname,fname),'...Done'];
                drawnow

                app.Reg.RegMat = RegData.RegMat;
                app.Reg.wls = RegData.wavelengths;

                
                app.RegisterButton.Enable = 'on';
                

                if ~strcmp(app.Data.ImageFilename,'')
                    app.SplitRegisterButton.Enable = 'on';
                end
                drawnow;
            end
        end

        % Button pushed function: ImageFileSelectButton
        function ImageFileSelectButtonPushed(app, event)
            
            [fname, pname] = uigetfile('*.tif','Open Image Stack');
            figure(app.QuantiTrackSplitAlignChannelsUIFigure);

            if fname ~= 0
                %save the image name and path to handles structure
                app.Data.ImageFilename = fname;
                app.Data.ImageFilepath = pname;
                %read in the images
                app.StatusText.Text = ['Loading image: ',fullfile(pname,fname),'...'];
                drawnow
                [ImageStack, nImages] = TIFread(fullfile(pname,fname));
                app.StatusText.Text = ['Loading image: ',fullfile(pname,fname),'...Done'];
                drawnow
                app.Data.RawImages = ImageStack;


                %Ask if the user wants to restrict the number of frames read in
                nIms_str = inputdlg('Cut-off image stack at frame:','Limit Image Stack',1,{num2str(nImages)});
                app.Data.nImages = min(nImages,str2double(nIms_str));
                app.Data.RawImages = ImageStack(1:app.Data.nImages);


                %add the file name to the text box & enable the split colors button
                app.ImageFileText.Value = fullfile(pname,fname);
                app.SplitColorsButton.Enable = 'on';

                if ~strcmp(app.Reg.file,'')
                    app.SplitRegisterButton.Enable = 'on';
                end

            end
        end

        % Value changed function: ImageFileText
        function ImageFileTextValueChanged(app, event)
            value = app.ImageFileText.Value;
            
            if ~isempty(value)
                app.StatusText.Text = ['Loading image: ',value, '...'];
                drawnow

                try [ImageStack, nImages] = TIFread(value);

                catch
                    errordlg('File does not exist or is not an image','Error opening file');
                end
                app.StatusText.Text = ['Loading image: ',value,'...Done'];
                drawnow

                %Ask if the user wants to restrict the number of frames read in
                nIms_str = inputdlg('Cut-off image stack at frame:','Limit Image Stack',1,{num2str(nImages)});
                app.Data.nImages = min(nImages,str2double(nIms_str));
                app.Data.RawImages = ImageStack(1:app.Data.nImages);
                %add the file name to the text box & enable the split colors button
                app.SplitColorsButton.Enable = 'on';

                %Extract file name & path
                file_full = value;
                slash_pos = strfind(file_full,filesep);

                fname = file_full(slash_pos(end+1):end);
                pname = file_full(1:slash_pos(end-1));
                %save the image name and path to handles structure
                app.Data.ImageFilename = fname;
                app.Data.ImageFilepath = pname;

                if ~strcmp(app.Reg.file,'')
                    app.SplitRegisterButton.Enable = 'on';
                end

            end
        end

        % Menu selected function: LoadRegistrationMenu
        function LoadRegistrationMenuSelected(app, event)
            
            [fname, pname] = uigetfile('*.mat','Open Mat file with Registration info');
            figure(app.QuantiTrackSplitAlignChannelsUIFigure);
            if fname ~= 0
                app.Reg.file = fullfile(pname,fname);
                app.StatusText.Text = ['Loading Alignment data: ',fullfile(pname,fname),'...'];
                drawnow
                RegData = load(fullfile(pname,fname));
                app.StatusText.Text = ['Loading Alignment data: ',fullfile(pname,fname),'...Done'];
                drawnow
                app.Reg.RegMat = RegData.RegMat;
                app.Reg.wls = RegData.wavelengths;

                if ~isempty(app.Data.ImAlign)
                    app.RegisterButton.Enable = 'on';
                end

                if ~strcmp(app.Data.ImageFileName,'')
                    app.SplitRegisterButton.Enable = 'on';
                end

            end
        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
            [fname, pname] = uigetfile('*.tif','Open Image Stack');
            figure(app.QuantiTrackSplitAlignChannelsUIFigure);
            if fname ~= 0
                %save the image name and path to handles structure
                app.Data.ImageFilename = fname;
                app.Data.ImageFilepath = pname;
                %read in the images
                app.StatusText.Text = ['Loading image: ',fullfile(pname,fname),'...'];
                drawnow
                [ImageStack, nImages] = TIFread(fullfile(pname,fname));
                app.StatusText.Text = ['Loading image: ',fullfile(pname,fname),'...Done'];
                drawnow

                %Ask if the user wants to restrict the number of frames read in
                nIms_str = inputdlg('Cut-off image stack at frame:','Limit Image Stack',1,{num2str(nImages)});
                app.Data.nImages = min(nImages,str2double(nIms_str));
                app.Data.RawImages = ImageStack(1:app.Data.nImages);

                %add the file name to the text box & enable the split colors button
                app.ImageFileText.Value = fullfile(pname,fname);
                app.SplitColorsButton.Enable = 'on';

                if ~strcmp(app.Reg.file,'')
                    app.SplitRegisterButton.Enable = 'on';
                end

            end
        end

        % Menu selected function: NewRegistrationfromBeadsMenu
        function NewRegistrationfromBeadsMenuSelected(app, event)
            BeadCalibration_App(get(gcf,'Position'));
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            %Check if there is some image data from the current session
            if ~isempty(app.Data.ImAll)
                CurOrOldChoice = questdlg('Do you want to perform alignment on the images just split?','What do you want to align?','Current','Select','Current');
            else
                CurOrOldChoice = 'Select';
            end

            if ~isfield(app.Data,'ImageFilepath') || isempty(app.Data.ImageFilepath)
                app.Data.ImageFilepath = pwd;
                app.Data.ImageFilename = [];
            end
            if size(app.Reg.wls,1) == 3
                wl = [app.Reg.wls(1,1);app.Reg.wls(1,2);app.Reg.wls(2,2)];
            elseif size(app.Reg.wls,1) == 1
                wl = [app.Reg.wls(1,1);app.Reg.wls(1,2)];
            end
            pname = '';
            wl_indx = 1;
            if strcmp(CurOrOldChoice,'Select')
                for i = 1:length(wl)
                    openFileStr = ['Select file for ',num2str(wl(i)), ' channel'];
                    if strcmp(pname,'')
                        [fname, pname] = uigetfile('*.tif',openFileStr,app.Data.ImageFilepath);
                        figure(app.QuantiTrackSplitAlignChannelsUIFigure);
                    else
                        [fname, pname] = uigetfile('*.tif',openFileStr,pname);
                        figure(app.QuantiTrackSplitAlignChannelsUIFigure);
                    end
                    if fname ~= 0
                        app.Data.ImageFilepath = pname;
                        app.Data.ImageFilename = fname;
                        wluse(wl_indx,1) = wl(i);
                        [imStack,nImages] = TIFread([pname,filesep,fname]);
                        for j = 1:nImages
                            Im2Align{wl_indx,:}(:,:,j) = imStack(j).data;
                        end
                        continueFlag = 1;
                        wl_indx = wl_indx + 1;
                    elseif i == length(wl) && isempty(app.Data.ImageFilename)
                        continueFlag = 0;
                    else
                        continueFlag = 1;
                        pname = app.Data.ImageFilepath;
                    end
                end
            else
                pname = app.Data.ImageFilepath;
                Im2Align = app.Data.ImAll;
                wluse = app.Data.wls;
                continueFlag = 1;
            end


            %align the images
            if continueFlag == 1
                matlabpath = path;
                sc_pos = strfind(matlabpath,pathsep);

                app.Software.TempSaveDir = [matlabpath(1:sc_pos(1)-1), filesep, 'tmp',filesep]; %temporary save directory to avoid the error when saving to a folder that is open in Windows explorer
                TempDirExists = dir(app.Software.TempSaveDir);
                if isempty(TempDirExists)
                    mkdir(app.Software.TempSaveDir);
                end
                
                for i = 1:size(wluse,1)
                    app.StatusText.Text = ['Aligning Channel ', num2str(i), 'data...'];
                    drawnow
                    if wluse(i) ~= wl(1)
                        Reg_ind = find(app.Reg.wls(:,1) == wl(1) & app.Reg.wls(:,2) == wluse(i));
                        RegMat = app.Reg.RegMat{Reg_ind,:};
                        Roriginal = imref2d([size(Im2Align{1},1),size(Im2Align{1},2)]);
                        for j = 1:size(Im2Align{i},3)
                            app.Data.ImAlign{i}(:,:,j) = imwarp(Im2Align{i}(:,:,j),RegMat,'OutputView',Roriginal);
                            imwrite(app.Data.ImAlign{i}(:,:,j),[app.Software.TempSaveDir,filesep,app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],'WriteMode','append');
                        end
                        movefile([app.Software.TempSaveDir, app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],...
                            [app.Data.ImageFilepath, filesep, app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif']);
                        
                    end
                    app.StatusText.Text = ['Aligning Channel ', num2str(i), 'data...Done'];
                    drawnow;
                end
            end
            app.StatusText.Text = 'Channel Alignment Done!';
        end

        % Button pushed function: SplitColorsButton
        function SplitColorsButtonPushed(app, event)
            channelOrderStrCell = inputdlg('Enter the channel order for the loaded image stack as comma seperataed values','Channel Order',1,{'647,561,488'});
            
            channelOrderStr = channelOrderStrCell{1,:};
            %Determine how many channels are specified
            commaPos = strfind(channelOrderStr,',');
            fname2 = cell(3,1);
            if length(commaPos) == 1
                nChan = 2;
                wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                wl1_str = num2str(wl1_num);
                fname2{1,:} = [app.Data.ImageFilename(1:end-4),'_',wl1_str,'.tif'];
                wl2_num = str2double(channelOrderStr(commaPos(1)+1:end));
                wl2_str = num2str(wl2_num);
                fname2{2,:} = [app.Data.ImageFilename(1:end-4),'_',wl2_str,'.tif'];

                app.Data.wls = [wl1_num;wl2_num];

            elseif length(commaPos) == 2
                nChan = 3;
                wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                wl1_str = num2str(wl1_num);
                fname2{1,:} = [app.Data.ImageFilename(1:end-4),'_',wl1_str,'.tif'];
                wl2_num = str2double(channelOrderStr(commaPos(1)+1:commaPos(2)-1));
                wl2_str = num2str(wl2_num);
                fname2{2,:} = [app.Data.ImageFilename(1:end-4),'_',wl2_str,'.tif'];
                wl3_num = str2double(channelOrderStr(commaPos(2)+1:end));
                wl3_str = num2str(wl3_num);
                fname2{3,:} = [app.Data.ImageFilename(1:end-4),'_',wl3_str,'.tif'];

                app.Data.wls = [wl1_num;wl2_num;wl3_num];
            elseif isempty(commaPos)
                errordlg('A single channel was specified, or commas were missing','Unrecognized string format');
            elseif length(commaPos) > 2
                errordlg('Splitting can currently be done for a maximum of 3 wavelengths ','Too many input channels');
            end

            %Initialize image files
            app.Data.ImAll = cell(nChan,1);
            counter = ones(nChan,1);
            matlabpath = path;
            sc_pos = strfind(matlabpath,pathsep);

            app.Software.TempSaveDir = [matlabpath(1:sc_pos(1)-1), filesep, 'tmp',filesep]; %temporary save directory to avoid the error when saving to a folder that is open in Windows explorer
            TempDirExists = dir(app.Software.TempSaveDir);
            if isempty(TempDirExists)
                mkdir(app.Software.TempSaveDir);
            end

            for i = 1:app.Data.nImages
                im_tmp = app.Data.RawImages(i).data;
                if rem(i,nChan) == 1

                    app.Data.ImAll{1}(:,:,counter(1)) = im_tmp;
                    counter(1) = counter(1) + 1;
                    fnum = 1;
                elseif rem(i,nChan) == 2

                    app.Data.ImAll{2}(:,:,counter(2)) = im_tmp;
                    counter(2) = counter(2) + 1;
                    fnum = 2;
                elseif rem(i,nChan) == 0 && nChan == 2

                    app.Data.ImAll{2}(:,:,counter(2)) = im_tmp;
                    counter(2) = counter(2) + 1;
                    fnum = 2;
                elseif rem(i,nChan) == 0 && nChan == 3

                    app.Data.ImAll{3}(:,:,counter(3)) = im_tmp;
                    counter(3) = counter(3) + 1;
                    fnum = 3;
                end
                
            end
            if app.CutFirstFrame.Value == 1
                frame1 = 2;
            else
                frame1 = 1;
            end

            for i = 1:nChan
                app.StatusText.Text = ['Writing Channel ', num2str(i),' data...'];
                drawnow
                
                for j = frame1:counter(1)-1
                    im_tmp2 = app.Data.ImAll{i}(:,:,j);
                    if j == frame1
                        imwrite(im_tmp2,[app.Software.TempSaveDir, fname2{i,:}],'WriteMode','overwrite');
                    else
                        imwrite(im_tmp2,[app.Software.TempSaveDir, fname2{i,:}],'WriteMode','append');
                    end
                    
                    % imwrite(im_tmp2,[app.Software.TempSaveDir, fname2{i,:}],'WriteMode','append');
                end

                movefile([app.Software.TempSaveDir, filesep, fname2{i,:}],[app.Data.ImageFilepath, filesep, fname2{i,:}]);
                app.StatusText.Text = ['Writing Channel ', num2str(i), ' data...Done'];
                drawnow
            end
            app.StatusText.Text = 'Channel splitting Done!';
        end

        % Button pushed function: SplitRegisterButton
        function SplitRegisterButtonPushed(app, event)
            
            channelOrderStrCell = inputdlg('Enter the channel order for the loaded image stack as comma seperataed values','Channel Order',1,{'647,561,488'});
            wluse = 0;
            channelOrderStr = channelOrderStrCell{1,:};
            %Determine how many channels are specified
            commaPos = strfind(channelOrderStr,',');
            fname2 = cell(3,1);
            if length(commaPos) == 1
                nChan = 2;
                wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                wl1_str = num2str(wl1_num);
                fname2{1,:} = [app.Data.ImageFilename(1:end-4),'_',wl1_str,'.tif'];
                wl2_num = str2double(channelOrderStr(commaPos(1)+1:end));
                wl2_str = num2str(wl2_num);
                fname2{2,:} = [app.Data.ImageFilename(1:end-4),'_',wl2_str,'.tif'];

                app.Data.wls = [wl1_num;wl2_num];

            elseif length(commaPos) == 2
                nChan = 3;
                wl1_num = str2double(channelOrderStr(1:commaPos(1)-1));
                wl1_str = num2str(wl1_num);
                fname2{1,:} = [app.Data.ImageFilename(1:end-4),'_',wl1_str,'.tif'];
                wl2_num = str2double(channelOrderStr(commaPos(1)+1:commaPos(2)-1));
                wl2_str = num2str(wl2_num);
                fname2{2,:} = [app.Data.ImageFilename(1:end-4),'_',wl2_str,'.tif'];
                wl3_num = str2double(channelOrderStr(commaPos(2)+1:end));
                wl3_str = num2str(wl3_num);
                fname2{3,:} = [app.Data.ImageFilename(1:end-4),'_',wl3_str,'.tif'];

                app.Data.wls = [wl1_num;wl2_num;wl3_num];
            elseif isempty(commaPos)
                errordlg('A single channel was specified, or commas were missing','Unrecognized string format');
            elseif length(commaPos) > 2
                errordlg('Splitting can currently be done for a maximum of 3 wavelengths ','Too many input channels');
            end

            wluse = app.Data.wls;

            %Initialize image files
            app.Data.ImAll = cell(nChan,1);
            counter = ones(nChan,1);
            matlabpath = path;
            sc_pos = strfind(matlabpath,pathsep);

            app.Software.TempSaveDir = [matlabpath(1:sc_pos(1)-1), filesep, 'tmp',filesep]; %temporary save directory to avoid the error when saving to a folder that is open in Windows explorer
            TempDirExists = dir(app.Software.TempSaveDir);
            if isempty(TempDirExists)
                mkdir(app.Software.TempSaveDir);
            end
            app.StatusText.Text = 'Splitting Channels...';
            drawnow
            for i = 1:app.Data.nImages
                im_tmp = app.Data.RawImages(i).data;
                if rem(i,nChan) == 1

                    app.Data.ImAll{1}(:,:,counter(1)) = im_tmp;
                    counter(1) = counter(1) + 1;
                    fnum = 1;
                elseif rem(i,nChan) == 2

                    app.Data.ImAll{2}(:,:,counter(2)) = im_tmp;
                    counter(2) = counter(2) + 1;
                    fnum = 2;
                elseif rem(i,nChan) == 0 && nChan == 2

                    app.Data.ImAll{2}(:,:,counter(2)) = im_tmp;
                    counter(2) = counter(2) + 1;
                    fnum = 2;
                elseif rem(i,nChan) == 0 && nChan == 3

                    app.Data.ImAll{3}(:,:,counter(3)) = im_tmp;
                    counter(3) = counter(3) + 1;
                    fnum = 3;
                end

                
            end
            Im2Align = app.Data.ImAll;
            app.StatusText.Text = 'Splitting Channels...Done';
            drawnow

            if get(app.CutFirstFrame,'Value') == 1
                frame1 = 2;
            else
                frame1 = 1;
            end
            for i = 1:nChan
                app.StatusText.Text = ['Writing Channel ', num2str(i), ' data...'];
                drawnow
                for j = frame1:counter(1)-1
                    im_tmp2 = app.Data.ImAll{i}(:,:,j);
                    imwrite(im_tmp2,[app.Software.TempSaveDir, fname2{i,:}],'WriteMode','append');
                end

                movefile([app.Software.TempSaveDir, filesep, fname2{i,:}],[app.Data.ImageFilepath, filesep, fname2{i,:}]);
                im_max = max(app.Data.ImAll{i},[],3);
                imwrite(im_max,[app.Data.ImageFilepath, filesep, 'MAX_',fname2{i,:}]);
                %     im_sum = sum(handles.Data.ImAll{i},3);
                %     imwrite((im_sum),[handles.Data.ImageFilepath, filesep, 'SUM_',fname2{i,:}]);
                im_avg = mean(app.Data.ImAll{i},3);
                imwrite(uint16(im_avg),[app.Data.ImageFilepath, filesep, 'AVG_',fname2{i,:}]);
                
                app.StatusText.Text = ['Writing Channel ', num2str(i), ' data...Done'];
                drawnow
            end

            % mkdir([handles.Data.ImageFilepath, filesep,'aligned']);
            if size(app.Reg.wls,1) == 3
                wl = [app.Reg.wls(1,1);app.Reg.wls(1,2);app.Reg.wls(2,2)];
            elseif size(app.Reg.wls,1) == 1
                wl = [app.Reg.wls(1,1);app.Reg.wls(1,2)];
            end

            for i = 1:size(wluse,1)
                app.StatusText.Text = ['Aligning Channel ', num2str(i), ' data...'];
                drawnow
                if wluse(i) ~= wl(1)

                    Reg_ind = find(app.Reg.wls(:,1) == wl(1) & app.Reg.wls(:,2) == wluse(i));
                    RegMat = app.Reg.RegMat{Reg_ind,:};
                    Roriginal = imref2d([size(Im2Align{1},1),size(Im2Align{1},2)]);
                    for j = frame1:size(Im2Align{i},3)
                        app.Data.ImAlign{i}(:,:,j) = imwarp(Im2Align{i}(:,:,j),RegMat,'OutputView',Roriginal);
                        if j == frame1
                            imwrite(app.Data.ImAlign{i}(:,:,j),[app.Software.TempSaveDir,filesep,app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],'WriteMode','overwrite');
                        else
                            imwrite(app.Data.ImAlign{i}(:,:,j),[app.Software.TempSaveDir,filesep,app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],'WriteMode','append');
                        end
                        imwrite(app.Data.ImAlign{i}(:,:,j),[app.Software.TempSaveDir,filesep,app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],'WriteMode','append');
                    end
                    movefile([app.Software.TempSaveDir, app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif'],[app.Data.ImageFilepath, filesep, app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif']);
                    im_max = max(app.Data.ImAlign{i},[],3);
                    imwrite(im_max,[app.Data.ImageFilepath, filesep, 'MAX_',app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif']);
                    
                    im_avg = mean(app.Data.ImAlign{i},3);
                    imwrite(uint16(im_avg),[app.Data.ImageFilepath, filesep, 'AVG_',app.Data.ImageFilename(1:end-4),'_',num2str(wluse(i)),'_aligned.tif']);
                    app.StatusText.Text = ['Aligning Channel ', num2str(i), 'data...Done'];
                    
                end
                app.StatusText.Text = ['Aligning Channel ', num2str(i), ' data...Done'];
            end
            app.StatusText.Text = 'Splitting and Aligning Done!';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackSplitAlignChannelsUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackSplitAlignChannelsUIFigure.Name = 'QuantiTrack - Split & Align Channels';
            app.FileMenu = uimenu(app.QuantiTrackSplitAlignChannelsUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Accelerator = 'o';
            app.LoadImageMenu.Text = 'Load Image';
            app.LoadRegistrationMenu = uimenu(app.FileMenu);
            app.LoadRegistrationMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadRegistrationMenuSelected, true);
            app.LoadRegistrationMenu.Text = 'Load Registration';
            app.ToolsMenu = uimenu(app.QuantiTrackSplitAlignChannelsUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.NewRegistrationfromBeadsMenu = uimenu(app.ToolsMenu);
            app.NewRegistrationfromBeadsMenu.MenuSelectedFcn = createCallbackFcn(app, @NewRegistrationfromBeadsMenuSelected, true);
            app.NewRegistrationfromBeadsMenu.Accelerator = 'r';
            app.NewRegistrationfromBeadsMenu.Text = 'New Registration from Beads';
            app.BatchAlignmentMenu = uimenu(app.ToolsMenu);
            app.BatchAlignmentMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchAlignmentMenuSelected, true);
            app.BatchAlignmentMenu.Accelerator = 'b';
            app.BatchAlignmentMenu.Text = 'Batch Alignment';
            app.ImageFileEditFieldLabel = uilabel(app.QuantiTrackSplitAlignChannelsUIFigure);
            app.ImageFileEditFieldLabel.Text = 'Image File:';
            app.ImageFileText = uieditfield(app.QuantiTrackSplitAlignChannelsUIFigure, 'text');
            app.ImageFileText.ValueChangedFcn = createCallbackFcn(app, @ImageFileTextValueChanged, true);
            app.AlignmentFileEditFieldLabel = uilabel(app.QuantiTrackSplitAlignChannelsUIFigure);
            app.AlignmentFileEditFieldLabel.Text = 'Alignment File:';
            app.CalibFileText = uieditfield(app.QuantiTrackSplitAlignChannelsUIFigure, 'text');
            app.ImageFileSelectButton = uibutton(app.QuantiTrackSplitAlignChannelsUIFigure, 'push');
            app.ImageFileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileSelectButtonPushed, true);
            app.ImageFileSelectButton.Text = 'Browse...';
            app.CalibFileSelectButton = uibutton(app.QuantiTrackSplitAlignChannelsUIFigure, 'push');
            app.CalibFileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @CalibFileSelectButtonPushed, true);
            app.CalibFileSelectButton.Text = 'Browse...';
            app.CutFirstFrame = uicheckbox(app.QuantiTrackSplitAlignChannelsUIFigure);
            app.CutFirstFrame.Text = 'Remove First Frame from Sequence';
            app.SplitColorsButton = uibutton(app.QuantiTrackSplitAlignChannelsUIFigure, 'push');
            app.SplitColorsButton.ButtonPushedFcn = createCallbackFcn(app, @SplitColorsButtonPushed, true);
            app.SplitColorsButton.Enable = 'off';
            app.SplitColorsButton.Text = 'Split Channels';
            app.RegisterButton = uibutton(app.QuantiTrackSplitAlignChannelsUIFigure, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Text = 'Align Channels';
            app.SplitRegisterButton = uibutton(app.QuantiTrackSplitAlignChannelsUIFigure, 'push');
            app.SplitRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @SplitRegisterButtonPushed, true);
            app.SplitRegisterButton.Enable = 'off';
            app.SplitRegisterButton.Text = 'Split & Align';
            app.StatusText = uilabel(app.QuantiTrackSplitAlignChannelsUIFigure);
            app.StatusText.Text = '';
            % Show the figure after all components are created
            app.QuantiTrackSplitAlignChannelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SplitAndAlignImages_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackSplitAlignChannelsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackSplitAlignChannelsUIFigure)
        end
    end
end