classdef SpotTrainer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpotTrainerUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveTrainingDataMenu            matlab.ui.container.Menu
        LoadTrainingDataMenu            matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        CopySpotParametersMenu          matlab.ui.container.Menu
        CopyNumberofParticleDataMenu    matlab.ui.container.Menu
        UseModelValuesforAllSpotsMenu   matlab.ui.container.Menu
        MachineLearningMenu             matlab.ui.container.Menu
        MergeDatasetsandTrainModelMenu  matlab.ui.container.Menu
        PredictCurrentSpotsfromModelMenu  matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        CreateSpotImagesMenu            matlab.ui.container.Menu
        ImAxes                          matlab.ui.control.UIAxes
        HistAxes                        matlab.ui.control.UIAxes
        FrameNumberSliderLabel          matlab.ui.control.Label
        FrameNumberSlider               matlab.ui.control.Slider
        PreviousSpotButton              matlab.ui.control.Button
        NextSpotButton                  matlab.ui.control.Button
        NumberofParticlesinSpotSpinnerLabel  matlab.ui.control.Label
        NumberofParticlesinSpotSpinner  matlab.ui.control.Spinner
        CloseButton                     matlab.ui.control.Button
        TrainingParametersPanel         matlab.ui.container.Panel
        NumberofTreesSpinnerLabel       matlab.ui.control.Label
        NumberofTreesSpinner            matlab.ui.control.Spinner
        InBagFractionSpinnerLabel       matlab.ui.control.Label
        InBagFractionSpinner            matlab.ui.control.Spinner
        MinLeafSizeSpinnerLabel         matlab.ui.control.Label
        MinLeafSizeSpinner              matlab.ui.control.Spinner
        NumberofPredictorsSpinnerLabel  matlab.ui.control.Label
        NumberofPredictorsSpinner       matlab.ui.control.Spinner
        TrainModelButton                matlab.ui.control.Button
        OptimizeParametersButton        matlab.ui.control.Button
        OOBErrorLabel                   matlab.ui.control.Label
        WindowSizeSpinnerLabel          matlab.ui.control.Label
        WindowSizeSpinner               matlab.ui.control.Spinner
        CurrentSpotLabel                matlab.ui.control.Label
        TotalSpotsLabel                 matlab.ui.control.Label
        SubtractBackgroundCheckBox      matlab.ui.control.CheckBox
        StatusText                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        WhiteLevelSlider                matlab.ui.control.Slider
        Slider_2Label                   matlab.ui.control.Label
        BlackLevelSlider                matlab.ui.control.Slider
        BlackLevelSpinnerLabel          matlab.ui.control.Label
        BlackLevelSpinner               matlab.ui.control.Spinner
        WhiteLevelSpinnerLabel          matlab.ui.control.Label
        WhiteLevelSpinner               matlab.ui.control.Spinner
        AutoScaleButton                 matlab.ui.control.Button
        ResetScaleButton                matlab.ui.control.Button
        ModelPredictionLabel            matlab.ui.control.Label
        pred_NparticlesText             matlab.ui.control.Label
        UsePredictedValueButton         matlab.ui.control.Button
        MediansigmaGoodLabel            matlab.ui.control.Label
        SigmaMedianGoodText             matlab.ui.control.Label
        CurrentsigmaLabel               matlab.ui.control.Label
        SigmaCurText                    matlab.ui.control.Label
        MediansigmaAllLabel             matlab.ui.control.Label
        SigmaMedianAllText              matlab.ui.control.Label
    end

    
    properties (Access = private)
        imStack %stack of images
        Particles %list of all particles and their properties
        curInd %currently viewed particle
        xCur %X-coordinate of the current particle
        yCur %Y-coordinate of the current particle
        fCur %currently selected frame
        intensitiesAll %all raw intensities
        backgroundAll %all background values
        intensitiesBgAll %all background corrected intensities
        IntCur %raw intensity of current particle
        bgCur %background around current particle
        IntBgCur %bg corrected intensity of current particle
        IntMedian %median of intensity (either raw or bg corrected)
        isSaved %flag to determine if the data has been saved to disk
        dispPts2PxRat %Ratio of the number of points per pixel of the current display
        B %bagger output from TreeBagger
        OOBerror
        Predictors % Description
        clims % Description
        FullClims % Description
        Pred_NparticlesInSpot % Description
        IntMedianGood % Description
        SigmaMedianGood % Description
        SigmaMedianAll % Description
    end
    
    properties (Access = public)
        NparticlesInSpot %vector containing the number of molecules in each spot. used for the training
    end


    methods (Access = private)
        
        function updateDisplay(app)
            %update the text boxes
            app.NumberofParticlesinSpotSpinner.Value = app.NparticlesInSpot(app.curInd);
            app.CurrentSpotLabel.Text = ['Current Spot: ', num2str(app.curInd)];
            app.SigmaMedianAllText.Text = num2str(app.SigmaMedianAll,3);
            app.SigmaMedianGoodText.Text = num2str(app.SigmaMedianGood,3);
            app.SigmaCurText.Text = num2str(app.Predictors(app.curInd,3),3);
            
            if isempty(app.Pred_NparticlesInSpot)
                app.pred_NparticlesText.Text = 'N/A';
            else
                app.pred_NparticlesText.Text = num2str(app.Pred_NparticlesInSpot(app.curInd));
            end
            %determine which intensity to plot
            if app.SubtractBackgroundCheckBox.Value == 1
                histogram(app.HistAxes,app.intensitiesBgAll,100,'Normalization',"count");
            else
                histogram(app.HistAxes,app.intensitiesAll,100,'Normalization',"count");
            end
            %get the height of the histogram
            ylim = app.HistAxes.YLim;
            hold(app.HistAxes,"off");
            
            %plot the median intensity as a black line
            IntMed = repmat(app.IntMedian,size(ylim));
            plot(app.HistAxes,IntMed,ylim,"Color",[0 0 0],"LineWidth",1.5);
            hold(app.HistAxes,"on");
            
            %plot the median intensity as a green line
            IntMedGood = repmat(app.IntMedianGood,size(ylim));
            plot(app.HistAxes,IntMedGood,ylim,"Color",[0 1 0],"LineWidth",1.5);
            
            %get the current particle stats
            curPart = app.Particles(app.curInd,:);
            app.xCur = round(curPart(:,10));
            app.yCur = round(curPart(:,11));
            app.IntCur = curPart(:,7) + curPart(:,9);
            int_cur = repmat(app.IntCur,size(ylim));
            app.bgCur = curPart(:,9);
            app.IntBgCur = curPart(:,7);
            int_bg_cur = repmat(app.IntBgCur,size(ylim));
            
            %determine what to plot
            if app.SubtractBackgroundCheckBox.Value == 1
                plot(app.HistAxes,int_bg_cur,ylim,"Color",[1 0 0],"LineWidth",1.5);
            else
                plot(app.HistAxes,int_cur.*ones(size(ylim)),ylim,"Color",[1 0 0],"LineWidth",1.5);
            end
            
            
            %determine which intensity to plot
            if app.SubtractBackgroundCheckBox.Value == 1
                histogram(app.HistAxes,app.intensitiesBgAll,100,...
                    'Normalization',"count","FaceColor",[1 0.5 0]);
                legend(app.HistAxes,{['Median All = ', num2str(round(app.IntMedian))],...
                    ['Median True = ', num2str(round(app.IntMedianGood))],...
                    ['Current = ', num2str(round(app.IntBgCur))]});
            else
                histogram(app.HistAxes,app.intensitiesAll,100,...
                    'Normalization',"count","FaceColor",[1 0.5 0]);
                legend(app.HistAxes,{['Median All = ', num2str(round(app.IntMedian))],...
                    ['Median True = ', num2str(round(app.IntMedianGood))],...
                    ['Current = ', num2str(round(app.IntCur))]});
            end


            %adjust the frame slider if we updated the particle
            if app.fCur == app.Particles(app.curInd,6)
                app.FrameNumberSlider.Limits = [max(1,app.fCur - 2),min(length(app.imStack),app.fCur + 2)];
                app.FrameNumberSlider.Value = app.fCur;
                
                app.FrameNumberSlider.MajorTicks = app.FrameNumberSlider.Limits(1):1:app.FrameNumberSlider.Limits(2);
                slide_ind = app.FrameNumberSlider.Limits(1);
                for i = 1:length(app.FrameNumberSlider.MajorTicks)
                    app.FrameNumberSlider.MajorTickLabels(i) = {num2str(slide_ind)};
                    slide_ind = slide_ind + 1;
                end
                
                
            end
            
            %get the portion of the image that we need to display
            w = app.WindowSizeSpinner.Value;
            
            if (app.yCur - w) > 0 && (app.yCur + w) <= app.imStack(1).width && ...
                    (app.xCur - w) > 0 && (app.xCur + w) <= app.imStack(1).height
                
                curImView = app.imStack(app.fCur).data(app.yCur - w:app.yCur + w, ...
                    app.xCur - w:app.xCur + w);
            else
                ylimits = [max(1,(app.yCur - w)),...
                    min(app.imStack(1).width,(app.yCur + w))];
                xlimits = [max(1,(app.xCur - w)),...
                    min(app.imStack(1).height,(app.xCur + w))];
                
                curImView = app.imStack(app.fCur).data(ylimits(1):ylimits(2),...
                    xlimits(1):xlimits(2));
            end
            %display the image and stretch it to fit the axis
            app.ImAxes.NextPlot = 'replace';
            I = imagesc(curImView,'Parent',app.ImAxes,'XData',...
                [1 app.ImAxes.Position(3)], 'YData',[1 app.ImAxes.Position(4)],app.clims);
            app.ImAxes.XLim = [0 I.XData(2)];
            app.ImAxes.YLim = [0 I.YData(2)];
            app.ImAxes.XTick = [];
            app.ImAxes.YTick = [];
            
            colormap(app.ImAxes,"gray");
            hold(app.ImAxes,"on");
            
            %plot the center and the boundary of the spot
            plotX = (curPart(:,10) - app.xCur) + I.XData(2)/2;
            plotY = (curPart(:,11) - app.yCur) + I.YData(2)/2;
            plot(app.ImAxes,plotX,plotY,'+r');
            
            %plot the median sigma for good particles
            markerSz2 = 2.*app.SigmaMedianGood.*(I.XData(2)./(2*app.WindowSizeSpinner.Value +1)).*app.dispPts2PxRat;
            plot(app.ImAxes,plotX,plotY,'og','MarkerSize',markerSz2);
            %plot the current sigma
            markerSz = 2.*curPart(:,8).*(I.XData(2)./(2*app.WindowSizeSpinner.Value +1)).*app.dispPts2PxRat;
            plot(app.ImAxes,plotX,plotY,'or','MarkerSize',markerSz);
            
        end
        
        function oob_err = TreeBagger_fun(app,par_in,pred,resp)
            NumTrees = round(par_in(1));
            InBagFraction = par_in(2);
            MinLeafSize = round(par_in(3));
            NumPred = round(par_in(4));
            if NumPred == 0 || NumPred >= size(app.Particles,2)
                NumPred = 'all';
            end
            oob_errAll = zeros(NumTrees,10);
            options = struct('UseParallel',true,'UseSubstreams',false,'Streams',[]);
            for i = 1:10
                B_test = TreeBagger(NumTrees,pred,resp,"InBagFraction",InBagFraction,...
                    "MinLeafSize",MinLeafSize,"NumPredictorsToSample",NumPred,"OOBPrediction","on","Options",options);
                oob_errAll(:,i) = oobError(B_test,'Mode','individual');
            end
            oob_err = mean(oob_errAll);
            
            
        end
        
        function [par_out, B, ooberr] = TreeBagger_min(app,par0,pred,resp)
            fun = @(par_in)TreeBagger_fun(app,par_in,pred,resp);
            lb = [1, 0, 1, 1];
            ub = [Inf, 1, Inf, size(pred,2)];
            par_out = lsqnonlin(fun,par0,lb,ub);
            
            options = struct('UseParallel',true,'UseSubstreams',false,'Streams',[]);
            
            B = TreeBagger(par_out(1),pred,resp,"InBagFraction",par_out(2),...
                "MinLeafSize",par_out(3),"NumPredictorsToSample",par_out(4),"OOBPrediction","on","Options",options);
            ooberr = oobError(app.B,'Mode','ensemble');
        end
        
        function results = func4(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imStack, Particles)
            %make the image stack and list of particles available to other
            %functions in the app
            app.imStack = imStack;
            app.Particles = Particles(Particles(:,1)>0,:);
            app.Predictors = app.Particles(:,[4,7,8,9,12,14:18]);
            
            %set the current particle index to 1
            app.curInd = 1;
            
            %get the current position, frame, intensity, and background
            app.xCur = round(app.Particles(app.curInd,10));
            app.yCur = round(app.Particles(app.curInd,11));
            app.fCur = app.Particles(app.curInd,6);
            app.IntBgCur = app.Particles(app.curInd,7);
            app.bgCur = app.Particles(app.curInd,9);
            
            %calculate the background corrected intensity
            app.IntCur = app.IntCur + app.bgCur;
            
            %disable the Previous Spot button
            app.PreviousSpotButton.Enable = 'off';
            
            %Adjust the limits of the grayscale sliders
            app.BlackLevelSlider.Limits = [0 (2^16 - 1)];
            app.WhiteLevelSlider.Limits = [0 (2^16 - 1)];
            app.BlackLevelSpinner.Limits = [0 (2^16 - 2)];
            app.WhiteLevelSpinner.Limits = [1 (2^16 - 1)];
            % get the grayscale range for the image series
            MN = 2^16 - 1;
            MX = 0;
            for i = 1:length(app.imStack)
                curIm = app.imStack(i).data;
                if min(curIm(:)) < MN
                    MN = double(min(curIm(:)));
                end
                if max(curIm(:)) > MX
                    MX = double(max(curIm(:)));
                end
            end
            app.BlackLevelSlider.Value = MN;
            app.BlackLevelSpinner.Value = MN;
            app.WhiteLevelSlider.Value = MX;
            app.WhiteLevelSpinner.Value = MX;
            
            app.clims = [MN, MX];
            app.FullClims = [MN,MX];
            
            %populate the arrays for intensity and background
            app.intensitiesAll = app.Particles(:,7) + app.Particles(:,9);
            app.backgroundAll = app.Particles(:,9);
            app.intensitiesBgAll = app.Particles(:,7);
            
            %set the median intensity to the background corrected version
            app.IntMedian = median(app.intensitiesBgAll);
            
            %guess at the number of particles in each spot based on
            %intensity
            app.NparticlesInSpot = min(5,floor(app.intensitiesBgAll./median(app.intensitiesBgAll)));
            app.NparticlesInSpot = max(0,app.NparticlesInSpot);
            
            app.IntMedianGood = median(app.intensitiesBgAll(app.NparticlesInSpot == 1));
            app.SigmaMedianGood = median(app.Predictors(app.NparticlesInSpot == 1,3));
            app.SigmaMedianAll = median(app.Predictors(:,3));
            
            % Find the ratio of display pts to pixels
            curDispUnits = get(0,'Units');
            set(0,'Units','pixels');
            dispPx = get(0,'ScreenSize');
            
            set(0,'Units','points');
            dispPts = get(0,'ScreenSize');
            app.dispPts2PxRat = dispPts(4)./dispPx(4);
            
            set(0,'Units',curDispUnits);
            
            app.TotalSpotsLabel.Text = ['Total Spots: ', num2str(size(app.Particles,1))];
            app.Pred_NparticlesInSpot = [];
            
            updateDisplay(app);
            app.isSaved = 0;


        end

        % Value changed function: FrameNumberSlider
        function FrameNumberSliderValueChanged(app, event)
            value = round(app.FrameNumberSlider.Value);
            app.FrameNumberSlider.Value = value;
            app.fCur = value;
            updateDisplay(app);
        end

        % Value changed function: NumberofParticlesinSpotSpinner
        function NumberofParticlesinSpotSpinnerValueChanged(app, event)
            value = app.NumberofParticlesinSpotSpinner.Value;
            app.NparticlesInSpot(app.curInd) = value;
            app.isSaved = 0;
            
            value = app.SubtractBackgroundCheckBox.Value;
            
            if value == 1
                app.IntMedianGood = median(app.intensitiesBgAll(app.NparticlesInSpot == 1));
            else
                app.IntMedianGood = median(app.intensitiesAll(app.NparticlesInSpot == 1));
            end
            
            app.SigmaMedianGood = median(app.Predictors(app.NparticlesInSpot == 1,3));
            updateDisplay(app);
            
        end

        % Button pushed function: NextSpotButton
        function NextSpotButtonPushed(app, event)
            app.PreviousSpotButton.Enable = 'on';
            app.curInd = app.curInd + 1;
            
            app.fCur = app.Particles(app.curInd,6);
            if app.curInd == length(app.Particles)
                app.NextSpotButton.Enable = 'off';
                
            end
            
            updateDisplay(app);
            
        end

        % Button pushed function: PreviousSpotButton
        function PreviousSpotButtonPushed(app, event)
            app.NextSpotButton.Enable = 'on';
            app.curInd = app.curInd - 1;
            app.fCur = app.Particles(app.curInd,6);
            if app.curInd == 1
                app.PreviousSpotButton.Enable = 'off';
                
            end
            
            updateDisplay(app);
        end

        % Value changed function: WindowSizeSpinner
        function WindowSizeSpinnerValueChanged(app, event)
            
            updateDisplay(app);
        end

        % Value changed function: SubtractBackgroundCheckBox
        function SubtractBackgroundCheckBoxValueChanged(app, event)
            value = app.SubtractBackgroundCheckBox.Value;
            
            if value == 1
                app.IntMedian = median(app.intensitiesBgAll);
                app.IntMedianGood = median(app.intensitiesBgAll(app.NparticlesInSpot == 1));
            else
                app.IntMedian = median(app.intensitiesAll);
                app.IntMedianGood = median(app.intensitiesAll(app.NparticlesInSpot == 1));
            end
            
            updateDisplay(app);
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            NumTrees = app.NumberofTreesSpinner.Value;
            InBagFraction = app.InBagFractionSpinner.Value;
            MinLeafSize = app.MinLeafSizeSpinner.Value;
            NumPred = app.NumberofPredictorsSpinner.Value;
            if NumPred == 0 || NumPred >= size(app.Particles,2)
                NumPred = 'all';
            end
            options = struct('UseParallel',true,'UseSubstreams',false,'Streams',[]);
            
            resp_type = 4;
            switch resp_type
                case 1
                    Response1 = app.NparticlesInSpot == 1;
                    Response = Response1;
                case 2
                    Response1 = app.NparticlesInSpot == 1;
                    Response2 = app.NparticlesInSpot == 2;
                    Response = Response2 + Response1;
                case 3
                    Response = app.NparticlesInSpot;
                    Response(Response >= 3) = 0;
                case 4
                    Response = app.NparticlesInSpot;
                    
                    Response(Response > 3) = 3;
                    
            end
            
            app.StatusText.Text = 'Training Model...';
            drawnow
            for i = 1:100
                app.StatusText.Text = ['Training Model (iteration ' num2str(i) ' of 100)...'];
                drawnow
                app.B = TreeBagger(NumTrees,app.Predictors,Response,"InBagFraction",InBagFraction,...
                    "MinLeafSize",MinLeafSize,"NumPredictorsToSample",NumPred,"OOBPrediction","on","Options",options);
                oob_err(i,:) = oobError(app.B,'Mode','ensemble');
            end
            app.OOBerror = mean(oob_err);
            
            app.OOBErrorLabel.Text = ['OOB error = ',num2str(app.OOBerror,3)];
            app.StatusText.Text = 'Training Model...Done';
            drawnow
        end

        % Button pushed function: OptimizeParametersButton
        function OptimizeParametersButtonPushed(app, event)
            NumTrees = app.NumberofTreesSpinner.Value;
            InBagFraction = app.InBagFractionSpinner.Value;
            MinLeafSize = app.MinLeafSizeSpinner.Value;
            NumPred = 1;
            par0 = [NumTrees, InBagFraction, MinLeafSize, NumPred];
            [par_out, app.B, app.OOBerror] = TreeBagger_min(app,par0,app.Particles,app.NparticlesInSpot == 1);
            
            app.NumberofTreesSpinner.Value = par_out(1);
            app.InBagFractionSpinner.Value = par_out(2);
            app.MinLeafSizeSpinner.Value = par_out(3);
            app.NumberofPredictorsSpinner.Value = par_out(4);
            
            app.OOBErrorLabel.Text = ['OOB error = ',num2str(app.OOBerror)];
            
            
        end

        % Menu selected function: SaveTrainingDataMenu
        function SaveTrainingDataMenuSelected(app, event)
            [fname, pname] = uiputfile('*.mat', 'Save Training Data');
            drawnow;
            figure(app.SpotTrainerUIFigure);
            if fname ~= 0
                BagOtrees = app.B;
                oob_err = app.OOBerror;
                predictors = app.Predictors;
                response = app.NparticlesInSpot;
                
                save(fullfile(pname,fname),"response","predictors","oob_err","BagOtrees");
                app.isSaved = 1;
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if app.isSaved == 1
                close(app.SpotTrainerUIFigure);
            else
                answer = questdlg('Training Data has been edited, Would you like to save?','Save Training Data','Yes','No','Cancel','Yes');
                
                if strcmp(answer,'Cancel')
                    return
                elseif strcmp(answer,'No')
                    close(app.SpotTrainerUIFigure);
                else
                    [fname, pname] = uiputfile('*.mat', 'Save Training Data');
                    
                    if fname ~= 0
                        BagOtrees = app.B;
                        oob_err = app.OOBerror;
                        predictors = app.Predictors;
                        response = app.NparticlesInSpot;
                        
                        save(fullfile(pname,fname),"response","predictors","oob_err","BagOtrees");
                        close(app.SpotTrainerUIFigure);
                    end
                end
            end
        end

        % Menu selected function: LoadTrainingDataMenu
        function LoadTrainingDataMenuSelected(app, event)
            [fname, pname] = uigetfile('*.mat','Select the MAT file containing training data');
            drawnow;
            figure(app.SpotTrainerUIFigure);
            if fname ~= 0
                IN = load(fullfile(pname,fname));
                if size(IN.response,1) == size(app.Predictors,1)
                    app.NparticlesInSpot = IN.response;
                elseif size(IN.response,1) > size(app.Predictors,1)
                    warndlg('Loaded training data size does not match the current dataset, loading in the data anyway');
                    app.NparticlesInSpot = IN.response(1:size(app.Predictors,1));
                else
                    warndlg('Loaded training data size does not match the current dataset, loading in the data anyway');
                    app.NparticlesInSpot(1:size(IN.response,1)) = IN.response;
                end
                app.NumberofParticlesinSpotSpinner.Value = app.NparticlesInSpot(app.curInd);
                
            end
        end

        % Value changed function: BlackLevelSpinner
        function BlackLevelSpinnerValueChanged(app, event)
            
            MaxBrightnessStack = app.BlackLevelSlider.Limits(2);
            
            BlackLevel = app.BlackLevelSpinner.Value;
            %Make sure we don't try to set the brightness outside of the slider limits
            if BlackLevel < 0
                BlackLevel = 0;
            elseif BlackLevel > MaxBrightnessStack
                BlackLevel = MaxBrightnessStack-1;
            end
            
            app.BlackLevelSpinner.Value = BlackLevel;
            WhiteLevel = app.WhiteLevelSpinner.Value;
            
            if BlackLevel >= WhiteLevel
                WhiteLevel = BlackLevel+1;
                app.WhiteLevelSpinner.Value = WhiteLevel;
            end
            
            app.BlackLevelSlider.Value = BlackLevel;
            app.WhiteLevelSlider.Value = WhiteLevel;
            app.clims = [BlackLevel WhiteLevel];
            
            updateDisplay(app);
        end

        % Value changed function: BlackLevelSlider
        function BlackLevelSliderValueChanged(app, event)
            MaxBrightnessStack = app.BlackLevelSlider.Limits(2);
            
            BlackLevel = round(app.BlackLevelSlider.Value);
            WhiteLevel = round(app.WhiteLevelSlider.Value);
            
            if BlackLevel == MaxBrightnessStack
                BlackLevel = MaxBrightnessStack - 1;
            end
            if BlackLevel >= WhiteLevel
                WhiteLevel = BlackLevel + 1;
                app.WhiteLevelSlider.Value = WhiteLevel;
            end
            
            app.BlackLevelSlider.Value = BlackLevel;
            app.BlackLevelSpinner.Value = BlackLevel;
            app.WhiteLevelSpinner.Value = WhiteLevel;
            app.clims = [BlackLevel WhiteLevel];
            
            updateDisplay(app);
            
        end

        % Value changed function: WhiteLevelSpinner
        function WhiteLevelSpinnerValueChanged(app, event)
            MaxBrightnessStack = app.BlackLevelSlider.Limits(2);
            
            WhiteLevel = app.WhiteLevelSpinner.Value;
            %Make sure we don't try to set the brightness outside of the slider limits
            if WhiteLevel < 0
                WhiteLevel = 1;
            elseif WhiteLevel > MaxBrightnessStack
                WhiteLevel = MaxBrightnessStack;
            end
            
            app.WhiteLevelSpinner.Value = WhiteLevel;
            BlackLevel = app.BlackLevelSpinner.Value;
            
            if WhiteLevel <= BlackLevel
                BlackLevel = WhiteLevel-1;
                app.BlackLevelSpinner.Value = BlackLevel;
            end
            
            app.BlackLevelSlider.Value = BlackLevel;
            app.WhiteLevelSlider.Value = WhiteLevel;
            app.clims = [BlackLevel WhiteLevel];
            
            updateDisplay(app);
            
        end

        % Value changed function: WhiteLevelSlider
        function WhiteLevelSliderValueChanged(app, event)
            BlackLevel = round(app.BlackLevelSlider.Value);
            WhiteLevel = round(app.WhiteLevelSlider.Value);
            if WhiteLevel == 0
                WhiteLevel = 1;
            end
            
            if WhiteLevel <= BlackLevel
                BlackLevel = WhiteLevel-1;
                app.BlackLevelSlider.Value = BlackLevel;
            end
            
            app.WhiteLevelSlider.Value = WhiteLevel;
            app.BlackLevelSpinner.Value = BlackLevel;
            app.WhiteLevelSpinner.Value = WhiteLevel;
            app.clims = [BlackLevel WhiteLevel];
            
            updateDisplay(app);
        end

        % Button pushed function: AutoScaleButton
        function AutoScaleButtonPushed(app, event)
            %get the portion of the image that we need to display
            curImView = app.imStack(app.fCur).data(app.yCur - ...
                app.WindowSizeSpinner.Value:app.yCur + app.WindowSizeSpinner.Value,...
                app.xCur - app.WindowSizeSpinner.Value:app.xCur + app.WindowSizeSpinner.Value);
            
            MN = double(min(curImView(:)));
            MX = double(max(curImView(:)));
            
            app.clims = [MN,MX];
            
            app.BlackLevelSlider.Value = MN;
            app.BlackLevelSpinner.Value = MN;
            app.WhiteLevelSlider.Value = MX;
            app.WhiteLevelSpinner.Value = MX;
            
            updateDisplay(app);
        end

        % Button pushed function: ResetScaleButton
        function ResetScaleButtonPushed(app, event)
            BlackLevel = app.FullClims(1);
            WhiteLevel = app.FullClims(2);
            app.clims = app.FullClims;
            
            app.BlackLevelSlider.Value = double(BlackLevel);
            app.BlackLevelSpinner.Value = BlackLevel;
            app.WhiteLevelSlider.Value = double(WhiteLevel);
            app.WhiteLevelSpinner.Value = WhiteLevel;
            
            updateDisplay(app);
            
        end

        % Menu selected function: MergeDatasetsandTrainModelMenu
        function MergeDatasetsandTrainModelMenuSelected(app, event)
            %select files
            [files_train, path_train] = uigetfile('*.mat','Select Training Datasets',pwd,"MultiSelect","on");
            drawnow;
            figure(app.SpotTrainerUIFigure);
            if ~iscell(files_train)
                files_train = {files_train};
            end
            if files_train{1} ~= 0
                %select the save location
                [file_out,path_out] = uiputfile('*.mat','Choose Save Location',path_train);
                drawnow;
                figure(app.SpotTrainerUIFigure);
                %Set model parameters
                defTreesN = 100;
                defInBagFrac = 1.0;
                defMinLeaf = 1;
                defNumPred = 0;
                defValidFrac = 0.0;
                
                defaults = {num2str(defTreesN), num2str(defInBagFrac), ...
                    num2str(defMinLeaf),num2str(defNumPred), ...
                    num2str(defValidFrac)};
                prompt = {'Number of Trees', 'In Bag Fraction'...
                    'Minimum Leaf Size', 'Number of Predictors (0 = all)',...
                    'Fraction of set to use for Validation'};
                dlgtitle = 'Parameters for Bag of Trees ML Training';
                
                modelParams = inputdlg(prompt,dlgtitle,1,defaults);
                if isempty(modelParams)
                    return
                end
                treesN = str2double(modelParams{1});
                inBagFrac = str2double(modelParams{2});
                minLeaf = str2double(modelParams{3});
                numPred = str2double(modelParams{4});
                if numPred == 0
                    numPred = 'all';
                end
                validFrac = str2double(modelParams{5});
                
                %load in the predictors & responses
                resp_all = [];
                pred_all = [];
                %                 validAns = questdlg('Do you want to split the dataset into Training and Validation subsets?', ...
                %                     'Validation','Yes','No','Cancel','No');
                %                 if strcmp(validAns,'Cancel')
                %                     return
                %                 elseif strcmp(validAns,'Yes')
                %
                %                 end
                
                
                for i = 1:length(files_train)
                    train_in = load(fullfile(path_train,files_train{i}),'response','predictors');
                    pred_all = [pred_all;train_in.predictors];
                    resp_all = [resp_all; train_in.response];
                end


                trainN = round(length(resp_all).*(1 - validFrac));
                %if validating, we need to randomly sample the data
                if validFrac ~= 0.0
                    trainInd = randperm(length(resp_all),trainN);
                    trainOrValid = false(length(resp_all),1);
                    trainOrValid(trainInd) = 1;
                    
                else
                    trainOrValid = true(length(resp_all),1);
                    
                end
                %Use only 2 categories
                resp_all = resp_all == 1;
                
                trainPred = pred_all(trainOrValid,:);
                trainResp = resp_all(trainOrValid);
                
                valPred = pred_all(~trainOrValid,:);
                valResp = resp_all(~trainOrValid);


                %
                
                app.StatusText.Text = 'Training Model...';
                drawnow
%                 options = struct('UseParallel',true,'UseSubstreams',false,'Streams',[]);
                Bmerge = TreeBagger(treesN,trainPred,trainResp,"InBagFraction",inBagFrac,...
                    "MinLeafSize",minLeaf,"NumPredictorsToSample",numPred,"OOBPrediction","on");%,"Options",options);
                OOBerror_merge = oobError(Bmerge,'Mode','ensemble');
                %Validate model
                if validFrac ~= 0.0
                    app.StatusText.Text = 'Running Model on Validation data...';
                    drawnow
                    
                    respVal_out = predict(Bmerge,valPred);
                    
                    %Compare the output to the validation response
                    valResp_out = zeros(length(valResp),1);
                    for i = 1:length(respVal_out)
                        valResp_out(i) = str2double(respVal_out{i});
                    end
                    
                    accuracy = sum(valResp_out == valResp)./length(valResp);
                    
                else
                    accuracy = -1*(1 - OOBerror_merge);
                end
                BagOtrees = Bmerge;
                oob_err = OOBerror_merge;
                
                save(fullfile(path_out,file_out),"accuracy","oob_err","BagOtrees","files_train","path_train");
                app.StatusText.Text = 'Training & Validating Model...Done';
                drawnow
                
            end
        end

        % Menu selected function: CreateSpotImagesMenu
        function CreateSpotImagesMenuSelected(app, event)
            %Get the window size
            wndo = app.WindowSizeSpinner.Value;
            
            %Choose a save location
            path_out = uigetdir(pwd,'Select Location to Save Spot Images');
            drawnow
            figure(app.SpotTrainerUIFigure);
            
            if path_out == 0
                return
                
            end
            %Specify the prefix to add to the image name
            default = {'SpotImage'};
            prompt = {'Specify the Prefix to add to the images'};
            title = 'Prefix';
            
            Prefix = inputdlg(prompt,title,1,default);
            Prefix = Prefix{1};
            
            %Decide on the number of categories
            CatNans = questdlg('How Many Categories do you want to use?',...
                'Number of Categories','Two (Spot/No Spot)','All','Cancel',...
                'Two (Spot/No Spot)');
            if strcmp(CatNans,'Cancel')
                return
            end
            
            if strcmp(CatNans,'Two (Spot/No Spot)')
                response = uint16(app.NparticlesInSpot == 1);
            else
                response = app.NparticlesInSpot;
                
            end
            %Find the number of categories in the data
            Cat_cur = unique(response);
            nCat_cur = length(Cat_cur);
            %Determine if previous data is present
            curD = pwd;
            cd(path_out);
            Pathcontents = dir;
            ind = 1;
            
            for i = 1:length(Pathcontents)
                if Pathcontents(i).isdir && ~strcmp(Pathcontents(i).name,'.') && ~strcmp(Pathcontents(i).name,'..')
                    CatFolds(ind) = Pathcontents(i);
                    ind = ind + 1;
                end
            end
            
            nCat_old = ind - 1;
            if nCat_old == 0
                for i = 1:nCat_cur
                    CatFolds(i).name = num2str(Cat_cur(i));
                    mkdir(CatFolds(i).name);
                end
            else
                if nCat_old ~= nCat_cur
                    cd(curD);
                    errordlg('The folder contains a different number of categories','Categories');
                    
                    return
                    
                end
            end
            cd(curD);
            app.StatusText.Text = 'Writing images to appropriate folders...';
            drawnow
            for i = 1:size(app.Particles)
                d = num2str(i);
                if i < 10
                    d = ['0', d];
                end
                if i < 100
                    d = ['0', d];
                end
                curPart = app.Particles(i,:);
                x_cur = curPart(:,10);
                y_cur = curPart(:,11);
                t_cur = curPart(:,6);
                imCur = app.imStack(t_cur).data;
                spotIm = imCur(round(y_cur) - wndo:round(y_cur)+wndo,round(x_cur)-wndo:round(x_cur)+wndo);
                imwrite(uint16(spotIm),[path_out,filesep,CatFolds(response(i)+1).name,filesep,Prefix,'_',d,'.tif']);
                %                 if response(i) == 1
                %                     imwrite(uint16(spotIm),['Spot_images_1frame_2Cat_1_w7',filesep,'1',filesep,'E_',d,'.tif']);
                %                 else
                %                     imwrite(uint16(spotIm),['Spot_images_1frame_2Cat_1_w7',filesep,'0',filesep,'E_',d,'.tif']);
                %                 end
            end
            app.StatusText.Text = 'Writing images Done';
            drawnow;
        end

        % Menu selected function: PredictCurrentSpotsfromModelMenu
        function PredictCurrentSpotsfromModelMenuSelected(app, event)
            %Get the model file
            [modelFile,modelPath] = uigetfile('*.mat','Select the MAT file containing a trained model');
            drawnow
            figure(app.SpotTrainerUIFigure);
            
            if modelFile ~= 0
                modelIn = load(fullfile(modelPath,modelFile));
                app.StatusText.Text = 'Predicting Responses...';
                drawnow
                Ypred1 = predict(modelIn.BagOtrees,app.Predictors);
                
                app.Pred_NparticlesInSpot = zeros(length(app.Predictors),1);
                for i = 1:length(Ypred1)
                    app.Pred_NparticlesInSpot(i) = str2double(Ypred1{i});
                end
                app.StatusText.Text = 'Predicting Responses Done';
                drawnow
                app.ModelPredictionLabel.Enable = 1;
                app.pred_NparticlesText.Enable = 1;
                app.UsePredictedValueButton.Enable = 1;
                app.UseModelValuesforAllSpotsMenu.Enable = 1;
                app.pred_NparticlesText.Text = num2str(app.Pred_NparticlesInSpot(app.curInd));
                
                %replace the guesses for number of particles with the model
                %predictions if desired
                replaceAns = questdlg('Do you want to update all responses to the model values?','Use Model Values','Yes','No','No');
                
                if strcmp(replaceAns,'Yes')
                    app.NparticlesInSpot = app.Pred_NparticlesInSpot;
                    app.NumberofParticlesinSpotSpinner.Value = app.NparticlesInSpot(app.curInd);
                    value = app.SubtractBackgroundCheckBox.Value;
                    
                    if value == 1
                        app.IntMedianGood = median(app.intensitiesBgAll(app.NparticlesInSpot == 1));
                    else
                        app.IntMedianGood = median(app.intensitiesAll(app.NparticlesInSpot == 1));
                        
                    end
                    app.SigmaMedianGood = median(app.Predictors(app.NparticlesInSpot == 1,3));
                    
                end
                updateDisplay(app);
            end
        end

        % Button pushed function: UsePredictedValueButton
        function UsePredictedValueButtonPushed(app, event)
            app.NparticlesInSpot(app.curInd) = app.Pred_NparticlesInSpot(app.curInd);
            app.NumberofParticlesinSpotSpinner.Value = app.NparticlesInSpot(app.curInd);
            
            value = app.SubtractBackgroundCheckBox.Value;
            
            if value == 1
                app.IntMedianGood = median(app.intensitiesBgAll(app.NparticlesInSpot == 1));
            else
                app.IntMedianGood = median(app.intensitiesAll(app.NparticlesInSpot == 1));
            end
            
            app.SigmaMedianGood = median(app.Predictors(app.NparticlesInSpot == 1,3));
            
            updateDisplay(app);
        end

        % Menu selected function: UseModelValuesforAllSpotsMenu
        function UseModelValuesforAllSpotsMenuSelected(app, event)
            app.NparticlesInSpot = app.Pred_NparticlesInSpot;
            app.NumberofParticlesinSpotSpinner.Value = app.NparticlesInSpot(app.curInd);
            if value == 1
                app.IntMedianGood = median(app.intensitiesBgAll(app.NparticlesInSpot == 1));
            else
                app.IntMedianGood = median(app.intensitiesAll(app.NparticlesInSpot == 1));
            end
            
            app.SigmaMedianGood = median(app.Predictors(app.NparticlesInSpot == 1,3));
            
            updateDisplay(app);
        end

        % Menu selected function: CopySpotParametersMenu
        function CopySpotParametersMenuSelected(app, event)
            num2clip(app.Predictors);
            
        end

        % Menu selected function: CopyNumberofParticleDataMenu
        function CopyNumberofParticleDataMenuSelected(app, event)
            num2clip(app.NparticlesInSpot);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpotTrainerUIFigure = uifigure('Visible', 'off');
            app.SpotTrainerUIFigure.Name = 'Spot Trainer';
            app.FileMenu = uimenu(app.SpotTrainerUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveTrainingDataMenu = uimenu(app.FileMenu);
            app.SaveTrainingDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveTrainingDataMenuSelected, true);
            app.SaveTrainingDataMenu.Text = 'Save Training Data';
            app.LoadTrainingDataMenu = uimenu(app.FileMenu);
            app.LoadTrainingDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTrainingDataMenuSelected, true);
            app.LoadTrainingDataMenu.Text = 'Load Training Data';
            app.EditMenu = uimenu(app.SpotTrainerUIFigure);
            app.EditMenu.Text = 'Edit';
            app.CopySpotParametersMenu = uimenu(app.EditMenu);
            app.CopySpotParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @CopySpotParametersMenuSelected, true);
            app.CopySpotParametersMenu.Text = 'Copy Spot Parameters';
            app.CopyNumberofParticleDataMenu = uimenu(app.EditMenu);
            app.CopyNumberofParticleDataMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyNumberofParticleDataMenuSelected, true);
            app.CopyNumberofParticleDataMenu.Text = 'Copy Number of Particle Data';
            app.UseModelValuesforAllSpotsMenu = uimenu(app.EditMenu);
            app.UseModelValuesforAllSpotsMenu.MenuSelectedFcn = createCallbackFcn(app, @UseModelValuesforAllSpotsMenuSelected, true);
            app.UseModelValuesforAllSpotsMenu.Enable = 'off';
            app.UseModelValuesforAllSpotsMenu.Separator = 'on';
            app.UseModelValuesforAllSpotsMenu.Text = 'Use Model Values for All Spots';
            app.MachineLearningMenu = uimenu(app.SpotTrainerUIFigure);
            app.MachineLearningMenu.Text = 'Machine Learning';
            app.MergeDatasetsandTrainModelMenu = uimenu(app.MachineLearningMenu);
            app.MergeDatasetsandTrainModelMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeDatasetsandTrainModelMenuSelected, true);
            app.MergeDatasetsandTrainModelMenu.Text = 'Merge Datasets and Train Model';
            app.PredictCurrentSpotsfromModelMenu = uimenu(app.MachineLearningMenu);
            app.PredictCurrentSpotsfromModelMenu.MenuSelectedFcn = createCallbackFcn(app, @PredictCurrentSpotsfromModelMenuSelected, true);
            app.PredictCurrentSpotsfromModelMenu.Text = 'Predict Current Spots from Model';
            app.ToolsMenu = uimenu(app.SpotTrainerUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CreateSpotImagesMenu = uimenu(app.ToolsMenu);
            app.CreateSpotImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateSpotImagesMenuSelected, true);
            app.CreateSpotImagesMenu.Text = 'Create Spot Images';
            app.ImAxes = uiaxes(app.SpotTrainerUIFigure);
            title(app.ImAxes, '')
            xlabel(app.ImAxes, '')
            ylabel(app.ImAxes, '')
            app.ImAxes.XTick = [];
            app.ImAxes.YTick = [];
            app.HistAxes = uiaxes(app.SpotTrainerUIFigure);
            title(app.HistAxes, 'Spot Intensities')
            xlabel(app.HistAxes, 'Intensity')
            ylabel(app.HistAxes, 'Frequency')
            app.FrameNumberSliderLabel = uilabel(app.SpotTrainerUIFigure);
            app.FrameNumberSliderLabel.Text = 'Frame Number';
            app.FrameNumberSlider = uislider(app.SpotTrainerUIFigure);
            app.FrameNumberSlider.ValueChangedFcn = createCallbackFcn(app, @FrameNumberSliderValueChanged, true);
            app.FrameNumberSlider.MinorTicks = [];
            app.PreviousSpotButton = uibutton(app.SpotTrainerUIFigure, 'push');
            app.PreviousSpotButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousSpotButtonPushed, true);
            app.PreviousSpotButton.Text = 'Previous Spot';
            app.NextSpotButton = uibutton(app.SpotTrainerUIFigure, 'push');
            app.NextSpotButton.ButtonPushedFcn = createCallbackFcn(app, @NextSpotButtonPushed, true);
            app.NextSpotButton.Text = 'Next Spot';
            app.NumberofParticlesinSpotSpinnerLabel = uilabel(app.SpotTrainerUIFigure);
            app.NumberofParticlesinSpotSpinnerLabel.Text = 'Number of Particles in Spot';
            app.NumberofParticlesinSpotSpinner = uispinner(app.SpotTrainerUIFigure);
            app.NumberofParticlesinSpotSpinner.Limits = [0 5];
            app.NumberofParticlesinSpotSpinner.RoundFractionalValues = 'on';
            app.NumberofParticlesinSpotSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesinSpotSpinnerValueChanged, true);
            app.CloseButton = uibutton(app.SpotTrainerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TrainingParametersPanel = uipanel(app.SpotTrainerUIFigure);
            app.TrainingParametersPanel.Title = 'Training Parameters';
            app.NumberofTreesSpinnerLabel = uilabel(app.TrainingParametersPanel);
            app.NumberofTreesSpinnerLabel.Text = 'Number of Trees';
            app.NumberofTreesSpinner = uispinner(app.TrainingParametersPanel);
            app.NumberofTreesSpinner.Limits = [0 Inf];
            app.NumberofTreesSpinner.RoundFractionalValues = 'on';
            app.NumberofTreesSpinner.Value = 100;
            app.InBagFractionSpinnerLabel = uilabel(app.TrainingParametersPanel);
            app.InBagFractionSpinnerLabel.Text = 'In Bag Fraction';
            app.InBagFractionSpinner = uispinner(app.TrainingParametersPanel);
            app.InBagFractionSpinner.Step = 0.1;
            app.InBagFractionSpinner.Limits = [0 1];
            app.InBagFractionSpinner.Value = 1;
            app.MinLeafSizeSpinnerLabel = uilabel(app.TrainingParametersPanel);
            app.MinLeafSizeSpinnerLabel.Text = 'Min. Leaf Size';
            app.MinLeafSizeSpinner = uispinner(app.TrainingParametersPanel);
            app.MinLeafSizeSpinner.Limits = [1 Inf];
            app.MinLeafSizeSpinner.Value = 1;
            app.NumberofPredictorsSpinnerLabel = uilabel(app.TrainingParametersPanel);
            app.NumberofPredictorsSpinnerLabel.Text = 'Number of Predictors';
            app.NumberofPredictorsSpinner = uispinner(app.TrainingParametersPanel);
            app.TrainModelButton = uibutton(app.TrainingParametersPanel, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.OptimizeParametersButton = uibutton(app.TrainingParametersPanel, 'push');
            app.OptimizeParametersButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeParametersButtonPushed, true);
            app.OptimizeParametersButton.Text = 'Optimize Parameters';
            app.OOBErrorLabel = uilabel(app.TrainingParametersPanel);
            app.OOBErrorLabel.Text = 'OOB Error';
            app.WindowSizeSpinnerLabel = uilabel(app.SpotTrainerUIFigure);
            app.WindowSizeSpinnerLabel.Text = 'Window Size';
            app.WindowSizeSpinner = uispinner(app.SpotTrainerUIFigure);
            app.WindowSizeSpinner.Limits = [1 Inf];
            app.WindowSizeSpinner.RoundFractionalValues = 'on';
            app.WindowSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowSizeSpinnerValueChanged, true);
            app.WindowSizeSpinner.Value = 7;
            app.CurrentSpotLabel = uilabel(app.SpotTrainerUIFigure);
            app.CurrentSpotLabel.Text = 'Current Spot:';
            app.TotalSpotsLabel = uilabel(app.SpotTrainerUIFigure);
            app.TotalSpotsLabel.Text = 'Total Spots:';
            app.SubtractBackgroundCheckBox = uicheckbox(app.SpotTrainerUIFigure);
            app.SubtractBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractBackgroundCheckBoxValueChanged, true);
            app.SubtractBackgroundCheckBox.Text = 'Subtract Background';
            app.SubtractBackgroundCheckBox.Value = true;
            app.StatusText = uilabel(app.SpotTrainerUIFigure);
            app.StatusText.Text = '';
            app.Label = uilabel(app.SpotTrainerUIFigure);
            app.Label.Text = '';
            app.WhiteLevelSlider = uislider(app.SpotTrainerUIFigure);
            app.WhiteLevelSlider.MajorTicks = [];
            app.WhiteLevelSlider.ValueChangedFcn = createCallbackFcn(app, @WhiteLevelSliderValueChanged, true);
            app.WhiteLevelSlider.MinorTicks = [];
            app.Slider_2Label = uilabel(app.SpotTrainerUIFigure);
            app.Slider_2Label.Text = '';
            app.BlackLevelSlider = uislider(app.SpotTrainerUIFigure);
            app.BlackLevelSlider.MajorTicks = [];
            app.BlackLevelSlider.ValueChangedFcn = createCallbackFcn(app, @BlackLevelSliderValueChanged, true);
            app.BlackLevelSlider.MinorTicks = [];
            app.BlackLevelSpinnerLabel = uilabel(app.SpotTrainerUIFigure);
            app.BlackLevelSpinnerLabel.Text = 'Black Level';
            app.BlackLevelSpinner = uispinner(app.SpotTrainerUIFigure);
            app.BlackLevelSpinner.ValueDisplayFormat = '%.0f';
            app.BlackLevelSpinner.ValueChangedFcn = createCallbackFcn(app, @BlackLevelSpinnerValueChanged, true);
            app.WhiteLevelSpinnerLabel = uilabel(app.SpotTrainerUIFigure);
            app.WhiteLevelSpinnerLabel.Text = 'White Level';
            app.WhiteLevelSpinner = uispinner(app.SpotTrainerUIFigure);
            app.WhiteLevelSpinner.ValueDisplayFormat = '%.0f';
            app.WhiteLevelSpinner.ValueChangedFcn = createCallbackFcn(app, @WhiteLevelSpinnerValueChanged, true);
            app.AutoScaleButton = uibutton(app.SpotTrainerUIFigure, 'push');
            app.AutoScaleButton.ButtonPushedFcn = createCallbackFcn(app, @AutoScaleButtonPushed, true);
            app.AutoScaleButton.Tooltip = {'Scales the intensities using only the current particle '};
            app.AutoScaleButton.Text = 'Auto Scale';
            app.ResetScaleButton = uibutton(app.SpotTrainerUIFigure, 'push');
            app.ResetScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScaleButtonPushed, true);
            app.ResetScaleButton.Tooltip = {'Scales the intensities using all particles'};
            app.ResetScaleButton.Text = 'Reset Scale';
            app.ModelPredictionLabel = uilabel(app.SpotTrainerUIFigure);
            app.ModelPredictionLabel.Enable = 'off';
            app.ModelPredictionLabel.Text = 'Model Prediction';
            app.pred_NparticlesText = uilabel(app.SpotTrainerUIFigure);
            app.pred_NparticlesText.Enable = 'off';
            app.pred_NparticlesText.Text = 'N/A';
            app.UsePredictedValueButton = uibutton(app.SpotTrainerUIFigure, 'push');
            app.UsePredictedValueButton.ButtonPushedFcn = createCallbackFcn(app, @UsePredictedValueButtonPushed, true);
            app.UsePredictedValueButton.Enable = 'off';
            app.UsePredictedValueButton.Text = {'Use Predicted '; 'Value'};
            app.MediansigmaGoodLabel = uilabel(app.SpotTrainerUIFigure);
            app.MediansigmaGoodLabel.Text = 'Median sigma (Good):';
            app.SigmaMedianGoodText = uilabel(app.SpotTrainerUIFigure);
            app.SigmaMedianGoodText.Text = '';
            app.CurrentsigmaLabel = uilabel(app.SpotTrainerUIFigure);
            app.CurrentsigmaLabel.Text = 'Current sigma:';
            app.SigmaCurText = uilabel(app.SpotTrainerUIFigure);
            app.SigmaCurText.Text = '';
            app.MediansigmaAllLabel = uilabel(app.SpotTrainerUIFigure);
            app.MediansigmaAllLabel.Text = 'Median sigma (All):';
            app.SigmaMedianAllText = uilabel(app.SpotTrainerUIFigure);
            app.SigmaMedianAllText.Text = {''; ''};
            % Show the figure after all components are created
            app.SpotTrainerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpotTrainer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpotTrainerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpotTrainerUIFigure)
        end
    end
end