classdef TrackPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadTrackingDataMenu            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NormalizeCurvesCheckBox         matlab.ui.control.CheckBox
        Setstartto0CheckBox             matlab.ui.control.CheckBox
        UpdatePlotButton                matlab.ui.control.Button
        BackgroundCorrectionCheckBox    matlab.ui.control.CheckBox
        TimeIntervalsEditField          matlab.ui.control.NumericEditField
        TimeIntervalsEditFieldLabel     matlab.ui.control.Label
        CopyTrackDatatoClipboardButton  matlab.ui.control.Button
        TrackstoPlotListBox             matlab.ui.control.ListBox
        TrackstoPlotListBoxLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        SaveCurrentFigureButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Tracks % place holder for tracks
        FrameTime_orig % frame interval specified in the file in seconds
        FrameTime_set % frame interval set in the app in seconds
        CurTracks % list of tracks currently displayed
        LegStr % string for the legend
        imStack % stack of images
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin > 1
                if ~isempty(varargin{1})
                    app.Tracks = varargin{1};
                    app.FrameTime_orig = varargin{2};
                    track_name_arr = cell(max(app.Tracks(:,4)),1);
                    track_ind_arr = cell(max(app.Tracks(:,4)),1);
                    for i = 1:max(app.Tracks(:,4))
                        track_name_arr{i,:} = ['Track ', num2str(i)];
                        track_ind_arr{i,:} = i;
                    end
                    app.TrackstoPlotListBox.Items = track_name_arr;
                    app.TrackstoPlotListBox.ItemsData = track_ind_arr;
                    app.TrackstoPlotListBox.Value = {1};
                    
                    app.TimeIntervalsEditField.Value = app.FrameTime_orig;
                    app.FrameTime_set = app.FrameTime_orig;
                end
                %%
                if length(varargin) > 2
                    pos_fig = varargin{3};
                    ChangeAppPosition(app.UIFigure,pos_fig);
                    drawnow;
                end
            end
        end

        % Menu selected function: LoadTrackingDataMenu
        function LoadTrackingDataMenuSelected(app, event)
            [fname, pname] = uigetfile('*.mat','Select MatTrack tracking file');
            drawnow;
            figure(app.UIFigure);
            if fname ~= 0
                IN = load(fullfile(pname,fname));
                if ~isfield(IN.Results,'PreAnalysis')
                    %If the data has not been preprocessed we can't get
                    %the intensities
                    errordlg('Please track  the data and perform Pre-processing first','No Tracks');
                    return
                else
                    app.Tracks = IN.Results.PreAnalysis.Tracks_um;
                    tracks_px = IN.Results.Tracking.Tracks;
                end
                % load in the imagestack
                app.imStack = IN.Results.Data.imageStack;
                track_name_arr = cell(max(app.Tracks(:,4)),1);
                track_ind_arr = cell(max(app.Tracks(:,4)),1);
                for i = 1:max(app.Tracks(:,4))
                    track_name_arr{i,:} = ['Track ', num2str(i)];
                    track_ind_arr{i,:} = i;
                end
                app.TrackstoPlotListBox.Items = track_name_arr;
                app.TrackstoPlotListBox.ItemsData = track_ind_arr;
                app.TrackstoPlotListBox.Value = {1};
                app.FrameTime_orig = IN.Results.Parameters.Acquisition.frameTime;
                app.TimeIntervalsEditField.Value = app.FrameTime_orig;
                app.FrameTime_set = app.FrameTime_orig;
                
                nTrks = i;
                extTracks = [];
                tMax = max(app.Tracks(:,3));
                
                for i = 1:nTrks
                    %get the current track data (in px and um)
                    curT = app.Tracks(app.Tracks(:,4) == i,:);
                    curTpx = tracks_px(tracks_px(:,4) == i,:);
                    
                    %get the last position & frame
                    lastFrame = curT(end,3);
                    lastPos = curT(end,1:2);
                    lastROI = curT(end,5);
                    lastPosPx = curTpx(end,1:2);
                    
                    nFrames2add = tMax - lastFrame;
                    
                    %extend the track to the end of the time-course
                    addTrk = zeros(nFrames2add,5);
                    addTrk(:,1:2) = repmat(lastPosPx,nFrames2add,1);
                    addTrk(:,3) = (lastFrame+1:tMax)';
                    addTrk(:,4) = repmat(i,nFrames2add,1);
                    addTrk(:,5) = repmat(lastROI,nFrames2add,1);
                   
                    if ~isempty(addTrk)
                        addTrk = AddAiryIntensity(addTrk,IN.Results.Parameters.Acquisition.pixelSize,app.imStack);
                        addTrk(:,1:2) = repmat(lastPos,nFrames2add,1);
                    else
                        addTrk = [];
                    end
                    
                    %concatenate to generate the new tracking data
                    extTracks = [extTracks; curT; addTrk];
                end
                app.Tracks = extTracks;
                
            end
        end

        % Value changed function: TimeIntervalsEditField
        function TimeIntervalsEditFieldValueChanged(app, event)
            value = app.TimeIntervalsEditField.Value;
            app.FrameTime_set = value;
            
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            tracks2plot = app.TrackstoPlotListBox.Value;
            bg_corr_flag = app.BackgroundCorrectionCheckBox.Value;
            if ~isempty(tracks2plot)
                tmpTracks = app.Tracks;
                maxT = max(tmpTracks(:,3));
                tmpTracks(:,3) = app.FrameTime_set.*(round(tmpTracks(:,3))-1);
                
                
                %                 app.UIAxes.XLim = [0,(maxT-1).*app.FrameTime_set];
                app.CurTracks = (1:maxT)';
                app.CurTracks = (app.CurTracks - 1)*app.FrameTime_set;
                
                app.CurTracks = [app.CurTracks, zeros(size(app.CurTracks,1),length(tracks2plot))];
                for i = 1:length(tracks2plot)
                    ind = tracks2plot{i};
                    curTrk = tmpTracks(tmpTracks(:,4) == ind,:);
                    
                    if bg_corr_flag == 1
                        curTrk = [curTrk(:,3),curTrk(:,6)-curTrk(:,7)];
                    else
                        curTrk = [curTrk(:,3),curTrk(:,6)];
                        
                    end
                    if app.NormalizeCurvesCheckBox.Value == 1
                        mn = min(curTrk(:,2));
                        mx = max(curTrk(:,2));
                        curTrk(:,2) = (curTrk(:,2) - mn)./(mx - mn);
                    end
                    if app.Setstartto0CheckBox.Value == 1
                        curTrk(:,1) = curTrk(:,1) - min(curTrk(:,1));
                    end
                    for j = 1:size(app.CurTracks,1)
                        if ~isempty(find(abs(curTrk(:,1) - app.CurTracks(j,1)) < 1000*eps, 1,"first"))
                            app.CurTracks(j,i+1) = curTrk(abs(curTrk(:,1)-app.CurTracks(j,1))<1000*eps,2);
                        else
                            app.CurTracks(j,i+1) = NaN;
                        end
                        
                    end
                    leg_str{i,:} = ['Track ', num2str(ind)];
                    
                end
                for i = 1:length(tracks2plot)
                    plot(app.UIAxes,app.CurTracks(:,1),app.CurTracks(:,i+1));
                    hold(app.UIAxes,'on');
                end
                hold(app.UIAxes,"off");
                legend(app.UIAxes,leg_str);
                app.LegStr = leg_str;
                
            else
                Z
            end
        end

        % Button pushed function: CopyTrackDatatoClipboardButton
        function CopyTrackDatatoClipboardButtonPushed(app, event)
            num2clip(app.CurTracks);
            msgbox('The parameters have been copied to the clipboard');
        end

        % Button pushed function: SaveCurrentFigureButton
        function SaveCurrentFigureButtonPushed(app, event)
            [fname,pname,type_ind] = uiputfile({'*.fig';'*.tif';'*.png'},'Choose Save Name',fullfile(pwd,'Track_intensities'));
            drawnow;
            figure(app.UIFigure);
            if fname ~= 0
                h_fig = figure;
                h_ax = axes(h_fig);
                for i = 2:size(app.CurTracks,2)
                    plot(h_ax,app.CurTracks(:,1),app.CurTracks(:,i));
                    
                end
                xlabel(h_ax,'Time (s)')
                ylabel(h_ax,'Intensity (a.u.)')
                legend(h_ax,app.LegStr);
                if type_ind == 1
                    
                    savefig(h_fig,fullfile(pname,fname));
                else
                    
                    saveas(h_fig,fullfile(pname,fname));
                    
                end
                close(h_fig);
                
                
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {185, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadTrackingDataMenu = uimenu(app.FileMenu);
            app.LoadTrackingDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTrackingDataMenuSelected, true);
            app.LoadTrackingDataMenu.Text = 'Load Tracking Data';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TrackstoPlotListBoxLabel = uilabel(app.LeftPanel);
            app.TrackstoPlotListBoxLabel.Text = 'Tracks to Plot';
            app.TrackstoPlotListBox = uilistbox(app.LeftPanel);
            app.TrackstoPlotListBox.Items = {};
            app.TrackstoPlotListBox.Multiselect = 'on';
            app.TrackstoPlotListBox.Value = {};
            app.CopyTrackDatatoClipboardButton = uibutton(app.LeftPanel, 'push');
            app.CopyTrackDatatoClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTrackDatatoClipboardButtonPushed, true);
            app.CopyTrackDatatoClipboardButton.Text = 'Copy Track Data to Clipboard';
            app.TimeIntervalsEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeIntervalsEditFieldLabel.Text = 'Time Interval (s)';
            app.TimeIntervalsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimeIntervalsEditField.Limits = [0 Inf];
            app.TimeIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervalsEditFieldValueChanged, true);
            app.TimeIntervalsEditField.Value = 0.2;
            app.BackgroundCorrectionCheckBox = uicheckbox(app.LeftPanel);
            app.BackgroundCorrectionCheckBox.Text = 'Background Correction';
            app.UpdatePlotButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.Setstartto0CheckBox = uicheckbox(app.LeftPanel);
            app.Setstartto0CheckBox.Text = 'Set start to 0';
            app.Setstartto0CheckBox.Value = true;
            app.NormalizeCurvesCheckBox = uicheckbox(app.LeftPanel);
            app.NormalizeCurvesCheckBox.Text = 'Normalize Curves';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, {'Time (s)'; ''})
            ylabel(app.UIAxes, 'Intensity (a.u.)')
            app.SaveCurrentFigureButton = uibutton(app.RightPanel, 'push');
            app.SaveCurrentFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentFigureButtonPushed, true);
            app.SaveCurrentFigureButton.Text = 'Save Current Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackPlotter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end