classdef BatchTrackingMovieSelect_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackBatchMovieListUIFigure  matlab.ui.Figure
        DoneButton  matlab.ui.control.Button
        AddButton   matlab.ui.control.Button
        RemButton   matlab.ui.control.Button
        MoviestoIncludeinProcessingListBox  matlab.ui.control.ListBox
        MoviestoIncludeinProcessingListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        filenames % Description
        pathnames
        BatchMainFig
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, movieList, pathList, BatchFig, varargin)
            if ~isempty(varargin)
                pos_fig = varargin{1};
                ChangeAppPosition(app.QuantiTrackBatchMovieListUIFigure,pos_fig);
                drawnow;
            end
            
            app.filenames = movieList;
            app.pathnames = pathList;
            app.BatchMainFig = BatchFig;
            if ~isempty(movieList)
                app.MoviestoIncludeinProcessingListBox.Items = movieList;
            end
            outFilesNpaths{1} = app.filenames;
            outFilesNpaths{2} = app.pathnames;
            setappdata(app.BatchMainFig,'UpdatedFiles',outFilesNpaths);


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            FileRootPath = uigetdir(pwd,'Select Root Folder containing images');
            figure(app.QuantiTrackBatchMovieListUIFigure);

            if FileRootPath ~= 0
                
                [IMfiles, IMpaths] = extractMovieFileNames(FileRootPath,app.QuantiTrackBatchMovieListUIFigure);


                %check if the movies have already been split and aligned
                Paths_present = unique(IMpaths,'stable');

                if length(Paths_present) < length(IMpaths)
                    [IMfiles_base, IMfiles_ends] = getMovieFileNameParts(IMpaths,IMfiles,Paths_present);
                else
                    IMfiles_base = IMfiles;
                end
                
                %only add files that are not already in the list
                if isempty(app.filenames)
                    app.filenames = IMfiles_base;
                    app.pathnames = Paths_present;
                else
                    filesAdded = 0;
                    for i = 1:length(IMfiles_base)
                        curNewFile = fullfile(Paths_present{i,:},IMfiles_base{i,:});
                        curFileInList = 0;
                        for j = 1:length(app.filenames)
                            oldFile = fullfile(app.pathnames{j,:}, app.filenames{j,:});
                            if strcmp(curNewFile,oldFile) == 1
                                curFileInList = 1;
                                break
                            end
                        end
                        if curFileInList == 0
                            app.filenames = [app.filenames; IMfiles_base{i,:}];
                            app.pathnames = [app.pathnames; Paths_present{i,:}];
                            filesAdded = filesAdded + 1;
    
                        end
                    end
                    if filesAdded <= length(IMfiles_base)
                        msgbox('Some selected files were already present')
                    end
                    
                end
                app.MoviestoIncludeinProcessingListBox.Items = app.filenames;

                outFilesNpaths{1} = app.filenames;
                outFilesNpaths{2} = app.pathnames;
                setappdata(app.BatchMainFig,'UpdatedFiles',outFilesNpaths);

                
            end

        end

        % Button pushed function: RemButton
        function RemButtonPushed(app, event)
            selMovie = app.MoviestoIncludeinProcessingListBox.ValueIndex;
            app.filenames(selMovie,:) = [];
            app.pathnames(selMovie,:) = [];
            app.MoviestoIncludeinProcessingListBox.Items = app.filenames;
            outFilesNpaths{1} = app.filenames;
            outFilesNpaths{2} = app.pathnames;
            setappdata(app.BatchMainFig,'UpdatedFiles',outFilesNpaths);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            close(app.QuantiTrackBatchMovieListUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackBatchMovieListUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackBatchMovieListUIFigure.Name = 'QuantiTrack Batch Movie List';
            app.MoviestoIncludeinProcessingListBoxLabel = uilabel(app.QuantiTrackBatchMovieListUIFigure);
            app.MoviestoIncludeinProcessingListBoxLabel.Text = 'Movies to Include in Processing';
            app.MoviestoIncludeinProcessingListBox = uilistbox(app.QuantiTrackBatchMovieListUIFigure);
            app.MoviestoIncludeinProcessingListBox.Items = {};
            app.MoviestoIncludeinProcessingListBox.Multiselect = 'on';
            app.MoviestoIncludeinProcessingListBox.Value = {};
            app.RemButton = uibutton(app.QuantiTrackBatchMovieListUIFigure, 'push');
            app.RemButton.ButtonPushedFcn = createCallbackFcn(app, @RemButtonPushed, true);
            app.RemButton.Text = 'Remove';
            app.AddButton = uibutton(app.QuantiTrackBatchMovieListUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Tag = 'QuantiTrack - Batch Files';
            app.AddButton.Text = 'Add...';
            app.DoneButton = uibutton(app.QuantiTrackBatchMovieListUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.QuantiTrackBatchMovieListUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatchTrackingMovieSelect_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackBatchMovieListUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackBatchMovieListUIFigure)
        end
    end
end