classdef QuantiTrack_BatchTracking_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackBatchTrackingUIFigure  matlab.ui.Figure
        ChannelSpecificationsROIsPanel  matlab.ui.container.Panel
        ROIClassEditField               matlab.ui.control.EditField
        ROIClassEditFieldLabel          matlab.ui.control.Label
        ROIminIntensity0autoEditField   matlab.ui.control.NumericEditField
        ROIminIntensity0autoEditFieldLabel  matlab.ui.control.Label
        ReferenceImageChannelEditField  matlab.ui.control.EditField
        ReferenceImageChannelEditFieldLabel  matlab.ui.control.Label
        PrimaryChanneltoTrackEditField  matlab.ui.control.EditField
        PrimaryChanneltoTrackEditFieldLabel  matlab.ui.control.Label
        ChannelOrderinMoviesEditField   matlab.ui.control.EditField
        ChannelOrderinMoviesEditFieldLabel  matlab.ui.control.Label
        RunBatchButton                  matlab.ui.control.Button
        SaveLocationPanel               matlab.ui.container.Panel
        SessionDropDown                 matlab.ui.control.DropDown
        SessionDropDownLabel            matlab.ui.control.Label
        ConditionDropDown               matlab.ui.control.DropDown
        ConditionDropDownLabel          matlab.ui.control.Label
        CellProteinDropDown             matlab.ui.control.DropDown
        CellProteinDropDownLabel        matlab.ui.control.Label
        ChangeDatabaseButton            matlab.ui.control.Button
        TrackDatabaseLocationLabel      matlab.ui.control.Label
        MoviestoProcessPanel            matlab.ui.container.Panel
        EditMovieListButton             matlab.ui.control.Button
        NumberOfMoviesText              matlab.ui.control.Label
        MovieBrowseButton               matlab.ui.control.Button
        DirectoryofMoviesEditField      matlab.ui.control.EditField
        DirectoryofMoviesEditFieldLabel  matlab.ui.control.Label
        TrackingParametersPanel         matlab.ui.container.Panel
        LengthofGapsframesSpinner       matlab.ui.control.Spinner
        LengthofGapsframesSpinnerLabel  matlab.ui.control.Label
        ShortestTrackframesSpinner      matlab.ui.control.Spinner
        ShortestTrackframesSpinnerLabel  matlab.ui.control.Label
        MaxJumppxSpinner                matlab.ui.control.Spinner
        MaxJumppxSpinnerLabel           matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ParticleIdentificationParametersPanel  matlab.ui.container.Panel
        LowerLimitSpinner               matlab.ui.control.Spinner
        LowerLimitSpinnerLabel          matlab.ui.control.Label
        WindowSizeSpinner               matlab.ui.control.Spinner
        WindowSizeSpinnerLabel          matlab.ui.control.Label
        IntensityThresholdSpinner       matlab.ui.control.Spinner
        IntensityThresholdSpinnerLabel  matlab.ui.control.Label
        UpperLimitSpinner               matlab.ui.control.Spinner
        UpperLimitSpinnerLabel          matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        FilterTypeDropDownLabel         matlab.ui.control.Label
        AcquisitionParametersPanel      matlab.ui.container.Panel
        ProfileDropDown                 matlab.ui.control.DropDown
        ProfileDropDownLabel            matlab.ui.control.Label
        EmissionWavelengthnmEditField   matlab.ui.control.NumericEditField
        EmissionWavelengthnmEditFieldLabel  matlab.ui.control.Label
        ObjectiveNAEditField            matlab.ui.control.NumericEditField
        ObjectiveNAEditFieldLabel       matlab.ui.control.Label
        TimelapseIntervalsEditField     matlab.ui.control.NumericEditField
        TimelapseIntervalsEditFieldLabel  matlab.ui.control.Label
        ExposureTimesEditField          matlab.ui.control.NumericEditField
        ExposureTimesEditFieldLabel     matlab.ui.control.Label
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        ChannelAlignmentPanel           matlab.ui.container.Panel
        WavelengthsPresent              matlab.ui.control.Label
        SplitandAlignMoviesCheckBox     matlab.ui.control.CheckBox
        NewBeadRegistationButton        matlab.ui.control.Button
        RegistrationFileBrowseButton    matlab.ui.control.Button
        BeadRegistrationFileEditField   matlab.ui.control.EditField
        BeadRegistrationFileEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Description
    end
    
    properties (Access = public)
        db_path % Description
        lastCellProtein % Description
        lastCondition % Description
        lastSession % Description
        
        useParallel 
        imFilesRaw % Description
        imPaths % Description
        RegMat % Description
        RegLambdas % Description
        IMlambdaOrderStr % Description
        TrackLambdaStr % Description
        RefLambdaStr % Description
        saveFolder % Description
        imFilesTrack % Description
        imFilesRef % Description
    end
    
    methods (Access = private)
        
        function setCellProteinList(app)
            %get the cell_protein folders within the track database
            db_folds = dir(app.db_path);
            
            cell_protein_folds = {'Add New...'};
            lastCellProtein_present = 0;
            for i = 1:length(db_folds)
                if db_folds(i).isdir && ~strcmp(db_folds(i).name,'.') && ~strcmp(db_folds(i).name,'..') && isempty(strfind(db_folds(i).name,'results')) && isempty(strfind(db_folds(i).name,'transition_probabilities'))
                    cell_protein_folds = [cell_protein_folds; db_folds(i).name];
                    if ~isempty(app.lastCellProtein) && strcmp(db_folds(i).name,app.lastCellProtein)
                        lastCellProtein_present = 1;
                    end
                end
            end
            
            
            %if there are no cell_protein folders ask the user to create a
            %new folder
            if length(cell_protein_folds) == 1
                prompt = {'Enter Name of Cell/Protein:'};
                dlgtitle = 'New Cell/Protein Folder';
                dims = [1 70];
                definput = {'New Cell/Protein'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);

                new_cell_protein = fold_name{1};

                %create the new folder
                mkdir(fullfile(app.db_path,new_cell_protein));

                %add the folder to the list
                cell_protein_folds = [cell_protein_folds; new_cell_protein];
                lastCellProtein_present = 1;
                app.lastCellProtein = new_cell_protein;
            end

            app.CellProteinDropDown.Items = cell_protein_folds;

            if lastCellProtein_present == 1
                app.CellProteinDropDown.Value = app.lastCellProtein;
            else
                app.CellProteinDropDown.Value = cell_protein_folds{2,:};
            end
        end
        
        function setConditionList(app)
            %setup the Conditions dropdown only if a real folder is
            %selected
            

            if ~strcmp(app.CellProteinDropDown.Value, 'Add New...')
                
                cond_folds = {'Add New...'};
                cell_protein_contents = dir(fullfile(app.db_path,app.CellProteinDropDown.Value));
                lastCond_present = 0;
                for i = 1:length(cell_protein_contents)
                    if cell_protein_contents(i).isdir && ~strcmp(cell_protein_contents(i).name,'.') && ~strcmp(cell_protein_contents(i).name,'..') && isempty(strfind(cell_protein_contents(i).name,'results'))
                        cond_folds = [cond_folds; cell_protein_contents(i).name];
                        if strcmp(app.lastCondition,cell_protein_contents(i).name)
                            lastCond_present = 1;
                        end
                    end
                end
                %if there are no condition folders ask the user to create a
                %new folder
                if length(cond_folds) == 1
                    prompt = {'Enter Name of Condition:'};
                    dlgtitle = 'New Condition Folder';
                    dims = [1 70];
                    definput = {'New Condition'};
                    fold_name = inputdlg(prompt,dlgtitle,dims,definput);

                    new_condition = fold_name{1};

                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,new_condition));

                    %add the folder to the list
                    cond_folds = [cond_folds; new_condition];
                    lastCond_present = 1;
                    app.lastCondition = new_condition;
                end

                app.ConditionDropDown.Items = cond_folds;

                if lastCond_present == 1
                    app.ConditionDropDown.Value = app.lastCondition;
                else
                    app.ConditionDropDown.Value = cond_folds{2,:};
                end
            end
        end
        
        function setSessionList(app)
            %setup the Sessions dropdown only if a real folder is
            %selected
            

            if ~strcmp(app.ConditionDropDown.Value, 'Add New...')
                
                session_folds = {'Add New...'};
                condition_contents = dir(fullfile(app.db_path,app.CellProteinDropDown.Value,app.ConditionDropDown.Value));
                lastSession_present = 0;
                for i = 1:length(condition_contents)
                    if condition_contents(i).isdir && ~strcmp(condition_contents(i).name,'.') && ~strcmp(condition_contents(i).name,'..') && isempty(strfind(condition_contents(i).name,'results'))
                        session_folds = [session_folds; condition_contents(i).name];
                        if strcmp(app.lastSession,condition_contents(i).name)
                            lastSession_present = 1;
                        end
                    end
                end
                %if there are no condition folders ask the user to create a
                %new folder
                if length(session_folds) == 1
                    prompt = {'Enter Name of Session:'};
                    dlgtitle = 'New Session Folder';
                    dims = [1 70];
                    definput = {'New Session'};
                    fold_name = inputdlg(prompt,dlgtitle,dims,definput);

                    new_session = fold_name{1};

                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,app.ConditionDropDown.Value, new_session));

                    %add the folder to the list
                    session_folds = [session_folds; new_session];
                    lastSession_present = 1;
                    app.lastSession = new_session;
                end

                app.SessionDropDown.Items = session_folds;

                if lastSession_present == 1
                    app.SessionDropDown.Value = app.lastSession;
                else
                    app.SessionDropDown.Value = session_folds{2,:};
                end
            end
        end
        
        function checkEverythingToRun(app)
            if ~isempty(app.IMlambdaOrderStr) && ~isempty(app.TrackLambdaStr) ...
                    && ~isempty(app.RefLambdaStr) && ~isempty(app.imFilesRaw) ...
                    && (~isempty(app.RegMat) || app.SplitandAlignMoviesCheckBox.Value == 0)
                app.RunBatchButton.Enable = "on";
            else
                app.RunBatchButton.Enable = "off";
            end
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Profiles, lastProfileID, defParams, useParallel, varargin)
            %Move the window to correspond to the position of the main
            %window
            app.useParallel = useParallel;
            if ~isempty(varargin)
                pos_fig = varargin{1};
                ChangeAppPosition(app.QuantiTrackBatchTrackingUIFigure,pos_fig);
                drawnow;
            end

            %Set the profile and acquisiton parameters based on the
            %current profile selected in the main QT window
            for i = 1:length(Profiles)
                app.ProfileDropDown.Items{i,:} = Profiles(i).Name;
            end
            app.ProfileDropDown.Value = Profiles(lastProfileID).Name;


            app.EmissionWavelengthnmEditField.Value = Profiles(lastProfileID).lambda.*1000;
            app.ObjectiveNAEditField.Value = Profiles(lastProfileID).objNA;
            app.TimelapseIntervalsEditField.Value = Profiles(lastProfileID).frameTime;
            app.ExposureTimesEditField.Value = Profiles(lastProfileID).exposureTime;
            app.PixelSizenmEditField.Value = Profiles(lastProfileID).pixelSize*1000;

            %populate the save location items
            db_path = Profiles(lastProfileID).trackingSaveDir;
            app.db_path = db_path;
            app.lastCellProtein = Profiles(lastProfileID).lastCellProtein;
            app.lastCondition = Profiles(lastProfileID).lastCondition;
            app.lastSession = Profiles(lastProfileID).lastSession;
            
            
            app.TrackDatabaseLocationLabel.Text = db_path;

            setCellProteinList(app);
            setConditionList(app);
            setSessionList(app);
            
            app.saveFolder = [app.db_path, filesep, app.lastCellProtein, filesep, app.lastCondition, filesep, app.lastSession];
            
            %Populate the particle identification parameters
            app.FilterTypeDropDown.Items = defParams.filtOptions;
            app.FilterTypeDropDown.Value = defParams.filtOptions{defParams.filtVal};
            if strcmp(defParams.filtOptions{defParams.filtVal},'LoG')
                app.LowerLimitSpinnerLabel.Text = 'Sigma';
                app.UpperLimitSpinnerLabel.Text = 'Width';
            end
            app.LowerLimitSpinner.Value = defParams.lpThresh;
            app.UpperLimitSpinner.Value = defParams.hpThresh;
            app.IntensityThresholdSpinner.Value = defParams.IntThresh;
            app.WindowSizeSpinner.Value = defParams.windowSz;

            %Populate the Tracking Parameters
            app.MethodDropDown.Items = defParams.trackOptions;
            app.MethodDropDown.Value = defParams.trackOptions{defParams.trackVal};
            app.MaxJumppxSpinner.Value = defParams.maxJump;
            app.ShortestTrackframesSpinner.Value = defParams.shTrack;
            app.LengthofGapsframesSpinner.Value = defParams.gaps;


        end

        % Button pushed function: NewBeadRegistationButton
        function NewBeadRegistationButtonPushed(app, event)
            bead_app = BeadCalibration_App(get(gcf,'Position'),app.QuantiTrackBatchTrackingUIFigure);
            waitfor(bead_app);

            RegFullFile = getappdata(app.QuantiTrackBatchTrackingUIFigure,'RegFullFile');
            app.BeadRegistrationFileEditField.Value = RegFullFile;


        end

        % Button pushed function: MovieBrowseButton
        function MovieBrowseButtonPushed(app, event)
            FileRootPath = uigetdir(pwd,'Select Root Folder containing images');
            figure(app.QuantiTrackBatchTrackingUIFigure);
            if FileRootPath ~= 0
                app.DirectoryofMoviesEditField.Value = FileRootPath;

                [IMfiles, IMpaths] = extractMovieFileNames(FileRootPath,app.QuantiTrackBatchTrackingUIFigure);


                %check if the movies have already been split and aligned
                Paths_present = unique(IMpaths,'stable');

                if length(Paths_present) < length(IMpaths)
                    % ReAlignAns = questdlg("Multiple Movies present, Re-split and align?",'Existing alignment data','Yes','No','No');
                    ReAlignAns = 'Yes';
                    [IMfiles_base, IMfiles_ends] = getMovieFileNameParts(IMpaths,IMfiles,Paths_present);
                else
                    ReAlignAns = 'Yes';
                    IMfiles_base = IMfiles;
                end
                
                app.SplitandAlignMoviesCheckBox.Enable = "on";
                app.ChannelAlignmentPanel.Enable = "on";

                if strcmp(ReAlignAns,'No')
                    % app.SplitandAlignMoviesCheckBox.Value = 0;
                    % app.NewBeadRegistationButton.Enable = "off";
                    % app.BeadRegistrationFileEditField.Enable = "off";
                    % app.BeadRegistrationFileEditFieldLabel.Enable = "off";
                    % app.RegistrationFileBrowseButton.Enable = "off";
                    % app.WavelengthsPresent.Enable = "off";

                    %Since multiple movies are present and you don't want
                    % split again, we also need to ask which is the tracking
                    % channel and which is the reference (GUI with dropdown
                    % menus). TBD


                else
                    %enable the channel specification panel
                    app.ChannelSpecificationsROIsPanel.Enable = "on";
                    app.ChannelOrderinMoviesEditField.Enable = "on";
                    app.ChannelOrderinMoviesEditFieldLabel.Enable = "on";

                    app.PrimaryChanneltoTrackEditField.Enable = "on";
                    app.PrimaryChanneltoTrackEditFieldLabel.Enable = "on";
                    app.ReferenceImageChannelEditFieldLabel.Enable = "on";
                    app.ReferenceImageChannelEditField.Enable = "on";
                    app.ROIminIntensity0autoEditField.Enable = "on";
                    app.ROIminIntensity0autoEditFieldLabel.Enable = "on";
                    app.ROIClassEditField.Enable = "on";
                    app.ROIClassEditFieldLabel.Enable = "on";
                    
                    app.SplitandAlignMoviesCheckBox.Value = 1;
                    app.NewBeadRegistationButton.Enable = "on";
                    app.BeadRegistrationFileEditField.Enable = "on";
                    app.BeadRegistrationFileEditFieldLabel.Enable = "on";
                    app.RegistrationFileBrowseButton.Enable = "on";
                    app.WavelengthsPresent.Enable = "on";

                    app.IMlambdaOrderStr = app.ChannelOrderinMoviesEditField.Value;
                    app.TrackLambdaStr = app.PrimaryChanneltoTrackEditField.Value;
                    app.RefLambdaStr = app.ReferenceImageChannelEditField.Value;


                    %Take only the movie from each folder with the shortest filename
                    app.imFilesRaw = IMfiles_base;
                    app.imPaths = Paths_present;
                end

                app.EditMovieListButton.Enable = "on";
               
                app.NumberOfMoviesText.Text = [num2str(length(Paths_present)), ' movies to be processed'];

                if ~isempty(app.RegMat)
                    app.RunBatchButton.Enable = "on";
                end

                
            end
            checkEverythingToRun(app);
            
        end

        % Button pushed function: RegistrationFileBrowseButton
        function RegistrationFileBrowseButtonPushed(app, event)
            CalibPath = pwd;
            [fname, pname] = uigetfile(fullfile(CalibPath,'*.mat'),'Open Mat file with Registration info');
            figure(app.QuantiTrackBatchTrackingUIFigure);
            if fname ~= 0
                IN = load(fullfile(pname,fname));
                app.RegMat = IN.RegMat;
                app.RegLambdas = IN.wavelengths;
                app.BeadRegistrationFileEditField.Value = fullfile(pname,fname);
                newText = ['Wavelengths present in Alignment file: ', num2str(app.RegLambdas(1,1)), ...
                    ', ', num2str(app.RegLambdas(1,2))];
                wl_str = [num2str(app.RegLambdas(1,1)), ',', num2str(app.RegLambdas(1,2))];
                if size(app.RegLambdas,1) > 1
                    newText = [newText,', ', num2str(app.RegLambdas(2,2))];
                    wl_str = [wl_str, ',',num2str(app.RegLambdas(2,2))];
                end
                
                app.ChannelOrderinMoviesEditField.Value = wl_str;
                app.WavelengthsPresent.Text = newText;
                %enable the channel specification panel
                app.ChannelSpecificationsROIsPanel.Enable = "on";
                app.ChannelOrderinMoviesEditField.Enable = "on";
                app.ChannelOrderinMoviesEditFieldLabel.Enable = "on";

                app.PrimaryChanneltoTrackEditField.Enable = "on";
                app.PrimaryChanneltoTrackEditFieldLabel.Enable = "on";
                app.ReferenceImageChannelEditFieldLabel.Enable = "on";
                app.ReferenceImageChannelEditField.Enable = "on";
                app.ROIminIntensity0autoEditField.Enable = "on";
                app.ROIminIntensity0autoEditFieldLabel.Enable = "on";
                app.ROIClassEditField.Enable = "on";
                app.ROIClassEditFieldLabel.Enable = "on";


            end
            checkEverythingToRun(app);

        end

        % Value changed function: SplitandAlignMoviesCheckBox
        function SplitandAlignMoviesCheckBoxValueChanged(app, event)
            value = app.SplitandAlignMoviesCheckBox.Value;
            if value == 0
                app.SplitandAlignMoviesCheckBox.Value = 0;
                app.NewBeadRegistationButton.Enable = "off";
                app.BeadRegistrationFileEditField.Enable = "off";
                app.BeadRegistrationFileEditFieldLabel.Enable = "off";
                app.RegistrationFileBrowseButton.Enable = "off";
                app.WavelengthsPresent.Enable = "off";

                % app.ChannelSpecificationsROIsPanel.Enable = "on";
                % app.ChannelOrderinMoviesEditField.Enable = "on";
                % app.ChannelOrderinMoviesEditFieldLabel.Enable = "on";
                % 
                % app.PrimaryChanneltoTrackEditField.Enable = "on";
                % app.PrimaryChanneltoTrackEditFieldLabel.Enable = "on";
                % app.ReferenceImageChannelEditFieldLabel.Enable = "on";
                % app.ReferenceImageChannelEditField.Enable = "on";
                % app.ROIminIntensity0autoEditField.Enable = "on";
                % app.ROIminIntensity0autoEditFieldLabel.Enable = "on";
                % app.ROIClassEditField.Enable = "on";
                % app.ROIClassEditFieldLabel.Enable = "on";

            else
                app.SplitandAlignMoviesCheckBox.Value = 1;
                app.NewBeadRegistationButton.Enable = "on";
                app.BeadRegistrationFileEditField.Enable = "on";
                app.BeadRegistrationFileEditFieldLabel.Enable = "on";
                app.RegistrationFileBrowseButton.Enable = "on";
                app.WavelengthsPresent.Enable = "on";


            end
            checkEverythingToRun(app);
        end

        % Button pushed function: ChangeDatabaseButton
        function ChangeDatabaseButtonPushed(app, event)
            db_selected = uigetdir(app.db_path,'Select Database location');
            drawnow
            figure(app.QuantiTrackBatchTrackingUIFigure);

            if db_selected ~= 0
                %update the stored db path
                app.db_path = db_selected;
                app.TrackDatabaseLocationLabel.Text = db_selected;
                
                %set the cell/protein dropdown values
                setCellProteinList(app);

                %set the condition dropdown values
                setConditionList(app);

                %set the session dropdown values
                setSessionList(app);

                %if all fields are specified, enable the save button
                % if ~strcmp(app.CellProteinDropDown.Value,'Add New...') && ~strcmp(app.ConditionDropDown.Value,'Add New...') ...
                %         && ~strcmp(app.SessionDropDown.Value,'Add New...') && ~isempty(app.NameEditField.Value)
                %     app.SaveButton.Enable = 'on';
                % else
                %     app.SaveButton.Enable = 'off';
                % end
            end
        end

        % Value changed function: CellProteinDropDown
        function CellProteinDropDownValueChanged(app, event)
            value = app.CellProteinDropDown.Value;
            if strcmp(value,'Add New...')
                cell_protein_folds = app.CellProteinDropDown.Items;

                prompt = {'Enter Name of Cell/Protein:'};
                dlgtitle = 'New Cell/Protein Folder';
                dims = [1 70];
                definput = {'New Cell/Protein'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);
                if ~isempty(fold_name)
                    new_cell_protein = fold_name{1};
                    
                    %create the new folder
                    mkdir(fullfile(app.db_path,new_cell_protein));
    
                    %add the folder to the list
                    cell_protein_folds = [cell_protein_folds, new_cell_protein];

                    app.CellProteinDropDown.Items = cell_protein_folds;

                    app.CellProteinDropDown.Value = new_cell_protein;

                    
                end
            end

            %set the conditions dropdown values
            setConditionList(app);

            %set the session dropdown values
            setSessionList(app);
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            value = app.ConditionDropDown.Value;
            if strcmp(value,'Add New...')
                cond_folds = app.ConditionDropDown.Items;
                prompt = {'Enter Name of Condition:'};
                dlgtitle = 'New Condition Folder';
                dims = [1 70];
                definput = {'New Condition'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);
                
                if ~isempty(fold_name)
                    new_condition = fold_name{1};
                    
                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,new_condition));
    
                    %add the folder to the list
                    cond_folds = [cond_folds, new_condition];

                    app.ConditionDropDown.Items = cond_folds;

                    app.ConditionDropDown.Value = new_condition;
                end
            end

            %set the session dropdown values
            setSessionList(app);
        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            if strcmp(value,'Add New...')
                session_folds = app.SessionDropDown.Items;

                prompt = {'Enter Name of Session:'};
                dlgtitle = 'New Session Folder';
                dims = [1 70];
                definput = {'New Session'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);
                
                if ~isempty(fold_name)
                    new_session = fold_name{1};

                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,app.ConditionDropDown.Value, new_session));

                    %add the folder to the list
                    session_folds = [session_folds, new_session];

                    app.SessionDropDown.Items = session_folds;

                    app.SessionDropDown.Value = new_session;
                end

            end
        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            app = runBatchTrackingFromApp(app);
        end

        % Button pushed function: EditMovieListButton
        function EditMovieListButtonPushed(app, event)
            
            editmovieListgui = BatchTrackingMovieSelect_App(app.imFilesRaw,app.imPaths,app.QuantiTrackBatchTrackingUIFigure,get(gcf,'Position'));
            
            waitfor(editmovieListgui);
            filesWithPaths = getappdata(app.QuantiTrackBatchTrackingUIFigure,'UpdatedFiles');
            app.imFilesRaw = filesWithPaths{1};
            app.imPaths = filesWithPaths{2};
            app.NumberOfMoviesText.Text = [num2str(length(app.imFilesRaw)), ' movies to be processed'];
            if ~isempty(app.imFilesRaw)
                app.RunBatchButton.Enable = "on";
            else
                app.RunBatchButton.Enable = "off";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackBatchTrackingUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackBatchTrackingUIFigure.Name = 'QuantiTrack - Batch Tracking';
            app.ChannelAlignmentPanel = uipanel(app.QuantiTrackBatchTrackingUIFigure);
            app.ChannelAlignmentPanel.Title = 'Channel Alignment';
            app.BeadRegistrationFileEditFieldLabel = uilabel(app.ChannelAlignmentPanel);
            app.BeadRegistrationFileEditFieldLabel.Text = 'Bead Registration File';
            app.BeadRegistrationFileEditField = uieditfield(app.ChannelAlignmentPanel, 'text');
            app.BeadRegistrationFileEditField.Editable = 'off';
            app.RegistrationFileBrowseButton = uibutton(app.ChannelAlignmentPanel, 'push');
            app.RegistrationFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationFileBrowseButtonPushed, true);
            app.RegistrationFileBrowseButton.Text = 'Browse...';
            app.NewBeadRegistationButton = uibutton(app.ChannelAlignmentPanel, 'push');
            app.NewBeadRegistationButton.ButtonPushedFcn = createCallbackFcn(app, @NewBeadRegistationButtonPushed, true);
            app.NewBeadRegistationButton.Text = 'New Bead Registation';
            app.SplitandAlignMoviesCheckBox = uicheckbox(app.ChannelAlignmentPanel);
            app.SplitandAlignMoviesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitandAlignMoviesCheckBoxValueChanged, true);
            app.SplitandAlignMoviesCheckBox.Text = 'Split and Align Movies';
            app.SplitandAlignMoviesCheckBox.Value = true;
            app.WavelengthsPresent = uilabel(app.ChannelAlignmentPanel);
            app.WavelengthsPresent.Text = 'Wavelengths present in Alignment file: ';
            app.AcquisitionParametersPanel = uipanel(app.QuantiTrackBatchTrackingUIFigure);
            app.AcquisitionParametersPanel.Title = 'Acquisition Parameters';
            app.PixelSizenmEditFieldLabel = uilabel(app.AcquisitionParametersPanel);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size (nm)';
            app.PixelSizenmEditField = uieditfield(app.AcquisitionParametersPanel, 'numeric');
            app.PixelSizenmEditField.Value = 144;
            app.ExposureTimesEditFieldLabel = uilabel(app.AcquisitionParametersPanel);
            app.ExposureTimesEditFieldLabel.Text = 'Exposure Time (s)';
            app.ExposureTimesEditField = uieditfield(app.AcquisitionParametersPanel, 'numeric');
            app.ExposureTimesEditField.Value = 0.01;
            app.TimelapseIntervalsEditFieldLabel = uilabel(app.AcquisitionParametersPanel);
            app.TimelapseIntervalsEditFieldLabel.Text = 'Time-lapse Interval (s)';
            app.TimelapseIntervalsEditField = uieditfield(app.AcquisitionParametersPanel, 'numeric');
            app.TimelapseIntervalsEditField.Value = 0.2;
            app.ObjectiveNAEditFieldLabel = uilabel(app.AcquisitionParametersPanel);
            app.ObjectiveNAEditFieldLabel.Text = 'Objective N.A.';
            app.ObjectiveNAEditField = uieditfield(app.AcquisitionParametersPanel, 'numeric');
            app.ObjectiveNAEditField.Value = 1.49;
            app.EmissionWavelengthnmEditFieldLabel = uilabel(app.AcquisitionParametersPanel);
            app.EmissionWavelengthnmEditFieldLabel.Text = 'Emission Wavelength (nm)';
            app.EmissionWavelengthnmEditField = uieditfield(app.AcquisitionParametersPanel, 'numeric');
            app.EmissionWavelengthnmEditField.Value = 675;
            app.ProfileDropDownLabel = uilabel(app.AcquisitionParametersPanel);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.AcquisitionParametersPanel);
            app.ProfileDropDown.Items = {'Default'};
            app.ProfileDropDown.Value = 'Default';
            app.ParticleIdentificationParametersPanel = uipanel(app.QuantiTrackBatchTrackingUIFigure);
            app.ParticleIdentificationParametersPanel.Title = 'Particle Identification Parameters';
            app.FilterTypeDropDownLabel = uilabel(app.ParticleIdentificationParametersPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.ParticleIdentificationParametersPanel);
            app.FilterTypeDropDown.Items = {'Bandpass', 'LoG', 'Local BG, no filtering (SLIMfast)'};
            app.FilterTypeDropDown.Value = 'Bandpass';
            app.UpperLimitSpinnerLabel = uilabel(app.ParticleIdentificationParametersPanel);
            app.UpperLimitSpinnerLabel.Text = 'Upper Limit';
            app.UpperLimitSpinner = uispinner(app.ParticleIdentificationParametersPanel);
            app.UpperLimitSpinner.ValueDisplayFormat = '%.0f';
            app.UpperLimitSpinner.Value = 5;
            app.IntensityThresholdSpinnerLabel = uilabel(app.ParticleIdentificationParametersPanel);
            app.IntensityThresholdSpinnerLabel.Text = 'Intensity Threshold';
            app.IntensityThresholdSpinner = uispinner(app.ParticleIdentificationParametersPanel);
            app.IntensityThresholdSpinner.Value = 100;
            app.WindowSizeSpinnerLabel = uilabel(app.ParticleIdentificationParametersPanel);
            app.WindowSizeSpinnerLabel.Text = 'Window Size';
            app.WindowSizeSpinner = uispinner(app.ParticleIdentificationParametersPanel);
            app.WindowSizeSpinner.ValueDisplayFormat = '%.0f';
            app.WindowSizeSpinner.Value = 3;
            app.LowerLimitSpinnerLabel = uilabel(app.ParticleIdentificationParametersPanel);
            app.LowerLimitSpinnerLabel.Text = 'Lower Limit';
            app.LowerLimitSpinner = uispinner(app.ParticleIdentificationParametersPanel);
            app.LowerLimitSpinner.ValueDisplayFormat = '%.0f';
            app.LowerLimitSpinner.Value = 1;
            app.TrackingParametersPanel = uipanel(app.QuantiTrackBatchTrackingUIFigure);
            app.TrackingParametersPanel.Title = 'Tracking Parameters';
            app.MethodDropDownLabel = uilabel(app.TrackingParametersPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.TrackingParametersPanel);
            app.MethodDropDown.Items = {'Nearest Neighbor', 'LAP (uTrack)', 'MTT (SLIMfast)'};
            app.MethodDropDown.Value = 'Nearest Neighbor';
            app.MaxJumppxSpinnerLabel = uilabel(app.TrackingParametersPanel);
            app.MaxJumppxSpinnerLabel.Text = 'Max. Jump (px)';
            app.MaxJumppxSpinner = uispinner(app.TrackingParametersPanel);
            app.MaxJumppxSpinner.ValueDisplayFormat = '%.0f';
            app.MaxJumppxSpinner.Value = 4;
            app.ShortestTrackframesSpinnerLabel = uilabel(app.TrackingParametersPanel);
            app.ShortestTrackframesSpinnerLabel.Text = 'Shortest Track (frames)';
            app.ShortestTrackframesSpinner = uispinner(app.TrackingParametersPanel);
            app.ShortestTrackframesSpinner.ValueDisplayFormat = '%.0f';
            app.ShortestTrackframesSpinner.Value = 2;
            app.LengthofGapsframesSpinnerLabel = uilabel(app.TrackingParametersPanel);
            app.LengthofGapsframesSpinnerLabel.Text = 'Length of Gaps (frames)';
            app.LengthofGapsframesSpinner = uispinner(app.TrackingParametersPanel);
            app.LengthofGapsframesSpinner.ValueDisplayFormat = '%.0f';
            app.LengthofGapsframesSpinner.Value = 2;
            app.MoviestoProcessPanel = uipanel(app.QuantiTrackBatchTrackingUIFigure);
            app.MoviestoProcessPanel.Title = 'Movies to Process';
            app.DirectoryofMoviesEditFieldLabel = uilabel(app.MoviestoProcessPanel);
            app.DirectoryofMoviesEditFieldLabel.Text = 'Directory of Movies';
            app.DirectoryofMoviesEditField = uieditfield(app.MoviestoProcessPanel, 'text');
            app.DirectoryofMoviesEditField.Editable = 'off';
            app.MovieBrowseButton = uibutton(app.MoviestoProcessPanel, 'push');
            app.MovieBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MovieBrowseButtonPushed, true);
            app.MovieBrowseButton.Text = 'Browse...';
            app.NumberOfMoviesText = uilabel(app.MoviestoProcessPanel);
            app.NumberOfMoviesText.Text = '';
            app.EditMovieListButton = uibutton(app.MoviestoProcessPanel, 'push');
            app.EditMovieListButton.ButtonPushedFcn = createCallbackFcn(app, @EditMovieListButtonPushed, true);
            app.EditMovieListButton.Text = 'Edit Movie List';
            app.SaveLocationPanel = uipanel(app.QuantiTrackBatchTrackingUIFigure);
            app.SaveLocationPanel.Title = 'Save Location';
            app.TrackDatabaseLocationLabel = uilabel(app.SaveLocationPanel);
            app.TrackDatabaseLocationLabel.Text = 'TrackDatabase Location';
            app.ChangeDatabaseButton = uibutton(app.SaveLocationPanel, 'push');
            app.ChangeDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDatabaseButtonPushed, true);
            app.ChangeDatabaseButton.Text = 'Change Database...';
            app.CellProteinDropDownLabel = uilabel(app.SaveLocationPanel);
            app.CellProteinDropDownLabel.Text = 'Cell/Protein';
            app.CellProteinDropDown = uidropdown(app.SaveLocationPanel);
            app.CellProteinDropDown.Items = {'Add New...'};
            app.CellProteinDropDown.ValueChangedFcn = createCallbackFcn(app, @CellProteinDropDownValueChanged, true);
            app.CellProteinDropDown.Value = 'Add New...';
            app.ConditionDropDownLabel = uilabel(app.SaveLocationPanel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.SaveLocationPanel);
            app.ConditionDropDown.Items = {'Add New...'};
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Value = 'Add New...';
            app.SessionDropDownLabel = uilabel(app.SaveLocationPanel);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.SaveLocationPanel);
            app.SessionDropDown.Items = {'Add New...'};
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.SessionDropDown.Value = 'Add New...';
            app.RunBatchButton = uibutton(app.QuantiTrackBatchTrackingUIFigure, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Enable = 'off';
            app.RunBatchButton.Text = 'Run Batch';
            app.ChannelSpecificationsROIsPanel = uipanel(app.QuantiTrackBatchTrackingUIFigure);
            app.ChannelSpecificationsROIsPanel.Title = 'Channel Specifications & ROIs';
            app.ChannelOrderinMoviesEditFieldLabel = uilabel(app.ChannelSpecificationsROIsPanel);
            app.ChannelOrderinMoviesEditFieldLabel.Text = 'Channel Order in Movies';
            app.ChannelOrderinMoviesEditField = uieditfield(app.ChannelSpecificationsROIsPanel, 'text');
            app.ChannelOrderinMoviesEditField.Tooltip = {'Comma-separated wavelengths in the movie file'};
            app.ChannelOrderinMoviesEditField.Value = '647,488';
            app.PrimaryChanneltoTrackEditFieldLabel = uilabel(app.ChannelSpecificationsROIsPanel);
            app.PrimaryChanneltoTrackEditFieldLabel.Text = 'Primary Channel to Track';
            app.PrimaryChanneltoTrackEditField = uieditfield(app.ChannelSpecificationsROIsPanel, 'text');
            app.PrimaryChanneltoTrackEditField.Tooltip = {'Wavelength from the above list that should be tracked'};
            app.PrimaryChanneltoTrackEditField.Value = '647';
            app.ReferenceImageChannelEditFieldLabel = uilabel(app.ChannelSpecificationsROIsPanel);
            app.ReferenceImageChannelEditFieldLabel.Text = 'Reference Image Channel';
            app.ReferenceImageChannelEditField = uieditfield(app.ChannelSpecificationsROIsPanel, 'text');
            app.ReferenceImageChannelEditField.Tooltip = {'Wavelength from above list that should be used to identify ROIs'};
            app.ReferenceImageChannelEditField.Value = '488';
            app.ROIminIntensity0autoEditFieldLabel = uilabel(app.ChannelSpecificationsROIsPanel);
            app.ROIminIntensity0autoEditFieldLabel.Text = 'ROI min. Intensity (0 - auto)';
            app.ROIminIntensity0autoEditField = uieditfield(app.ChannelSpecificationsROIsPanel, 'numeric');
            app.ROIClassEditFieldLabel = uilabel(app.ChannelSpecificationsROIsPanel);
            app.ROIClassEditFieldLabel.Text = 'ROI Class';
            app.ROIClassEditField = uieditfield(app.ChannelSpecificationsROIsPanel, 'text');
            app.ROIClassEditField.Tooltip = {'Comma-separated wavelengths in the movie file'};
            app.ROIClassEditField.Value = 'Nucleus';
            % Show the figure after all components are created
            app.QuantiTrackBatchTrackingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuantiTrack_BatchTracking_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackBatchTrackingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackBatchTrackingUIFigure)
        end
    end
end