classdef QuantiTrack_SaveToDatabase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackSaveTrackingDataUIFigure  matlab.ui.Figure
        matLabel                    matlab.ui.control.Label
        CancelButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        ChangeDatabaseButton        matlab.ui.control.Button
        TrackDatabaseLocationLabel  matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        SessionDropDown             matlab.ui.control.DropDown
        SessionDropDownLabel        matlab.ui.control.Label
        ConditionDropDown           matlab.ui.control.DropDown
        ConditionDropDownLabel      matlab.ui.control.Label
        CellProteinDropDown         matlab.ui.control.DropDown
        CellProteinDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        db_path % Description
        lastCellProtein % Description
        lastCondition % Description
        lastSession % Description
        lastFname % Description
        
        QTmainFigH % Description
    end
    
    methods (Access = private)
        
        function setConditionList(app)
            %setup the Conditions dropdown only if a real folder is
            %selected
            

            if ~strcmp(app.CellProteinDropDown.Value, 'Add New...')
                
                cond_folds = {'Add New...'};
                cell_protein_contents = dir(fullfile(app.db_path,app.CellProteinDropDown.Value));
                lastCond_present = 0;
                for i = 1:length(cell_protein_contents)
                    if cell_protein_contents(i).isdir && ~strcmp(cell_protein_contents(i).name,'.') && ~strcmp(cell_protein_contents(i).name,'..') && isempty(strfind(cell_protein_contents(i).name,'results'))
                        cond_folds = [cond_folds; cell_protein_contents(i).name];
                        if strcmp(app.lastCondition,cell_protein_contents(i).name)
                            lastCond_present = 1;
                        end
                    end
                end
                %if there are no condition folders ask the user to create a
                %new folder
                if length(cond_folds) == 1
                    prompt = {'Enter Name of Condition:'};
                    dlgtitle = 'New Condition Folder';
                    dims = [1 70];
                    definput = {'New Condition'};
                    fold_name = inputdlg(prompt,dlgtitle,dims,definput);

                    new_condition = fold_name{1};

                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,new_condition));

                    %add the folder to the list
                    cond_folds = [cond_folds; new_condition];
                    lastCond_present = 1;
                    app.lastCondition = new_condition;
                end

                app.ConditionDropDown.Items = cond_folds;

                if lastCond_present == 1
                    app.ConditionDropDown.Value = app.lastCondition;
                else
                    app.ConditionDropDown.Value = cond_folds{2,:};
                end
            end
        end
        
        function setSessionList(app)
            %setup the Sessions dropdown only if a real folder is
            %selected
            

            if ~strcmp(app.ConditionDropDown.Value, 'Add New...')
                
                session_folds = {'Add New...'};
                condition_contents = dir(fullfile(app.db_path,app.CellProteinDropDown.Value,app.ConditionDropDown.Value));
                lastSession_present = 0;
                for i = 1:length(condition_contents)
                    if condition_contents(i).isdir && ~strcmp(condition_contents(i).name,'.') && ~strcmp(condition_contents(i).name,'..') && isempty(strfind(condition_contents(i).name,'results'))
                        session_folds = [session_folds; condition_contents(i).name];
                        if strcmp(app.lastSession,condition_contents(i).name)
                            lastSession_present = 1;
                        end
                    end
                end
                %if there are no condition folders ask the user to create a
                %new folder
                if length(session_folds) == 1
                    prompt = {'Enter Name of Session:'};
                    dlgtitle = 'New Session Folder';
                    dims = [1 70];
                    definput = {'New Session'};
                    fold_name = inputdlg(prompt,dlgtitle,dims,definput);

                    new_session = fold_name{1};

                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,app.ConditionDropDown.Value, new_session));

                    %add the folder to the list
                    session_folds = [session_folds; new_session];
                    lastSession_present = 1;
                    app.lastSession = new_session;
                end

                app.SessionDropDown.Items = session_folds;

                if lastSession_present == 1
                    app.SessionDropDown.Value = app.lastSession;
                else
                    app.SessionDropDown.Value = session_folds{2,:};
                end
            end
        end
        
        function setCellProteinList(app)
            %get the cell_protein folders within the track database
            db_folds = dir(app.db_path);
            
            cell_protein_folds = {'Add New...'};
            lastCellProtein_present = 0;
            for i = 1:length(db_folds)
                if db_folds(i).isdir && ~strcmp(db_folds(i).name,'.') && ~strcmp(db_folds(i).name,'..') && isempty(strfind(db_folds(i).name,'results')) && isempty(strfind(db_folds(i).name,'transition_probabilities'))
                    cell_protein_folds = [cell_protein_folds; db_folds(i).name];
                    if ~isempty(app.lastCellProtein) && strcmp(db_folds(i).name,app.lastCellProtein)
                        lastCellProtein_present = 1;
                    end
                end
            end
            
            
            %if there are no cell_protein folders ask the user to create a
            %new folder
            if length(cell_protein_folds) == 1
                prompt = {'Enter Name of Cell/Protein:'};
                dlgtitle = 'New Cell/Protein Folder';
                dims = [1 70];
                definput = {'New Cell/Protein'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);

                new_cell_protein = fold_name{1};

                %create the new folder
                mkdir(fullfile(app.db_path,new_cell_protein));

                %add the folder to the list
                cell_protein_folds = [cell_protein_folds; new_cell_protein];
                lastCellProtein_present = 1;
                app.lastCellProtein = new_cell_protein;
            end

            app.CellProteinDropDown.Items = cell_protein_folds;

            if lastCellProtein_present == 1
                app.CellProteinDropDown.Value = app.lastCellProtein;
            else
                app.CellProteinDropDown.Value = cell_protein_folds{2,:};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, db_path, lastCellProtein, lastCondition, lastSession, lastFname, QTmainFigH, varargin)
            if ~isempty(varargin)
                pos_fig = varargin{1};
                ChangeAppPosition(app.QuantiTrackSaveTrackingDataUIFigure,pos_fig);
                drawnow;
            end
            
            app.db_path = db_path;
            app.lastCellProtein = lastCellProtein;
            app.lastCondition = lastCondition;
            app.lastSession = lastSession;
            app.lastFname = lastFname;
            app.QTmainFigH = QTmainFigH;

            app.TrackDatabaseLocationLabel.Text = db_path;

            setCellProteinList(app);

            setConditionList(app);

            setSessionList(app);
            

            %set the Name field to the last known name
            if ~isempty(lastFname)
                app.NameEditField.Value = lastFname;
            end
            
            %if all fields are specified, enable the save button
            if ~strcmp(app.CellProteinDropDown.Value,'Add New...') && ~strcmp(app.ConditionDropDown.Value,'Add New...') ...
                    && ~strcmp(app.SessionDropDown.Value,'Add New...') && ~isempty(app.NameEditField.Value)
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end


        end

        % Button pushed function: ChangeDatabaseButton
        function ChangeDatabaseButtonPushed(app, event)
            db_selected = uigetdir(app.db_path,'Select Database location');
            drawnow
            figure(app.QuantiTrackSaveTrackingDataUIFigure);

            if db_selected ~= 0
                %update the stored db path
                app.db_path = db_selected;
                app.TrackDatabaseLocationLabel.Text = db_selected;
                
                %set the cell/protein dropdown values
                setCellProteinList(app);

                %set the condition dropdown values
                setConditionList(app);

                %set the session dropdown values
                setSessionList(app);

                %if all fields are specified, enable the save button
                if ~strcmp(app.CellProteinDropDown.Value,'Add New...') && ~strcmp(app.ConditionDropDown.Value,'Add New...') ...
                        && ~strcmp(app.SessionDropDown.Value,'Add New...') && ~isempty(app.NameEditField.Value)
                    app.SaveButton.Enable = 'on';
                else
                    app.SaveButton.Enable = 'off';
                end
            end


        end

        % Value changed function: CellProteinDropDown
        function CellProteinDropDownValueChanged(app, event)
            value = app.CellProteinDropDown.Value;
            
            if strcmp(value,'Add New...')
                cell_protein_folds = app.CellProteinDropDown.Items;

                prompt = {'Enter Name of Cell/Protein:'};
                dlgtitle = 'New Cell/Protein Folder';
                dims = [1 70];
                definput = {'New Cell/Protein'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);
                if ~isempty(fold_name)
                    new_cell_protein = fold_name{1};
                    
                    %create the new folder
                    mkdir(fullfile(app.db_path,new_cell_protein));
    
                    %add the folder to the list
                    cell_protein_folds = [cell_protein_folds, new_cell_protein];

                    app.CellProteinDropDown.Items = cell_protein_folds;

                    app.CellProteinDropDown.Value = new_cell_protein;

                    
                end
            end

            %set the conditions dropdown values
            setConditionList(app);

            %set the session dropdown values
            setSessionList(app);

            %if all fields are specified, enable the save button
            if ~strcmp(app.CellProteinDropDown.Value,'Add New...') && ~strcmp(app.ConditionDropDown.Value,'Add New...') ...
                    && ~strcmp(app.SessionDropDown.Value,'Add New...') && ~isempty(app.NameEditField.Value)
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            value = app.ConditionDropDown.Value;
            
            if strcmp(value,'Add New...')
                cond_folds = app.ConditionDropDown.Items;
                prompt = {'Enter Name of Condition:'};
                dlgtitle = 'New Condition Folder';
                dims = [1 70];
                definput = {'New Condition'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);
                
                if ~isempty(fold_name)
                    new_condition = fold_name{1};
                    
                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,new_condition));
    
                    %add the folder to the list
                    cond_folds = [cond_folds, new_condition];

                    app.ConditionDropDown.Items = cond_folds;

                    app.ConditionDropDown.Value = new_condition;
                end
            end

            %set the session dropdown values
            setSessionList(app);

            %if all fields are specified, enable the save button
            if ~strcmp(app.CellProteinDropDown.Value,'Add New...') && ~strcmp(app.ConditionDropDown.Value,'Add New...') ...
                    && ~strcmp(app.SessionDropDown.Value,'Add New...') && ~isempty(app.NameEditField.Value)
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end


        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            if strcmp(value,'Add New...')
                session_folds = app.SessionDropDown.Items;

                prompt = {'Enter Name of Session:'};
                dlgtitle = 'New Session Folder';
                dims = [1 70];
                definput = {'New Session'};
                fold_name = inputdlg(prompt,dlgtitle,dims,definput);
                
                if ~isempty(fold_name)
                    new_session = fold_name{1};

                    %create the new folder
                    mkdir(fullfile(app.db_path,app.CellProteinDropDown.Value,app.ConditionDropDown.Value, new_session));

                    %add the folder to the list
                    session_folds = [session_folds, new_session];

                    app.SessionDropDown.Items = session_folds;

                    app.SessionDropDown.Value = new_session;
                end

            end
            %if all fields are specified, enable the save button
            if ~strcmp(app.CellProteinDropDown.Value,'Add New...') && ~strcmp(app.ConditionDropDown.Value,'Add New...') ...
                    && ~strcmp(app.SessionDropDown.Value,'Add New...') && ~isempty(app.NameEditField.Value)
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end
            

        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            
            
            if ~strcmp(app.CellProteinDropDown.Value,'Add New...') && ~strcmp(app.ConditionDropDown.Value,'Add New...') ...
                    && ~strcmp(app.SessionDropDown.Value,'Add New...') && ~isempty(app.NameEditField.Value)
                app.SaveButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            %Store the important values
            OUTvars{1} = app.TrackDatabaseLocationLabel.Text;
            OUTvars{2} = app.CellProteinDropDown.Value;
            OUTvars{3} = app.ConditionDropDown.Value;
            OUTvars{4} = app.SessionDropDown.Value;
            OUTvars{5} = app.NameEditField.Value;
            
            setappdata(app.QTmainFigH,'QuantiTrack_SavePars',OUTvars);

            close(app.QuantiTrackSaveTrackingDataUIFigure);


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            %Set the outputs to empty
            OUTvars{1} = [];
            OUTvars{2} = [];
            OUTvars{3} = [];
            OUTvars{4} = [];
            OUTvars{5} = [];
            
            setappdata(0,'QuantiTrack_SavePars',OUTvars);

            close(app.QuantiTrackSaveTrackingDataUIFigure);
        end

        % Close request function: QuantiTrackSaveTrackingDataUIFigure
        function QuantiTrackSaveTrackingDataUIFigureCloseRequest(app, event)
            %Set the outputs to empty
            OUTvars{1} = [];
            OUTvars{2} = [];
            OUTvars{3} = [];
            OUTvars{4} = [];
            OUTvars{5} = [];
            
            setappdata(0,'QuantiTrack_SavePars',OUTvars);

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackSaveTrackingDataUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackSaveTrackingDataUIFigure.Name = 'QuantiTrack - Save Tracking Data';
            app.QuantiTrackSaveTrackingDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @QuantiTrackSaveTrackingDataUIFigureCloseRequest, true);
            app.CellProteinDropDownLabel = uilabel(app.QuantiTrackSaveTrackingDataUIFigure);
            app.CellProteinDropDownLabel.Text = 'Cell/Protein';
            app.CellProteinDropDown = uidropdown(app.QuantiTrackSaveTrackingDataUIFigure);
            app.CellProteinDropDown.Items = {'Add New...'};
            app.CellProteinDropDown.ValueChangedFcn = createCallbackFcn(app, @CellProteinDropDownValueChanged, true);
            app.CellProteinDropDown.Value = 'Add New...';
            app.ConditionDropDownLabel = uilabel(app.QuantiTrackSaveTrackingDataUIFigure);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.QuantiTrackSaveTrackingDataUIFigure);
            app.ConditionDropDown.Items = {'Add New...'};
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Value = 'Add New...';
            app.SessionDropDownLabel = uilabel(app.QuantiTrackSaveTrackingDataUIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.QuantiTrackSaveTrackingDataUIFigure);
            app.SessionDropDown.Items = {'Add New...'};
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.SessionDropDown.Value = 'Add New...';
            app.NameEditFieldLabel = uilabel(app.QuantiTrackSaveTrackingDataUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.QuantiTrackSaveTrackingDataUIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.TrackDatabaseLocationLabel = uilabel(app.QuantiTrackSaveTrackingDataUIFigure);
            app.TrackDatabaseLocationLabel.Text = 'TrackDatabase Location';
            app.ChangeDatabaseButton = uibutton(app.QuantiTrackSaveTrackingDataUIFigure, 'push');
            app.ChangeDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDatabaseButtonPushed, true);
            app.ChangeDatabaseButton.Text = 'Change Database...';
            app.SaveButton = uibutton(app.QuantiTrackSaveTrackingDataUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.QuantiTrackSaveTrackingDataUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.matLabel = uilabel(app.QuantiTrackSaveTrackingDataUIFigure);
            app.matLabel.Text = '.mat';
            % Show the figure after all components are created
            app.QuantiTrackSaveTrackingDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuantiTrack_SaveToDatabase(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.QuantiTrackSaveTrackingDataUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.QuantiTrackSaveTrackingDataUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackSaveTrackingDataUIFigure)
        end
    end
end