classdef QuantiTrack_SetParams_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuantiTrackParameterEditorUIFigure  matlab.ui.Figure
        BrowseButton                    matlab.ui.control.Button
        TrackingDatabaseEditField       matlab.ui.control.EditField
        TrackingDatabaseEditFieldLabel  matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        SaveCloseButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitionTab                  matlab.ui.container.Tab
        EmissionWavelengthnmSpinner     matlab.ui.control.Spinner
        EmissionWavelengthnmSpinnerLabel  matlab.ui.control.Label
        ObjectiveNASpinner              matlab.ui.control.Spinner
        ObjectiveNASpinnerLabel         matlab.ui.control.Label
        FrameTimesSpinner               matlab.ui.control.Spinner
        FrameTimesSpinnerLabel          matlab.ui.control.Label
        ExposureTimesSpinner            matlab.ui.control.Spinner
        ExposureTimesSpinnerLabel       matlab.ui.control.Label
        PixelSizenmSpinner              matlab.ui.control.Spinner
        PixelSizenmSpinnerLabel         matlab.ui.control.Label
        AutoROIsTab                     matlab.ui.container.Tab
        Thresholdforsummedimage0autoSpinner  matlab.ui.control.Spinner
        Thresholdforsummedimage0autoSpinnerLabel  matlab.ui.control.Label
        Thresholdforindividualimage0autoSpinner  matlab.ui.control.Spinner
        Thresholdforindividualimage0autoSpinnerLabel  matlab.ui.control.Label
        ErodeDilateelementradiuspixelsSpinner  matlab.ui.control.Spinner
        ErodeDilateelementradiuspixelsSpinnerLabel  matlab.ui.control.Label
        FilterWindowsizepixelsSpinner   matlab.ui.control.Spinner
        FilterWindowsizepixelsSpinnerLabel  matlab.ui.control.Label
        ROIradiuspixelsSpinner          matlab.ui.control.Spinner
        ROIradiuspixelsSpinnerLabel     matlab.ui.control.Label
        CheckingTracksTab               matlab.ui.container.Tab
        MinimumlengthoftrackstocheckframesSpinner  matlab.ui.control.Spinner
        MinimumlengthoftrackstocheckframesSpinnerLabel  matlab.ui.control.Label
        LAPtrackingTab                  matlab.ui.container.Tab
        AmplitudeRatioofMergedfeaturesPanel  matlab.ui.container.Panel
        MaximumAmplitudeRatioSpinner    matlab.ui.control.Spinner
        MaximumAmplitudeRatioSpinnerLabel  matlab.ui.control.Label
        MinimumAmplitudeRatioSpinner    matlab.ui.control.Spinner
        MinimumAmplitudeRatioSpinnerLabel  matlab.ui.control.Label
        MaximumSearchRadiuspxSpinner    matlab.ui.control.Spinner
        MaximumSearchRadiuspxSpinnerLabel  matlab.ui.control.Label
        MotionTypeDropDown              matlab.ui.control.DropDown
        MotionTypeDropDownLabel         matlab.ui.control.Label
        MergeSplitTracksDropDown        matlab.ui.control.DropDown
        MergeSplitTracksDropDownLabel   matlab.ui.control.Label
        GapPenaltySpinner               matlab.ui.control.Spinner
        GapPenaltySpinnerLabel          matlab.ui.control.Label
        MaximumAngleSpinner             matlab.ui.control.Spinner
        MaximumAngleSpinnerLabel        matlab.ui.control.Label
        NearestNeighborTimeWindowSpinner  matlab.ui.control.Spinner
        NearestNeighborTimeWindowSpinnerLabel  matlab.ui.control.Label
        BrownianMultiplactionFactorSpinner  matlab.ui.control.Spinner
        BrownianMultiplactionFactorSpinnerLabel  matlab.ui.control.Label
        MinimumSearchRadiuspxSpinner    matlab.ui.control.Spinner
        MinimumSearchRadiuspxSpinnerLabel  matlab.ui.control.Label
        ExponentforLinearsearchradiusscalingPanel  matlab.ui.container.Panel
        AfterreachingConfinementSpinner_2  matlab.ui.control.Spinner
        AfterreachingConfinementSpinner_2Label  matlab.ui.control.Label
        BeforereachingConfinementSpinner_2  matlab.ui.control.Spinner
        BeforereachingConfinementSpinner_2Label  matlab.ui.control.Label
        ExponentforBrowniansearchradiusscalingPanel  matlab.ui.container.Panel
        AfterreachingConfinementSpinner  matlab.ui.control.Spinner
        AfterreachingConfinementSpinnerLabel  matlab.ui.control.Label
        BeforereachingConfinementSpinner  matlab.ui.control.Spinner
        BeforereachingConfinementSpinnerLabel  matlab.ui.control.Label
        MTTtrackingTab                  matlab.ui.container.Tab
        MaximumDum2sLabel               matlab.ui.control.Label
        MaximumIterationsSpinner        matlab.ui.control.Spinner
        MaximumIterationsSpinnerLabel   matlab.ui.control.Label
        DiffusionWeightSpinner          matlab.ui.control.Spinner
        DiffusionWeightSpinnerLabel     matlab.ui.control.Label
        IntensityFluctuationWeightSpinner  matlab.ui.control.Spinner
        IntensityFluctuationWeightSpinnerLabel  matlab.ui.control.Label
        MaximumComponentsSpinner        matlab.ui.control.Spinner
        MaximumComponentsSpinnerLabel   matlab.ui.control.Label
        SearchSizeFactorSpinner         matlab.ui.control.Spinner
        SearchSizeFactorSpinnerLabel    matlab.ui.control.Label
        CountsphotonSpinner             matlab.ui.control.Spinner
        CountsphotonSpinnerLabel        matlab.ui.control.Label
        MinimumIntensitySpinner         matlab.ui.control.Spinner
        MinimumIntensitySpinnerLabel    matlab.ui.control.Label
        AveragingTimeWindowSpinner      matlab.ui.control.Spinner
        AveragingTimeWindowSpinnerLabel  matlab.ui.control.Label
        DeflationLoopsSpinner           matlab.ui.control.Spinner
        DeflationLoopsSpinnerLabel      matlab.ui.control.Label
        MaximumDum2sSpinner             matlab.ui.control.Spinner
        ErrorRate10valueSpinner         matlab.ui.control.Spinner
        ErrorRate10valueSpinnerLabel    matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        DisplacementHistogramBinsizeumLabel  matlab.ui.control.Label
        MaximumFramesforDisplacementHistogramLabel  matlab.ui.control.Label
        MeanSquaredDisplacementsLabel   matlab.ui.control.Label
        AngleAnisotropyLabel            matlab.ui.control.Label
        BootstrapIterationsSpinner      matlab.ui.control.Spinner
        BootstrapIterationsSpinnerLabel  matlab.ui.control.Label
        BinSizeforSpatialAnisotropynmSpinner  matlab.ui.control.Spinner
        BinSizeforSpatialAnisotropynmSpinnerLabel  matlab.ui.control.Label
        BinSizeforTemporalAnisotropysSpinner  matlab.ui.control.Spinner
        BinSizeforTemporalAnisotropysSpinnerLabel  matlab.ui.control.Label
        HistogramofDisplacementsLabel   matlab.ui.control.Label
        NumberofframesforlinearfitofMSDSpinner  matlab.ui.control.Spinner
        NumberofframesforlinearfitofMSDSpinnerLabel  matlab.ui.control.Label
        DisplacementHistogramBinSizeumSpinner  matlab.ui.control.Spinner
        MaximumFramesforDisplacementsHistogramSpinner  matlab.ui.control.Spinner
        BoundTab                        matlab.ui.container.Tab
        MinimumnumberofframestocallthemoleculeboundframesSpinner  matlab.ui.control.Spinner
        MinimumnumberofframestocallthemoleculeboundframesSpinnerLabel  matlab.ui.control.Label
        MaximumdisplacementforboundmoleculesumSpinner  matlab.ui.control.Spinner
        MaximumdisplacementforboundmoleculesumSpinnerLabel  matlab.ui.control.Label
        FrametoFramemaxdisplacementforboundmoleculesumSpinner  matlab.ui.control.Spinner
        FrametoFramemaxdisplacementforboundmoleculesumSpinnerLabel  matlab.ui.control.Label
        NewNameEditField                matlab.ui.control.EditField
        NewNameEditFieldLabel           matlab.ui.control.Label
        CurrentProfileDropDown          matlab.ui.control.DropDown
        CurrentProfileDropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Profiles_in % Structure containing the settings for each profile when it enters the app
        sel_Profile_in % The currently selected Profile number
        QT_location % directory where the profile .mat file should be stored
        
        Profiles_out % Structure containing the settings for each profile when it exits the app
        sel_Profile_out
        
        QTmainFigH % Description
    end
    
    methods (Access = private)
        
        
        function setValues(app,curProfile)
            %sets all values for the current Profile
            
            %Name
            app.NewNameEditField.Value = curProfile.Name;

            %save directory
            app.TrackingDatabaseEditField.Value = curProfile.trackingSaveDir;
            
            %Acquisition Parameters
            app.PixelSizenmSpinner.Value = curProfile.pixelSize*1000;
            app.ExposureTimesSpinner.Value = curProfile.exposureTime;
            app.FrameTimesSpinner.Value = curProfile.frameTime;
            app.ObjectiveNASpinner.Value = curProfile.objNA;
            app.EmissionWavelengthnmSpinner.Value = curProfile.lambda*1000;
            
            %Checking Tracks
            app.MinimumlengthoftrackstocheckframesSpinner.Value = curProfile.ShortestTrack;
            
            %Analysis
            app.DisplacementHistogramBinSizeumSpinner.Value = curProfile.JHistBinSize;
            app.MaximumFramesforDisplacementsHistogramSpinner.Value = curProfile.JHistMaxFrameN;
            app.NumberofframesforlinearfitofMSDSpinner.Value = curProfile.MSD_fit_Threshold;
            app.BinSizeforTemporalAnisotropysSpinner.Value = curProfile.Anisotropy_binT;
            app.BinSizeforSpatialAnisotropynmSpinner.Value = curProfile.Anisotropy_binD*1000;
            app.BootstrapIterationsSpinner.Value = curProfile.Anisotropy_bootIter;

            
            %Bound
            app.FrametoFramemaxdisplacementforboundmoleculesumSpinner.Value = curProfile.ThreshL;
            app.MaximumdisplacementforboundmoleculesumSpinner.Value = curProfile.ThreshH;
            app.MinimumnumberofframestocallthemoleculeboundframesSpinner.Value = curProfile.minBoundFrames;
            
            %LAP Tracking
            app.MinimumSearchRadiuspxSpinner.Value = curProfile.LAP_params.minSearchRadius;
            app.MaximumSearchRadiuspxSpinner.Value = curProfile.LAP_params.maxSearchRadius;
            app.BrownianMultiplactionFactorSpinner.Value = curProfile.LAP_params.brownStdMult;
            app.NearestNeighborTimeWindowSpinner.Value = curProfile.LAP_params.nnWindow;
            app.MaximumAngleSpinner.Value = curProfile.LAP_params.maxAngleVV;
            app.GapPenaltySpinner.Value = curProfile.LAP_params.gapPenalty;
            
            switch curProfile.LAP_params.linearMotion
                case 0 
                    app.MotionTypeDropDown.Value = 'Random Motion';
                case 1
                    app.MotionTypeDropDown.Value = 'Random + Directed';
                case 2
                    app.MotionTypeDropDown.Value = 'Random + 1D Diffusion';
            end
            
            switch curProfile.LAP_params.mergeSplit
                case 0
                    app.MergeSplitTracksDropDown.Value = 'No Merge nor Split';
                case 1
                    app.MergeSplitTracksDropDown.Value = 'Merge & Split';
                case 2
                    app.MergeSplitTracksDropDown.Value = 'Merge Only';
                case 3
                    app.MergeSplitTracksDropDown.Value = 'Split Only';
            end
            app.BeforereachingConfinementSpinner.Value = curProfile.LAP_params.brownScaling(1);
            app.AfterreachingConfinementSpinner.Value = curProfile.LAP_params.brownScaling(2);
            app.BeforereachingConfinementSpinner_2.Value = curProfile.LAP_params.linScaling(1);
            app.AfterreachingConfinementSpinner_2.Value = curProfile.LAP_params.linScaling(2);
            app.MinimumAmplitudeRatioSpinner.Value = curProfile.LAP_params.ampRatioLimit(1);
            app.MaximumAmplitudeRatioSpinner.Value = curProfile.LAP_params.ampRatioLimit(2);

            %MTT Tracking
            app.MaximumDum2sSpinner.Value = curProfile.MTT_params.maxD;
            app.ErrorRate10valueSpinner.Value = curProfile.MTT_params.errorRate;
            app.DeflationLoopsSpinner.Value = curProfile.MTT_params.deflateLoops;
            app.AveragingTimeWindowSpinner.Value = curProfile.MTT_params.statWin;
            app.MinimumIntensitySpinner.Value = curProfile.MTT_params.minIntensity;
            app.MaximumIterationsSpinner.Value = curProfile.MTT_params.maxOptimIter;
            app.CountsphotonSpinner.Value = curProfile.MTT_params.countsPerPhoton;
            app.SearchSizeFactorSpinner.Value = curProfile.MTT_params.searchExpFac;
            app.MaximumComponentsSpinner.Value = curProfile.MTT_params.maxComp;
            app.IntensityFluctuationWeightSpinner.Value = curProfile.MTT_params.intLawWeight;
            app.DiffusionWeightSpinner.Value = curProfile.MTT_params.diffLawWeight;
            
            %Auto ROI parameters
            app.ROIradiuspixelsSpinner.Value = curProfile.autoROI_params.ROIradius;
            app.FilterWindowsizepixelsSpinner.Value = curProfile.autoROI_params.filtWindow;
            app.ErodeDilateelementradiuspixelsSpinner.Value = curProfile.autoROI_params.Morphradius;
            app.Thresholdforindividualimage0autoSpinner.Value = curProfile.autoROI_params.indTH;
            app.Thresholdforsummedimage0autoSpinner.Value = curProfile.autoROI_params.sumTH;


        end
        
        function curProfile = getValues(app)
            %Name
            curProfile.Name = app.NewNameEditField.Value;

            %save directory
            curProfile.trackingSaveDir = app.TrackingDatabaseEditField.Value;

            %Acquisition Parameters
            curProfile.pixelSize = app.PixelSizenmSpinner.Value/1000;
            curProfile.exposureTime = app.ExposureTimesSpinner.Value;
            curProfile.frameTime = app.FrameTimesSpinner.Value;
            curProfile.objNA = app.ObjectiveNASpinner.Value;
            curProfile.lambda = app.EmissionWavelengthnmSpinner.Value/1000;

            %Checking Tracks
            curProfile.ShortestTrack = app.MinimumlengthoftrackstocheckframesSpinner.Value;
            
            %Analysis
            curProfile.JHistBinSize = app.DisplacementHistogramBinSizeumSpinner.Value;
            curProfile.JHistMaxFrameN = app.MaximumFramesforDisplacementsHistogramSpinner.Value;
            curProfile.MSD_fit_Threshold = app.NumberofframesforlinearfitofMSDSpinner.Value;
            curProfile.Anisotropy_binT = app.BinSizeforTemporalAnisotropysSpinner.Value;
            curProfile.Anisotropy_binD = app.BinSizeforSpatialAnisotropynmSpinner.Value/1000;
            curProfile.Anisotropy_bootIter = app.BootstrapIterationsSpinner.Value;

            %Bound
            curProfile.ThreshL = app.FrametoFramemaxdisplacementforboundmoleculesumSpinner.Value;
            curProfile.ThreshH = app.MaximumdisplacementforboundmoleculesumSpinner.Value;
            curProfile.minBoundFrames = app.MinimumnumberofframestocallthemoleculeboundframesSpinner.Value;

            %LAP Tracking
            curProfile.LAP_params.minSearchRadius = app.MinimumSearchRadiuspxSpinner.Value;
            curProfile.LAP_params.maxSearchRadius = app.MaximumSearchRadiuspxSpinner.Value;
            curProfile.LAP_params.brownStdMult = app.BrownianMultiplactionFactorSpinner.Value;
            curProfile.LAP_params.nnWindow = app.NearestNeighborTimeWindowSpinner.Value;
            curProfile.LAP_params.maxAngleVV = app.MaximumAngleSpinner.Value;
            curProfile.LAP_params.gapPenalty = app.GapPenaltySpinner.Value;
            
            
            switch app.MotionTypeDropDown.Value
                case 'Random Motion' 
                     curProfile.LAP_params.linearMotion = 0;
                case 'Random + Directed'
                    curProfile.LAP_params.linearMotion = 1;
                case 'Random + 1D Diffusion'
                    curProfile.LAP_params.linearMotion = 2;
            end
            
            switch app.MergeSplitTracksDropDown.Value
                case 'No Merge nor Split'
                    curProfile.LAP_params.mergeSplit = 0;
                case 'Merge & Split'
                    curProfile.LAP_params.mergeSplit = 1;
                case 'Merge Only'
                    curProfile.LAP_params.mergeSplit = 2;
                case 'Split Only'
                    curProfile.LAP_params.mergeSplit = 3;
            end
            curProfile.LAP_params.brownScaling(1) = app.BeforereachingConfinementSpinner.Value;
            curProfile.LAP_params.brownScaling(2) = app.AfterreachingConfinementSpinner.Value;
            curProfile.LAP_params.linScaling(1) = app.BeforereachingConfinementSpinner_2.Value;
            curProfile.LAP_params.linScaling(2) = app.AfterreachingConfinementSpinner_2.Value;
            curProfile.LAP_params.ampRatioLimit(1) = app.MinimumAmplitudeRatioSpinner.Value;
            curProfile.LAP_params.ampRatioLimit(2) = app.MaximumAmplitudeRatioSpinner.Value;

            %MTT Tracking
            curProfile.MTT_params.maxD = app.MaximumDum2sSpinner.Value;
            curProfile.MTT_params.errorRate = app.ErrorRate10valueSpinner.Value;
            curProfile.MTT_params.deflateLoops = app.DeflationLoopsSpinner.Value;
            curProfile.MTT_params.statWin = app.AveragingTimeWindowSpinner.Value;
            curProfile.MTT_params.minIntensity = app.MinimumIntensitySpinner.Value;
            curProfile.MTT_params.maxOptimIter = app.MaximumIterationsSpinner.Value;
            curProfile.MTT_params.countsPerPhoton = app.CountsphotonSpinner.Value;
            curProfile.MTT_params.searchExpFac = app.SearchSizeFactorSpinner.Value;
            curProfile.MTT_params.maxComp = app.MaximumComponentsSpinner.Value;
            curProfile.MTT_params.intLawWeight = app.IntensityFluctuationWeightSpinner.Value;
            curProfile.MTT_params.diffLawWeight = app.DiffusionWeightSpinner.Value;

            %Auto ROI parameters
            curProfile.autoROI_params.ROIradius = app.ROIradiuspixelsSpinner.Value;
            curProfile.autoROI_params.filtWindow = app.FilterWindowsizepixelsSpinner.Value;
            curProfile.autoROI_params.Morphradius = app.ErodeDilateelementradiuspixelsSpinner.Value;
            curProfile.autoROI_params.indTH = app.Thresholdforindividualimage0autoSpinner.Value;
            curProfile.autoROI_params.sumTH = app.Thresholdforsummedimage0autoSpinner.Value;
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, QTmainFigH, varargin)
            if length(varargin) > 1
                app.Profiles_in = varargin{1};
                app.sel_Profile_in = varargin{2};
                app.QT_location = varargin{3};
                ParamTab = varargin{4};
                switch ParamTab
                    case 1
                        app.TabGroup.SelectedTab = app.AcquisitionTab;
                    case 2
                        app.TabGroup.SelectedTab = app.CheckingTracksTab;
                    case 3
                        app.TabGroup.SelectedTab = app.AnalysisTab;
                    case 4
                        app.TabGroup.SelectedTab = app.BoundTab;
                    case 5
                        app.TabGroup.SelectedTab = app.LAPtrackingTab;
                    case 6
                        app.TabGroup.SelectedTab = app.MTTtrackingTab;
                    case 7
                        app.TabGroup.SelectedTab = app.AutoROIsTab;
                end
                
                %update the output Profile structure
                app.Profiles_out = app.Profiles_in;
                app.sel_Profile_out = app.sel_Profile_in;
                app.QTmainFigH = QTmainFigH;
                
                
                if length(varargin) > 4
                    pos_fig = varargin{5};
                    ChangeAppPosition(app.QuantiTrackParameterEditorUIFigure,pos_fig);
                    drawnow;
                end

                %Populate the dropdown menu
                profNames = cell(1,length(app.Profiles_in)+1);
                nProfs = length(app.Profiles_in);
                
                
                for i = 1:length(app.Profiles_in)
                    profNames{i} = app.Profiles_in(i).Name;
                end
                profNames{length(app.Profiles_in)+1} = 'New...';

                app.CurrentProfileDropDown.Items = profNames;
                
                if app.sel_Profile_in > nProfs
                    app.Profiles_out(app.sel_Profile_out) = app.Profiles_in(1);
                    app.Profiles_out(app.sel_Profile_out).Name =  [app.Profiles_in(1).Name, '_1'];
                    app.CurrentProfileDropDown.Value = 'New...';
                    app.CurrentProfileDropDown.Enable = "off";
                    
                else                    
                    app.CurrentProfileDropDown.Value = app.Profiles_in(app.sel_Profile_in).Name;
                end
                    
                setValues(app,app.Profiles_out(app.sel_Profile_out));
                

            end
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            %update the parameter values for the currently selected profile
            curProfile = getValues(app);
            curProfile.lastCellProtein = app.Profiles_out(app.sel_Profile_out).lastCellProtein;
            curProfile.lastCondition = app.Profiles_out(app.sel_Profile_out).lastCondition;
            curProfile.lastSession = app.Profiles_out(app.sel_Profile_out).lastSession;
            curProfile.lastName = app.Profiles_out(app.sel_Profile_out).lastName;

            app.Profiles_out(app.sel_Profile_out) = curProfile;

            Profiles = app.Profiles_out;
            lastProfile = app.sel_Profile_out;
            save(fullfile(app.QT_location,'QuantiTrack_localDefaults.mat'), 'Profiles', 'lastProfile');

            %store the Profile and lastProfile variable
            OUTvars{1} = Profiles;
            OUTvars{2} = lastProfile;

            setappdata(app.QTmainFigH,'QuantiTrackParamEditorVals',OUTvars);
            

            delete(app)


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             %store the Profile and lastProfile variable

            OUTvars{1} = app.Profiles_in;
            OUTvars{2} = app.sel_Profile_in;
    
            setappdata(app.QTmainFigH,'QuantiTrackParamEditorVals',OUTvars);

            delete(app)
        end

        % Value changed function: CurrentProfileDropDown
        function CurrentProfileDropDownValueChanged(app, event)
            selProfName = app.CurrentProfileDropDown.Value;
            
            if strcmp(selProfName,'New...')
                nProfs = length(app.Profiles_out);

                app.Profiles_out(nProfs+1) = app.Profiles_out(app.sel_Profile_out);
                app.Profiles_out(nProfs+1).Name = [app.Profiles_out(nProfs+1).Name, '_1'];
                app.NewNameEditField.Value = app.Profiles_out(nProfs+1).Name;
                app.sel_Profile_out = nProfs + 1;

            else
                for i = 1:length(app.Profiles_out)
                    if strcmp(app.Profiles_out(i).Name,selProfName)
                        app.sel_Profile_out = i;
                        break;
                    end
                end

                setValues(app,app.Profiles_out(app.sel_Profile_out));
            end


        end

        % Close request function: QuantiTrackParameterEditorUIFigure
        function QuantiTrackParameterEditorUIFigureCloseRequest(app, event)
            %store the Profile and lastProfile variable

            OUTvars{1} = app.Profiles_in;
            OUTvars{2} = app.sel_Profile_in;
    
            setappdata(0,'QuantiTrackParamEditorVals',OUTvars);
            delete(app)


        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            curDbase = app.TrackingDatabaseEditField.Value;
            db_selected = uigetdir(curDbase,'Select Database location');
            
            if db_selected ~= 0
                %update the stored db path
                
                app.TrackingDatabaseEditField.Value = db_selected;
                

            else
                app.TrackingDatabaseEditField.Value = curDbase;

            end
            drawnow
            figure(app.QuantiTrackParameterEditorUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuantiTrackParameterEditorUIFigure = uifigure('Visible', 'off');
            app.QuantiTrackParameterEditorUIFigure.Name = 'QuantiTrack Parameter Editor';
            app.QuantiTrackParameterEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @QuantiTrackParameterEditorUIFigureCloseRequest, true);
            app.CurrentProfileDropDownLabel = uilabel(app.QuantiTrackParameterEditorUIFigure);
            app.CurrentProfileDropDownLabel.Text = 'Current Profile';
            app.CurrentProfileDropDown = uidropdown(app.QuantiTrackParameterEditorUIFigure);
            app.CurrentProfileDropDown.Items = {'Default'};
            app.CurrentProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentProfileDropDownValueChanged, true);
            app.CurrentProfileDropDown.Value = 'Default';
            app.NewNameEditFieldLabel = uilabel(app.QuantiTrackParameterEditorUIFigure);
            app.NewNameEditFieldLabel.Text = 'New Name';
            app.NewNameEditField = uieditfield(app.QuantiTrackParameterEditorUIFigure, 'text');
            app.TabGroup = uitabgroup(app.QuantiTrackParameterEditorUIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.PixelSizenmSpinnerLabel = uilabel(app.AcquisitionTab);
            app.PixelSizenmSpinnerLabel.Text = 'Pixel Size (nm)';
            app.PixelSizenmSpinner = uispinner(app.AcquisitionTab);
            app.ExposureTimesSpinnerLabel = uilabel(app.AcquisitionTab);
            app.ExposureTimesSpinnerLabel.Text = 'Exposure Time (s)';
            app.ExposureTimesSpinner = uispinner(app.AcquisitionTab);
            app.FrameTimesSpinnerLabel = uilabel(app.AcquisitionTab);
            app.FrameTimesSpinnerLabel.Text = 'Frame Time (s)';
            app.FrameTimesSpinner = uispinner(app.AcquisitionTab);
            app.ObjectiveNASpinnerLabel = uilabel(app.AcquisitionTab);
            app.ObjectiveNASpinnerLabel.Text = 'Objective N.A.';
            app.ObjectiveNASpinner = uispinner(app.AcquisitionTab);
            app.EmissionWavelengthnmSpinnerLabel = uilabel(app.AcquisitionTab);
            app.EmissionWavelengthnmSpinnerLabel.Text = 'Emission Wavelength (nm)';
            app.EmissionWavelengthnmSpinner = uispinner(app.AcquisitionTab);
            app.AutoROIsTab = uitab(app.TabGroup);
            app.AutoROIsTab.Title = 'Auto ROIs';
            app.ROIradiuspixelsSpinnerLabel = uilabel(app.AutoROIsTab);
            app.ROIradiuspixelsSpinnerLabel.Text = 'ROI radius (pixels)';
            app.ROIradiuspixelsSpinner = uispinner(app.AutoROIsTab);
            app.ROIradiuspixelsSpinner.Value = 5;
            app.FilterWindowsizepixelsSpinnerLabel = uilabel(app.AutoROIsTab);
            app.FilterWindowsizepixelsSpinnerLabel.Text = 'Filter Window size (pixels)';
            app.FilterWindowsizepixelsSpinner = uispinner(app.AutoROIsTab);
            app.FilterWindowsizepixelsSpinner.Value = 5;
            app.ErodeDilateelementradiuspixelsSpinnerLabel = uilabel(app.AutoROIsTab);
            app.ErodeDilateelementradiuspixelsSpinnerLabel.Text = 'Erode/Dilate element radius (pixels)';
            app.ErodeDilateelementradiuspixelsSpinner = uispinner(app.AutoROIsTab);
            app.ErodeDilateelementradiuspixelsSpinner.Value = 3;
            app.Thresholdforindividualimage0autoSpinnerLabel = uilabel(app.AutoROIsTab);
            app.Thresholdforindividualimage0autoSpinnerLabel.Text = 'Threshold for individual image (0 = auto)';
            app.Thresholdforindividualimage0autoSpinner = uispinner(app.AutoROIsTab);
            app.Thresholdforindividualimage0autoSpinner.Value = 300;
            app.Thresholdforsummedimage0autoSpinnerLabel = uilabel(app.AutoROIsTab);
            app.Thresholdforsummedimage0autoSpinnerLabel.Text = 'Threshold for summed image (0 = auto)';
            app.Thresholdforsummedimage0autoSpinner = uispinner(app.AutoROIsTab);
            app.Thresholdforsummedimage0autoSpinner.Value = 35;
            app.CheckingTracksTab = uitab(app.TabGroup);
            app.CheckingTracksTab.Title = 'Checking Tracks';
            app.MinimumlengthoftrackstocheckframesSpinnerLabel = uilabel(app.CheckingTracksTab);
            app.MinimumlengthoftrackstocheckframesSpinnerLabel.Text = 'Minimum length of tracks to check (frames)';
            app.MinimumlengthoftrackstocheckframesSpinner = uispinner(app.CheckingTracksTab);
            app.LAPtrackingTab = uitab(app.TabGroup);
            app.LAPtrackingTab.Title = 'LAP tracking';
            app.ExponentforBrowniansearchradiusscalingPanel = uipanel(app.LAPtrackingTab);
            app.ExponentforBrowniansearchradiusscalingPanel.Title = 'Exponent for Brownian search radius scaling';
            app.BeforereachingConfinementSpinnerLabel = uilabel(app.ExponentforBrowniansearchradiusscalingPanel);
            app.BeforereachingConfinementSpinnerLabel.Text = 'Before reaching Confinement';
            app.BeforereachingConfinementSpinner = uispinner(app.ExponentforBrowniansearchradiusscalingPanel);
            app.BeforereachingConfinementSpinner.Value = 0.5;
            app.AfterreachingConfinementSpinnerLabel = uilabel(app.ExponentforBrowniansearchradiusscalingPanel);
            app.AfterreachingConfinementSpinnerLabel.Text = 'After reaching Confinement';
            app.AfterreachingConfinementSpinner = uispinner(app.ExponentforBrowniansearchradiusscalingPanel);
            app.AfterreachingConfinementSpinner.Limits = [0 Inf];
            app.AfterreachingConfinementSpinner.Value = 0.01;
            app.ExponentforLinearsearchradiusscalingPanel = uipanel(app.LAPtrackingTab);
            app.ExponentforLinearsearchradiusscalingPanel.Title = 'Exponent for Linear search radius scaling';
            app.BeforereachingConfinementSpinner_2Label = uilabel(app.ExponentforLinearsearchradiusscalingPanel);
            app.BeforereachingConfinementSpinner_2Label.Text = 'Before reaching Confinement';
            app.BeforereachingConfinementSpinner_2 = uispinner(app.ExponentforLinearsearchradiusscalingPanel);
            app.BeforereachingConfinementSpinner_2.Value = 1;
            app.AfterreachingConfinementSpinner_2Label = uilabel(app.ExponentforLinearsearchradiusscalingPanel);
            app.AfterreachingConfinementSpinner_2Label.Text = 'After reaching Confinement';
            app.AfterreachingConfinementSpinner_2 = uispinner(app.ExponentforLinearsearchradiusscalingPanel);
            app.AfterreachingConfinementSpinner_2.Limits = [0 Inf];
            app.AfterreachingConfinementSpinner_2.Value = 0.01;
            app.MinimumSearchRadiuspxSpinnerLabel = uilabel(app.LAPtrackingTab);
            app.MinimumSearchRadiuspxSpinnerLabel.Text = 'Minimum Search Radius (px)';
            app.MinimumSearchRadiuspxSpinner = uispinner(app.LAPtrackingTab);
            app.MinimumSearchRadiuspxSpinner.Value = 2;
            app.BrownianMultiplactionFactorSpinnerLabel = uilabel(app.LAPtrackingTab);
            app.BrownianMultiplactionFactorSpinnerLabel.Text = 'Brownian Multiplaction Factor';
            app.BrownianMultiplactionFactorSpinner = uispinner(app.LAPtrackingTab);
            app.BrownianMultiplactionFactorSpinner.Value = 3;
            app.NearestNeighborTimeWindowSpinnerLabel = uilabel(app.LAPtrackingTab);
            app.NearestNeighborTimeWindowSpinnerLabel.Text = 'Nearest Neighbor Time Window';
            app.NearestNeighborTimeWindowSpinner = uispinner(app.LAPtrackingTab);
            app.NearestNeighborTimeWindowSpinner.Value = 5;
            app.MaximumAngleSpinnerLabel = uilabel(app.LAPtrackingTab);
            app.MaximumAngleSpinnerLabel.Text = 'Maximum Angle';
            app.MaximumAngleSpinner = uispinner(app.LAPtrackingTab);
            app.MaximumAngleSpinner.Limits = [0 180];
            app.MaximumAngleSpinner.Value = 30;
            app.GapPenaltySpinnerLabel = uilabel(app.LAPtrackingTab);
            app.GapPenaltySpinnerLabel.Text = 'Gap Penalty';
            app.GapPenaltySpinner = uispinner(app.LAPtrackingTab);
            app.GapPenaltySpinner.Value = 1.5;
            app.MergeSplitTracksDropDownLabel = uilabel(app.LAPtrackingTab);
            app.MergeSplitTracksDropDownLabel.Text = 'Merge/Split Tracks';
            app.MergeSplitTracksDropDown = uidropdown(app.LAPtrackingTab);
            app.MergeSplitTracksDropDown.Items = {'No Merge nor Split', 'Merge & Split', 'Merge Only', 'Split Only'};
            app.MergeSplitTracksDropDown.Value = 'No Merge nor Split';
            app.MotionTypeDropDownLabel = uilabel(app.LAPtrackingTab);
            app.MotionTypeDropDownLabel.Text = 'Motion Type';
            app.MotionTypeDropDown = uidropdown(app.LAPtrackingTab);
            app.MotionTypeDropDown.Items = {'Random Motion', 'Random + Directed', 'Random + 1D Diffusion'};
            app.MotionTypeDropDown.Value = 'Random Motion';
            app.MaximumSearchRadiuspxSpinnerLabel = uilabel(app.LAPtrackingTab);
            app.MaximumSearchRadiuspxSpinnerLabel.Text = 'Maximum Search Radius (px)';
            app.MaximumSearchRadiuspxSpinner = uispinner(app.LAPtrackingTab);
            app.MaximumSearchRadiuspxSpinner.Value = 6;
            app.AmplitudeRatioofMergedfeaturesPanel = uipanel(app.LAPtrackingTab);
            app.AmplitudeRatioofMergedfeaturesPanel.Title = 'Amplitude Ratio of Merged features';
            app.MinimumAmplitudeRatioSpinnerLabel = uilabel(app.AmplitudeRatioofMergedfeaturesPanel);
            app.MinimumAmplitudeRatioSpinnerLabel.Text = 'Minimum Amplitude Ratio';
            app.MinimumAmplitudeRatioSpinner = uispinner(app.AmplitudeRatioofMergedfeaturesPanel);
            app.MinimumAmplitudeRatioSpinner.Value = 0.5;
            app.MaximumAmplitudeRatioSpinnerLabel = uilabel(app.AmplitudeRatioofMergedfeaturesPanel);
            app.MaximumAmplitudeRatioSpinnerLabel.Text = 'Maximum Amplitude Ratio';
            app.MaximumAmplitudeRatioSpinner = uispinner(app.AmplitudeRatioofMergedfeaturesPanel);
            app.MaximumAmplitudeRatioSpinner.Limits = [0 Inf];
            app.MaximumAmplitudeRatioSpinner.Value = 2;
            app.MTTtrackingTab = uitab(app.TabGroup);
            app.MTTtrackingTab.Title = 'MTT tracking';
            app.ErrorRate10valueSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.ErrorRate10valueSpinnerLabel.Text = 'Error Rate (10^{value})';
            app.ErrorRate10valueSpinner = uispinner(app.MTTtrackingTab);
            app.ErrorRate10valueSpinner.Value = -6;
            app.MaximumDum2sSpinner = uispinner(app.MTTtrackingTab);
            app.MaximumDum2sSpinner.Value = 1;
            app.DeflationLoopsSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.DeflationLoopsSpinnerLabel.Text = 'Deflation Loops';
            app.DeflationLoopsSpinner = uispinner(app.MTTtrackingTab);
            app.DeflationLoopsSpinner.Value = 1;
            app.AveragingTimeWindowSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.AveragingTimeWindowSpinnerLabel.Text = 'Averaging Time Window';
            app.AveragingTimeWindowSpinner = uispinner(app.MTTtrackingTab);
            app.AveragingTimeWindowSpinner.Value = 10;
            app.MinimumIntensitySpinnerLabel = uilabel(app.MTTtrackingTab);
            app.MinimumIntensitySpinnerLabel.Text = 'Minimum Intensity';
            app.MinimumIntensitySpinner = uispinner(app.MTTtrackingTab);
            app.CountsphotonSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.CountsphotonSpinnerLabel.Text = 'Counts/photon';
            app.CountsphotonSpinner = uispinner(app.MTTtrackingTab);
            app.CountsphotonSpinner.Value = 20.2;
            app.SearchSizeFactorSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.SearchSizeFactorSpinnerLabel.Text = 'Search Size Factor';
            app.SearchSizeFactorSpinner = uispinner(app.MTTtrackingTab);
            app.SearchSizeFactorSpinner.Value = 1.2;
            app.MaximumComponentsSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.MaximumComponentsSpinnerLabel.Text = 'Maximum Components';
            app.MaximumComponentsSpinner = uispinner(app.MTTtrackingTab);
            app.MaximumComponentsSpinner.Value = 3;
            app.IntensityFluctuationWeightSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.IntensityFluctuationWeightSpinnerLabel.Text = 'Intensity Fluctuation Weight';
            app.IntensityFluctuationWeightSpinner = uispinner(app.MTTtrackingTab);
            app.IntensityFluctuationWeightSpinner.Value = 0.9;
            app.DiffusionWeightSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.DiffusionWeightSpinnerLabel.Text = 'Diffusion Weight';
            app.DiffusionWeightSpinner = uispinner(app.MTTtrackingTab);
            app.DiffusionWeightSpinner.Value = 0.5;
            app.MaximumIterationsSpinnerLabel = uilabel(app.MTTtrackingTab);
            app.MaximumIterationsSpinnerLabel.Text = 'Maximum Iterations';
            app.MaximumIterationsSpinner = uispinner(app.MTTtrackingTab);
            app.MaximumIterationsSpinner.Value = 50;
            app.MaximumDum2sLabel = uilabel(app.MTTtrackingTab);
            app.MaximumDum2sLabel.Text = 'Maximum D (um2/s)';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.MaximumFramesforDisplacementsHistogramSpinner = uispinner(app.AnalysisTab);
            app.DisplacementHistogramBinSizeumSpinner = uispinner(app.AnalysisTab);
            app.NumberofframesforlinearfitofMSDSpinnerLabel = uilabel(app.AnalysisTab);
            app.NumberofframesforlinearfitofMSDSpinnerLabel.Text = 'Number of frames for linear fit of MSD';
            app.NumberofframesforlinearfitofMSDSpinner = uispinner(app.AnalysisTab);
            app.HistogramofDisplacementsLabel = uilabel(app.AnalysisTab);
            app.HistogramofDisplacementsLabel.Text = 'Histogram of Displacements';
            app.BinSizeforTemporalAnisotropysSpinnerLabel = uilabel(app.AnalysisTab);
            app.BinSizeforTemporalAnisotropysSpinnerLabel.Text = 'Bin Size  for Temporal Anisotropy (s)';
            app.BinSizeforTemporalAnisotropysSpinner = uispinner(app.AnalysisTab);
            app.BinSizeforSpatialAnisotropynmSpinnerLabel = uilabel(app.AnalysisTab);
            app.BinSizeforSpatialAnisotropynmSpinnerLabel.Text = 'Bin Size  for Spatial Anisotropy (nm)';
            app.BinSizeforSpatialAnisotropynmSpinner = uispinner(app.AnalysisTab);
            app.BootstrapIterationsSpinnerLabel = uilabel(app.AnalysisTab);
            app.BootstrapIterationsSpinnerLabel.Text = 'Bootstrap Iterations';
            app.BootstrapIterationsSpinner = uispinner(app.AnalysisTab);
            app.AngleAnisotropyLabel = uilabel(app.AnalysisTab);
            app.AngleAnisotropyLabel.Text = 'Angle Anisotropy';
            app.MeanSquaredDisplacementsLabel = uilabel(app.AnalysisTab);
            app.MeanSquaredDisplacementsLabel.Text = 'Mean Squared Displacements';
            app.MaximumFramesforDisplacementHistogramLabel = uilabel(app.AnalysisTab);
            app.MaximumFramesforDisplacementHistogramLabel.Text = 'Maximum Frames for Displacement Histogram';
            app.DisplacementHistogramBinsizeumLabel = uilabel(app.AnalysisTab);
            app.DisplacementHistogramBinsizeumLabel.Text = 'Displacement Histogram Bin size (um)';
            app.BoundTab = uitab(app.TabGroup);
            app.BoundTab.Title = 'Bound';
            app.FrametoFramemaxdisplacementforboundmoleculesumSpinnerLabel = uilabel(app.BoundTab);
            app.FrametoFramemaxdisplacementforboundmoleculesumSpinnerLabel.Text = 'Frame to Frame max displacement for bound molecules (um)';
            app.FrametoFramemaxdisplacementforboundmoleculesumSpinner = uispinner(app.BoundTab);
            app.MaximumdisplacementforboundmoleculesumSpinnerLabel = uilabel(app.BoundTab);
            app.MaximumdisplacementforboundmoleculesumSpinnerLabel.Text = 'Maximum displacement for bound molecules (um)';
            app.MaximumdisplacementforboundmoleculesumSpinner = uispinner(app.BoundTab);
            app.MinimumnumberofframestocallthemoleculeboundframesSpinnerLabel = uilabel(app.BoundTab);
            app.MinimumnumberofframestocallthemoleculeboundframesSpinnerLabel.Text = 'Minimum number of frames to call the molecule bound (frames)';
            app.MinimumnumberofframestocallthemoleculeboundframesSpinner = uispinner(app.BoundTab);
            app.SaveCloseButton = uibutton(app.QuantiTrackParameterEditorUIFigure, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = 'Save & Close';
            app.CancelButton = uibutton(app.QuantiTrackParameterEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TrackingDatabaseEditFieldLabel = uilabel(app.QuantiTrackParameterEditorUIFigure);
            app.TrackingDatabaseEditFieldLabel.Text = 'Tracking Database';
            app.TrackingDatabaseEditField = uieditfield(app.QuantiTrackParameterEditorUIFigure, 'text');
            app.BrowseButton = uibutton(app.QuantiTrackParameterEditorUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            % Show the figure after all components are created
            app.QuantiTrackParameterEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuantiTrack_SetParams_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuantiTrackParameterEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuantiTrackParameterEditorUIFigure)
        end
    end
end